classdef TAT08AnalysisConservativeTrTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileActionsMenu                 matlab.ui.container.Menu
        ReadconservativetracerobservationsMenu  matlab.ui.container.Menu
        SaveparametersMenu              matlab.ui.container.Menu
        ReadparametersMenu              matlab.ui.container.Menu
        SaveplottedresultsMenu          matlab.ui.container.Menu
        TaskControlMenu                 matlab.ui.container.Menu
        DesignMenu                      matlab.ui.container.Menu
        desConservativetracerMenu       matlab.ui.container.Menu
        desTemperatureprofilesMenu      matlab.ui.container.Menu
        desReactivetracerselectionMenu  matlab.ui.container.Menu
        desReactivetracerMenu           matlab.ui.container.Menu
        AnalysisMenu                    matlab.ui.container.Menu
        analConservativetracerMenu      matlab.ui.container.Menu
        analReactivetracerMenu          matlab.ui.container.Menu
        CommonVariablesMenu             matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        SendtoMenu                      matlab.ui.container.Menu
        PullfromMenu                    matlab.ui.container.Menu
        PlotOptPnl                      matlab.ui.container.Panel
        FillColorColorPicker            matlab.ui.control.ColorPicker
        FillColorColorPickerLabel       matlab.ui.control.Label
        EdgeColorColorPicker            matlab.ui.control.ColorPicker
        EdgeColorColorPickerLabel       matlab.ui.control.Label
        PlotOptPnlEdgeThicknessLabel    matlab.ui.control.Label
        PlotOptPnlEdgeThicknessEditField  matlab.ui.control.NumericEditField
        PlotOptPnlMarkerStyleDropDown   matlab.ui.control.DropDown
        PlotOptPnlMarkerSizeEditField   matlab.ui.control.NumericEditField
        PlotOptPnlMarkerSizeLabel       matlab.ui.control.Label
        PlotOptPnlMarkerStyleLabel      matlab.ui.control.Label
        PlotOptPnlDataMarkerPropertiesLabel  matlab.ui.control.Label
        PlotOptPnlFittedLinePropertiesLabel  matlab.ui.control.Label
        PlotOptPnlLinePropertiesUITable  matlab.ui.control.Table
        PlotOptPnl_MaxLabel             matlab.ui.control.Label
        PlotOptPnl_MinLabel             matlab.ui.control.Label
        PlotOptPnl_c_Label              matlab.ui.control.Label
        PlotOptPnl_t_Label              matlab.ui.control.Label
        PlotOptPnl_t_min_EditField      matlab.ui.control.NumericEditField
        PlotOptPnl_t_max_EditField      matlab.ui.control.NumericEditField
        PlotOptPnl_c_min_EditField      matlab.ui.control.NumericEditField
        PlotOptPnl_c_max_EditField      matlab.ui.control.NumericEditField
        ResultsPanel                    matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ResultsInfoTab                  matlab.ui.container.Tab
        ResultsPnlTextArea              matlab.ui.control.TextArea
        FittedParametersTable           matlab.ui.container.Tab
        FittedParametersUITable         matlab.ui.control.Table
        ClearResultsButton              matlab.ui.control.Button
        ResultsCopy2TableButton         matlab.ui.control.Button
        TolFunEditField                 matlab.ui.control.NumericEditField
        FunctiontoleranceLabel          matlab.ui.control.Label
        MaxIterMultEditField            matlab.ui.control.NumericEditField
        MaxiterationsmultiplierLabel    matlab.ui.control.Label
        TolParamEditField               matlab.ui.control.NumericEditField
        RelativetoleranceEditFieldLabel  matlab.ui.control.Label
        MaxFuncEvalsMultEditField       matlab.ui.control.NumericEditField
        MaxfunctionevalsmultiplierEditFieldLabel  matlab.ui.control.Label
        SolverSelectionDropDown         matlab.ui.control.DropDown
        SolverselectionDropDownLabel    matlab.ui.control.Label
        BreakthroughcurveloadedLamp     matlab.ui.control.Lamp
        BreakthroughcurveloadedLampLabel  matlab.ui.control.Label
        ResultsPnlFitDataButton         matlab.ui.control.Button
        EqHelpButton                    matlab.ui.control.Button
        EqImage                         matlab.ui.control.Image
        PlotsPanel                      matlab.ui.container.Panel
        UpdatePlotswithCurrentPlotOptionsButton  matlab.ui.control.Button
        PltPnlTabGroup                  matlab.ui.container.TabGroup
        PltPnlTabGrpCvstTab             matlab.ui.container.Tab
        PltPnlTabGrpCvsTUIAxes          matlab.ui.control.UIAxes
        PltPnlTabGrpResidualCvstTab     matlab.ui.container.Tab
        PltPnlTabGrpResidualCvsTUIAxes  matlab.ui.control.UIAxes
        PltPnlTabGrpNormProbPlotResTab  matlab.ui.container.Tab
        PltPnlTabGrpNormProbPlotResUIAxes_3  matlab.ui.control.UIAxes
        ParamPanel                      matlab.ui.container.Panel
        mLabel                          matlab.ui.control.Label
        cLabel                          matlab.ui.control.Label
        ParamPnl_alpha_m_EditField      matlab.ui.control.NumericEditField
        ParamPnl_alpha_FunctionLabel    matlab.ui.control.Label
        ParamPnl_alpha_c_EditField      matlab.ui.control.NumericEditField
        ParamPnl_nChn_EditField         matlab.ui.control.NumericEditField
        ParamPnl_delta_p_Label          matlab.ui.control.Label
        ParamPnl_delta_p_EditField      matlab.ui.control.NumericEditField
        ParamPnlAddChannelButton        matlab.ui.control.Button
        ParamPnlDeleteChannelsButton    matlab.ui.control.Button
        ParamPnlParamOptLabel           matlab.ui.control.Label
        ParamPnlTree                    matlab.ui.container.CheckBoxTree
        Chn1Node                        matlab.ui.container.TreeNode
        VelocityNode                    matlab.ui.container.TreeNode
        PathLengthNode                  matlab.ui.container.TreeNode
        FluidMassFracNode               matlab.ui.container.TreeNode
        ParamPnl_T_res_label            matlab.ui.control.Label
        ParamPnl_T_res_EditField        matlab.ui.control.NumericEditField
        ParamPnlPumpRateLabel           matlab.ui.control.Label
        ParamPnl_Q_EditField            matlab.ui.control.NumericEditField
        ParamPnl_M_con_Label            matlab.ui.control.Label
        ParamPnl_M_con_EditField        matlab.ui.control.NumericEditField
        ParamPnl_nChn_Label             matlab.ui.control.Label
        ParamPnlUITable                 matlab.ui.control.Table
        MainLabel                       matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        CleartextareaMenu               matlab.ui.container.Menu
    end

    
    properties (Access = private)
        % Start up
        TATPath                         % Path to Tracer Analysis Toolbox
        TATName                         % Name of Tracer Analysis Toolbox file
        TAText                          % Extension for Tracer Analysis Toolbox file
        MLDefaultColors                 % Vector of RGB codes for line default colors us in Matlab
        hScale                          % Horizontal scaling factor for monitor resolution differences
        vScale                          % Vertical scaling factor for monitor resolution differences

        CommonVars_Hndl                  
        DesignConservativeTrTest_Hndl
        DesignTemperatureProfile_Hndl
        DesignSelectReactiveTr_Hndl
        DesignReactiveTrTest_Hndl
        AnalysisReactiveTrTest_Hndl        
        
        % Parameter Panel
        TableCheckBox        % Checkboxes used in the Table
        nChn                           % Number of channels to use in design workflow
        tData                           % Time value for the measured concentration vs time data
        cData                           % Concentration value for the measured concentration vs time data
%MAP
        ChnActiveVec                    % Vector of channel numbers for which checkboxes are checked
        cFitMean                        % Concentration (fitted) of average of several channels reaching well
        cFit                            % Concentration (fitted) at exit of a single channel 
        ParamArray                      % Array of parameters for selected channels, pre & post fitting
        FitPlots                        % All of the BTC curves (fitted channels & mean) from the current fitting
        ResPlot                         % Residuals of fitted mean vs obs
        StdResPlot                      % Standardized residuals of fitted mean vs obs

        TrMass                          % Mass of conservative tracer
        PumpRate                        % Pumping rate of extraction well
        OpTime                          % Operating time of the injection
        node1                           % Level 1 node in tree
        node2                           % Level 1 node in tree
        node3                           % Level 1 node in tree
        node4                           % Level 1 node in tree
        node5                           % Level 1 node in tree
        node6                           % Level 1 node in tree
        node7                           % Level 1 node in tree
        node8                           % Level 1 node in tree
        node9                           % Level 1 node in tree
        node10                          % Level 1 node in tree
        node1Vel                        % Node in tree checkbox for velocity
        node1PathL                      % Node in tree checkbox for path length
        node1Frac                       % Node in tree checkbox for mass fraction in channel
        node2Vel                        % Node in tree checkbox for velocity
        node2PathL                      % Node in tree checkbox for path length
        node2Frac                       % Node in tree checkbox for mass fraction in channel
        node3Vel                        % Node in tree checkbox for velocity
        node3PathL                      % Node in tree checkbox for path length
        node3Frac                       % Node in tree checkbox for mass fraction in channel
        node4Vel                        % Node in tree checkbox for velocity
        node4PathL                      % Node in tree checkbox for path length
        node4Frac                       % Node in tree checkbox for mass fraction in channel
        node5Vel                        % Node in tree checkbox for velocity
        node5PathL                      % Node in tree checkbox for path length
        node5Frac                       % Node in tree checkbox for mass fraction in channel
        node6Vel                        % Node in tree checkbox for velocity
        node6PathL                      % Node in tree checkbox for path length
        node6Frac                       % Node in tree checkbox for mass fraction in channel
        node7Vel                        % Node in tree checkbox for velocity
        node7PathL                      % Node in tree checkbox for path length
        node7Frac                       % Node in tree checkbox for mass fraction in channel
        node8Vel                        % Node in tree checkbox for velocity
        node8PathL                      % Node in tree checkbox for path length
        node8Frac                       % Node in tree checkbox for mass fraction in channel
        node9Vel                        % Node in tree checkbox for velocity
        node9PathL                      % Node in tree checkbox for path length
        node9Frac                       % Node in tree checkbox for mass fraction in channel
        node10Vel                       % Node in tree checkbox for velocity
        node10PathL                     % Node in tree checkbox for path length
        node10Frac                      % Node in tree checkbox for mass fraction in channel

        % Plot Options Panel
        PlotOptlineStyleList % Line styles to be used in plot
        InitialColor                    % Initial color of lines to appear in plot
        PlotOptTableData     % Copy of data used in plot options table        
    end
    
    methods (Access = private)
        
        function PlotDataCvT(app,x,y)
            % Get Plot Options
            %
            MarkerSyms = {'o';'+';'*';'.';'x';'_';'|';'square';'diamond';'^';'v';'>';'<';'pentagram';'hexagram';'none'};
            markerStyle = MarkerSyms(ismember(app.PlotOptPnlMarkerStyleDropDown.Items,app.PlotOptPnlMarkerStyleDropDown.Value));

            markerSize = app.PlotOptPnlMarkerSizeEditField.Value;
            markerEdgeColor = app.EdgeColorColorPicker.Value;
            markerFillColor = app.FillColorColorPicker.Value; 
            LineWidthness = app.PlotOptPnlEdgeThicknessEditField.Value;

            % Plot Data
            plot(app.PltPnlTabGrpCvsTUIAxes,x,y,"Marker",markerStyle,"MarkerSize", markerSize, "MarkerEdgeColor", markerEdgeColor,"MarkerFaceColor", markerFillColor,"LineStyle","none","LineWidth",LineWidthness)
        end
        
        function TATSetup(app)
        % SETUP: establishes basic information about the app
        % including location, screen size, default plot colors
            % Get location of app
            [app.TATPath, app.TATName, app.TAText] = fileparts(mfilename('fullpath'));

            % Add path to functions
            addPathNames = strcat(app.TATPath,"\TAT_Fcts");
            addpath(genpath(addPathNames));

            % Get Screen size and define scaling factors %MAP
            screenSize = get(groot,'ScreenSize');
           if screenSize(3) < app.UIFigure.Position(3)
               app.hScale = screenSize(3)/2560;
           else 
               app.hScale = 1;
           end 
           if screenSize(4) < app.UIFigure.Position(4)
               app.vScale = screenSize(4)/1440;
           else 
               app.vScale = 1;
           end 

            % Set Matlab's default colors for lines
            app.MLDefaultColors = ["0.000, 0.447, 0.741" "0.850, 0.325, 0.098" "0.929, 0.694, 0.125" "0.494, 0.184, 0.556" "0.466, 0.674, 0.188" ...
                "0.301, 0.745, 0.933" "0.635, 0.078, 0.184","0.000 0.000 1.000","0.000 0.500 0.000","1.000 0.000 0.000" "0.000 0.000 0.000"]; 
        end
        
        function ParamTableInitial(app)
            app.TableCheckBox = {true; true; true; true; true; true; true; true; true; true};
            app.ParamPnlUITable.Data = table(1,1,100,1,1,true);
            app.ParamPnlUITable.Data.Properties.VariableNames={'Channel','v','L','f','alpha','Include'};
            app.nChn = 1;
            app.ParamPnl_nChn_EditField.Value = app.nChn;
            s1 = uistyle('BackgroundColor',[0.65,0.65,0.65]);
            addStyle(app.ParamPnlUITable,s1,'column',5)
        end
        
        function UpdateParamPnl(app)
            % Populate dispersivity column with calculated values 
            app.ParamPnlUITable.Data.alpha = app.ParamPnl_alpha_c_EditField.Value .* app.ParamPnlUITable.Data.L .^ app.ParamPnl_alpha_m_EditField.Value;
        end
        
        function UpdatePlotOptTable(app)
        % Update Plot Options Table
            % Define columns (include extra row for mean of channels)
            col1 = {'0';'0';'0';'0';'0';'0';'0';'0';'0';'0';'0'};
            col2 = {app.MLDefaultColors(1);app.MLDefaultColors(2);app.MLDefaultColors(3);app.MLDefaultColors(4);app.MLDefaultColors(5);app.MLDefaultColors(6);...
                    app.MLDefaultColors(7);app.MLDefaultColors(8);app.MLDefaultColors(9);app.MLDefaultColors(10);app.MLDefaultColors(11)};            
            col3 = {2;2;2;2;2;2;2;2;2;2;2};
            lStyle = app.PlotOptlineStyleList(2);
            col4 = {lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle};

            % First column is channel ID        
            for i = 1 : app.nChn
                col1{i} = app.ParamPnlUITable.Data{i,1};
            end
            col1(app.nChn+1) = {'Mean C'};
            col2{app.nChn+1,1} = app.MLDefaultColors(11);                      
            
            app.PlotOptTableData = table(col1, col2, col3, col4);
            app.PlotOptPnlLinePropertiesUITable.Data = app.PlotOptTableData(1 : app.nChn + 1,:);

            % Change background color of column 2
            endLoop = app.nChn+1;
            for i = 1:endLoop
                myColorStyle = uistyle("BackgroundColor",app.PlotOptPnlLinePropertiesUITable.Data{i,2}{1},"FontColor",[0.94 0.94 0.94]);
                addStyle(app.PlotOptPnlLinePropertiesUITable,myColorStyle,'cell',[i,2]);
            end      
        end       

        function analysisPlotOptPnlTableInitialize(app)
        % Initialize the Plot Options Table in the Plot Options panel
            app.PlotOptlineStyleList = categorical({'Solid', 'Dashed', 'Dotted', 'Dash-Dotted','none'});
            app.InitialColor = [app.MLDefaultColors(1)];
            col1 = {'1'; 'Mean C'};
            col2 = [app.MLDefaultColors(1);app.MLDefaultColors(11)];
            col3 = {2;2};
            col4 = {app.PlotOptlineStyleList(2);app.PlotOptlineStyleList(1)};
            app.PlotOptTableData = table(col1, col2, col3, col4);

            % Initialize values associated with table
            app.PlotOptPnlLinePropertiesUITable.Data = app.PlotOptTableData;

            % Format the Table
            app.PlotOptPnlLinePropertiesUITable.ColumnWidth = {65 130 81 125};
            for i = 1:2
                myColorStyle = uistyle("BackgroundColor",app.PlotOptPnlLinePropertiesUITable.Data{i,2},"FontColor",[0.94 0.94 0.94]);
                addStyle(app.PlotOptPnlLinePropertiesUITable,myColorStyle,'cell',[i,2]);
            end
        end
        
        function UpdateTree(app)
	    % Delete all nodes from existing tree
	    app.ParamPnlTree.Children.delete;
	
	    % Add Nodes to tree
        % Define level 1 node
	    nodeText = strcat('Chn',num2str(app.ParamPnlUITable.Data{1,1}));
	    app.node1 = uitreenode(app.ParamPnlTree,"Text", nodeText);
            % Define level 2 nodes
		    app.node1Vel = uitreenode(app.node1, "Text", "Velocity");
		    app.node1PathL = uitreenode(app.node1, app.node1Vel, "after", "Text", "Path Length");
		    app.node1Frac = uitreenode(app.node1, app.node1PathL, "after", "Text", "Fluid Mass Frac.");
            % Check the checkboxes
            app.ParamPnlTree.CheckedNodes = [app.node1Vel app.node1PathL app.node1Frac];
	    if (app.nChn == 1)
            return; 
        end
        
        % Define the level 1 node
        nodeText = strcat('Chn',num2str(app.ParamPnlUITable.Data{2,1}));
	    app.node2 = uitreenode(app.ParamPnlTree,"Text", nodeText);
            % Define the level 2 nodes
		    app.node2Vel = uitreenode(app.node2, "Text", "Velocity");	    
		    app.node2PathL = uitreenode(app.node2, app.node2Vel, "after", "Text", "Path Length");
            app.node2Frac = uitreenode(app.node2, app.node2PathL, "after", "Text", "Fluid Mass Frac.");
            % Check the checkboxes
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node2Vel;
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node2PathL;
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node2Frac;
	    if (app.nChn == 2)
            return; 
        end   

        % Define the level 1 node
        nodeText = strcat('Chn',num2str(app.ParamPnlUITable.Data{3,1}));
	    app.node3 = uitreenode(app.ParamPnlTree,"Text", nodeText);
            % Define level 2 nodes
		    app.node3Vel = uitreenode(app.node3, "Text", "Velocity");
		    app.node3PathL = uitreenode(app.node3, app.node3Vel, "after", "Text", "Path Length");
            app.node3Frac = uitreenode(app.node3, app.node3PathL, "after", "Text", "Fluid Mass Frac.");
            % Check the checkboxes
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node3Vel;
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node3PathL;
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node3Frac;
	    if (app.nChn == 3); return; end

        % Define the level 1 node
        nodeText = strcat('Chn',num2str(app.ParamPnlUITable.Data{4,1}));
	    app.node4 = uitreenode(app.ParamPnlTree,"Text", nodeText);
            % Define level 2 nodes
		    app.node4Vel = uitreenode(app.node4, "Text", "Velocity");
		    app.node4PathL = uitreenode(app.node4, app.node4Vel, "after", "Text", "Path Length");
            app.node4Frac = uitreenode(app.node4, app.node4PathL, "after", "Text", "Fluid Mass Frac.");
            % Check the checkboxes
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node4Vel;
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node4PathL;
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node4Frac;
	    if (app.nChn == 4); return; end

        % Define the level 1 node
        nodeText = strcat('Chn',num2str(app.ParamPnlUITable.Data{5,1}));
	    app.node5 = uitreenode(app.ParamPnlTree,"Text", nodeText);
            % Define level 2 nodes
		    app.node5Vel = uitreenode(app.node5, "Text", "Velocity");
		    app.node5PathL = uitreenode(app.node5, app.node5Vel, "after", "Text", "Path Length");
            app.node5Frac = uitreenode(app.node5, app.node5PathL, "after", "Text", "Fluid Mass Frac.");
            % Check the checkboxes
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node5Vel;
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node5PathL;
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node5Frac;
	    if (app.nChn == 5); return; end

        % Define the level 1 node
        nodeText = strcat('Chn',num2str(app.ParamPnlUITable.Data{6,1}));
	    app.node6 = uitreenode(app.ParamPnlTree,"Text", nodeText);
		    % Define level 2 nodes
            app.node6Vel = uitreenode(app.node6, "Text", "Velocity");
		    app.node6PathL = uitreenode(app.node6, app.node6Vel, "after", "Text", "Path Length");
            app.node6Frac = uitreenode(app.node6, app.node6PathL, "after", "Text", "Fluid Mass Frac.");
            % Check the checkboxes
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node6Vel;
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node6PathL;
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node6Frac;
	    if (app.nChn == 6); return; end

        % Define the level 1 node
        nodeText = strcat('Chn',num2str(app.ParamPnlUITable.Data{7,1}));
	    app.node7 = uitreenode(app.ParamPnlTree,"Text", nodeText);
            % Define level 2 nodes
		    app.node7Vel = uitreenode(app.node7, "Text", "Velocity");
		    app.node7PathL = uitreenode(app.node7, app.node7Vel, "after", "Text", "Path Length");
            app.node7Frac = uitreenode(app.node7, app.node7PathL, "after", "Text", "Fluid Mass Frac.");
            % Check the checkboxes
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node7Vel;
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node7PathL;
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node7Frac;
	    if (app.nChn == 7); return; end

        % Define the level 1 node
        nodeText = strcat('Chn',num2str(app.ParamPnlUITable.Data{8,1}));
	    app.node8 = uitreenode(app.ParamPnlTree,"Text", nodeText);
            % Define level 2 nodes
		    app.node8Vel = uitreenode(app.node8, "Text", "Velocity");
		    app.node8PathL = uitreenode(app.node8, app.node8Vel, "after", "Text", "Path Length");
            app.node8Frac = uitreenode(app.node8, app.node8PathL, "after", "Text", "Fluid Mass Frac.");
            % Check the checkboxes
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node8Vel;
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node8PathL;
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node8Frac;
	    if (app.nChn == 8); return; end

        % Define the level 1 node
        nodeText = strcat('Chn',num2str(app.ParamPnlUITable.Data{9,1}));
	    app.node9 = uitreenode(app.ParamPnlTree,"Text", nodeText);
            % Define level 2 nodes
		    app.node9Vel = uitreenode(app.node9, "Text", "Velocity");
		    app.node9PathL = uitreenode(app.node9, app.node9Vel, "after", "Text", "Path Length");
            app.node9Frac = uitreenode(app.node9, app.node9PathL, "after", "Text", "Fluid Mass Frac.");
            % Check the checkboxes
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node9Vel;
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node9PathL;
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node9Frac;
	    if (app.nChn == 9); return; end

        % Define the level 1 node
        nodeText = strcat('Chn',num2str(app.ParamPnlUITable.Data{10,1}));
	    app.node10 = uitreenode(app.ParamPnlTree,"Text", nodeText);
            % Define level 2 nodes
		    app.node10Vel = uitreenode(app.node9, "Text", "Velocity");
		    app.node10PathL = uitreenode(app.node10, app.node10Vel, "after", "Text", "Path Length");
            app.node10Frac = uitreenode(app.node10, app.node10PathL, "after", "Text", "Fluid Mass Frac.");
            % Check the checkboxes
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node10Vel;
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node10PathL;
            app.ParamPnlTree.CheckedNodes(end + 1) = app.node10Frac;
	    if (app.nChn == 10); return; end
        end
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Get screen size and define hScale, vScale, and plot default colors
            TATSetup(app)

            % Inialize Parameter Table
            ParamTableInitial(app)

            % Initialize Plot Options Table
            analysisPlotOptPnlTableInitialize(app)

            % Initialize Tree
            app.ParamPnlTree.CheckedNodes = [app.VelocityNode app.PathLengthNode app.FluidMassFracNode];
    
        end

        % Callback function
        function PlotOptPnlEdgeColorEditFieldValueChanged(app, event)
            % Open color selection menu
            colorOld = str2num(app.PlotOptPnlEdgeColorEditField.Value);
            app.PlotOptPnlEdgeColorEditField.Value = num2str(round(uisetcolor(colorOld),3));
        end

        % Callback function
        function PlotOptPnlFillColorEditFieldValueChanged(app, event)
            % Open color selection menu
            colorOld = str2num(app.PlotOptPnlFillColorEditField.Value);
            app.PlotOptPnlFillColorEditField.Value = num2str(round(uisetcolor(colorOld),3));
        end

        % Button pushed function: UpdatePlotswithCurrentPlotOptionsButton
        function UpdatePlotswithCurrentPlotOptionsButtonPushed(app, event)
           % Plot concentration versus time data using updated line/symbols

           % If app.FitPlots hasn't been created, create them from parameter table and tell user they are not fitted
           % [app.cFitMean, app.cFit] = ConTracer(tcData(1,:),M,Q,alphaParams,app.ParamArray,rhof);          
           % app.FitPlots = plot(app.PltPnlTabGrpCvsTUIAxes,...
           %                                             tcData(1,:),app.cFit,'x',...
           %                                             tcData(1,:),app.cFitMean,'k-',...
           %                                             tcData(1,:),tcData(2,:),'ko');
           % legend(app.PltPnlTabGrpCvsTUIAxes,[string(app.ChnActiveVec);'Mean c';'BTC data']);            
            
           % Match up line choices to the plotted ('active') channels          
            nChn = length(app.ParamPnlUITable.Data.Channel);
            nChnActive = length(app.ChnActiveVec);
            LineStyles = {'-';'--';':';'-.';''};
            RowIndices = find(ismember(app.ParamPnlUITable.Data.Channel,app.ChnActiveVec));
            for j = 1: nChnActive + 1
                if j > nChnActive
                    i = nChn + 1;
                else
                    i = RowIndices(j);
                end
                app.FitPlots(j).Color = str2num(app.PlotOptPnlLinePropertiesUITable.Data{i,2}{1});
                app.FitPlots(j).LineWidth = app.PlotOptPnlLinePropertiesUITable.Data.col3{i};
                app.FitPlots(j).LineStyle = LineStyles(ismember(app.PlotOptlineStyleList, app.PlotOptPnlLinePropertiesUITable.Data.col4{i}));
            end      
            MarkerSyms = {'o';'+';'*';'.';'x';'_';'|';'square';'diamond';'^';'v';'>';'<';'pentagram';'hexagram';'none'};
            app.FitPlots(nChnActive + 2).Marker = MarkerSyms(ismember(app.PlotOptPnlMarkerStyleDropDown.Items,app.PlotOptPnlMarkerStyleDropDown.Value));               
            app.FitPlots(nChnActive + 2).MarkerEdgeColor = app.EdgeColorColorPicker.Value;
            app.FitPlots(nChnActive + 2).MarkerFaceColor = app.FillColorColorPicker.Value;           
        end

        % Button pushed function: ParamPnlAddChannelButton
        function ParamPnlAddChannelButtonPushed(app, event)

            % Check if the number of channels is <= 10
            if(app.nChn < 10)
                if(app.nChn == 0)
                    maxChn = 0;
                else
                    % Find the largest channel number
                    maxChn = max(app.ParamPnlUITable.Data{:,1});
                    % maxChn = app.ParamPnlUITable.Data{1,1};
                    for i = 2:app.nChn
                        if(app.ParamPnlUITable.Data{i,1} > maxChn)
                            maxChn = app.ParamPnlUITable.Data{i,1};
                        end
                    end
                end
                maxChn = maxChn + 1;

                % Increment the number of channels and update editfield displaying nChn
                app.nChn = app.nChn + 1;
                app.ParamPnl_nChn_EditField.Value = app.nChn;

                % Create new row from last row, with checkbox. Add row to table
                newrow = app.ParamPnlUITable.Data(end,:) ;
                newrow.Channel = maxChn;
                newrow.L = newrow.L * 1.2 ;
                app.ParamPnlUITable.Data  = [app.ParamPnlUITable.Data; newrow];   

                % Update Parameters backup Table and use it to update checkbox column
                UpdateParamPnl(app)

                % Generate New Line for Plot Options Table
                UpdatePlotOptTable(app)
                
                % Update Tree Checkbox
                UpdateTree(app)
            else
               errordlg('Number of channels must be <= 10','Input Error')
               return
            end
        end

        % Button pushed function: ParamPnlDeleteChannelsButton
        function ParamPnlDeleteChannelsButtonPushed(app, event)
        % Button pushed function: analysisConParamPnlDeleteChannelsButton
        % Open dialogue box to input channels to be deleted

            % Create response dialogbox and get response
 	        prompt = {'Enter space-separated channel numbers:'};
            deleteDlgTitle = 'Delete Channels';
            dims = [1,40];
            deleteResponse = inputdlg(prompt,deleteDlgTitle,dims);
            if(isempty(deleteResponse))
                return
            end
            deleteChn = str2num(deleteResponse{1});
            nDelete = size(deleteChn,2);

        % Display error msg if none or all channels are specified            
            if(nDelete == 0)
                return
            elseif (nDelete == app.nChn)
                errTxt = 'Cannot delete all channels';
                errordlg(errTxt,'Input Error');
                return                
            end

            % Determine the row for each channel to be deleted and add it
            % to the deleteRows vector
            for i = 1:nDelete
                 % Set error code for finding channel to 1
                chnFindError = 1;
                for j = 1: app.nChn
                    if (deleteChn(i) == app.ParamPnlUITable.Data{j,1})
                        deleteRows(1,i) = j;
                        chnFindError = 0;
                        break;
                    end
                end

                % Display error message if channel number not found
                if (chnFindError == 1)
                    errTxt = ['Channel ' num2str(deleteChn(i)) ' not found. Check input.'];
                    errordlg(errTxt,'Input Error')
                    return
                end
            end

            % Delete rows from table if all channels found
            app.ParamPnlUITable.Data(deleteRows,:) = [];
            app.PlotOptPnlLinePropertiesUITable.Data(deleteRows,:) = [];
            app.nChn = app.nChn - nDelete;
            app.ParamPnl_nChn_EditField.Value = app.nChn;

            % Update table with new fractional flow values
            %designRTUpDateFraction(app);
            
            % Update Tree Checkbox
            UpdateTree(app)
        end

        % Button pushed function: ResultsPnlFitDataButton
        function ResultsPnlFitDataButtonPushed(app, event)
            % Check that all elements are in place for fitting
            % (Need non-zero parameters & BTC)
            % The BTC curve is app.tData, app.cData
            tcData = [app.tData; app.cData];
            if isempty(tcData)
                msgbox('Breakthrough curve data not loaded; execution halted')
                return
            end

            % Collect dispersivity function parameters
            alphaParams = [app.ParamPnl_alpha_c_EditField.Value app.ParamPnl_alpha_m_EditField.Value];

            % Collect values from the tracer dialog
            ChnActiveData = app.ParamPnlUITable.Data(app.ParamPnlUITable.Data.Include==1,{'Channel','v','L','f','alpha'});          
            nChnActive = height(ChnActiveData);
            if(nChnActive == 0) 
                msgbox('No channels active; execution halted')
            end
            if(min([ChnActiveData.v; ChnActiveData.L])==0)
                msgbox('Velocity & L must be > 0; execution halted')
            end
            M = app.ParamPnl_M_con_EditField.Value;
            Q = app.ParamPnl_Q_EditField.Value;
            T_res = app.ParamPnl_T_res_EditField.Value;
            % delta_p = app.ParamPnl_delta_p_EditField.Value;
            if(min([nChnActive, M, Q]) <= 0)
                msgbox('Flow parameters must be > 0; execution halted')
                return
            end            
            rhof = rhoH2OCalc(T_res); %Calculated parameters  

            % Collect the array of parameters to fit
            checked = app.ParamPnlTree.CheckedNodes;

            % Collect a list of which parameters to fit
            if size(checked,1)==0
                CheckedNodesList=[];
                return
            end
            node_num=size(checked,1);
            CheckedNodesList=table(Size=[ node_num 3],VariableTypes=["double" "double" "string"],VariableNames=["node_level" "Channel" "Variable"]);
                        
            for node_id=1:node_num
               if checked(node_id).Parent.Type~="uitreenode" % if parent node is not uitreenode, then top level
                   CheckedNodesList.node_level(node_id)=1;
                   CheckedNodesList.Channel(node_id)=str2double(extract(checked(node_id).Text,digitsPattern)); % note parent nodes have themselves as parent here
                   CheckedNodesList.Variable(node_id)=checked(node_id).Text;
               else
                   CheckedNodesList.node_level(node_id)=2;
                   CheckedNodesList.Channel(node_id)=str2double(extract(checked(node_id).Parent.Text,digitsPattern));
                   CheckedNodesList.Variable(node_id)=checked(node_id).Text;
               end
            end            
            CheckedNodesList((CheckedNodesList.node_level==1),:)=[]; 
            
            % Restrict the fit list to those both 'included' and 'checked' 
            rows = ismember(CheckedNodesList.Channel,ChnActiveData.Channel);
            CheckedNodesList = CheckedNodesList(rows,:);
    
            % Collect fitted parameters into a vector and make a matrix to
            % get their indices in the parameter matrix
            % Preallocate variables before starting loop
            NumParamFit = height(CheckedNodesList);
            ParamFit = zeros(NumParamFit,1); % initialize parameter vector
            ParamTable = ChnActiveData(:,{'v','L','f'}); % cols are v,L,& f
            app.ChnActiveVec = table2array(ChnActiveData(:,1)); %MAP 
            ParamTableFitFlag = 0 .* ParamTable; % initialize flag array indicating elements (v,L,f) to fit
            for i = 1 : height(CheckedNodesList)
                j = find(ChnActiveData.Channel == CheckedNodesList.Channel(i));    
                switch CheckedNodesList.Variable(i)                 
                    case 'Velocity'
                        ParamFit(i) = ChnActiveData{j,2}; % Get the initial guess
                        ParamTableFitFlag{j,1} = 1; % Set FitFlag to 1
                    case 'Path Length'
                        ParamFit(i) = ChnActiveData{j,3};
                        ParamTableFitFlag{j,2} = 1;                  
                    case 'Fluid Mass Frac.'
                        ParamFit(i) = ChnActiveData{j,4};
                        ParamTableFitFlag{j,3} = 1;
                end
            end
            ParamFitIndices = find(table2array(ParamTableFitFlag)==1);
            app.ParamArray = table2array(ParamTable); %convert needed part of table to array           
            LBnds = repmat([0 0 0],nChnActive,1); %MAP

            %Define initial values for fitted parameters
            ParamFit = app.ParamArray(ParamFitIndices);
            LBndsParamFit = LBnds(ParamFitIndices);
                       
            % Problem-specific objective function
            SolverMethod = app.SolverSelectionDropDown.Value;
            MaxFunEvals = NumParamFit * app.MaxFuncEvalsMultEditField.Value;
            MaxIter = NumParamFit * app.MaxIterMultEditField.Value;
            TolParam = app.TolParamEditField.Value;
            TolFun = app.TolFunEditField.Value;
            ObjFun = @(ParamFit) MinFun(tcData,M,Q,rhof,alphaParams, ParamFit,ParamFitIndices,app.ParamArray, SolverMethod);  
            nonlconFun = @(ParamFit) nonlcon(ParamFit,ParamFitIndices,app.ParamArray);

            if contains(SolverMethod,'min')
                options = optimset('MaxFunEvals',MaxFunEvals,...
                                   'MaxIter',MaxIter,...
                                   'TolX',TolParam,...
                                   'TolFun',TolFun,...
                                   'Display','notify');%,...
                                   %'PlotFcns',@optimplotfval);                
                switch SolverMethod 
                    case "fminsearch"
                            [ParamFit,fval,exitflag,output] = fminsearch(ObjFun,ParamFit,options);    
                    case "fminsearchbnd"
                            [ParamFit,fval,exitflag,output] = fminsearchbnd(ObjFun,ParamFit,LBndsParamFit,[],options);    
                    case "fminsearchcon"
                            [ParamFit,fval,exitflag,output] = fminsearchcon(ObjFun,ParamFit,LBndsParamFit,[],[],[],nonlconFun,options);    
                    case "minimize"
                            [ParamFit,fval,exitflag,output] = minimize(ObjFun,ParamFit,[],[],[],[],LBndsParamFit,[],nonlconFun,options);    
                end            

                % Info for dialog box      
                ResultsText = {   ['Sum of squared errors = ', num2str(fval)];...
                            ['Iterations = ', num2str(output.iterations)];...
                            ['No. of function evals = ', num2str(output.funcCount)];...
                            ['Algorithm: ', output.algorithm]   };
            else
                % Use Levenberg-Marquardt Toolbox function to solve minimization problem
                % [ParamFit,fval,fval,exitflag] = LevenbergMarquardt(ObjFun,ParamFit,LBndsFitParam,[]);    
                [ParamFit,fval,ResVec,exitflag] = LevenbergMarquardt(ObjFun,ParamFit,LBndsParamFit);    
                output.message = []; 

                % Info for dialog box      
            ResultsText = {       ['Sum of squared errors = ', num2str(fval)]};
            end
            
            % Calculate uncertainty from gradient or hessian, if fit successful
            TextUNCERT = 'Uncertainty not calculated'; % Initialize text output
exitflag = 0; %MAP; uncert calc turned off until this section can be fixed
            if exitflag
                % This opt func will run just enough to get gradients
                options = optimset('MaxFunEvals',5,'MaxIter',5); 
                [ParamFit,fval2,exitflag2,output2,GRAD,HESS] = fminlbfgs(ObjFun,ParamFit,options);
                UNCERT = sqrt(diag(inv(HESS)) .* fval2/(length(ParamFit)-1));

                % Create output for text area
                TextUNCERT = num2str(UNCERT);
            end              

            % Create fitted parameter table and display it
            FittedParamArray = NaN(nChnActive,3); %initialize with value to show for non-fitted params
            FittedParamArray(ParamFitIndices) = ParamFit; 
            FittedParamArray = [FittedParamArray alphaParams(1).*FittedParamArray(:,2).^alphaParams(2)]; % Add a col to right showing dispersivity
            FittedParamArray = [app.ChnActiveVec FittedParamArray];
            app.FittedParametersUITable.Data = array2table(FittedParamArray,'VariableNames',{'Channel','v','L','f','alpha'});

            % Combine all message pieces
            if strcmp(app.ResultsPnlTextArea.Value,'')
                message_disp =  vertcat(['vvvvvvvvvvvvvvvvv ',datestr(datetime()),' vvvvvvvvvvvvvvvvv'],...
                                        output.message,ResultsText,TextUNCERT);
            else
                message_disp = [app.ResultsPnlTextArea.Value;...
                                vertcat(['vvvvvvvvvvvvvvvvv ',datestr(datetime()),' vvvvvvvvvvvvvvvvv'],...
                                         output.message,ResultsText,TextUNCERT)];
            end
            app.ResultsPnlTextArea.Value = message_disp;
            scroll(app.ResultsPnlTextArea, 'bottom') %autoscroll text area to bottom            

            % Calculate and plot concentration vs time
            app.ParamArray(ParamFitIndices) = ParamFit;        
            [app.cFitMean, app.cFit] = ConTracer('Time',tcData(1,:),M,Q,alphaParams,app.ParamArray,rhof);          
            app.FitPlots = plot(app.PltPnlTabGrpCvsTUIAxes,...
                                                       tcData(1,:),app.cFit,'-',...
                                                       tcData(1,:),app.cFitMean,'k-',...
                                                       tcData(1,:),tcData(2,:),'ko');
            legend(app.PltPnlTabGrpCvsTUIAxes,[string(app.ChnActiveVec);'Mean c';'BTC data']);
 
            % Call the UpdatePlotsButtonPushed fcn to assign plot properties
            UpdatePlotswithCurrentPlotOptionsButtonPushed(app);
            
            % Calculate and plot concentration vs time residuals
            cResidual = app.cFitMean - tcData(2,:);
            app.ResPlot = plot(app.PltPnlTabGrpResidualCvsTUIAxes, tcData(1,:), cResidual, 'ko');

            % Calculate and plot standardized residuals
            cResidualStdRank = 1:length(cResidual);
            cResidualStd = sort(cResidual)/std(cResidual);
            app.StdResPlot = plot(app.PltPnlTabGrpNormProbPlotResUIAxes_3, cResidualStd, cResidualStdRank, 'ko');

            %% General function to be minimized
            function output = MinFun(tcData,M,Q,rhof,alphaParams, ParamFit,ParamFitInd,ParamArray,SolverMethod)
            %  8th arg is string that is either 'fminsearchbnd' or 'Levenberg-Marquardt'       
            % If former, then vector of residuals returned, otherwise, SSE          
            ParamArray(ParamFitInd) = ParamFit;        
            Cmean = ConTracer('Time',tcData(1,:),M,Q,alphaParams,ParamArray,rhof);
            ErrVec = Cmean - tcData(2,:);
            SSE = sum(ErrVec.^2);
            if contains(SolverMethod,'min')
                output = SSE;
            else                        
                output = ErrVec';
            end
            end
            
            %% Sum of fractions should be <=1
            function [c,ceq] = nonlcon(ParamFit,ParamFitInd,ParamArray)
            ParamArray(ParamFitInd) = ParamFit;
            c = sum(ParamArray(:,3))-1;   
            ceq = [];
            end
            
        end

        % Button pushed function: ResultsCopy2TableButton
        function ResultsCopy2TableButtonPushed(app, event)
            %MAP Application to'included' could fail if changed after fit
            RowsToChange = app.ParamPnlUITable.Data.Include==1;
            app.ParamPnlUITable.Data{RowsToChange,2:4} = app.ParamArray;
            
            app.ParamPnlUITableCellEdit(app);
        end

        % Cell edit callback: ParamPnlUITable
        function ParamPnlUITableCellEdit(app, event)
       % Update dispersivity values 
         alphaParams = [app.ParamPnl_alpha_c_EditField.Value app.ParamPnl_alpha_m_EditField.Value];
         app.ParamPnlUITable.Data(:,5) = alphaParams(1).* app.ParamPnlUITable.Data(:,3).^alphaParams(2);            
        end

        % Menu selected function: ReadconservativetracerobservationsMenu
        function ReadconservativetracerobservationsMenuSelected(app, event)
            % Delete previously imported data
            if ~isempty(app.tData)
                app.tData = []; app.cData = [];
            end

            % Call external read data function
            [tcArray,~] = ReadData();
            if isempty(tcArray); return; end

            % Separate array into needed vectors
            app.tData = tcArray(1,:);
            app.cData = tcArray(2,:);

            % Call the plotting function
            PlotDataCvT(app,app.tData,app.cData)

            % Activate lamp and 'Fit Data' button
            app.BreakthroughcurveloadedLamp.Color = [0 1 0];            
            app.ResultsPnlFitDataButton.Enable = 'on';

        end

        % Menu selected function: SaveparametersMenu
        function SaveparametersMenuSelected(app, event)
            % Select file to save to
            [filename,pathname] = uiputfile('*.*');
            if isequal(filename,0)
                return;
            end
            fileNamefull = fullfile(pathname,filename);    

            % Read the preexisting structure if file exists
            if isfile(fileNamefull)
                Struct = read_namelist(fileNamefull);
            end

            % Place relevant variables into structure
            Struct.AnalysisConservativeTrTest.M_con     = app.ParamPnl_M_con_EditField.Value;
            Struct.AnalysisConservativeTrTest.Q         = app.ParamPnl_Q_EditField.Value;
            Struct.AnalysisConservativeTrTest.T_res     = app.ParamPnl_T_res_EditField.Value;
            Struct.AnalysisConservativeTrTest.delta_p   = app.ParamPnl_delta_p_EditField.Value;
            Struct.AnalysisConservativeTrTest.alpha_c   = app.ParamPnl_alpha_c_EditField.Value;
            Struct.AnalysisConservativeTrTest.alpha_m   = app.ParamPnl_alpha_m_EditField.Value;
            Struct.AnalysisConservativeTrTest.nChn      = app.ParamPnl_nChn_EditField.Value;

            % Channel data table
            Struct.AnalysisConservativeTrTest.Table_ChNum       = app.ParamPnlUITable.Data.Channel		    ; 
            Struct.AnalysisConservativeTrTest.Table_v           = app.ParamPnlUITable.Data.v		        ; 
            Struct.AnalysisConservativeTrTest.Table_L           = app.ParamPnlUITable.Data.L		        ; 
            Struct.AnalysisConservativeTrTest.Table_f           = app.ParamPnlUITable.Data.f		        ; 
            Struct.AnalysisConservativeTrTest.Table_alpha       = app.ParamPnlUITable.Data.alpha		    ; 
            Struct.AnalysisConservativeTrTest.Table_Include     = double(app.ParamPnlUITable.Data.Include)	;            

            % Fitted parameters table
            if ~isempty(app.FittedParametersUITable.Data)
                Struct.AnalysisConservativeTrTest.TableFitted_ChNum       = app.FittedParametersUITable.Data.Channel	    ; 
                Struct.AnalysisConservativeTrTest.TableFitted_v           = app.FittedParametersUITable.Data.v		        ; 
                Struct.AnalysisConservativeTrTest.TableFitted_L           = app.FittedParametersUITable.Data.L		        ; 
                Struct.AnalysisConservativeTrTest.TableFitted_f           = app.FittedParametersUITable.Data.f		        ; 
                Struct.AnalysisConservativeTrTest.TableFitted_alpha       = app.FittedParametersUITable.Data.alpha		    ;     
            end

            % Solver EditFields
            Struct.AnalysisConservativeTrTest.t_min     = app.PlotOptPnl_t_min_EditField.Value     ;
            Struct.AnalysisConservativeTrTest.t_max     = app.PlotOptPnl_t_max_EditField.Value     ;
            Struct.AnalysisConservativeTrTest.c_min     = app.PlotOptPnl_c_min_EditField.Value     ;
            Struct.AnalysisConservativeTrTest.c_max     = app.PlotOptPnl_c_max_EditField.Value     ;

            % Plot options Editfields
            Struct.AnalysisConservativeTrTest.MaxFuncEvalsMult  = app.MaxFuncEvalsMultEditField.Value   ;      
            Struct.AnalysisConservativeTrTest.MaxIterMult  		= app.MaxIterMultEditField.Value        ;      
            Struct.AnalysisConservativeTrTest.TolFun       		= app.TolFunEditField.Value             ;      
            Struct.AnalysisConservativeTrTest.TolParam     		= app.TolParamEditField.Value           ;                                          
            
            % Array of conservative tracer observations
            if exist('app.cData')
                Struct.AnalysisConservativeTrTest.tData = app.tData;
                Struct.AnalysisConservativeTrTest.cData = app.cData;
            end
            
            % Save structure to file
            write_namelist(Struct,fileNamefull);
        end

        % Menu selected function: ReadparametersMenu
        function ReadparametersMenuSelected(app, event)
        % Function reads text file for the optimization of conservative tracer data
        
        % Select file to read
        [filename,pathname] = uigetfile('*.*');
        if isequal(filename,0)
            return;
        end
        fileNamefull = fullfile(pathname,filename);    

        % Read the structure 
        Struct = read_namelist(fileNamefull);

        % Stop if no data
        if ~isfield(Struct,'AnalysisConservativeTrTest')
            msgbox('No tracer test analysis data found; execution halted')
            return
        end            
          
        % Use the pieces to define the relevant field
        app.ParamPnl_Q_EditField.Value     	    = Struct.AnalysisConservativeTrTest.Q               ;
        app.ParamPnl_T_res_EditField.Value	    = Struct.AnalysisConservativeTrTest.T_res           ;
        app.ParamPnl_M_con_EditField.Value      = Struct.AnalysisConservativeTrTest.M_con           ;
        app.ParamPnl_delta_p_EditField.Value    = Struct.AnalysisConservativeTrTest.delta_p         ;
        app.ParamPnl_alpha_c_EditField.Value	= Struct.AnalysisConservativeTrTest.alpha_c         ;
        app.ParamPnl_alpha_m_EditField.Value	= Struct.AnalysisConservativeTrTest.alpha_m         ;            
        app.ParamPnl_nChn_EditField.Value	    = Struct.AnalysisConservativeTrTest.nChn            ;

        % Channel data table
        nChn = Struct.AnalysisConservativeTrTest.nChn;
        app.nChn  = nChn;
        app.ParamPnlUITable.Data = [];    % Delete existing table        
        app.ParamPnlTree.Children.delete; % Delete all nodes from existing tree
        Channel             =   Struct.AnalysisConservativeTrTest.Table_Channel'              ;
        v                   =   Struct.AnalysisConservativeTrTest.Table_v'          	    ;
        L                   =   Struct.AnalysisConservativeTrTest.Table_L'          	    ;
        f                   =   Struct.AnalysisConservativeTrTest.Table_f'          	    ;
        alpha               =   Struct.AnalysisConservativeTrTest.Table_alpha'   	        ;
        Include             =   logical(Struct.AnalysisConservativeTrTest.Table_Include')   ;
        app.ParamPnlUITable.Data = table(Channel, v, L, f, alpha, Include, ...
                                         'VariableNames',{'Channel','v','L','f','alpha','Include'});
        % app.ParamPnlUITable.Data.Include = logical(app.ParamPnlUITable.Data.Include);        

        % Plot Options EditFields
        app.PlotOptPnl_t_min_EditField.Value    = Struct.AnalysisConservativeTrTest.t_min       ; 
        app.PlotOptPnl_t_max_EditField.Value    = Struct.AnalysisConservativeTrTest.t_max       ; 
        app.PlotOptPnl_c_min_EditField.Value    = Struct.AnalysisConservativeTrTest.c_min       ; 
        app.PlotOptPnl_c_max_EditField.Value    = Struct.AnalysisConservativeTrTest.c_max       ; 

        % Solver EditFields
        app.MaxFuncEvalsMultEditField.Value     = Struct.AnalysisConservativeTrTest.MaxFuncEvalsMult    ;
        app.MaxIterMultEditField.Value          = Struct.AnalysisConservativeTrTest.MaxIterMult         ;
        app.TolFunEditField.Value               = Struct.AnalysisConservativeTrTest.TolFun              ;
        app.TolParamEditField.Value             = Struct.AnalysisConservativeTrTest.TolParam            ;
                
        % Update Parameter Table, Parameter Panel, x-axis of plot
        UpdateParamPnl(app);

        % Check for uniqueness of channel numbers
        ChnUniqueErr = ChnUniqueness(app,app.ParamPnlUITable.Data);
        if (ChnUniqueErr == 1)
            app.ParamPnlUITable.Data = app.ParamTableDatOld;                
            return;
        end

        % Update Tree
        UpdateTree(app)

        % Update Plot Options Table
        UpdatePlotOptTable(app)  

        % Import the conservative tracer test data used
        app.tData = Struct.AnalysisConservativeTrTest.tData; 
        app.cData = Struct.AnalysisConservativeTrTest.cData;  
        end

        % Menu selected function: SaveplottedresultsMenu
        function SaveplottedresultsMenuSelected(app, event)
        % Check for existence of plots
        if isempty(app.FitPlots)
            msgbox("No plots created; halting execution","Error");
            return
        end

        % Get the channel numbers from the fitted parameters table
        % Include = table2array(app.paramTable.Data(:,end));
        Channels = app.FittedParametersUITable.Data.Channel; % Could also use app.ChnActiveVec

        % Define the basic header pieces
        preheader = 'Conservative tracer analysis results'; % An extra line to store avg C for distance case
        ChnHeaders = strcat("Chn-",num2str(Channels));
        header = ['Time (days)'  ChnHeaders' 'Mean C' 'Obs Mean C' 'Residuals' 'Stdized Residuals' ];
        
        % Define xy data
        dataMatrix = app.FitPlots(1).XData'; % Could also use app.tData (which is paired with app.cData to make local tcData
        for i = 1 : length(app.FitPlots)
            dataMatrix = [dataMatrix, app.FitPlots(i).YData'];
        end

        % Add residuals cols to data matrix
        dataMatrix = [dataMatrix, app.ResPlot.YData', app.StdResPlot.YData'];

        % Save headers and data to file
        [savefile,savepath] = uiputfile('*.*');
        if ~savefile==0
            saveFileFull = [savepath savefile];
            writematrix(preheader,saveFileFull)                
            writematrix(header,saveFileFull,'Delimiter',',','WriteMode','append')
            writematrix(dataMatrix,saveFileFull,'Delimiter',',','WriteMode','append')  
        end    
        end

        % Menu selected function: ViewMenu
        function ViewMenuSelected(app, event)
        app.CommonVars_Hndl = TAT08CommonVars;            
        end

        % Menu selected function: SendtoMenu
        function SendtoMenuSelected(app, event)
        app.CommonVars_Hndl = TAT08CommonVars;
        app.CommonVars_Hndl.M_con_EditField.Value    = app.ParamPnl_M_con_EditField.Value;            
        app.CommonVars_Hndl.Q_EditField.Value        = app.ParamPnl_Q_EditField.Value;
        app.CommonVars_Hndl.T_res_EditField.Value    = app.ParamPnl_T_res_EditField.Value;
        app.CommonVars_Hndl.delta_p_EditField.Value  = app.ParamPnl_delta_p_EditField.Value;
        app.CommonVars_Hndl.alpha_c_EditField.Value  = app.ParamPnl_alpha_c_EditField.Value;
        app.CommonVars_Hndl.alpha_m_EditField.Value  = app.ParamPnl_alpha_m_EditField.Value;   
        
        % CommonVars table is  Channel,v,L,alpha,b,f,Include; this table same but excludes b right now
        app.CommonVars_Hndl.Channel_UITable.Data = [];
        Nrows = height(app.ParamPnlUITable.Data);
        b = table(1e-2 .* ones(Nrows,1),'VariableNames',{'b'});
        app.CommonVars_Hndl.Channel_UITable.Data = [app.ParamPnlUITable.Data(:,{'Channel','v','L','alpha'}), ...   
                                                    b, app.ParamPnlUITable.Data(:,{'f','Include'})] ;                    
        end

        % Menu selected function: PullfromMenu
        function PullfromMenuSelected(app, event)
        app.CommonVars_Hndl = TAT08CommonVars;
        app.ParamPnl_M_con_EditField.Value      = app.CommonVars_Hndl.M_con_EditField.Value;
        app.ParamPnl_Q_EditField.Value          = app.CommonVars_Hndl.Q_EditField.Value;
        app.ParamPnl_T_res_EditField.Value      = app.CommonVars_Hndl.T_res_EditField.Value;
        app.ParamPnl_delta_p_EditField.Value    = app.CommonVars_Hndl.delta_p_EditField.Value;
        app.ParamPnl_alpha_c_EditField.Value    = app.CommonVars_Hndl.alpha_c_EditField.Value;
        app.ParamPnl_alpha_m_EditField.Value    = app.CommonVars_Hndl.alpha_m_EditField.Value;    
        
        if height(app.ParamPnlUITable.Data) > 0 
            % This table is         Channel,v,L,f,alpha,    Include
            % CommonVars table is   Channel,v,L,  alpha,b,f,Include
            app.ParamPnlUITable.Data = app.CommonVars_Hndl.Channel_UITable.Data(:,{'Channel','v','L','f','alpha','Include'}) ;    
    
            % Increment the number of channels and update editfield
            % displaying designConnChn
            app.nChn = height(app.ParamPnlUITable.Data);
            app.ParamPnl_nChn_EditField.Value = app.nChn;
        end

        % Update optimized parameters tree
        UpdateTree(app)

        % Update Plot Options Table
        UpdatePlotOptTable(app)        
        
        % Return control to self
        TAT08AnalysisConservativeTrTest ;             
        end

        % Menu selected function: desConservativetracerMenu
        function desConservativetracerMenuSelected(app, event)
        app.DesignConservativeTrTest_Hndl = TAT08DesignConservativeTrTest;      
        end

        % Menu selected function: desTemperatureprofilesMenu
        function desTemperatureprofilesMenuSelected(app, event)
        app.DesignTemperatureProfile_Hndl = TAT08DesignTemperatureProfile;
        end

        % Menu selected function: desReactivetracerselectionMenu
        function desReactivetracerselectionMenuSelected(app, event)
        app.DesignSelectReactiveTr_Hndl = TAT08DesignSelectReactiveTr;
        end

        % Menu selected function: desReactivetracerMenu
        function desReactivetracerMenuSelected(app, event)
        app.DesignReactiveTrTest_Hndl = TAT08DesignReactiveTrTest;
        end

        % Menu selected function: analReactivetracerMenu
        function analReactivetracerMenuSelected(app, event)
        app.AnalysisReactiveTrTest_Hndl = TAT08AnalysisReactiveTrTest;
        end

        % Cell selection callback: PlotOptPnlLinePropertiesUITable
        function PlotOptPnlLinePropertiesUITableCellSelection(app, event)
        indices = event.Indices;
        if and(~isempty(indices),size((indices),1)==1) %if clicked in channel row
           if and(~isempty(indices),size((indices),1)==1) %if clicked in channel row
                colorOld = str2num(app.PlotOptPnlLinePropertiesUITable.Data{indices(1),2}{1});
                colorNew = num2str(round(uisetcolor(colorOld),3));
                if ~strcmp(colorNew,'0') %if operation not canceled
                    app.PlotOptPnlLinePropertiesUITable.Data{indices(1),2}{1} = colorNew;
                    myColorStyle = uistyle("BackgroundColor",colorNew);
                    addStyle(app.PlotOptPnlLinePropertiesUITable,myColorStyle,'cell',[indices(1),indices(2)]);          
                    app.PlotOptPnlLinePropertiesUITable.Selection = [indices(1),1];
                end
            end
        end       
        end

        % Button pushed function: ClearResultsButton
        function ClearResultsButtonPushed(app, event)
        app.ResultsPnlTextArea.Value='';
        end

        % Button pushed function: EqHelpButton
        function EqHelpButtonPushed(app, event)
        % Define file location
        eqHelpPath = fullfile(app.TATPath,'Docs','ConTracerHelp.pdf');
            
        % Open Help File
        winopen(eqHelpPath)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Analysis Workflow -- Conservative Tracer';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'appdata', 'TATIcon.png');
            app.FileActionsMenu = uimenu(app.UIFigure);
            app.FileActionsMenu.Text = 'File Actions';
            app.ReadconservativetracerobservationsMenu = uimenu(app.FileActionsMenu);
            app.ReadconservativetracerobservationsMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadconservativetracerobservationsMenuSelected, true);
            app.ReadconservativetracerobservationsMenu.Text = 'Read conservative tracer observations';
            app.SaveparametersMenu = uimenu(app.FileActionsMenu);
            app.SaveparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveparametersMenuSelected, true);
            app.SaveparametersMenu.Text = 'Save parameters';
            app.ReadparametersMenu = uimenu(app.FileActionsMenu);
            app.ReadparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadparametersMenuSelected, true);
            app.ReadparametersMenu.Text = 'Read parameters';
            app.SaveplottedresultsMenu = uimenu(app.FileActionsMenu);
            app.SaveplottedresultsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveplottedresultsMenuSelected, true);
            app.SaveplottedresultsMenu.Text = 'Save plotted results';
            app.TaskControlMenu = uimenu(app.UIFigure);
            app.TaskControlMenu.Text = 'Task Control';
            app.DesignMenu = uimenu(app.TaskControlMenu);
            app.DesignMenu.Text = 'Design';
            app.desConservativetracerMenu = uimenu(app.DesignMenu);
            app.desConservativetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @desConservativetracerMenuSelected, true);
            app.desConservativetracerMenu.Text = 'Conservative tracer';
            app.desTemperatureprofilesMenu = uimenu(app.DesignMenu);
            app.desTemperatureprofilesMenu.MenuSelectedFcn = createCallbackFcn(app, @desTemperatureprofilesMenuSelected, true);
            app.desTemperatureprofilesMenu.Text = 'Temperature profiles';
            app.desReactivetracerselectionMenu = uimenu(app.DesignMenu);
            app.desReactivetracerselectionMenu.MenuSelectedFcn = createCallbackFcn(app, @desReactivetracerselectionMenuSelected, true);
            app.desReactivetracerselectionMenu.Text = 'Reactive tracer selection';
            app.desReactivetracerMenu = uimenu(app.DesignMenu);
            app.desReactivetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @desReactivetracerMenuSelected, true);
            app.desReactivetracerMenu.Text = 'Reactive tracer';
            app.AnalysisMenu = uimenu(app.TaskControlMenu);
            app.AnalysisMenu.Text = 'Analysis';
            app.analConservativetracerMenu = uimenu(app.AnalysisMenu);
            app.analConservativetracerMenu.Enable = 'off';
            app.analConservativetracerMenu.Text = 'Conservative tracer';
            app.analReactivetracerMenu = uimenu(app.AnalysisMenu);
            app.analReactivetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @analReactivetracerMenuSelected, true);
            app.analReactivetracerMenu.Text = 'Reactive tracer';
            app.CommonVariablesMenu = uimenu(app.UIFigure);
            app.CommonVariablesMenu.Text = 'Common Variables';
            app.ViewMenu = uimenu(app.CommonVariablesMenu);
            app.ViewMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.ViewMenu.Text = 'View';
            app.SendtoMenu = uimenu(app.CommonVariablesMenu);
            app.SendtoMenu.MenuSelectedFcn = createCallbackFcn(app, @SendtoMenuSelected, true);
            app.SendtoMenu.Text = 'Send to';
            app.PullfromMenu = uimenu(app.CommonVariablesMenu);
            app.PullfromMenu.MenuSelectedFcn = createCallbackFcn(app, @PullfromMenuSelected, true);
            app.PullfromMenu.Text = 'Pull from';
            app.MainLabel = uilabel(app.UIFigure);
            app.MainLabel.Text = 'Conservative Tracer Analysis';
            app.ParamPanel = uipanel(app.UIFigure);
            app.ParamPanel.Title = 'Parameters';
            app.ParamPnlUITable = uitable(app.ParamPanel);
            app.ParamPnlUITable.ColumnName = {'Channel'; 'v'; 'L'; 'f'; 'alpha'; 'Include'};
            app.ParamPnlUITable.RowName = {};
            app.ParamPnlUITable.ColumnEditable = [true true true true false true];
            app.ParamPnlUITable.CellEditCallback = createCallbackFcn(app, @ParamPnlUITableCellEdit, true);
            app.ParamPnlUITable.Tooltip = {'v: velocity (m/s)'; 'L: path length (m)'; 'f: fluid mass fraction (-)'; 'alpha: calculated dispersivity (m)'; 'b: aperture (m)'};
            app.ParamPnl_nChn_Label = uilabel(app.ParamPanel);
            app.ParamPnl_nChn_Label.Text = 'No. Channels';
            app.ParamPnl_M_con_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_M_con_EditField.Value = 1;
            app.ParamPnl_M_con_Label = uilabel(app.ParamPanel);
            app.ParamPnl_M_con_Label.Text = {'Tracer Mass,'; 'M_con (kg)'};
            app.ParamPnl_Q_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_Q_EditField.Value = 1;
            app.ParamPnlPumpRateLabel = uilabel(app.ParamPanel);
            app.ParamPnlPumpRateLabel.Interpreter = 'html';
            app.ParamPnlPumpRateLabel.Text = {'Pumping Rate,'; 'Q (kg/s)'};
            app.ParamPnl_T_res_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_T_res_EditField.Value = 100;
            app.ParamPnl_T_res_label = uilabel(app.ParamPanel);
            app.ParamPnl_T_res_label.Text = {'Reservoir Temp,'; 'T_r (°C)'};
            app.ParamPnlTree = uitree(app.ParamPanel, 'checkbox');
            app.ParamPnlTree.Editable = 'on';
            app.Chn1Node = uitreenode(app.ParamPnlTree);
            app.Chn1Node.Text = 'Chn 1';
            app.VelocityNode = uitreenode(app.Chn1Node);
            app.VelocityNode.Text = 'Velocity';
            app.PathLengthNode = uitreenode(app.Chn1Node);
            app.PathLengthNode.Text = 'Path Length';
            app.FluidMassFracNode = uitreenode(app.Chn1Node);
            app.FluidMassFracNode.Text = 'Fluid Mass Frac.';
            app.ParamPnlParamOptLabel = uilabel(app.ParamPanel);
            app.ParamPnlParamOptLabel.Text = 'Parameters to be Optimized';
            app.ParamPnlDeleteChannelsButton = uibutton(app.ParamPanel, 'push');
            app.ParamPnlDeleteChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @ParamPnlDeleteChannelsButtonPushed, true);
            app.ParamPnlDeleteChannelsButton.Text = 'Delete Channels';
            app.ParamPnlAddChannelButton = uibutton(app.ParamPanel, 'push');
            app.ParamPnlAddChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ParamPnlAddChannelButtonPushed, true);
            app.ParamPnlAddChannelButton.Text = 'Add Channel';
            app.ParamPnl_delta_p_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_delta_p_Label = uilabel(app.ParamPanel);
            app.ParamPnl_delta_p_Label.Interpreter = 'html';
            app.ParamPnl_delta_p_Label.Text = {'Pressure drop'; '&Delta; p (kPa)'};
            app.ParamPnl_nChn_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_nChn_EditField.Editable = 'off';
            app.ParamPnl_alpha_c_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_alpha_c_EditField.Limits = [0 Inf];
            app.ParamPnl_alpha_c_EditField.Value = 0.21;
            app.ParamPnl_alpha_FunctionLabel = uilabel(app.ParamPanel);
            app.ParamPnl_alpha_FunctionLabel.Interpreter = 'html';
            app.ParamPnl_alpha_FunctionLabel.Text = {'Dispersivity parameters'; '(&alpha; = c L<sup>m</sup>)'};
            app.ParamPnl_alpha_m_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_alpha_m_EditField.Limits = [0 Inf];
            app.ParamPnl_alpha_m_EditField.Value = 0.51;
            app.cLabel = uilabel(app.ParamPanel);
            app.cLabel.Text = 'c';
            app.mLabel = uilabel(app.ParamPanel);
            app.mLabel.Text = 'm';
            app.PlotsPanel = uipanel(app.UIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.PltPnlTabGroup = uitabgroup(app.PlotsPanel);
            app.PltPnlTabGrpCvstTab = uitab(app.PltPnlTabGroup);
            app.PltPnlTabGrpCvstTab.Title = 'C vs t';
            app.PltPnlTabGrpCvsTUIAxes = uiaxes(app.PltPnlTabGrpCvstTab);
            title(app.PltPnlTabGrpCvsTUIAxes, 'Conservative Tracer Concentrations')
            xlabel(app.PltPnlTabGrpCvsTUIAxes, 'Time')
            ylabel(app.PltPnlTabGrpCvsTUIAxes, 'Concentration')
            zlabel(app.PltPnlTabGrpCvsTUIAxes, 'Z')
            app.PltPnlTabGrpResidualCvstTab = uitab(app.PltPnlTabGroup);
            app.PltPnlTabGrpResidualCvstTab.Title = 'Residual C vs t';
            app.PltPnlTabGrpResidualCvsTUIAxes = uiaxes(app.PltPnlTabGrpResidualCvstTab);
            title(app.PltPnlTabGrpResidualCvsTUIAxes, 'Conservative Tracer Residual Concentrations')
            xlabel(app.PltPnlTabGrpResidualCvsTUIAxes, 'Time')
            ylabel(app.PltPnlTabGrpResidualCvsTUIAxes, 'Residual Concentration')
            zlabel(app.PltPnlTabGrpResidualCvsTUIAxes, 'Z')
            app.PltPnlTabGrpNormProbPlotResTab = uitab(app.PltPnlTabGroup);
            app.PltPnlTabGrpNormProbPlotResTab.Title = 'Norm. Prob. Plot Res.';
            app.PltPnlTabGrpNormProbPlotResUIAxes_3 = uiaxes(app.PltPnlTabGrpNormProbPlotResTab);
            title(app.PltPnlTabGrpNormProbPlotResUIAxes_3, 'Normal Probability Plot Conservative Tracer Residual Concentrations')
            xlabel(app.PltPnlTabGrpNormProbPlotResUIAxes_3, 'Z-Score')
            ylabel(app.PltPnlTabGrpNormProbPlotResUIAxes_3, 'Rank Residual Concentration')
            zlabel(app.PltPnlTabGrpNormProbPlotResUIAxes_3, 'Z')
            app.UpdatePlotswithCurrentPlotOptionsButton = uibutton(app.PlotsPanel, 'push');
            app.UpdatePlotswithCurrentPlotOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotswithCurrentPlotOptionsButtonPushed, true);
            app.UpdatePlotswithCurrentPlotOptionsButton.Text = 'Update Plots with Current Plot Options';
            app.EqImage = uiimage(app.UIFigure);
            app.EqImage.ImageSource = fullfile(pathToMLAPP, 'appdata', 'ConservEq.png');
            app.EqHelpButton = uibutton(app.UIFigure, 'push');
            app.EqHelpButton.ButtonPushedFcn = createCallbackFcn(app, @EqHelpButtonPushed, true);
            app.EqHelpButton.Text = 'Eq. Help';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.ResultsPnlFitDataButton = uibutton(app.ResultsPanel, 'push');
            app.ResultsPnlFitDataButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsPnlFitDataButtonPushed, true);
            app.ResultsPnlFitDataButton.Enable = 'off';
            app.ResultsPnlFitDataButton.Tooltip = {'Requires that conservative tracer observations (breakthrough curve) have been loaded and that suitable channel data estimates are provided. '};
            app.ResultsPnlFitDataButton.Text = 'Fit Data';
            app.BreakthroughcurveloadedLampLabel = uilabel(app.ResultsPanel);
            app.BreakthroughcurveloadedLampLabel.Text = 'Breakthrough curve loaded';
            app.BreakthroughcurveloadedLamp = uilamp(app.ResultsPanel);
            app.SolverselectionDropDownLabel = uilabel(app.ResultsPanel);
            app.SolverselectionDropDownLabel.Text = 'Solver selection';
            app.SolverSelectionDropDown = uidropdown(app.ResultsPanel);
            app.SolverSelectionDropDown.Items = {'fminsearch', 'fminsearchbnd', 'fminsearchcon', 'minimize', 'Levenberg-Marquardt'};
            app.SolverSelectionDropDown.Value = 'fminsearch';
            app.MaxfunctionevalsmultiplierEditFieldLabel = uilabel(app.ResultsPanel);
            app.MaxfunctionevalsmultiplierEditFieldLabel.Text = 'Max function evals multiplier';
            app.MaxFuncEvalsMultEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.MaxFuncEvalsMultEditField.Limits = [1 Inf];
            app.MaxFuncEvalsMultEditField.RoundFractionalValues = 'on';
            app.MaxFuncEvalsMultEditField.Value = 200;
            app.RelativetoleranceEditFieldLabel = uilabel(app.ResultsPanel);
            app.RelativetoleranceEditFieldLabel.Text = 'Relative tolerance';
            app.TolParamEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.TolParamEditField.ValueDisplayFormat = '%5.0e';
            app.TolParamEditField.Value = 0.0001;
            app.MaxiterationsmultiplierLabel = uilabel(app.ResultsPanel);
            app.MaxiterationsmultiplierLabel.Text = 'Max iterations multiplier';
            app.MaxIterMultEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.MaxIterMultEditField.Limits = [1 Inf];
            app.MaxIterMultEditField.RoundFractionalValues = 'on';
            app.MaxIterMultEditField.Value = 200;
            app.FunctiontoleranceLabel = uilabel(app.ResultsPanel);
            app.FunctiontoleranceLabel.Text = 'Function tolerance';
            app.TolFunEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.TolFunEditField.ValueDisplayFormat = '%5.0e';
            app.TolFunEditField.Value = 0.0001;
            app.ResultsCopy2TableButton = uibutton(app.ResultsPanel, 'push');
            app.ResultsCopy2TableButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsCopy2TableButtonPushed, true);
            app.ResultsCopy2TableButton.Tooltip = {'Copy fitted results to parameter table of guesses'};
            app.ResultsCopy2TableButton.Text = 'Results to Table';
            app.ClearResultsButton = uibutton(app.ResultsPanel, 'push');
            app.ClearResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearResultsButtonPushed, true);
            app.ClearResultsButton.Tooltip = {'Copy results to parameter table'};
            app.ClearResultsButton.Text = 'Clear Results';
            app.TabGroup = uitabgroup(app.ResultsPanel);
            app.ResultsInfoTab = uitab(app.TabGroup);
            app.ResultsInfoTab.Title = 'Results Info';
            app.ResultsPnlTextArea = uitextarea(app.ResultsInfoTab);
            app.FittedParametersTable = uitab(app.TabGroup);
            app.FittedParametersTable.Title = 'Fitted Parameters Table';
            app.FittedParametersUITable = uitable(app.FittedParametersTable);
            app.FittedParametersUITable.ColumnName = {'Channel'; 'v'; 'L'; 'f'; 'alpha'};
            app.FittedParametersUITable.RowName = {};
            app.FittedParametersUITable.Tooltip = {'v: velocity (m/s)'; 'L: path length (m)'; 'f: fluid mass fraction (-)'; 'alpha: dispersivity (m)'};
            app.PlotOptPnl = uipanel(app.UIFigure);
            app.PlotOptPnl.Title = 'Plotting Options';
            app.PlotOptPnl_c_max_EditField = uieditfield(app.PlotOptPnl, 'numeric');
            app.PlotOptPnl_c_max_EditField.Limits = [0 Inf];
            app.PlotOptPnl_c_max_EditField.Value = Inf;
            app.PlotOptPnl_c_min_EditField = uieditfield(app.PlotOptPnl, 'numeric');
            app.PlotOptPnl_c_min_EditField.Limits = [0 Inf];
            app.PlotOptPnl_t_max_EditField = uieditfield(app.PlotOptPnl, 'numeric');
            app.PlotOptPnl_t_max_EditField.Limits = [0 Inf];
            app.PlotOptPnl_t_max_EditField.Value = Inf;
            app.PlotOptPnl_t_min_EditField = uieditfield(app.PlotOptPnl, 'numeric');
            app.PlotOptPnl_t_Label = uilabel(app.PlotOptPnl);
            app.PlotOptPnl_t_Label.Text = 'Time';
            app.PlotOptPnl_c_Label = uilabel(app.PlotOptPnl);
            app.PlotOptPnl_c_Label.Text = 'Conc';
            app.PlotOptPnl_MinLabel = uilabel(app.PlotOptPnl);
            app.PlotOptPnl_MinLabel.Text = 'Min';
            app.PlotOptPnl_MaxLabel = uilabel(app.PlotOptPnl);
            app.PlotOptPnl_MaxLabel.Text = 'Max';
            app.PlotOptPnlLinePropertiesUITable = uitable(app.PlotOptPnl);
            app.PlotOptPnlLinePropertiesUITable.ColumnName = {'Channel'; 'Color (RGB)'; 'Line Width'; 'Line Style'};
            app.PlotOptPnlLinePropertiesUITable.RowName = {};
            app.PlotOptPnlLinePropertiesUITable.ColumnEditable = [false true true true];
            app.PlotOptPnlLinePropertiesUITable.CellSelectionCallback = createCallbackFcn(app, @PlotOptPnlLinePropertiesUITableCellSelection, true);
            app.PlotOptPnlFittedLinePropertiesLabel = uilabel(app.PlotOptPnl);
            app.PlotOptPnlFittedLinePropertiesLabel.Text = 'Fitted Line Properties';
            app.PlotOptPnlDataMarkerPropertiesLabel = uilabel(app.PlotOptPnl);
            app.PlotOptPnlDataMarkerPropertiesLabel.Text = 'Data Marker Properties';
            app.PlotOptPnlMarkerStyleLabel = uilabel(app.PlotOptPnl);
            app.PlotOptPnlMarkerStyleLabel.Text = 'Marker Style';
            app.PlotOptPnlMarkerSizeLabel = uilabel(app.PlotOptPnl);
            app.PlotOptPnlMarkerSizeLabel.Text = 'Marker Size';
            app.PlotOptPnlMarkerSizeEditField = uieditfield(app.PlotOptPnl, 'numeric');
            app.PlotOptPnlMarkerSizeEditField.Limits = [0 Inf];
            app.PlotOptPnlMarkerSizeEditField.Value = 7;
            app.PlotOptPnlMarkerStyleDropDown = uidropdown(app.PlotOptPnl);
            app.PlotOptPnlMarkerStyleDropDown.Items = {'Circle', 'Plus sign', 'Asterisk', 'Point', 'Cross', 'Horizonal Line', 'Vertical Line', 'Square', 'Diamond', 'Upward-Pointing Triangle', 'Downward-Pointing Triangle', 'Right-Pointing Triangle', 'Left-Pointing Triangle', 'Pentagram', 'Hexagram', 'None'};
            app.PlotOptPnlMarkerStyleDropDown.Value = 'Circle';
            app.PlotOptPnlEdgeThicknessEditField = uieditfield(app.PlotOptPnl, 'numeric');
            app.PlotOptPnlEdgeThicknessEditField.Limits = [0 Inf];
            app.PlotOptPnlEdgeThicknessEditField.Value = 1.2;
            app.PlotOptPnlEdgeThicknessLabel = uilabel(app.PlotOptPnl);
            app.PlotOptPnlEdgeThicknessLabel.Text = 'Line Width';
            app.EdgeColorColorPickerLabel = uilabel(app.PlotOptPnl);
            app.EdgeColorColorPickerLabel.Text = 'Edge Color';
            app.EdgeColorColorPicker = uicolorpicker(app.PlotOptPnl);
            app.EdgeColorColorPicker.Value = [0.149 0.149 0.149];
            app.FillColorColorPickerLabel = uilabel(app.PlotOptPnl);
            app.FillColorColorPickerLabel.Text = 'Fill Color';
            app.FillColorColorPicker = uicolorpicker(app.PlotOptPnl);
            app.FillColorColorPicker.Value = [1 1 1];
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.CleartextareaMenu = uimenu(app.ContextMenu);
            app.CleartextareaMenu.Text = 'Clear text area';
            % Assign app.ContextMenu
            app.ResultsPnlTextArea.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TAT08AnalysisConservativeTrTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end