classdef TAT08AnalysisReactiveTrTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalysisWorkflowReactiveTracerTestUIFigure  matlab.ui.Figure
        FileActionsMenu                 matlab.ui.container.Menu
        ReadconservativetracerobservationsMenu  matlab.ui.container.Menu
        ReadreactivetracerobservationsMenu  matlab.ui.container.Menu
        ReadreactiveconservativeratiodataMenu  matlab.ui.container.Menu
        SaveparametersMenu              matlab.ui.container.Menu
        ReadparametersMenu              matlab.ui.container.Menu
        SaveplotteddataMenu             matlab.ui.container.Menu
        TaskControlMenu                 matlab.ui.container.Menu
        DesignMenu                      matlab.ui.container.Menu
        desConservativetracerMenu       matlab.ui.container.Menu
        desTemperatureprofilesMenu      matlab.ui.container.Menu
        desReactivetracerselectionMenu  matlab.ui.container.Menu
        desReactivetracerMenu           matlab.ui.container.Menu
        AnalysisMenu                    matlab.ui.container.Menu
        analConservativetracerMenu      matlab.ui.container.Menu
        analReactivetracerMenu          matlab.ui.container.Menu
        CommonVariablesMenu             matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        SendtoMenu                      matlab.ui.container.Menu
        PullfromMenu                    matlab.ui.container.Menu
        EqHelpButton                    matlab.ui.control.Button
        EqImage                         matlab.ui.control.Image
        PlotOptionsPanel                matlab.ui.container.Panel
        PlotOptPnlFittedLinePropertiesLabel_2  matlab.ui.control.Label
        FillColorColorPicker_1          matlab.ui.control.ColorPicker
        FillColorLabel                  matlab.ui.control.Label
        FillColorColorPicker_2          matlab.ui.control.ColorPicker
        EdgeColorColorPicker_2          matlab.ui.control.ColorPicker
        EdgeColorColorPicker_1          matlab.ui.control.ColorPicker
        EdgeColorLabel                  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        PlotOptPnlMarkerSizeEditField_2  matlab.ui.control.NumericEditField
        PlotOptPnlMarkerStyleDropDown_2  matlab.ui.control.DropDown
        PlotOptPnl_t_max_pred_EditField  matlab.ui.control.NumericEditField
        PlotOptPnl_t_pred_Label         matlab.ui.control.Label
        PlotOptPnl_t_min_pred_EditField  matlab.ui.control.NumericEditField
        PlotOptPnlMarkerStyleDropDown_1  matlab.ui.control.DropDown
        PlotOptPnlMarkerSizeEditField_1  matlab.ui.control.NumericEditField
        PlotOptPnlMarkerSizeLabel       matlab.ui.control.Label
        PlotOptPnlMarkerStyleLabel      matlab.ui.control.Label
        PlotOptPnlDataMarkerPropertiesLabel  matlab.ui.control.Label
        PlotOptPnlFittedLinePropertiesLabel  matlab.ui.control.Label
        PlotOptPnlLinePropertiesUITable  matlab.ui.control.Table
        PlotOptPnlMaxLabel              matlab.ui.control.Label
        PlotOptPnlMinLabel              matlab.ui.control.Label
        PlotOptPnl_c_max_EditField      matlab.ui.control.NumericEditField
        PlotOptPnl_t_max_travel_EditField  matlab.ui.control.NumericEditField
        PlotOptPnl_c_min_EditField      matlab.ui.control.NumericEditField
        PlotOptPnl_c_Label              matlab.ui.control.Label
        PlotOptPnl_t_travel_Label       matlab.ui.control.Label
        PlotOptPnl_t_min_travel_EditField  matlab.ui.control.NumericEditField
        PlotsPanel                      matlab.ui.container.Panel
        UpdatePlotsUsingCurrentPlottingOptionsButton  matlab.ui.control.Button
        PltPnlTabGroup                  matlab.ui.container.TabGroup
        PltPnlTabGrpBTCsTab             matlab.ui.container.Tab
        PltPnlGrpBTCsUIAxes             matlab.ui.control.UIAxes
        PltPnlTabGrpResidualsTab        matlab.ui.container.Tab
        PltPnlTabGrpResidualsUIAxes     matlab.ui.control.UIAxes
        PltPnlTabGrpNormProbPlotResTab  matlab.ui.container.Tab
        PltPnlTabGrpNormProbPlotResUIAxes  matlab.ui.control.UIAxes
        PltPnlTabGrpLogRelCTab          matlab.ui.container.Tab
        PltPnlTabGrpLogRelCUIAxes       matlab.ui.control.UIAxes
        PltPnlTabGrpTempProfTab         matlab.ui.container.Tab
        PltPnlGrpTempProfUIAxes         matlab.ui.control.UIAxes
        PltPnlTabGrpTempPredictTab      matlab.ui.container.Tab
        PltPnlGrpTempPredictUIAxes      matlab.ui.control.UIAxes
        EnthalpyPredictionTab           matlab.ui.container.Tab
        PltPnlGrpEnthalpyPredictUIAxes  matlab.ui.control.UIAxes
        ResultsPanel                    matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ResultsInfoTab                  matlab.ui.container.Tab
        ResultsPnlTextArea              matlab.ui.control.TextArea
        ResultsTable                    matlab.ui.container.Tab
        FittedParametersUITable         matlab.ui.control.Table
        RecalculateRCratiouponloadingeitherSwitch  matlab.ui.control.ToggleSwitch
        RecalculateRCratiouponloadingeitherSwitchLabel  matlab.ui.control.Label
        TolFunEditField                 matlab.ui.control.NumericEditField
        FunctiontoleranceLabel          matlab.ui.control.Label
        MaxIterMultEditField            matlab.ui.control.NumericEditField
        MaxiterationsmultiplierLabel    matlab.ui.control.Label
        TolParamEditField               matlab.ui.control.NumericEditField
        RelativetoleranceEditFieldLabel  matlab.ui.control.Label
        MaxFuncEvalsMultEditField       matlab.ui.control.NumericEditField
        MaxfunctionevalsmultiplierEditFieldLabel  matlab.ui.control.Label
        SolverSelectionDropDown         matlab.ui.control.DropDown
        SolverselectionDropDownLabel    matlab.ui.control.Label
        BTCsLoadedLamp                  matlab.ui.control.Lamp
        BreakthroughcurveratiodataloadedLabel  matlab.ui.control.Label
        ResultsPnlFitDataButton         matlab.ui.control.Button
        ParamPanel                      matlab.ui.container.Panel
        ParamPnl_lambda_r_EditField     matlab.ui.control.NumericEditField
        ParamPnl_lambda_r_Label         matlab.ui.control.Label
        DispersivityalphacLsupmsupLabel  matlab.ui.control.Label
        ReactiveTracerLabel             matlab.ui.control.Label
        ConservativeTracerLabel         matlab.ui.control.Label
        ThermalDataLabel                matlab.ui.control.Label
        HydraulicDataLabel              matlab.ui.control.Label
        ParamPnl_alpha_m_Label          matlab.ui.control.Label
        ParamPnl_alpha_m_EditField      matlab.ui.control.NumericEditField
        ParamPnl_alpha_c_Label          matlab.ui.control.Label
        ParamPnl_alpha_c_EditField      matlab.ui.control.NumericEditField
        ParamPnl_Arr_Ea_Label           matlab.ui.control.Label
        ParamPnl_Arr_Ea_EditField       matlab.ui.control.NumericEditField
        ParamPnl_Arr_A_Label            matlab.ui.control.Label
        ParamPnl_Arr_A_EditField        matlab.ui.control.NumericEditField
        ParamPnl_M_rea_Label            matlab.ui.control.Label
        ParamPnl_M_rea_EditField        matlab.ui.control.NumericEditField
        ParamPnl_M_con_Label            matlab.ui.control.Label
        ParamPnl_M_con_EditField        matlab.ui.control.NumericEditField
        ParamPnl_delta_p_EditField      matlab.ui.control.NumericEditField
        ParamPnl_delta_p_Label          matlab.ui.control.Label
        ParamPnl_Q_Label                matlab.ui.control.Label
        ParamPnl_Q_EditField            matlab.ui.control.NumericEditField
        ParamPnlUITable                 matlab.ui.control.Table
        ParamPnl_t_op_EditField         matlab.ui.control.NumericEditField
        ParamPnl_t_op_Label             matlab.ui.control.Label
        ParamPnl_cp_r_EditField         matlab.ui.control.NumericEditField
        ParamPnl_cp_r_Label             matlab.ui.control.Label
        ParamPnl_rho_r_EditField        matlab.ui.control.NumericEditField
        ParamPnl_rho_r_Label            matlab.ui.control.Label
        ParamPnl_T_inj_EditField        matlab.ui.control.NumericEditField
        ParamPnl_T_inj_Label            matlab.ui.control.Label
        ParamPnl_T_res_EditField        matlab.ui.control.NumericEditField
        ParamPnl_T_res_Label            matlab.ui.control.Label
        ParamPnlDeleteChannelButton     matlab.ui.control.Button
        ParamPnlAddChannelButton        matlab.ui.control.Button
        ParamPnl_nChn_EditField         matlab.ui.control.NumericEditField
        ParamPnl_nChn_Label             matlab.ui.control.Label
        analysisReaTrAnalLabel          matlab.ui.control.Label
    end

        properties (Access = private)
        % Start up
        hScale                          % Horizontal scaling factor for monitor resolution differences
        TAText                          % Extension for Tracer Analysis Toolbox file
        TATName                         % Name of Tracer Analysis Toolbox file
        TATPath                         % Path to Tracer Analysis Toolbox
        vScale                          % Vertical scaling factor for monitor resolution differences       		

        CommonVars_Hndl                      
        DesignConservativeTrTest_Hndl
        DesignTemperatureProfile_Hndl
        DesignSelectReactiveTr_Hndl
        DesignReactiveTrTest_Hndl
        AnalysisConservativeTrTest_Hndl
        
        % Parameter Panel
        TableCheckBox           	    % Checkboxes used in the Table
        nChn                    	    % Number of channels for temperature profile in design workflow

        % Pass between functions
        tCcData                         % Measured conservative tracer concentration vs time
        tCrData                         % Measured reactive tracer concentration vs time data
        tRatioData                      % Measured reactive/conservative tracer ratio vs time data
        
        % Graphics handles for plots
        cObsPlot                        
        rObsPlot                        
        RatioObsPlot  
        FitBTCsPlot
        FitRatioPlot
        FitResidualsPlot
        FitResidualsProbPlot
        FitTProfilePlot
        % FitTPredictionPlot
        % FitEnthalpyPlot

        % Plot Options Panel
        LStyleDescrip_All      % ALL line style descriptions as they appear in cells
        LStyle_All             % ALL line styles as matlab line styles        
        MLDefaultColors        % Vector of RGB codes for line default colors us in Matlab        
    end
    
    methods (Access = private)

        function TATSetup(app)
        % SETUP: establishes basic information about the app
        % including location, screen size, default plot colors
            % Get location of app
            [app.TATPath, app.TATName, app.TAText] = fileparts(mfilename('fullpath'));

            % Add path to functions
            addPathNames = strcat(app.TATPath,"\TAT_Fcts");
            addpath(genpath(addPathNames));

            % Get Screen size and define scaling factors %MAP
            screenSize = get(groot,'ScreenSize');
           if screenSize(3) < app.AnalysisWorkflowReactiveTracerTestUIFigure.Position(3)
               app.hScale = screenSize(3)/2560;
           else 
               app.hScale = 1;
           end 
           if screenSize(4) < app.AnalysisWorkflowReactiveTracerTestUIFigure.Position(4)
               app.vScale = screenSize(4)/1440;
           else 
               app.vScale = 1;
           end 

            % Set Matlab's default colors for lines, as well as line style descriptions for tables and those converted to matlab code
            app.MLDefaultColors = ["0.000, 0.447, 0.741" "0.850, 0.325, 0.098" "0.929, 0.694, 0.125" "0.494, 0.184, 0.556" "0.466, 0.674, 0.188" ...
                "0.301, 0.745, 0.933" "0.635, 0.078, 0.184","0.000 0.000 1.000","0.000 0.500 0.000","1.000 0.000 0.000" "0.000 0.000 0.000"]; 
            app.LStyleDescrip_All = categorical({'Solid', 'Dashed', 'Dotted', 'Dash-Dotted','none'}) ;
            app.LStyle_All = {'-';'--';':';'-.';''}  ;
        end
        
        function ParamTableInitial(app)
            app.TableCheckBox = {true; true; true; true; true; true; true; true; true; true};
            app.ParamPnlUITable.Data = table(1,1e-3,1000,1,2.5,0.01,NaN,true,'VariableNames',{'Channel','v','L','f','lambda_r','b','b_guess','Include'});
            app.nChn = 1;
            app.ParamPnl_nChn_EditField.Value = app.nChn;
        end
        
        function PlotOptPnlTableInitialize(app)
            % Initialize the Plot Options Table in the Plot Options panel
            ID = {'C_con_avg';'C_rea_avg';'1'};
            LColor = [app.MLDefaultColors(11);app.MLDefaultColors(10);app.MLDefaultColors(1)];
            LWidth = {2;2;1};
            LStyleDescrip = {app.LStyleDescrip_All(1);app.LStyleDescrip_All(1);app.LStyleDescrip_All(2)};
            app.PlotOptPnlLinePropertiesUITable.Data = table(ID, LColor, LWidth, LStyleDescrip);

            % Format the Table
            for i = 1:3
                myColorStyle = uistyle("BackgroundColor",app.PlotOptPnlLinePropertiesUITable.Data{i,2},"FontColor",[0.94 0.94 0.94]);
                addStyle(app.PlotOptPnlLinePropertiesUITable,myColorStyle,'cell',[i,2]);
            end            
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Get screen size and define hScale, vScale, and plot default colors
        TATSetup(app)

        % Initialize Parameter Table
        ParamTableInitial(app)

        % Initialize Plot Options Table
        PlotOptPnlTableInitialize(app)           
        end

        % Button pushed function: ParamPnlAddChannelButton
        function ParamPnlAddChannelButtonPushed(app, event)
        % Check if the number of channels is <= 10
        if(app.nChn < 10)
            if(app.nChn == 0)
                NewChnNum = 0;
            else
                % Find the lowest unused channel number
                NewChnNum = find(~ismember(1:10,app.ParamPnlUITable.Data{:,1}), 1 );

                % Increment the number of channels and update editfield displaying designConnChn
                app.nChn = app.nChn + 1;
                app.ParamPnl_nChn_EditField.Value = app.nChn;

                % Create new row, add row to table
                % newRow = {NewChnNum,1e-3,1000,1/app.nChn,2.5,0.01,NaN,true};
                newRow = app.ParamPnlUITable.Data(end,:) ;
                newRow.Channel = NewChnNum;
                newRow.v = newRow.v * 0.8 ;
                app.ParamPnlUITable.Data  = [app.ParamPnlUITable.Data; newRow];   
    
                % Generate New Line for Plot Options Table
                newRow = {NewChnNum, app.MLDefaultColors(1), 2, {app.LStyleDescrip_All(2)}};
                app.PlotOptPnlLinePropertiesUITable.Data = [app.PlotOptPnlLinePropertiesUITable.Data; newRow] ;

                % Change background color of color cell in new row           
                myColorStyle = uistyle("BackgroundColor",app.MLDefaultColors(NewChnNum),"FontColor",[0.94 0.94 0.94]);
                addStyle(app.PlotOptPnlLinePropertiesUITable,myColorStyle,'cell',[app.nChn + 2,2]);
            end
        else
           errordlg('Number of channels must be <= 10','Input Error')
           return
        end
        end

        % Button pushed function: ParamPnlDeleteChannelButton
        function ParamPnlDeleteChannelButtonPushed(app, event)
        % Create response dialog box to get channels to delete
	        prompt = {'Enter space-separated channel numbers:'};
        deleteDlgTitle = 'Delete Channels';
        dims = [1,40];
        deleteResponse = inputdlg(prompt,deleteDlgTitle,dims);
        if(isempty(deleteResponse))
            return
        end

        deleteChn = str2num(deleteResponse{1})';
        nDelete = size(deleteChn,1);

        % Display error msg if none or all channels are specified
        app.nChn  = app.ParamPnl_nChn_EditField.Value; % Make sure num channels var is up-to-date
        if(nDelete == 0)
            return
        elseif (nDelete == app.nChn)
            errTxt = 'Cannot delete all channels';
            errordlg(errTxt,'Input Error');
            return
        end

        % Display error message if channel number not found
        for i = deleteChn
            if (~ismember(deleteChn, app.ParamPnlUITable.Data.Channel))
                errTxt = ['Channel ' num2str(i) ' not found. Check input.'];
                errordlg(errTxt,'Input Error');   
                return
            end
        end

        % Delete rows from relevant tables for channels found
        deleteRows = find(ismember(app.ParamPnlUITable.Data.Channel,deleteChn));
        app.ParamPnlUITable.Data(deleteRows,:) = [];
        deleteRows = find(ismember(string(app.PlotOptPnlLinePropertiesUITable.Data.ID), num2str(deleteChn))) ;
        app.PlotOptPnlLinePropertiesUITable.Data(deleteRows,:) = [];
        app.nChn = app.nChn - length(deleteRows);
        app.ParamPnl_nChn_EditField.Value = app.nChn;
        end

        % Menu selected function: ReadconservativetracerobservationsMenu
        function ReadconservativetracerobservationsMenuSelected(app, event)
        % Delete previously imported data
        if ~isempty(app.tCcData)
            app.tCcData = [];
        end

        % Call external read data function
        app.tCcData = ReadData();   
        if isempty(app.tCcData); return; end

        % Plot data
        hold(app.PltPnlGrpBTCsUIAxes, 'on');
        delete(app.cObsPlot);
        app.cObsPlot = plot(app.PltPnlGrpBTCsUIAxes,app.tCcData(1,:),app.tCcData(2,:),'ko','DisplayName', 'Obs C_c_o_n');
        legend(app.PltPnlGrpBTCsUIAxes,'Visible',true)

        % Calculate ratios again
        if and(isequal(app.RecalculateRCratiouponloadingeitherSwitch.Value,'On'), ~isempty(app.tCrData))
                app.tRatioData = RatioOnCommonTime(app.tCcData,app.tCrData);
                app.RatioObsPlot = semilogy(app.PltPnlTabGrpLogRelCUIAxes,app.tRatioData(1,:),app.tRatioData(2,:),'ks','DisplayName', 'Obs C Ratio');  
                legend(app.PltPnlTabGrpLogRelCUIAxes)
                % Activate lamp and 'Fit Data' button
                app.BTCsLoadedLamp.Color = [0 1 0];            
                app.ResultsPnlFitDataButton.Enable = 'on';
        end
        end

        % Menu selected function: ReadreactivetracerobservationsMenu
        function ReadreactivetracerobservationsMenuSelected(app, event)
     
        % Delete previously imported data
        if ~isempty(app.tCrData)
            app.tCrData = [];
        end            
        
        % Call external read data function
        [app.tCrData,~] = ReadData();
        if isempty(app.tCrData); return; end

        % Plot data
        hold(app.PltPnlGrpBTCsUIAxes, 'on');
        delete(app.rObsPlot);
        app.rObsPlot  = plot(app.PltPnlGrpBTCsUIAxes,app.tCrData(1,:),app.tCrData(2,:),'ro','DisplayName', 'Obs C_r_e_a');
        legend(app.PltPnlGrpBTCsUIAxes,'Visible','on')

        % Calculate ratios again
        if and(isequal(app.RecalculateRCratiouponloadingeitherSwitch.Value,'On'), ~isempty(app.tCcData))
            app.tRatioData = RatioOnCommonTime(app.tCcData,app.tCrData);
            app.RatioObsPlot = semilogy(app.PltPnlTabGrpLogRelCUIAxes,app.tRatioData(1,:),app.tRatioData(2,:),'ko','DisplayName', 'Obs C Ratio');  
            legend(app.PltPnlTabGrpLogRelCUIAxes)            
            % Activate lamp and 'Fit Data' button 
            app.BTCsLoadedLamp.Color = [0 1 0];            
            app.ResultsPnlFitDataButton.Enable = 'on';
        end
        end

        % Menu selected function: SaveparametersMenu
        function SaveparametersMenuSelected(app, event)
        % Select file to save to
        [filename,pathname] = uiputfile('*.*');
        if isequal(filename,0)
            return;
        end
        fileNamefull = fullfile(pathname,filename);    

        % Read the preexisting structure if file exists
        if isfile(fileNamefull)
            Struct = read_namelist(fileNamefull);
        end

        % Place variables into relevant structure sections
        Struct.AnalysisConservativeTrTest.Q                 = app.ParamPnl_Q_EditField.Value        ;
        Struct.AnalysisConservativeTrTest.delta_p           = app.ParamPnl_delta_p_EditField.Value  ;
        Struct.AnalysisConservativeTrTest.T_res             = app.ParamPnl_T_res_EditField.Value    ;
        Struct.AnalysisConservativeTrTest.M_con             = app.ParamPnl_M_con_EditField.Value    ;
        Struct.AnalysisConservativeTrTest.alpha_c           = app.ParamPnl_alpha_c_EditField.Value  ;
        Struct.AnalysisConservativeTrTest.alpha_m           = app.ParamPnl_alpha_m_EditField.Value  ;
        Struct.AnalysisConservativeTrTest.nChn              = app.ParamPnl_nChn_EditField.Value     ;

        Struct.AnalysisReactiveTrTest.t_op              = app.ParamPnl_t_op_EditField.Value     ;
        Struct.AnalysisReactiveTrTest.T_inj             = app.ParamPnl_T_inj_EditField.Value    ;
        Struct.AnalysisReactiveTrTest.rho_r             = app.ParamPnl_rho_r_EditField.Value    ;
        Struct.AnalysisReactiveTrTest.cp_r              = app.ParamPnl_cp_r_EditField.Value     ;
        Struct.AnalysisReactiveTrTest.M_rea             = app.ParamPnl_M_rea_EditField.Value    ;
        Struct.AnalysisReactiveTrTest.Arr_A             = app.ParamPnl_Arr_A_EditField.Value    ;
        Struct.AnalysisReactiveTrTest.Arr_Ea            = app.ParamPnl_Arr_Ea_EditField.Value   ;
     
        % Channel data table
        Struct.AnalysisConservativeTrTest.Table_Channel   = app.ParamPnlUITable.Data.Channel		; 
        Struct.AnalysisConservativeTrTest.Table_v         = app.ParamPnlUITable.Data.v		        ; 
        Struct.AnalysisConservativeTrTest.Table_L         = app.ParamPnlUITable.Data.L		        ; 
        Struct.AnalysisConservativeTrTest.Table_f         = app.ParamPnlUITable.Data.f		        ; 
        Struct.AnalysisConservativeTrTest.Table_Include   = double(app.ParamPnlUITable.Data.Include);
        
        Struct.AnalysisReactiveTrTest.Table_lambda_r  = app.ParamPnlUITable.Data.lambda_r		    ; 
        Struct.AnalysisReactiveTrTest.Table_b         = app.ParamPnlUITable.Data.b	                ; 
        Struct.AnalysisReactiveTrTest.Table_b_guess     = app.ParamPnlUITable.Data.b_guess		        ; 

        % Solver EditFields
        Struct.AnalysisReactiveTrTest.MaxFuncEvalsMult  = app.MaxFuncEvalsMultEditField.Value   ;      
        Struct.AnalysisReactiveTrTest.MaxIterMult  		= app.MaxIterMultEditField.Value        ;      
        Struct.AnalysisReactiveTrTest.TolFun       		= app.TolFunEditField.Value             ;      
        Struct.AnalysisReactiveTrTest.TolParam     		= app.TolParamEditField.Value           ;                  
        
        % Plot Options EditFields
        Struct.AnalysisReactiveTrTest.t_min_travel      = app.PlotOptPnl_t_min_travel_EditField.Value   ;
        Struct.AnalysisReactiveTrTest.t_max_travel      = app.PlotOptPnl_t_max_travel_EditField.Value   ;
        Struct.AnalysisReactiveTrTest.t_min_pred        = app.PlotOptPnl_t_min_pred_EditField.Value     ;
        Struct.AnalysisReactiveTrTest.t_max_pred        = app.PlotOptPnl_t_max_pred_EditField.Value     ;
        Struct.AnalysisReactiveTrTest.c_min             = app.PlotOptPnl_c_min_EditField.Value          ;
        Struct.AnalysisReactiveTrTest.c_max             = app.PlotOptPnl_c_max_EditField.Value          ;                
        
        % % Arrays of obs times, and conservative and reactive tracers observations
        % % ObsArrays = (app.tCcData, app.tCcData, app.tCrData, app.tRatioData) ;
        % ObsArrays = {'app.tCcData', 'app.tCcData', 'app.tCrData', 'app.tRatioData'} ;
        % OutputVar = {'timeData','CcData','CrData','RatioData'} ;
        % for i = 1 : width(ObsArrays)
        %     if exist(ObsArrays{i})
        %         disp(ObsArrays{i})
        %         eval(OutputVar{i}) = 1 
        %     end
        % end
        % Struct.AnalysisReactiveTrTest.tData = app.tCcData(1,:) ;        
        % Struct.AnalysisReactiveTrTest.CcData = app.tCcData(2,:) ;
        % Struct.AnalysisReactiveTrTest.CrData = app.tCrData(2,:) ;           
        % Struct.AnalysisReactiveTrTest.RatioData = app.tRatioData(2,:) ;        
        
        % Save structure to file
        write_namelist(Struct,fileNamefull);
        end

        % Menu selected function: ReadparametersMenu
        function ReadparametersMenuSelected(app, event)
        % Select file to read
        [filename,pathname] = uigetfile('*.*');
        if isequal(filename,0)
            return;
        end
        fileNamefull = fullfile(pathname,filename);
        Struct = read_namelist(fileNamefull);

        % Stop if no data
        if ~isfield(Struct,'AnalysisConservativeTrTest')
            msgbox('No tracer test analysis data found; execution halted')
            return
        end            
        
        % Editfield data for conservative tracer
        app.ParamPnl_Q_EditField.Value              = Struct.AnalysisConservativeTrTest.Q       ;
        app.ParamPnl_delta_p_EditField.Value        = Struct.AnalysisConservativeTrTest.delta_p ; 
        app.ParamPnl_T_res_EditField.Value          = Struct.AnalysisConservativeTrTest.T_res   ; 
        app.ParamPnl_M_con_EditField.Value          = Struct.AnalysisConservativeTrTest.M_con   ; 
        app.ParamPnl_alpha_c_EditField.Value        = Struct.AnalysisConservativeTrTest.alpha_c ; 
        app.ParamPnl_alpha_m_EditField.Value        = Struct.AnalysisConservativeTrTest.alpha_m ; 
        app.ParamPnl_nChn_EditField.Value           = Struct.AnalysisConservativeTrTest.nChn    ; 

        % Update number of channels by reading min of table lengths
        app.nChn = min( width(Struct.AnalysisConservativeTrTest.Table_Channel), width(Struct.AnalysisConservativeTrTest.Table_v) ) ;
        app.ParamPnl_nChn_EditField.Value = app.nChn ;

        % Read in some table data (note that this needs to deal better w different sections having different num rows
        app.ParamPnlUITable.Data = [];            
        
        Channel    =   Struct.AnalysisConservativeTrTest.Table_Channel(1:app.nChn)'         ;
        v          =   Struct.AnalysisConservativeTrTest.Table_v(1:app.nChn)'          	;
        L          =   Struct.AnalysisConservativeTrTest.Table_L(1:app.nChn)'          	;
        f          =   Struct.AnalysisConservativeTrTest.Table_f(1:app.nChn)'          	;
        Include    =   logical(Struct.AnalysisConservativeTrTest.Table_Include(1:app.nChn)') 	    ;

        if  ~isfield(Struct,'AnalysisReactiveTrTest')
            msgbox('No Reactive tracer test analysis data found; using default values')
        else
            % Editfield data for reactive tracer           
            app.ParamPnl_t_op_EditField.Value           = Struct.AnalysisReactiveTrTest.t_op    ; 
            app.ParamPnl_T_inj_EditField.Value          = Struct.AnalysisReactiveTrTest.T_inj   ; 
            app.ParamPnl_rho_r_EditField.Value          = Struct.AnalysisReactiveTrTest.rho_r   ; 
            app.ParamPnl_cp_r_EditField.Value           = Struct.AnalysisReactiveTrTest.cp_r    ;             
            app.ParamPnl_M_rea_EditField.Value          = Struct.AnalysisReactiveTrTest.M_rea   ; 
            app.ParamPnl_Arr_A_EditField.Value          = Struct.AnalysisReactiveTrTest.Arr_A   ; 
            app.ParamPnl_Arr_Ea_EditField.Value         = Struct.AnalysisReactiveTrTest.Arr_Ea  ;
            
            % Read in some table data
            if width(Struct.AnalysisReactiveTrTest.Table_lambda_r) >= width(Struct.AnalysisConservativeTrTest.Table_v)
                lambda_r   =   Struct.AnalysisReactiveTrTest.Table_lambda_r(1:app.nChn)'   	;
                b          =   Struct.AnalysisReactiveTrTest.Table_b(1:app.nChn)'       	;
                b_guess      =   Struct.AnalysisReactiveTrTest.Table_b_guess(1:app.nChn)'    	;
            else
                lambda_r(1:app.nChn)    =   2.5   	;
                b(1:app.nChn)           =   0      ;
                b_guess(1:app.nChn)       =   0      ;
            end

            % Create table from variables
            app.ParamPnlUITable.Data = table(Channel, v, L, f, lambda_r, b, b_guess, Include) ;
            
            % Solver EditFields
            app.MaxFuncEvalsMultEditField.Value         = Struct.AnalysisReactiveTrTest.MaxFuncEvalsMult;
            app.MaxIterMultEditField.Value              = Struct.AnalysisReactiveTrTest.MaxIterMult;
            app.TolFunEditField.Value                   = Struct.AnalysisReactiveTrTest.TolFun;
            app.TolParamEditField.Value                 = Struct.AnalysisReactiveTrTest.TolParam;

             % Plot Options EditFields          
            app.PlotOptPnl_t_min_travel_EditField.Value = Struct.AnalysisReactiveTrTest.t_min_travel; 
            app.PlotOptPnl_t_max_travel_EditField.Value = Struct.AnalysisReactiveTrTest.t_max_travel; 
            app.PlotOptPnl_t_min_pred_EditField.Value   = Struct.AnalysisReactiveTrTest.t_min_pred; 
            app.PlotOptPnl_t_max_pred_EditField.Value   = Struct.AnalysisReactiveTrTest.t_max_pred; 
            app.PlotOptPnl_c_min_EditField.Value        = Struct.AnalysisReactiveTrTest.c_min; 
            app.PlotOptPnl_c_max_EditField.Value        = Struct.AnalysisReactiveTrTest.c_max;                    
        end

        % Update plot options table for channel rows, not average plots
        ChannelNumbers =  app.ParamPnlUITable.Data.Channel(app.ParamPnlUITable.Data.Include) ;
        NumRows = sum(app.ParamPnlUITable.Data.Include) ;
        app.PlotOptPnlLinePropertiesUITable.Data(3:end,:) = [] ; 
        for i = 1 : NumRows
            %Append another row
            app.PlotOptPnlLinePropertiesUITable.Data = [ app.PlotOptPnlLinePropertiesUITable.Data ;...
                                                  {ChannelNumbers(i), app.MLDefaultColors(ChannelNumbers(i)), 1, {'Dashed'} }] ;
            TableIndex = i + 2 ;
            myColorStyle = uistyle("BackgroundColor",app.MLDefaultColors(ChannelNumbers(i)),"FontColor",[0.94 0.94 0.94]);
            addStyle(app.PlotOptPnlLinePropertiesUITable,myColorStyle,'cell',[TableIndex,2]);
            
        end
       
        % Arrays of conservative and reactive tracers observations
        % if
        %     app.tData  = Struct.ConservativeAnalysisTask.tData;        
        %     app.CcData = Struct.ConservativeAnalysisTask.tCcData;
        %     app.CrData = Struct.AnalysisReactiveTrTest.tCrData;   
        %     app.RatioData = Struct.AnalysisReactiveTrTest.tRatioData;
        % end
        
        % % Plot the observation data %MAP This is redundant. 
        % if ~isempty(app.tCcData)
        %     delete(app.cObsPlot);
        %     hold(app.PltPnlGrpBTCsUIAxes, 'on');
        %     app.cObsPlot = plot(app.PltPnlGrpBTCsUIAxes,app.tCcData(1,:),app.tCcData(2,:),'ko','DisplayName', 'Obs C_c_o_n');
        % end
        % if ~isempty(app.tCrData)
        %     delete(app.rObsPlot);
        %     hold(app.PltPnlGrpBTCsUIAxes, 'on');
        %     app.rObsPlot = plot(app.PltPnlGrpBTCsUIAxes,app.tCrData(1,:),app.tCrData(2,:),'ro','DisplayName', 'Obs C_r_e_a');
        % end
        % if ~isempty(app.tRatioData)
        %     delete(app.RatioObsPlot);
        %     hold(app.PltPnlGrpBTCsUIAxes, 'on');
        %     app.RatioObsPlot = semilogy(app.PltPnlTabGrpLogRelCUIAxes,app.tRatioData(1,:),app.tRatioData(2,:),'ro','DisplayName', 'Obs C_r_a_t_i_o');
        % end            
        % 
        % % Activate lamp and 'Fit Data' button %MAP This needs check on data exist - which should be a general helper function
        % app.BTCsLoadedLamp.Color = [0 1 0];            
        % app.ResultsPnlFitDataButton.Enable = 'on';
        end

        % Menu selected function: SaveplotteddataMenu
        function SaveplotteddataMenuSelected(app, event)
%--------------------------------------
       % % Pass between functions
       %  tCcData                         % Measured conservative tracer concentration vs time
       %  tCrData                         % Measured reactive tracer concentration vs time data
       %  tRatioData                      % Measured reactive/conservative tracer ratio vs time data
       % 
       %  % Graphics handles for plots
       %  cObsPlot                        
       %  rObsPlot                        
       %  RatioObsPlot  
       %  FitBTCsPlot
       %  FitRatioPlot
       %  FitResidualsPlot
       %  FitResidualsProbPlot
       %  FitTProfilePlot
       %  FitTPredictionPlot
%--------------------------------------
        
        % Check for existence of plots
        if isempty(app.FitBTCsPlot)
            msgbox("No plots created; halting execution","Error");
            return
        end

        % Get the channel numbers from the fitted parameters table
        Include = app.paramTable.Data.Include;
        Channels = app.FittedParametersUITable.Data.Channel(Include); % Could also use app.ChnActiveVec

        % Define the basic header pieces
        preheader = 'Reactive tracer analysis results'; % An extra line 
        ChnHeaders = strcat("Chn-",num2str(Channels));
        header = ['Time (days)'  ChnHeaders' 'Obs C_con' 'Obs C_rea' 'Fit C_con' 'Fit C_rea' 'Residuals' 'Stdized Residuals' 'Rel C' 'TvsX' 'Tvst' 'Hvst'];
        
        % Define xy data
        dataMatrix = app.FitPlots(1).XData'; % Could also use app.tData (which is paired with app.cData to make local tcData
        for i = 1 : length(app.FitPlots)
            dataMatrix = [dataMatrix, app.FitPlots(i).YData'];
        end

        % Add residuals cols to data matrix
        dataMatrix = [dataMatrix, app.ResPlot.YData', app.StdResPlot.YData'];

        % Save headers and data to file
        [savefile,savepath] = uiputfile('*.*');
        if ~savefile==0
            saveFileFull = [savepath savefile];
            writematrix(preheader,saveFileFull)                
            writematrix(header,saveFileFull,'Delimiter',',','WriteMode','append')
            writematrix(dataMatrix,saveFileFull,'Delimiter',',','WriteMode','append')  
        end    
        end

        % Menu selected function: ViewMenu
        function ViewMenuSelected(app, event)
        app.CommonVars_Hndl = TAT08CommonVars;            
        end

        % Menu selected function: SendtoMenu
        function SendtoMenuSelected(app, event)
        app.CommonVars_Hndl = TAT08CommonVars;

        app.CommonVars_Hndl.Q_EditField.Value        = app.ParamPnl_Q_EditField.Value;
        app.CommonVars_Hndl.delta_p_EditField.Value  = app.ParamPnl_delta_p_EditField.Value;
        app.CommonVars_Hndl.t_op_EditField.Value     = app.ParamPnl_t_op_EditField.Value;

        app.CommonVars_Hndl.T_res_EditField.Value    = app.ParamPnl_T_res_EditField.Value;
        app.CommonVars_Hndl.T_inj_EditField.Value    = app.ParamPnl_T_inj_EditField.Value;
        app.CommonVars_Hndl.rho_r_EditField.Value    = app.ParamPnl_rho_r_EditField.Value;
        app.CommonVars_Hndl.cp_r_EditField.Value     = app.ParamPnl_cp_r_EditField.Value;
        app.CommonVars_Hndl.lambda_r_EditField.Value = app.ParamPnl_lambda_r_EditField.Value;

        app.CommonVars_Hndl.M_con_EditField.Value    = app.ParamPnl_M_con_EditField.Value;
        app.CommonVars_Hndl.alpha_c_EditField.Value  = app.ParamPnl_alpha_c_EditField.Value;
        app.CommonVars_Hndl.alpha_m_EditField.Value  = app.ParamPnl_alpha_m_EditField.Value;

        app.CommonVars_Hndl.M_rea_EditField.Value    = app.ParamPnl_M_rea_EditField.Value;
        app.CommonVars_Hndl.Arr_A_EditField.Value    = app.ParamPnl_Arr_A_EditField.Value;
        app.CommonVars_Hndl.Arr_Ea_EditField.Value   = app.ParamPnl_Arr_Ea_EditField.Value;

        % This table is {'Channel','v','L','f','lambda_r','b','b_guess','Include'}
        % CommonVars table is {'Channel','v','L','alpha','b','f','Include'}   
        app.CommonVars_Hndl.Channel_UITable.Data  = [] ;     
        app.CommonVars_Hndl.Channel_UITable.Data = app.ParamPnlUITable.Data(:,{'Channel','v','L','b','f','Include'}) ;  

        % Add alpha column
        Nrows = height(app.ParamPnlUITable.Data) ;
        alpha(1:Nrows) = app.ParamPnl_alpha_c_EditField.Value .* app.ParamPnlUITable.Data.L .^  app.ParamPnl_alpha_m_EditField.Value;
        app.CommonVars_Hndl.Channel_UITable.Data = addvars(app.CommonVars_Hndl.Channel_UITable.Data, alpha', 'after', 'L', 'NewVariableNames',{'alpha'}) ;         
        end

        % Menu selected function: PullfromMenu
        function PullfromMenuSelected(app, event)
        app.CommonVars_Hndl = TAT08CommonVars;

        app.ParamPnl_Q_EditField.Value          = app.CommonVars_Hndl.Q_EditField.Value;
        app.ParamPnl_delta_p_EditField.Value    = app.CommonVars_Hndl.delta_p_EditField.Value;
        app.ParamPnl_t_op_EditField.Value       = app.CommonVars_Hndl.t_op_EditField.Value;

        app.ParamPnl_T_res_EditField.Value      = app.CommonVars_Hndl.T_res_EditField.Value;
        app.ParamPnl_T_inj_EditField.Value      = app.CommonVars_Hndl.T_inj_EditField.Value;
        app.ParamPnl_cp_r_EditField.Value       = app.CommonVars_Hndl.cp_r_EditField.Value;
        app.ParamPnl_rho_r_EditField.Value      = app.CommonVars_Hndl.rho_r_EditField.Value;

        app.ParamPnl_M_con_EditField.Value      = app.CommonVars_Hndl.M_con_EditField.Value;
        app.ParamPnl_alpha_c_EditField.Value    = app.CommonVars_Hndl.alpha_c_EditField.Value;
        app.ParamPnl_alpha_m_EditField.Value    = app.CommonVars_Hndl.alpha_m_EditField.Value;

        app.ParamPnl_M_rea_EditField.Value      = app.CommonVars_Hndl.M_rea_EditField.Value;
        app.ParamPnl_Arr_A_EditField.Value      = app.CommonVars_Hndl.Arr_A_EditField.Value;
        app.ParamPnl_Arr_Ea_EditField.Value     = app.CommonVars_Hndl.Arr_Ea_EditField.Value;  

        if height(app.CommonVars_Hndl.Channel_UITable.Data) > 0 
            % Collect channel data from table
            % Get number of active channels
            app.nChn = height(app.CommonVars_Hndl.Channel_UITable.Data);
            app.ParamPnl_nChn_EditField.Value = app.nChn;  
    
            % Pull values from table, creating data where necessary
            % This table is         Channel,v,L,f,b,b_guess,Include
            % CommonVars table is   Channel,v,L,alpha,b,f,Include
            app.ParamPnlUITable.Data = app.CommonVars_Hndl.Channel_UITable.Data(:,{'Channel','v','L','f','alpha','b','b','Include'}) ; 
            app.ParamPnlUITable.Data.Properties.VariableNames = {'Channel','v','L','f','lambda_r','b','b_guess','Include'} ;
    
            % Make corrections %MAP
            app.ParamPnlUITable.Data.lambda_r(1:app.nChn) = app.ParamPnl_lambda_r_EditField.Value;
        end

        % Update plot options table
        Rows = app.ParamPnlUITable.Data.Channel(app.ParamPnlUITable.Data.Include) ;
        app.PlotOptPnlLinePropertiesUITable.Data(3:end,:) = [] ;
        for i = 1 : app.nChn
            app.PlotOptPnlLinePropertiesUITable.Data = [ app.PlotOptPnlLinePropertiesUITable.Data ;...
                                                        {Rows(i), app.MLDefaultColors(10), 2, {'Dashed'}}] ;
        end            

  % Convert line descriptions to styles
  %MAP  PlotOptPnlLinePropertiesUITableCellEdit(app, event)    %Not sure what this is for but it doesn't exist
        
        % Return control to self
        TAT08AnalysisReactiveTrTest ;
        end

        % Button pushed function: ResultsPnlFitDataButton
        function ResultsPnlFitDataButtonPushed(app, event)
        % Get the reactive tracer concentration data to fit
        if isempty(app.tCrData)
            msgbox('Breakthrough curve data not loaded; execution halted')
            return
        end
        
        % Get list of included channels
        Chn = app.ParamPnlUITable.Data.Channel;            
        include = app.ParamPnlUITable.Data.Include;
        ChnInclude = Chn(include);
        nChnInclude = sum(include);
        if nChnInclude == 0
            errordlg('Number of included channels must be => 1','Input Error');
            return
        end
        
        % Constants
        % Get the parameters and other data
        app.nChn = app.ParamPnl_nChn_EditField.Value;
        T_res = app.ParamPnl_T_res_EditField.Value;
        T_inj = app.ParamPnl_T_inj_EditField.Value;
        Q = app.ParamPnl_Q_EditField.Value;
        M_con = app.ParamPnl_M_con_EditField.Value;
        M_rea = app.ParamPnl_M_rea_EditField.Value;
        rho_r = app.ParamPnl_rho_r_EditField.Value;
        cp_r = app.ParamPnl_cp_r_EditField.Value;
        lambda_r_constant = app.ParamPnl_lambda_r_EditField.Value; %MAP

        t_op = app.ParamPnl_t_op_EditField.Value;
        Arr_A = app.ParamPnl_Arr_A_EditField.Value;
        Arr_Ea = app.ParamPnl_Arr_Ea_EditField.Value;
        alpha_c = app.ParamPnl_alpha_c_EditField.Value;
        alpha_m = app.ParamPnl_alpha_m_EditField.Value;
        delta_p = app.ParamPnl_delta_p_EditField.Value;
        tMaxPred = app.PlotOptPnl_t_max_pred_EditField.Value;

        % Calculated parameters
        rho_f = rhoH2OCalc(T_res);
        cp_f = cpH2OCalc(T_res);   
        mu_f = muH2O_LCalc(T_res);         

        % Parameters from channel data table, using only checked channels
        v = app.ParamPnlUITable.Data.v(include);
        L = app.ParamPnlUITable.Data.L(include);
        f = app.ParamPnlUITable.Data.f(include);
        lambda_r = app.ParamPnlUITable.Data.lambda_r(include); %MAP
        b = app.ParamPnlUITable.Data.b(include); % b = sqrt(12.*L.*v.*mu_f/delta_p) ;
        b_guess = app.ParamPnlUITable.Data.b_guess(include);
        
        % Values calculated from vectors
        t_trans = L./v ;
        alpha_fit_C = alpha_c.*L.^alpha_m;   % Calculated parameter          

        ParamArray = [v,L,f,b_guess,lambda_r];       
        % Create x-axis vector
        nPts = 101;
        x_rel = linspace(0,1,nPts);
        
        % Prepare arguments for function calls
        ConstantsTempCell = {x_rel,Q,T_res,T_inj,cp_f,cp_r,rho_f,rho_r};
        ConstantsRTCell = {Q,M_con,M_rea,rho_f,alpha_c,alpha_m,Arr_A,Arr_Ea};

        ParamFitIndices = (nChnInclude*4-nChnInclude+1):(nChnInclude*4) ; %aperture column
        LBndsParamFit = zeros(nChnInclude,1);

        %Define initial values for fitted parameters (just aperture)
        ParamFit = ParamArray(ParamFitIndices)';
        NumParamFit = length(ParamFit);            
                   
        % Problem-specific objective function
        SolverMethod = app.SolverSelectionDropDown.Value;
        MaxFunEvals = NumParamFit * app.MaxFuncEvalsMultEditField.Value;
        MaxIter = NumParamFit * app.MaxIterMultEditField.Value;
        TolParam = app.TolParamEditField.Value;
        TolFun = app.TolFunEditField.Value;
        ObjFun = @(ParamFit) MinFun(app.tCrData,t_op,ConstantsTempCell,ConstantsRTCell,ParamFit,ParamFitIndices,ParamArray,SolverMethod);  

        % Run solver
        if contains(SolverMethod,'min')
            options = optimset('MaxFunEvals',MaxFunEvals,...
                               'MaxIter',MaxIter,...
                               'TolX',TolParam,...
                               'TolFun',TolFun,...
                               'Display','notify');%,...
                               %'PlotFcns',@optimplotfval);                
            switch SolverMethod 
                case "fminsearch"
                        [ParamFit,fval,exitflag,output] = fminsearch(ObjFun,ParamFit,options);    
                case "fminsearchbnd"
                        [ParamFit,fval,exitflag,output] = fminsearchbnd(ObjFun,ParamFit,LBndsParamFit,[],options);    
            end            
            % Info for dialog box      
            temp1 = {['Sum of squared errors = ', num2str(fval)];...
                     ['Iterations = ', num2str(output.iterations)];...
                     ['No. of function evals = ', num2str(output.funcCount)];...
                     ['Algorithm: ', output.algorithm]};
        else
            % Use Levenberg-Marquardt Toolbox function to solve minimization problem
            % [ParamFit,fval,fval,exitflag] = LevenbergMarquardt(ObjFun,ParamFit,LBndsFitParam,[]);    
            [ParamFit,fval,ResVec,exitflag] = LevenbergMarquardt(ObjFun,ParamFit,LBndsParamFit);    
            output.message = []; 

            % Info for dialog box      
            temp1 = {['Sum of squared errors = ', num2str(fval)]};
        end
        %% Results calculations
        % Use fitted values to calculate derived values
        b_fit_R = ParamFit ; 
        v_fit_R = sqrt(b_fit_R.^2./12 .* delta_p./mu_f .* 1./t_trans) ;
        L_fit_R = t_trans .* v_fit_R ;     
        ParamArray = [v_fit_R,L_fit_R,f,b_fit_R,lambda_r] ;
        alpha_adjusted = alpha_fit_C .* L_fit_R ./ L ;
        alpha_c_fit_R = alpha_adjusted ./ (L_fit_R .^ alpha_m) ;
        alpha_fit_R = alpha_c_fit_R .* L_fit_R .^ alpha_m ;

        % Prepare arguments for function calls that include alpha
        ConstantsRTCell = {Q,M_con,M_rea,rho_f,alpha_c_fit_R,alpha_m,Arr_A,Arr_Ea};
                
        % Calculate temperatures, needed for reactive tracer calcs
        yTProf = Temperature(t_op,ConstantsTempCell,ParamArray);
        yTProf_avg = TMixCalc(yTProf(:,end),f); % Avg the production well values

        % Calculate predicted temperature vs time
        t_vec = x_rel .* tMaxPred * 86400;          % TempProfile function requires time in seconds
        yTPred = TempProfile(lambda_r,L,1,v,b,cp_f,cp_r,rho_f,rho_r,t_vec,T_res,T_inj) ;                    
        % yTPred = Temperature(tMaxPred,ConstantsTempCell,ParamArray);
        yTPredMean = TMixCalc(yTPred,f);

        % Calculate current effective path temperatures, needed for reactive tracer calcs
        T_eff_path = TempProfile(lambda_r,L,1,v,b,cp_f,cp_r,rho_f,rho_r,t_op*86400,T_res,T_inj) ;
        T_eff_mean = TMixCalc(T_eff_path,f) ;
        
        % Calculate predicted enthalpy (MW) vs time
        H_t = 10^-6 .* (yTPredMean .* Q .* rho_f .* cpH2OCalc(yTPredMean) - T_inj .* Q .* rhoH2OCalc(T_inj) .* cpH2OCalc(T_inj));
        H_t_sum = sum(H_t,1);

        % Calculate BTC data
        [C_rea_avg,C_con_avg,C_rea,C_con] = ReactiveTracer(app.tCrData(1,:),yTProf,ConstantsRTCell,ParamArray);
       
        % Calculate relative concentrations (should be constant)
        % C_rea_rel = (C_rea.*M_con)./(C_con.*M_rea);
        % C_rea_avg_rel = (C_rea_avg.*M_con)./(C_con_avg.*M_rea);
        C_rea_rel = (C_rea)./(C_con);
        C_rea_avg_rel = (C_rea_avg)./(C_con_avg);        
        
        % Calculate residuals and ranked residuals
        ResVec = C_rea_avg - app.tCrData(2,:);
        ResidualStdRank = 1:length(ResVec);
        ResidualStd = sort(ResVec)/std(ResVec,"omitnan");

        %% Update results text and table
        % Add text describing mean effective temperature
        temp2 = ['Mean effective path temperature (degC) = ', num2str(T_eff_mean)] ;

        % Create text describing uncertainty
        TextUncert = 'Uncertainty not calculated'; % Initialize text output        
        if exitflag
            % Variance from residuals SSE
            var = fval/(size(app.tCrData,2) - (nChnInclude-1));

            % Covariance matrix from Hessian                
            options = optimset('MaxFunEvals',5,'MaxIter',5); % run just enough to get gradients
            [ParamFit,fval2,exitflag2,output2,GRAD,HESS] = fminlbfgs(ObjFun,ParamFit,options);
            Uncert1 = var .* sqrt(diag(inv(HESS)));
            
            % Covariance matrix from Jacobian Toolbox
            [J,h,func_evals,f_0]=jacobiansimple(ObjFun,ParamFit);
            cov2 = var * inv(transpose(J)*J);    % This frequently causes warning: ~'Matrix is singular to working precision'       
            Uncert2 = sqrt(diag(cov2));
            
            if sum(ismember(Uncert2,Inf)) < 1
                TextUncert = num2str(Uncert2);
            end
        end              

        % Combine all message pieces for text area
        if strcmp(app.ResultsPnlTextArea.Value,'')
            message_disp = vertcat(['vvvvvvvvvvvvvvvvvvvvvv ',datestr(datetime()),' vvvvvvvvvvvvvvvvvvvvvv'],...
                                output.message,temp1,temp2,TextUncert);
        else
            message_disp = vertcat(...
                                 app.ResultsPnlTextArea.Value,...
                                ['vvvvvvvvvvvvvvvvvvvvvv ',datestr(datetime()),' vvvvvvvvvvvvvvvvvvvvvv'],...
                                output.message,temp1,temp2,TextUncert);
        end
        app.ResultsPnlTextArea.Value = message_disp;
        scroll(app.ResultsPnlTextArea, 'bottom') %autoscroll text area to bottom        
        
        app.FittedParametersUITable.Data = table(ChnInclude, T_eff_path, b, b_guess, b_fit_R, v_fit_R, L_fit_R, alpha_c_fit_R, alpha_fit_R,...
                                        'VariableNames',{'Channel', 'T_eff_path','b_fit_C','b_guess','b_fit_R','v_fit_R','L_fit_R','alpha_c_fit_R','alpha_fit_R'}) ;

        %% Create/Update Plots
        % Get line style data as nx1 cell arrays
        LColor = cellstr(app.PlotOptPnlLinePropertiesUITable.Data.LColor) ;
        LWidth = app.PlotOptPnlLinePropertiesUITable.Data.LWidth ;        
        [~,loc] = ismember(string(app.PlotOptPnlLinePropertiesUITable.Data.LStyleDescrip), string(app.LStyleDescrip_All'));
        LStyle = app.LStyle_All(loc);    
        ChannelNames = cellstr( strcat("Channel ", num2str(ChnInclude)) ) ;

        % 1. Plot BTCs        
        % Remove previously created lines from plot
        linelist = findobj(app.PltPnlGrpBTCsUIAxes, 'Type', 'line') ;
        if size(linelist,1) > 2
            delete(linelist(1:2));
        end
        hold(app.PltPnlGrpBTCsUIAxes,'on')
        app.FitBTCsPlot = plot(app.PltPnlGrpBTCsUIAxes,...
                               app.tCrData(1,:), C_con_avg, ...
                               app.tCrData(1,:), C_rea_avg ) ;
        set(app.FitBTCsPlot, {'DisplayName'}, {'Mean C_r_e_a';'Mean C_c_o_n'}) ;

        % 2. Plot fitted ratio
        % Remove previously created lines from plot
        linelist = findobj(app.PltPnlTabGrpLogRelCUIAxes, 'Type', 'line') ;
        numlines = size(linelist,1) ;
        if numlines > 1
            delete(linelist(1:numlines-1));
        end        
        hold(app.PltPnlTabGrpLogRelCUIAxes,'on')
        app.FitRatioPlot = semilogy(app.PltPnlTabGrpLogRelCUIAxes, app.tCrData(1,:),C_rea_avg_rel,'-','DisplayName','Mean relative C_r_e_a');

        % 3. Plot residuals of fit
        app.FitResidualsPlot = plot(app.PltPnlTabGrpResidualsUIAxes,app.tCrData(1,:), ResVec, 'ko');

        % 4. Plot normalized residuals    
        app.FitResidualsProbPlot = plot(app.PltPnlTabGrpNormProbPlotResUIAxes, ResidualStd, ResidualStdRank,'ko');

        % 5. Plot temperature vs distance in fracture
        app.FitTProfilePlot = plot(app.PltPnlGrpTempProfUIAxes,x_rel,yTProf, 1,yTProf_avg,'ko'); 
        % for i = 1 : nChnInclude 
        %     TableIndex = i + 2 ;
        %     set(app.FitBTCsPlot(i), 'Color', LColor{TableIndex}, 'LineWidth', LWidth{TableIndex}, 'LineStyle', LStyle{TableIndex}) 
        % end
        ParamTblIndex = 1 : nChnInclude;
        PlotOptTblIndex = 2 + ParamTblIndex ;
        set(app.FitTProfilePlot(ParamTblIndex), {'Color'}, LColor(PlotOptTblIndex), {'LineWidth'}, LWidth(PlotOptTblIndex), {'LineStyle'}, LStyle(PlotOptTblIndex)) ;
        LegendVector = cellstr([ChannelNames; 'Average']) ;
        set(app.FitTProfilePlot, {'DisplayName'}, LegendVector) ;        
        legend(app.PltPnlGrpTempProfUIAxes,'Visible',true, 'Location','northwest');

        % 6. Plot predicted temperature for future operations
        plot(app.PltPnlGrpTempPredictUIAxes, x_rel.*tMaxPred, yTPredMean,'k-','LineWidth',2); % app.FitTPredictionPlot = 
        xline(app.PltPnlGrpTempPredictUIAxes, t_op,'-', 'Operating time') ;

        % 7. Plot enthalpy vs time
        plot(app.PltPnlGrpEnthalpyPredictUIAxes ,x_rel.*tMaxPred, H_t,'k-','LineWidth',2) ; 
        xline(app.PltPnlGrpEnthalpyPredictUIAxes, t_op, '-', 'Operating time') ;

        UpdatePlotsUsingCurrentPlottingOptionsButtonPushed(app, event)
        %% General function to be minimized
        function output = MinFun(tcData,t_op,ConstantsTempCell,ConstantsRTCell,ParamFit,ParamFitInd,ParamArray,SolverMethod)
            %  SolverMethod string that is either 'fminsearchxxx' or 'Levenberg-Marquardt'       
            % If latter, then vector of residuals returned, otherwise, SSE                
            ParamArray(ParamFitInd) = ParamFit;     
            TProf = Temperature(t_op,ConstantsTempCell,ParamArray);
            C_rea_avg = ReactiveTracer(tcData(1,:),TProf,ConstantsRTCell,ParamArray);
            ErrVec = C_rea_avg - tcData(2,:);
            SSE = sum(ErrVec.^2);
            if contains(SolverMethod,'min')
                output = SSE;
            else                        
                output = ErrVec';
            end
        end           
        %% Temperature: Calls external temperature profile calculation function
        % Based on Carslaw & Jaeger (1959, Section 15.3, Case III, p. 396)
        function TemperatureArray = Temperature(tMax,ConstantsTempCell,ParamArray)    

            % Assign variable names to needed inputs to external TempProfile function
            [x_rel,Q,T_res,T_inj,cp_f,cp_r,rho_f,rho_r] = ConstantsTempCell{:}; 
            v_loc = ParamArray(:,1);
            L_loc = ParamArray(:,2);
            b_loc = ParamArray(:,4);
            lambda_r_loc = ParamArray(:,5);
            t_vec = tMax * 86400;          % TempProfile function requires time in seconds
            TemperatureArray = TempProfile(lambda_r_loc, L_loc, x_rel, v_loc, b_loc, cp_f, cp_r, rho_f, rho_r, t_vec, T_res, T_inj) ;            
        end
        %% Tracer transport functions
        function [C_rMean,C_cMean,C_r,C_c] = ReactiveTracer(tData,yTP,ConstantsRTCell,ParamArray)
            % Define Constants
            R_gas = 8.31441; % J/(mol K)
            [Q,M_con,M_rea,rho_f,alpha_c,alpha_m,A,Ea_kJperMol] = ConstantsRTCell{:};  
            
            % Get parameter vectors from parameter array
            v = ParamArray(:,1);
            L = ParamArray(:,2);
            f = ParamArray(:,3);     
            nChan = length(v);
            alpha = alpha_c .*L .^ alpha_m; 
             
            tData = tData*86400;    % Convert time from days to seconds,         
            Tk = yTP + 273.15;      % Convert temperature to Kelvins
            Ea = Ea_kJperMol * 1000;% Convert temperature from C to K, Ea from kJ/Mol to J/Mol

            % Calculate integrand and define x values
            integrand = exp(-Ea./(R_gas*Tk));
            nPts = 101;
            x_rel = linspace(0,1,nPts);

            % Calculate Integral of rxn rate; produce decayFactor          
            lambda_avg = zeros(nChan,1); %Preallocation
            for i = 1:nChan
                lambda_avg(i,1) = trapz(x_rel,integrand(i,:),2);
            end

            % Calculate conservative and reactive tracer concentrations
            C_c = 10^9 .*f .*(v.*M_con./(rho_f*Q)).*(1./(sqrt(4*pi*alpha.*v.*tData)).*exp(-(L-v.*tData).^2./(4*alpha.*v.*tData))) ; 
            C_r = M_rea./M_con .* exp(-A.*lambda_avg.*L./v) .* C_c;
            C_cMean = sum(C_c,1);
            C_rMean = sum(C_r,1);
        end            
        end

        % Button pushed function: 
        % UpdatePlotsUsingCurrentPlottingOptionsButton
        function UpdatePlotsUsingCurrentPlottingOptionsButtonPushed(app, event)
        % Get Channel IDs 
        % ChannelNames = strcat("Channel ", num2str(app.ParamPnlUITable.Data.Channel(app.ParamPnlUITable.Data.Include))) ;

        %Update observation symbols    
        % Get MARKER options for observations and other symbol plots 
        markerStyles = {app.PlotOptPnlMarkerStyleDropDown_1.Value; app.PlotOptPnlMarkerStyleDropDown_2.Value};
        markerSize = {app.PlotOptPnlMarkerSizeEditField_1.Value; app.PlotOptPnlMarkerSizeEditField_2.Value};
        markerEdgeColor = {app.EdgeColorColorPicker_1.Value; app.EdgeColorColorPicker_2.Value};
        markerFillColor = {app.FillColorColorPicker_1.Value; app.FillColorColorPicker_2.Value};
        
        %Create lists of the plots to update, associated axes, & 'styles'
        PlotList = {app.cObsPlot, app.rObsPlot, app.RatioObsPlot};
        AxesList = {app.PltPnlGrpBTCsUIAxes,app.PltPnlGrpBTCsUIAxes,app.PltPnlTabGrpLogRelCUIAxes};
        StyleNum = [1,2,1];
        
        % Update markers
        for index = 1 : 3   
            i = StyleNum(index);
            PlotList{index}.Marker = markerStyles{i};
            PlotList{index}.MarkerSize = markerSize{i};
            PlotList{index}.MarkerEdgeColor = markerEdgeColor{i,:};
            PlotList{index}.MarkerFaceColor = markerFillColor{i,:};
        end

        %Update line plots 
        % Stop updates if fitplots not yet created
        if isempty(app.FitBTCsPlot); return; end

        % Convert linestyle descriptions to LStyle
        [~,loc] = ismember(string(app.PlotOptPnlLinePropertiesUITable.Data.LStyleDescrip), string(app.LStyleDescrip_All'));
        LStyle = app.LStyle_All(loc);     

        % Update fitted BTC curves (means, not channel-specific plots)
        for i = 1 : 2
            app.FitBTCsPlot(i).Color = app.PlotOptPnlLinePropertiesUITable.Data.LColor{i};
            app.FitBTCsPlot(i).LineWidth = app.PlotOptPnlLinePropertiesUITable.Data.LWidth{i};
            app.FitBTCsPlot(i).LineStyle = LStyle{i};
        end

        % Update temperature profile plot lines
        % RowIndices = find(~isnan(str2double(app.PlotOptPnlLinePropertiesUITable.Data.ID))) ;
        for i = 1 : app.nChn
           iTable = i + 2; % First 2 rows of plot table are reserved for means of channels of conservative and reactive tracers
            app.FitTProfilePlot(i).Color = app.PlotOptPnlLinePropertiesUITable.Data.LColor{iTable} ;
            app.FitTProfilePlot(i).LineWidth = app.PlotOptPnlLinePropertiesUITable.Data.LWidth{iTable} ;
            app.FitTProfilePlot(i).LineStyle = LStyle{iTable} ;
        end
        set(app.FitTProfilePlot(end),'Marker',app.PlotOptPnlMarkerStyleDropDown_1.Value,...
                                    'MarkerSize',app.PlotOptPnlMarkerSizeEditField_1.Value,...
                                    'MarkerEdgeColor',app.EdgeColorColorPicker_1.Value,...
                                    'MarkerFaceColor',app.FillColorColorPicker_1.Value);  

        % DON'T BOTHER to Update production temperature vs operating time plots        
        end

        % Menu selected function: desConservativetracerMenu
        function desConservativetracerMenuSelected(app, event)
        app.DesignConservativeTrTest_Hndl = TAT08DesignConservativeTrTest;
        end

        % Menu selected function: desTemperatureprofilesMenu
        function desTemperatureprofilesMenuSelected(app, event)
        app.DesignTemperatureProfile_Hndl = TAT08DesignTemperatureProfile;
        end

        % Menu selected function: desReactivetracerselectionMenu
        function desReactivetracerselectionMenuSelected(app, event)
        app.DesignSelectReactiveTr_Hndl = TAT08DesignSelectReactiveTr;          
        end

        % Menu selected function: desReactivetracerMenu
        function desReactivetracerMenuSelected(app, event)
            app.DesignReactiveTrTest_Hndl = TAT08DesignReactiveTrTest;        
        end

        % Menu selected function: analConservativetracerMenu
        function analConservativetracerMenuSelected(app, event)
        app.AnalysisConservativeTrTest_Hndl = TAT08AnalysisConservativeTrTest;
        end

        % Cell selection callback: PlotOptPnlLinePropertiesUITable
        function PlotOptPnlLinePropertiesUITableCellSelection(app, event)
        indices = event.Indices;
        if and(~isempty(indices),size((indices),1)==1) %if clicked in channel row
            if(indices(2) == 2) %if 2nd column cell selected
                colorOld = str2num(app.PlotOptPnlLinePropertiesUITable.Data{indices(1),2}{1});
                colorNew = num2str(round(uisetcolor(colorOld),3));
                if ~strcmp(colorNew,'0') %if operation not canceled
                    app.PlotOptPnlLinePropertiesUITable.Data{indices(1),2}{1} = colorNew;
                    myColorStyle = uistyle("BackgroundColor",colorNew);
                    addStyle(app.PlotOptPnlLinePropertiesUITable,myColorStyle,'cell',[indices(1),indices(2)]);          
                    app.PlotOptPnlLinePropertiesUITable.Selection = [indices(1),1];
                end
            end
        end     
        end

        % Button pushed function: EqHelpButton
        function EqHelpButtonPushed(app, event)
        % designRTEqHelpButtonPushed: Opens help file for the reactive tracer

        % Define file location
        eqTPHelpPath = fullfile(app.TATPath,'Docs','RTTracerHelp.pdf');
        
        % Open Manual
        winopen(eqTPHelpPath)                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AnalysisWorkflowReactiveTracerTestUIFigure = uifigure('Visible', 'off');
            app.AnalysisWorkflowReactiveTracerTestUIFigure.Name = 'Analysis Workflow -- Reactive Tracer Test';
            app.AnalysisWorkflowReactiveTracerTestUIFigure.Icon = fullfile(pathToMLAPP, 'appdata', 'TATIcon.png');
            app.FileActionsMenu = uimenu(app.AnalysisWorkflowReactiveTracerTestUIFigure);
            app.FileActionsMenu.Text = 'File Actions';
            app.ReadconservativetracerobservationsMenu = uimenu(app.FileActionsMenu);
            app.ReadconservativetracerobservationsMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadconservativetracerobservationsMenuSelected, true);
            app.ReadconservativetracerobservationsMenu.Text = 'Read conservative tracer observations';
            app.ReadreactivetracerobservationsMenu = uimenu(app.FileActionsMenu);
            app.ReadreactivetracerobservationsMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadreactivetracerobservationsMenuSelected, true);
            app.ReadreactivetracerobservationsMenu.Text = 'Read reactive tracer observations';
            app.ReadreactiveconservativeratiodataMenu = uimenu(app.FileActionsMenu);
            app.ReadreactiveconservativeratiodataMenu.Text = 'Read reactive/conservative ratio data';
            app.SaveparametersMenu = uimenu(app.FileActionsMenu);
            app.SaveparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveparametersMenuSelected, true);
            app.SaveparametersMenu.Text = 'Save parameters';
            app.ReadparametersMenu = uimenu(app.FileActionsMenu);
            app.ReadparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadparametersMenuSelected, true);
            app.ReadparametersMenu.Text = 'Read parameters';
            app.SaveplotteddataMenu = uimenu(app.FileActionsMenu);
            app.SaveplotteddataMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveplotteddataMenuSelected, true);
            app.SaveplotteddataMenu.Text = 'Save plotted data';
            app.TaskControlMenu = uimenu(app.AnalysisWorkflowReactiveTracerTestUIFigure);
            app.TaskControlMenu.Text = 'Task Control';
            app.DesignMenu = uimenu(app.TaskControlMenu);
            app.DesignMenu.Text = 'Design';
            app.desConservativetracerMenu = uimenu(app.DesignMenu);
            app.desConservativetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @desConservativetracerMenuSelected, true);
            app.desConservativetracerMenu.Text = 'Conservative tracer';
            app.desTemperatureprofilesMenu = uimenu(app.DesignMenu);
            app.desTemperatureprofilesMenu.MenuSelectedFcn = createCallbackFcn(app, @desTemperatureprofilesMenuSelected, true);
            app.desTemperatureprofilesMenu.Text = 'Temperature profiles';
            app.desReactivetracerselectionMenu = uimenu(app.DesignMenu);
            app.desReactivetracerselectionMenu.MenuSelectedFcn = createCallbackFcn(app, @desReactivetracerselectionMenuSelected, true);
            app.desReactivetracerselectionMenu.Text = 'Reactive tracer selection';
            app.desReactivetracerMenu = uimenu(app.DesignMenu);
            app.desReactivetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @desReactivetracerMenuSelected, true);
            app.desReactivetracerMenu.Text = 'Reactive tracer';
            app.AnalysisMenu = uimenu(app.TaskControlMenu);
            app.AnalysisMenu.Text = 'Analysis';
            app.analConservativetracerMenu = uimenu(app.AnalysisMenu);
            app.analConservativetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @analConservativetracerMenuSelected, true);
            app.analConservativetracerMenu.Text = 'Conservative tracer';
            app.analReactivetracerMenu = uimenu(app.AnalysisMenu);
            app.analReactivetracerMenu.Enable = 'off';
            app.analReactivetracerMenu.Text = 'Reactive tracer';
            app.CommonVariablesMenu = uimenu(app.AnalysisWorkflowReactiveTracerTestUIFigure);
            app.CommonVariablesMenu.Text = 'Common Variables';
            app.ViewMenu = uimenu(app.CommonVariablesMenu);
            app.ViewMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.ViewMenu.Text = 'View';
            app.SendtoMenu = uimenu(app.CommonVariablesMenu);
            app.SendtoMenu.MenuSelectedFcn = createCallbackFcn(app, @SendtoMenuSelected, true);
            app.SendtoMenu.Text = 'Send to';
            app.PullfromMenu = uimenu(app.CommonVariablesMenu);
            app.PullfromMenu.MenuSelectedFcn = createCallbackFcn(app, @PullfromMenuSelected, true);
            app.PullfromMenu.Text = 'Pull from';
            app.analysisReaTrAnalLabel = uilabel(app.AnalysisWorkflowReactiveTracerTestUIFigure);
            app.analysisReaTrAnalLabel.Text = 'Reactive Tracer Analysis';
            app.ParamPanel = uipanel(app.AnalysisWorkflowReactiveTracerTestUIFigure);
            app.ParamPanel.Tooltip = {'v: velocity (m/s)'; 'L: path length (m)'; 'f: fluid mass fraction (-)'; 'lambda: thermal conductivity (J/m/K)'; 'b: aperture (m)'};
            app.ParamPanel.Title = 'Parameters';
            app.ParamPnl_nChn_Label = uilabel(app.ParamPanel);
            app.ParamPnl_nChn_Label.Text = 'No. Channels';
            app.ParamPnl_nChn_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_nChn_EditField.Limits = [1 10];
            app.ParamPnl_nChn_EditField.ValueDisplayFormat = '%.0f';
            app.ParamPnl_nChn_EditField.Editable = 'off';
            app.ParamPnl_nChn_EditField.Value = 1;
            app.ParamPnlAddChannelButton = uibutton(app.ParamPanel, 'push');
            app.ParamPnlAddChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ParamPnlAddChannelButtonPushed, true);
            app.ParamPnlAddChannelButton.Text = 'Add Channel';
            app.ParamPnlDeleteChannelButton = uibutton(app.ParamPanel, 'push');
            app.ParamPnlDeleteChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ParamPnlDeleteChannelButtonPushed, true);
            app.ParamPnlDeleteChannelButton.Text = 'Delete Channel';
            app.ParamPnl_T_res_Label = uilabel(app.ParamPanel);
            app.ParamPnl_T_res_Label.Text = 'Reservoir T (°C)';
            app.ParamPnl_T_res_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_T_res_EditField.Limits = [0 Inf];
            app.ParamPnl_T_res_EditField.Tooltip = {'Reservoir temperature at start of operations'};
            app.ParamPnl_T_res_EditField.Value = 150;
            app.ParamPnl_T_inj_Label = uilabel(app.ParamPanel);
            app.ParamPnl_T_inj_Label.Text = 'Injection T (°C)';
            app.ParamPnl_T_inj_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_T_inj_EditField.Limits = [0 Inf];
            app.ParamPnl_T_inj_EditField.Tooltip = {'Injection temperature'};
            app.ParamPnl_T_inj_EditField.Value = 100;
            app.ParamPnl_rho_r_Label = uilabel(app.ParamPanel);
            app.ParamPnl_rho_r_Label.Interpreter = 'html';
            app.ParamPnl_rho_r_Label.Text = '&rho; (rock) (kg/m<sup>3</sup>)';
            app.ParamPnl_rho_r_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_rho_r_EditField.Tooltip = {'Rock density'};
            app.ParamPnl_rho_r_EditField.Value = 2650;
            app.ParamPnl_cp_r_Label = uilabel(app.ParamPanel);
            app.ParamPnl_cp_r_Label.Text = 'cp (rock) (J/kg/°C)';
            app.ParamPnl_cp_r_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_cp_r_EditField.Tooltip = {'Rock specific heat'};
            app.ParamPnl_cp_r_EditField.Value = 790;
            app.ParamPnl_t_op_Label = uilabel(app.ParamPanel);
            app.ParamPnl_t_op_Label.Text = 'Op. Time (days)';
            app.ParamPnl_t_op_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_t_op_EditField.Limits = [0 Inf];
            app.ParamPnl_t_op_EditField.Tooltip = {'Injection well operating time'};
            app.ParamPnl_t_op_EditField.Value = 10;
            app.ParamPnlUITable = uitable(app.ParamPanel);
            app.ParamPnlUITable.ColumnName = {'Channel'; 'v'; 'L'; 'f'; 'lambda (rock)'; 'b'; 'b_guess'; 'Include'};
            app.ParamPnlUITable.RowName = {};
            app.ParamPnlUITable.ColumnEditable = true;
            app.ParamPnlUITable.Tooltip = {'v: velocity (m/s)'; 'L: path length (m)'; 'f: flluid mass fraction (-)'; 'lambda: thermal conductivity (J/m/K)'; 'b: aperture (m)'};
            app.ParamPnl_Q_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_Q_EditField.Tooltip = {'Production well mass flow rate'};
            app.ParamPnl_Q_EditField.Value = 1;
            app.ParamPnl_Q_Label = uilabel(app.ParamPanel);
            app.ParamPnl_Q_Label.Interpreter = 'html';
            app.ParamPnl_Q_Label.Text = 'Pumping rate, Q (m<sup>3</sup>/s)';
            app.ParamPnl_delta_p_Label = uilabel(app.ParamPanel);
            app.ParamPnl_delta_p_Label.Text = 'Pressure drop (kPa)';
            app.ParamPnl_delta_p_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_delta_p_EditField.Limits = [0 Inf];
            app.ParamPnl_delta_p_EditField.Tooltip = {'Hydraulic head drop between injection well and production wells'};
            app.ParamPnl_delta_p_EditField.Value = 10;
            app.ParamPnl_M_con_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_M_con_EditField.Tooltip = {'Injection mass, conservative tracer'};
            app.ParamPnl_M_con_EditField.Value = 1;
            app.ParamPnl_M_con_Label = uilabel(app.ParamPanel);
            app.ParamPnl_M_con_Label.Text = 'Mass (kg)';
            app.ParamPnl_M_rea_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_M_rea_EditField.Tooltip = {'Injection mass, reactive tracer'};
            app.ParamPnl_M_rea_EditField.Value = 2;
            app.ParamPnl_M_rea_Label = uilabel(app.ParamPanel);
            app.ParamPnl_M_rea_Label.Text = 'Mass (kg)';
            app.ParamPnl_Arr_A_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_Arr_A_EditField.Tooltip = {'Arrhenius rxn preexponential constant'};
            app.ParamPnl_Arr_A_EditField.Value = 100;
            app.ParamPnl_Arr_A_Label = uilabel(app.ParamPanel);
            app.ParamPnl_Arr_A_Label.Text = 'A (1/s)';
            app.ParamPnl_Arr_Ea_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_Arr_Ea_EditField.Tooltip = {'Arrhenius rxn exponential factor'};
            app.ParamPnl_Arr_Ea_EditField.Value = 10;
            app.ParamPnl_Arr_Ea_Label = uilabel(app.ParamPanel);
            app.ParamPnl_Arr_Ea_Label.Text = 'Ea (kJ/Mol)';
            app.ParamPnl_alpha_c_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_alpha_c_EditField.Tooltip = {'Dispersivity equation proportionality constant'};
            app.ParamPnl_alpha_c_EditField.Value = 0.21;
            app.ParamPnl_alpha_c_Label = uilabel(app.ParamPanel);
            app.ParamPnl_alpha_c_Label.Interpreter = 'html';
            app.ParamPnl_alpha_c_Label.Text = 'c';
            app.ParamPnl_alpha_m_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_alpha_m_EditField.Tooltip = {'Dispersivity equation length exponent'};
            app.ParamPnl_alpha_m_EditField.Value = 0.51;
            app.ParamPnl_alpha_m_Label = uilabel(app.ParamPanel);
            app.ParamPnl_alpha_m_Label.Interpreter = 'html';
            app.ParamPnl_alpha_m_Label.Text = 'm';
            app.HydraulicDataLabel = uilabel(app.ParamPanel);
            app.HydraulicDataLabel.Text = 'Hydraulic Data';
            app.ThermalDataLabel = uilabel(app.ParamPanel);
            app.ThermalDataLabel.Text = 'Thermal Data';
            app.ConservativeTracerLabel = uilabel(app.ParamPanel);
            app.ConservativeTracerLabel.Text = 'Conservative Tracer';
            app.ReactiveTracerLabel = uilabel(app.ParamPanel);
            app.ReactiveTracerLabel.Text = 'Reactive Tracer';
            app.DispersivityalphacLsupmsupLabel = uilabel(app.ParamPanel);
            app.DispersivityalphacLsupmsupLabel.Interpreter = 'html';
            app.DispersivityalphacLsupmsupLabel.Text = 'Dispersivity (&alpha; = c L<sup>m</sup>)';
            app.ParamPnl_lambda_r_Label = uilabel(app.ParamPanel);
            app.ParamPnl_lambda_r_Label.Interpreter = 'html';
            app.ParamPnl_lambda_r_Label.Text = '&lambda; (rock) (J/m/°C)';
            app.ParamPnl_lambda_r_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_lambda_r_EditField.Tooltip = {'Rock specific heat'};
            app.ParamPnl_lambda_r_EditField.Value = 2.5;
            app.ResultsPanel = uipanel(app.AnalysisWorkflowReactiveTracerTestUIFigure);
            app.ResultsPanel.Title = 'Results';
            app.ResultsPnlFitDataButton = uibutton(app.ResultsPanel, 'push');
            app.ResultsPnlFitDataButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsPnlFitDataButtonPushed, true);
            app.ResultsPnlFitDataButton.Enable = 'off';
            app.ResultsPnlFitDataButton.Tooltip = {'Requires that several items have been loaded:'; '(1) conservative tracer observations (2) reactive tracer observations (3) channel data estimates consistent with conservative tracer observations'};
            app.ResultsPnlFitDataButton.Text = 'Fit Data';
            app.BreakthroughcurveratiodataloadedLabel = uilabel(app.ResultsPanel);
            app.BreakthroughcurveratiodataloadedLabel.Text = 'Breakthrough curve ratio data ready';
            app.BTCsLoadedLamp = uilamp(app.ResultsPanel);
            app.SolverselectionDropDownLabel = uilabel(app.ResultsPanel);
            app.SolverselectionDropDownLabel.Text = 'Solver selection';
            app.SolverSelectionDropDown = uidropdown(app.ResultsPanel);
            app.SolverSelectionDropDown.Items = {'fminsearch', 'fminsearchbnd', 'Levenberg-Marquardt'};
            app.SolverSelectionDropDown.Value = 'fminsearch';
            app.MaxfunctionevalsmultiplierEditFieldLabel = uilabel(app.ResultsPanel);
            app.MaxfunctionevalsmultiplierEditFieldLabel.Text = 'Max function evals multiplier';
            app.MaxFuncEvalsMultEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.MaxFuncEvalsMultEditField.Limits = [1 Inf];
            app.MaxFuncEvalsMultEditField.RoundFractionalValues = 'on';
            app.MaxFuncEvalsMultEditField.Tooltip = {'MaxFuncEvals = multiplier x num fitted parameters'};
            app.MaxFuncEvalsMultEditField.Value = 200;
            app.RelativetoleranceEditFieldLabel = uilabel(app.ResultsPanel);
            app.RelativetoleranceEditFieldLabel.Text = 'Relative tolerance';
            app.TolParamEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.TolParamEditField.ValueDisplayFormat = '%5.0e';
            app.TolParamEditField.Value = 0.0001;
            app.MaxiterationsmultiplierLabel = uilabel(app.ResultsPanel);
            app.MaxiterationsmultiplierLabel.Text = 'Max iterations multiplier';
            app.MaxIterMultEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.MaxIterMultEditField.Limits = [1 Inf];
            app.MaxIterMultEditField.RoundFractionalValues = 'on';
            app.MaxIterMultEditField.Tooltip = {'MaxIter = multiplier x num fitted parameters'};
            app.MaxIterMultEditField.Value = 200;
            app.FunctiontoleranceLabel = uilabel(app.ResultsPanel);
            app.FunctiontoleranceLabel.Text = 'Function tolerance';
            app.TolFunEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.TolFunEditField.ValueDisplayFormat = '%5.0e';
            app.TolFunEditField.Value = 0.0001;
            app.RecalculateRCratiouponloadingeitherSwitchLabel = uilabel(app.ResultsPanel);
            app.RecalculateRCratiouponloadingeitherSwitchLabel.Text = 'Recalculate R/C ratio upon loading either';
            app.RecalculateRCratiouponloadingeitherSwitch = uiswitch(app.ResultsPanel, 'toggle');
            app.RecalculateRCratiouponloadingeitherSwitch.Tooltip = {'Controls automatic recalculation of  the reactive:conservative tracer ratio when reactive tracer observations are loaded'};
            app.RecalculateRCratiouponloadingeitherSwitch.Value = 'On';
            app.TabGroup = uitabgroup(app.ResultsPanel);
            app.ResultsInfoTab = uitab(app.TabGroup);
            app.ResultsInfoTab.Title = 'Results Info';
            app.ResultsPnlTextArea = uitextarea(app.ResultsInfoTab);
            app.ResultsTable = uitab(app.TabGroup);
            app.ResultsTable.Title = 'Fitted Parameters Table';
            app.FittedParametersUITable = uitable(app.ResultsTable);
            app.FittedParametersUITable.ColumnName = {'Channel'; 'T_eff'; 'b_fit_C'; 'b_guess'; 'b_fit_R'; 'v_fit_R'; 'L_fit_R'; 'alpha_c_fit_R'; 'alpha_fit_R'};
            app.FittedParametersUITable.RowName = {};
            app.FittedParametersUITable.Tooltip = {'T_eff: Path-integrated temperature, b_fit_C: estimate using conservative tracer,'; 'b_guess: user''s guess for fitting process, x_fit_R:estimate based on inferred cooling rate'};
            app.PlotsPanel = uipanel(app.AnalysisWorkflowReactiveTracerTestUIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.PltPnlTabGroup = uitabgroup(app.PlotsPanel);
            app.PltPnlTabGrpBTCsTab = uitab(app.PltPnlTabGroup);
            app.PltPnlTabGrpBTCsTab.Title = 'BTCs';
            app.PltPnlGrpBTCsUIAxes = uiaxes(app.PltPnlTabGrpBTCsTab);
            title(app.PltPnlGrpBTCsUIAxes, 'Title')
            xlabel(app.PltPnlGrpBTCsUIAxes, 'Time (days)')
            ylabel(app.PltPnlGrpBTCsUIAxes, 'Concentration (-)')
            zlabel(app.PltPnlGrpBTCsUIAxes, 'Z')
            app.PltPnlTabGrpResidualsTab = uitab(app.PltPnlTabGroup);
            app.PltPnlTabGrpResidualsTab.Title = 'Residuals';
            app.PltPnlTabGrpResidualsUIAxes = uiaxes(app.PltPnlTabGrpResidualsTab);
            title(app.PltPnlTabGrpResidualsUIAxes, 'Relative C Residual Concentrations')
            xlabel(app.PltPnlTabGrpResidualsUIAxes, 'Time')
            ylabel(app.PltPnlTabGrpResidualsUIAxes, 'Residual Concentration')
            zlabel(app.PltPnlTabGrpResidualsUIAxes, 'Z')
            app.PltPnlTabGrpNormProbPlotResTab = uitab(app.PltPnlTabGroup);
            app.PltPnlTabGrpNormProbPlotResTab.Title = 'Norm. Prob. Plot Res.';
            app.PltPnlTabGrpNormProbPlotResUIAxes = uiaxes(app.PltPnlTabGrpNormProbPlotResTab);
            title(app.PltPnlTabGrpNormProbPlotResUIAxes, 'Normal Probability Plot Conservative Tracer Residual Concentrations')
            xlabel(app.PltPnlTabGrpNormProbPlotResUIAxes, 'Z-Score')
            ylabel(app.PltPnlTabGrpNormProbPlotResUIAxes, 'Rank Residual Concentration')
            zlabel(app.PltPnlTabGrpNormProbPlotResUIAxes, 'Z')
            app.PltPnlTabGrpLogRelCTab = uitab(app.PltPnlTabGroup);
            app.PltPnlTabGrpLogRelCTab.Title = 'Relative C';
            app.PltPnlTabGrpLogRelCUIAxes = uiaxes(app.PltPnlTabGrpLogRelCTab);
            title(app.PltPnlTabGrpLogRelCUIAxes, 'Relative Concentration')
            xlabel(app.PltPnlTabGrpLogRelCUIAxes, 'Time')
            ylabel(app.PltPnlTabGrpLogRelCUIAxes, 'ln(C something)')
            zlabel(app.PltPnlTabGrpLogRelCUIAxes, 'Z')
            app.PltPnlTabGrpTempProfTab = uitab(app.PltPnlTabGroup);
            app.PltPnlTabGrpTempProfTab.Title = 'Temp. Profile';
            app.PltPnlGrpTempProfUIAxes = uiaxes(app.PltPnlTabGrpTempProfTab);
            xlabel(app.PltPnlGrpTempProfUIAxes, 'Relative Distance (-)')
            ylabel(app.PltPnlGrpTempProfUIAxes, 'Temperature (^oC)')
            zlabel(app.PltPnlGrpTempProfUIAxes, 'Z')
            app.PltPnlTabGrpTempPredictTab = uitab(app.PltPnlTabGroup);
            app.PltPnlTabGrpTempPredictTab.Title = 'Temp Prediction';
            app.PltPnlGrpTempPredictUIAxes = uiaxes(app.PltPnlTabGrpTempPredictTab);
            xlabel(app.PltPnlGrpTempPredictUIAxes, 'Operating time (days)')
            ylabel(app.PltPnlGrpTempPredictUIAxes, 'Production temperature (^oC)')
            zlabel(app.PltPnlGrpTempPredictUIAxes, 'Z')
            app.EnthalpyPredictionTab = uitab(app.PltPnlTabGroup);
            app.EnthalpyPredictionTab.Title = 'Enthalpy Prediction';
            app.PltPnlGrpEnthalpyPredictUIAxes = uiaxes(app.EnthalpyPredictionTab);
            xlabel(app.PltPnlGrpEnthalpyPredictUIAxes, 'Operating time (days)')
            ylabel(app.PltPnlGrpEnthalpyPredictUIAxes, 'Produced enthalpy (MW)')
            zlabel(app.PltPnlGrpEnthalpyPredictUIAxes, 'Z')
            app.UpdatePlotsUsingCurrentPlottingOptionsButton = uibutton(app.PlotsPanel, 'push');
            app.UpdatePlotsUsingCurrentPlottingOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotsUsingCurrentPlottingOptionsButtonPushed, true);
            app.UpdatePlotsUsingCurrentPlottingOptionsButton.Text = 'Update Plots Using Current Plotting Options';
            app.PlotOptionsPanel = uipanel(app.AnalysisWorkflowReactiveTracerTestUIFigure);
            app.PlotOptionsPanel.Title = 'Plotting Options';
            app.PlotOptPnl_t_min_travel_EditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.PlotOptPnl_t_travel_Label = uilabel(app.PlotOptionsPanel);
            app.PlotOptPnl_t_travel_Label.Text = {'Transport'; 'Time (days)'};
            app.PlotOptPnl_c_Label = uilabel(app.PlotOptionsPanel);
            app.PlotOptPnl_c_Label.Text = 'Conc. (-)';
            app.PlotOptPnl_c_min_EditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.PlotOptPnl_c_min_EditField.Limits = [0 Inf];
            app.PlotOptPnl_t_max_travel_EditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.PlotOptPnl_t_max_travel_EditField.Limits = [0 Inf];
            app.PlotOptPnl_t_max_travel_EditField.Value = Inf;
            app.PlotOptPnl_c_max_EditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.PlotOptPnl_c_max_EditField.Limits = [0 Inf];
            app.PlotOptPnl_c_max_EditField.Value = Inf;
            app.PlotOptPnlMinLabel = uilabel(app.PlotOptionsPanel);
            app.PlotOptPnlMinLabel.Text = 'Min';
            app.PlotOptPnlMaxLabel = uilabel(app.PlotOptionsPanel);
            app.PlotOptPnlMaxLabel.Text = 'Max';
            app.PlotOptPnlLinePropertiesUITable = uitable(app.PlotOptionsPanel);
            app.PlotOptPnlLinePropertiesUITable.ColumnName = {'Channel'; 'Color (RGB)'; 'Line Width'; 'Line Style'};
            app.PlotOptPnlLinePropertiesUITable.RowName = {};
            app.PlotOptPnlLinePropertiesUITable.ColumnEditable = [false true true true];
            app.PlotOptPnlLinePropertiesUITable.CellSelectionCallback = createCallbackFcn(app, @PlotOptPnlLinePropertiesUITableCellSelection, true);
            app.PlotOptPnlFittedLinePropertiesLabel = uilabel(app.PlotOptionsPanel);
            app.PlotOptPnlFittedLinePropertiesLabel.Text = 'Fitted Data Plots Properties';
            app.PlotOptPnlDataMarkerPropertiesLabel = uilabel(app.PlotOptionsPanel);
            app.PlotOptPnlDataMarkerPropertiesLabel.Text = 'Observation Marker Properties';
            app.PlotOptPnlMarkerStyleLabel = uilabel(app.PlotOptionsPanel);
            app.PlotOptPnlMarkerStyleLabel.Text = {'Marker'; 'Style'};
            app.PlotOptPnlMarkerSizeLabel = uilabel(app.PlotOptionsPanel);
            app.PlotOptPnlMarkerSizeLabel.Text = {'Marker'; 'Size'};
            app.PlotOptPnlMarkerSizeEditField_1 = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.PlotOptPnlMarkerSizeEditField_1.Limits = [0 Inf];
            app.PlotOptPnlMarkerSizeEditField_1.Value = 7;
            app.PlotOptPnlMarkerStyleDropDown_1 = uidropdown(app.PlotOptionsPanel);
            app.PlotOptPnlMarkerStyleDropDown_1.Items = {'o', '+', '*', '.', 'x', '-', '|', 'Square', 'Diamond', '^', 'v', '>', '<', 'Pentagram', 'Hexagram', 'None'};
            app.PlotOptPnlMarkerStyleDropDown_1.Value = 'o';
            app.PlotOptPnl_t_min_pred_EditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.PlotOptPnl_t_pred_Label = uilabel(app.PlotOptionsPanel);
            app.PlotOptPnl_t_pred_Label.Text = {'Prediction'; 'Time (days)'};
            app.PlotOptPnl_t_max_pred_EditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.PlotOptPnl_t_max_pred_EditField.Limits = [0 Inf];
            app.PlotOptPnl_t_max_pred_EditField.Value = 2000;
            app.PlotOptPnlMarkerStyleDropDown_2 = uidropdown(app.PlotOptionsPanel);
            app.PlotOptPnlMarkerStyleDropDown_2.Items = {'o', '+', '*', '.', 'x', '-', '|', 'Square', 'Diamond', '^', 'v', '>', '<', 'Pentagram', 'Hexagram', 'None'};
            app.PlotOptPnlMarkerStyleDropDown_2.Value = 'Square';
            app.PlotOptPnlMarkerSizeEditField_2 = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.PlotOptPnlMarkerSizeEditField_2.Limits = [0 Inf];
            app.PlotOptPnlMarkerSizeEditField_2.Value = 7;
            app.Label_2 = uilabel(app.PlotOptionsPanel);
            app.Label_2.Text = '1';
            app.Label = uilabel(app.PlotOptionsPanel);
            app.Label.Text = '2';
            app.EdgeColorLabel = uilabel(app.PlotOptionsPanel);
            app.EdgeColorLabel.Text = {'Edge'; 'Color'};
            app.EdgeColorColorPicker_1 = uicolorpicker(app.PlotOptionsPanel);
            app.EdgeColorColorPicker_1.Value = [0 0 0];
            app.EdgeColorColorPicker_2 = uicolorpicker(app.PlotOptionsPanel);
            app.FillColorColorPicker_2 = uicolorpicker(app.PlotOptionsPanel);
            app.FillColorColorPicker_2.Value = [1 1 1];
            app.FillColorLabel = uilabel(app.PlotOptionsPanel);
            app.FillColorLabel.Text = {'Fill'; 'Color'};
            app.FillColorColorPicker_1 = uicolorpicker(app.PlotOptionsPanel);
            app.FillColorColorPicker_1.Value = [1 1 1];
            app.PlotOptPnlFittedLinePropertiesLabel_2 = uilabel(app.PlotOptionsPanel);
            app.PlotOptPnlFittedLinePropertiesLabel_2.Text = 'Abscissa Limits';
            app.EqImage = uiimage(app.AnalysisWorkflowReactiveTracerTestUIFigure);
            app.EqImage.ImageSource = fullfile(pathToMLAPP, 'appdata', 'ReactiveTracerEq.png');
            app.EqHelpButton = uibutton(app.AnalysisWorkflowReactiveTracerTestUIFigure, 'push');
            app.EqHelpButton.ButtonPushedFcn = createCallbackFcn(app, @EqHelpButtonPushed, true);
            app.EqHelpButton.Text = 'Eq. Help';
            % Show the figure after all components are created
            app.AnalysisWorkflowReactiveTracerTestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TAT08AnalysisReactiveTrTest

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AnalysisWorkflowReactiveTracerTestUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.AnalysisWorkflowReactiveTracerTestUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalysisWorkflowReactiveTracerTestUIFigure)
        end
    end
end