classdef TAT08CommonVars < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TATMainpanelWithCommonVariablesUIFigure  matlab.ui.Figure
        FileActionsMenu                 matlab.ui.container.Menu
        SaveparametersMenu              matlab.ui.container.Menu
        ReadparametersMenu              matlab.ui.container.Menu
        TaskControlMenu                 matlab.ui.container.Menu
        DesignMenu                      matlab.ui.container.Menu
        desConservativetracerMenu       matlab.ui.container.Menu
        desTemperatureprofileMenu       matlab.ui.container.Menu
        desReactivetracerselectionMenu  matlab.ui.container.Menu
        desReactivetracerMenu           matlab.ui.container.Menu
        AnalysisMenu                    matlab.ui.container.Menu
        analConservativetracerMenu      matlab.ui.container.Menu
        analReactivetracerMenu          matlab.ui.container.Menu
        ProgramInfoMenu                 matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        NoticeMenu                      matlab.ui.container.Menu
        WorkflowOverviewPanel           matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        HTML                            matlab.ui.control.HTML
        lambda_r_EditField              matlab.ui.control.NumericEditField
        Thermalconductivityrockkgm3Label  matlab.ui.control.Label
        rho_r_EditField                 matlab.ui.control.NumericEditField
        Densityrockkgm3Label            matlab.ui.control.Label
        Arr_Ea_EditField                matlab.ui.control.NumericEditField
        ArrheniustermEaLabel            matlab.ui.control.Label
        Arr_A_EditField                 matlab.ui.control.NumericEditField
        ArrheniustermALabel             matlab.ui.control.Label
        M_rea_EditField                 matlab.ui.control.NumericEditField
        TracerinjectionmasskgEditField_2Label_2  matlab.ui.control.Label
        cp_r_EditField                  matlab.ui.control.NumericEditField
        SpecificheatrockJLabel          matlab.ui.control.Label
        T_inj_EditField                 matlab.ui.control.NumericEditField
        FluidinjectiontemperatureCLabel  matlab.ui.control.Label
        alpha_m_EditField               matlab.ui.control.NumericEditField
        DispersivityfnmEditFieldLabel   matlab.ui.control.Label
        alpha_c_EditField               matlab.ui.control.NumericEditField
        DispersivityfncEditFieldLabel   matlab.ui.control.Label
        M_con_EditField                 matlab.ui.control.NumericEditField
        TracerinjectionmasskgEditField_2Label  matlab.ui.control.Label
        T_res_EditField                 matlab.ui.control.NumericEditField
        ReservoirtemperatureCLabel      matlab.ui.control.Label
        delta_p_EditField               matlab.ui.control.NumericEditField
        PressuredropbetweenwellskPaLabel  matlab.ui.control.Label
        t_op_EditField                  matlab.ui.control.NumericEditField
        OperatingtimedaysEditField_3Label  matlab.ui.control.Label
        Q_EditField                     matlab.ui.control.NumericEditField
        Pumpingratemsup3supsLabel       matlab.ui.control.Label
        ReactiveTracerParametersLabel   matlab.ui.control.Label
        ConservativeTracerParametersLabel  matlab.ui.control.Label
        ThermalParametersLabel          matlab.ui.control.Label
        FlowParametersLabel             matlab.ui.control.Label
        CommonUsedVariablesPanel        matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        Channel_UITable                 matlab.ui.control.Table
    end

  
    properties (Access = private)
        screenSize
        ComVarsStruc                    % Structure to store commonly used variables
        TATPath                         % Path to Tracer Analysis Toolbox
        TATName                         % Name of Tracer Analysis Toolbox file
        TAText                          % Extension for Tracer Analysis Toolbox file
        hScale                          % Horizontal scaling factor for monitor resolution differences
        vScale                          % Vertical scaling factor for monitor resolution differences  
        
        DesignConservativeTrTest_Hndl
        DesignTemperatureProfile_Hndl
        DesignSelectReactiveTr_Hndl
        DesignReactiveTrTest_Hndl
        AnalysisConservativeTrTest_Hndl
        AnalysisReactiveTrTest_Hndl        
    end
    
    properties (Access = public)

    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get Screen size and define scaling factors                                             
            app.screenSize = get(groot,'ScreenSize');
            % app.hScale = screenSize(3)/2560;                
            % app.vScale = screenSize(4)/1440;

            % Define figure dimensions
            ImageSize = [1920, 1080];
            FigureWidthRel = 2/5;  % fraction of horizontal screen width used for figure 

            % Now calculate absolute and relative positions
            FigureWidth = FigureWidthRel * app.screenSize(3);
            FigureHt = FigureWidth * ImageSize(2)/ImageSize(1) ;
            FigureHtRel = FigureHt / app.screenSize(4);
            xStartPosRel = (1-FigureWidthRel)/2;
            yStartPosRel = (1-FigureHtRel)/2;
            % ImageSizeNew = [FigureWidth, FigureHt]; 
            % f=uifigure("Name",'About','Units','normalized',...
            %     'Position', [xStartPosRel yStartPosRel FigureWidthRel FigureHtRel]);            

            % Get location of app
            [app.TATPath, app.TATName, app.TAText] = fileparts(mfilename('fullpath'));
            
            % Add path to functions
            addPathNames = strcat(app.TATPath,"\TAT_Fcts");
            addpath(genpath(addPathNames));            

            % Specify image
            % myImage = uiimage(f,'position',[0 0 ImageSizeNew(1) ImageSizeNew(2)],'ScaleMethod','fit');
            % myImage.ImageSource = fullfile(app.TATPath,'appdata','SplashPage3.jpg');

            % Keep window open for short time before closing
            % pause(7);
            % close(f);            
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            % Open Manual
            winopen(fullfile(app.TATPath,'Docs','Tracer Analysis Toolbox User Manual.docx'));
        end

        % Menu selected function: NoticeMenu
        function NoticeMenuSelected(app, event)
            % Open Legal Notice file
            winopen(fullfile(app.TATPath,'Docs','Notices&Restrictions.pdf'));            
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
        % Define figure's actual size and size relative to screen
        ImageSize = [1080, 1080];
        FigureWidthRel = 2/5;  % fraction of horizontal screen width used for figure 
        
        % Now calculate absolute and relative positions
        FigureWidth = FigureWidthRel * app.screenSize(3);
        FigureHt = FigureWidth * ImageSize(2)/ImageSize(1) ;
        FigureHtRel = FigureHt / app.screenSize(4);
        xStartPosRel = (1-FigureWidthRel)/2;
        yStartPosRel = (1-FigureHtRel)/2;
        ImageSizeNew = [FigureWidth, FigureHt]; 
        f=uifigure("Name",'About','Units','normalized',...
            'Position', [xStartPosRel yStartPosRel FigureWidthRel FigureHtRel]);            
        
        % Get location of app
        [app.TATPath, app.TATName, app.TAText] = fileparts(mfilename('fullpath'));
        
        % Specify image
        myImage = uiimage(f,'position',[0 0 ImageSizeNew(1) ImageSizeNew(2)],'ScaleMethod','fit');
        myImage.ImageSource = fullfile(app.TATPath,'appdata','AboutPage3.jpg');            
        end

        % Menu selected function: SaveparametersMenu
        function SaveparametersMenuSelected(app, event)
            
            % Select file to save to
            [filename,pathname] = uiputfile('*.*');
            if isequal(filename,0)
                return;
            end
            fileNamefull = fullfile(pathname,filename);    

            % Read the preexisting structure if file exists
            if isfile(fileNamefull)
                Struct = read_namelist(fileNamefull);
            end

            % Place relevant variables into structure
            Struct.CommonVars.Q             = app.Q_EditField.Value;
            Struct.CommonVars.L_min         = app.L_min_EditField.Value;
            Struct.CommonVars.delta_p       = app.delta_p_EditField.Value;
            Struct.CommonVars.t_op          = app.t_op_EditField.Value;
            Struct.CommonVars.t_travel      = app.t_travel_EditField.Value;
            Struct.CommonVars.nChn          = app.nChn_EditField.Value;

            Struct.CommonVars.T_res         = app.T_res_EditField.Value;
            Struct.CommonVars.T_inj         = app.T_inj_EditField.Value;
            Struct.CommonVars.rho_r         = app.rho_r_EditField.Value;
            Struct.CommonVars.cp_r          = app.cp_r_EditField.Value;
            Struct.CommonVars.lambda_r      = app.lambda_r_EditField.Value;

            Struct.CommonVars.M_con         = app.M_con_EditField.Value;
            Struct.CommonVars.alpha_c       = app.alpha_c_EditField.Value;
            Struct.CommonVars.alpha_m       = app.alpha_m_EditField.Value;

            Struct.CommonVars.M_rea         = app.M_rea_EditField.Value;
            Struct.CommonVars.Arr_A         = app.Arr_A_EditField.Value;
            Struct.CommonVars.Arr_Ea        = app.Arr_Ea_EditField.Value;
                    
            % Channel data table
            Struct.CommonVars.TableHdrs     = app.Channel_UITable.ColumnName';
            Struct.CommonVars.TableData     = app.Channel_UITable.Data(:,:);
                    
            % Save structure to file
            write_namelist(Struct,fileNamefull);
        end

        % Menu selected function: ReadparametersMenu
        function ReadparametersMenuSelected(app, event)

            % Select file to read
            [filename,pathname] = uigetfile('*.*');
            if isequal(filename,0)
            return;
            end
            fileNamefull = fullfile(pathname,filename);
            
            % Read parameters from structure; place into edit fields
            Struct = read_namelist(fileNamefull);
            
            % Use the pieces to define the relevant fields            
            app.Q_EditField.Value           = Struct.CommonVars.Q;
            app.L_min_EditField.Value       = Struct.CommonVars.L_min;
            app.delta_p_EditField.Value     = Struct.CommonVars.delta_p;
            app.t_op_EditField.Value        = Struct.CommonVars.t_op;
            app.t_travel_EditField.Value    = Struct.CommonVars.t_travel;
            app.nChn_EditField.Value        = Struct.CommonVars.nChn;

            app.T_res_EditField.Value       = Struct.CommonVars.T_res;
            app.T_inj_EditField.Value       = Struct.CommonVars.T_inj;
            app.rho_r_EditField.Value       = Struct.CommonVars.rho_r;
            app.cp_r_EditField.Value        = Struct.CommonVars.cp_r;
            app.lambda_r_EditField.Value    = Struct.CommonVars.lambda_r;
            
            app.M_con_EditField.Value       = Struct.CommonVars.M_con;
            app.alpha_c_EditField.Value     = Struct.CommonVars.alpha_c;
            app.alpha_m_EditField.Value     = Struct.CommonVars.alpha_m;
            
            app.M_rea_EditField.Value       = Struct.CommonVars.M_rea;
            app.Arr_A_EditField.Value       = Struct.CommonVars.Arr_A;
            app.Arr_Ea_EditField.Value      = Struct.CommonVars.Arr_Ea;
                    
            % Channel data table
            app.Channel_UITable.Data(:,:)   = array2table(Struct.CommonVars.TableData);
            app.Channel_UITable.ColumnName  = Struct.CommonVars.TableHdrs; %Hdrs must be last; o/w data not read
                    
        end

        % Menu selected function: desConservativetracerMenu
        function desConservativetracerMenuSelected(app, event)
            app.DesignConservativeTrTest_Hndl = TAT08DesignConservativeTrTest;
        end

        % Menu selected function: desTemperatureprofileMenu
        function desTemperatureprofileMenuSelected(app, event)
            app.DesignTemperatureProfile_Hndl = TAT08DesignTemperatureProfile;          
        end

        % Menu selected function: desReactivetracerselectionMenu
        function desReactivetracerselectionMenuSelected(app, event)
            app.DesignSelectReactiveTr_Hndl = TAT08DesignSelectReactiveTr;            
        end

        % Menu selected function: desReactivetracerMenu
        function desReactivetracerMenuSelected(app, event)
            app.DesignReactiveTrTest_Hndl = TAT08DesignReactiveTrTest;            
        end

        % Menu selected function: analConservativetracerMenu
        function analConservativetracerMenuSelected(app, event)
            app.AnalysisConservativeTrTest_Hndl = TAT08AnalysisConservativeTrTest;            
        end

        % Menu selected function: analReactivetracerMenu
        function analReactivetracerMenuSelected(app, event)
            app.AnalysisReactiveTrTest_Hndl = TAT08AnalysisReactiveTrTest;           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TATMainpanelWithCommonVariablesUIFigure = uifigure('Visible', 'off');
            app.TATMainpanelWithCommonVariablesUIFigure.Name = 'TAT Main panel -- With Common Variables';
            app.TATMainpanelWithCommonVariablesUIFigure.Icon = fullfile(pathToMLAPP, 'appdata', 'TATIcon.png');
            app.FileActionsMenu = uimenu(app.TATMainpanelWithCommonVariablesUIFigure);
            app.FileActionsMenu.Text = 'File Actions';
            app.SaveparametersMenu = uimenu(app.FileActionsMenu);
            app.SaveparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveparametersMenuSelected, true);
            app.SaveparametersMenu.Text = 'Save parameters';
            app.ReadparametersMenu = uimenu(app.FileActionsMenu);
            app.ReadparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadparametersMenuSelected, true);
            app.ReadparametersMenu.Text = 'Read parameters';
            app.TaskControlMenu = uimenu(app.TATMainpanelWithCommonVariablesUIFigure);
            app.TaskControlMenu.Text = 'Task Control';
            app.DesignMenu = uimenu(app.TaskControlMenu);
            app.DesignMenu.Text = 'Design';
            app.desConservativetracerMenu = uimenu(app.DesignMenu);
            app.desConservativetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @desConservativetracerMenuSelected, true);
            app.desConservativetracerMenu.Text = 'Conservative tracer';
            app.desTemperatureprofileMenu = uimenu(app.DesignMenu);
            app.desTemperatureprofileMenu.MenuSelectedFcn = createCallbackFcn(app, @desTemperatureprofileMenuSelected, true);
            app.desTemperatureprofileMenu.Text = 'Temperature profile';
            app.desReactivetracerselectionMenu = uimenu(app.DesignMenu);
            app.desReactivetracerselectionMenu.MenuSelectedFcn = createCallbackFcn(app, @desReactivetracerselectionMenuSelected, true);
            app.desReactivetracerselectionMenu.Text = 'Reactive tracer selection';
            app.desReactivetracerMenu = uimenu(app.DesignMenu);
            app.desReactivetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @desReactivetracerMenuSelected, true);
            app.desReactivetracerMenu.Text = 'Reactive tracer';
            app.AnalysisMenu = uimenu(app.TaskControlMenu);
            app.AnalysisMenu.Text = 'Analysis';
            app.analConservativetracerMenu = uimenu(app.AnalysisMenu);
            app.analConservativetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @analConservativetracerMenuSelected, true);
            app.analConservativetracerMenu.Text = 'Conservative tracer';
            app.analReactivetracerMenu = uimenu(app.AnalysisMenu);
            app.analReactivetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @analReactivetracerMenuSelected, true);
            app.analReactivetracerMenu.Text = 'Reactive tracer';
            app.ProgramInfoMenu = uimenu(app.TATMainpanelWithCommonVariablesUIFigure);
            app.ProgramInfoMenu.Text = 'Program Info';
            app.HelpMenu = uimenu(app.ProgramInfoMenu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.ProgramInfoMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.NoticeMenu = uimenu(app.ProgramInfoMenu);
            app.NoticeMenu.MenuSelectedFcn = createCallbackFcn(app, @NoticeMenuSelected, true);
            app.NoticeMenu.Text = 'Notice';
            app.CommonUsedVariablesPanel = uipanel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.CommonUsedVariablesPanel.Title = 'Common Used Variables';
            app.Channel_UITable = uitable(app.CommonUsedVariablesPanel);
            app.Channel_UITable.ColumnName = {'Channel'; 'v'; 'L'; 'alpha'; 'b'; 'f'; 'Include'};
            app.Channel_UITable.RowName = {};
            app.Channel_UITable.ColumnEditable = true;
            app.Channel_UITable.Tooltip = {'v: velocity (m/s)'; 'L: path length (m)'; 'alpha: dispersivity (m)'; 'b: aperture (m)'; 'f: fluid mass fraction (-)'};
            app.Label = uilabel(app.CommonUsedVariablesPanel);
            app.Label.Text = 'These commonly used variables can be accessed via the ''Common variables'' menu of each task window. ''Send to'' pushes variables to this page; ''Pull from'' retrieves variables from it.';
            app.FlowParametersLabel = uilabel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.FlowParametersLabel.Text = 'Flow Parameters';
            app.ThermalParametersLabel = uilabel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.ThermalParametersLabel.Text = 'Thermal Parameters';
            app.ConservativeTracerParametersLabel = uilabel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.ConservativeTracerParametersLabel.Text = 'Conservative Tracer Parameters';
            app.ReactiveTracerParametersLabel = uilabel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.ReactiveTracerParametersLabel.Text = 'Reactive Tracer Parameters';
            app.Pumpingratemsup3supsLabel = uilabel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.Pumpingratemsup3supsLabel.Text = 'Pumping rate, Q (kg/s)';
            app.Q_EditField = uieditfield(app.TATMainpanelWithCommonVariablesUIFigure, 'numeric');
            app.Q_EditField.Value = 1;
            app.OperatingtimedaysEditField_3Label = uilabel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.OperatingtimedaysEditField_3Label.Text = 'Operating time (days)';
            app.t_op_EditField = uieditfield(app.TATMainpanelWithCommonVariablesUIFigure, 'numeric');
            app.t_op_EditField.Value = 1000;
            app.PressuredropbetweenwellskPaLabel = uilabel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.PressuredropbetweenwellskPaLabel.Text = 'Pressure drop between wells (kPa)';
            app.delta_p_EditField = uieditfield(app.TATMainpanelWithCommonVariablesUIFigure, 'numeric');
            app.delta_p_EditField.Value = 100;
            app.ReservoirtemperatureCLabel = uilabel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.ReservoirtemperatureCLabel.Text = 'Reservoir temperature (°C)';
            app.T_res_EditField = uieditfield(app.TATMainpanelWithCommonVariablesUIFigure, 'numeric');
            app.T_res_EditField.Value = 150;
            app.TracerinjectionmasskgEditField_2Label = uilabel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.TracerinjectionmasskgEditField_2Label.Text = 'Tracer injection mass (kg)';
            app.M_con_EditField = uieditfield(app.TATMainpanelWithCommonVariablesUIFigure, 'numeric');
            app.M_con_EditField.Value = 5;
            app.DispersivityfncEditFieldLabel = uilabel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.DispersivityfncEditFieldLabel.Text = 'Dispersivity  fn ''c''';
            app.alpha_c_EditField = uieditfield(app.TATMainpanelWithCommonVariablesUIFigure, 'numeric');
            app.alpha_c_EditField.Value = 0.21;
            app.DispersivityfnmEditFieldLabel = uilabel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.DispersivityfnmEditFieldLabel.Text = 'Dispersivity  fn ''m''';
            app.alpha_m_EditField = uieditfield(app.TATMainpanelWithCommonVariablesUIFigure, 'numeric');
            app.alpha_m_EditField.Value = 0.51;
            app.FluidinjectiontemperatureCLabel = uilabel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.FluidinjectiontemperatureCLabel.Text = 'Fluid injection temperature (°C)';
            app.T_inj_EditField = uieditfield(app.TATMainpanelWithCommonVariablesUIFigure, 'numeric');
            app.T_inj_EditField.Value = 50;
            app.SpecificheatrockJLabel = uilabel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.SpecificheatrockJLabel.Text = 'Specific heat, rock (J/kg/°C)';
            app.cp_r_EditField = uieditfield(app.TATMainpanelWithCommonVariablesUIFigure, 'numeric');
            app.cp_r_EditField.Value = 790;
            app.TracerinjectionmasskgEditField_2Label_2 = uilabel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.TracerinjectionmasskgEditField_2Label_2.Text = 'Tracer injection mass (kg)';
            app.M_rea_EditField = uieditfield(app.TATMainpanelWithCommonVariablesUIFigure, 'numeric');
            app.M_rea_EditField.Value = 5;
            app.ArrheniustermALabel = uilabel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.ArrheniustermALabel.Text = 'Arrhenius term ''A'' (1/s)';
            app.Arr_A_EditField = uieditfield(app.TATMainpanelWithCommonVariablesUIFigure, 'numeric');
            app.Arr_A_EditField.Value = 100;
            app.ArrheniustermEaLabel = uilabel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.ArrheniustermEaLabel.Text = 'Arrhenius term ''Ea'' (kJ/Mol)';
            app.Arr_Ea_EditField = uieditfield(app.TATMainpanelWithCommonVariablesUIFigure, 'numeric');
            app.Arr_Ea_EditField.Value = 10;
            app.Densityrockkgm3Label = uilabel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.Densityrockkgm3Label.Interpreter = 'html';
            app.Densityrockkgm3Label.Text = 'Density, rock (kg/m<sup>3</sup>)';
            app.rho_r_EditField = uieditfield(app.TATMainpanelWithCommonVariablesUIFigure, 'numeric');
            app.rho_r_EditField.Value = 2600;
            app.Thermalconductivityrockkgm3Label = uilabel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.Thermalconductivityrockkgm3Label.Text = 'Thermal conductivity, rock (W/m/°C)';
            app.lambda_r_EditField = uieditfield(app.TATMainpanelWithCommonVariablesUIFigure, 'numeric');
            app.lambda_r_EditField.Value = 2.5;
            app.WorkflowOverviewPanel = uipanel(app.TATMainpanelWithCommonVariablesUIFigure);
            app.WorkflowOverviewPanel.Title = 'Workflow Overview';
            app.HTML = uihtml(app.WorkflowOverviewPanel);
            app.HTML.HTMLSource = '<body><style>div{font-size:15px; font-family:Arial; line-height:1.4}</style><div>Select an <i>item</i> from the ''Task Control'' menu to start a workflow task:<br><b><i>Design Tasks</i></b><li><i>Conservative tracer</i>: Examine conservative tracer breakthrough curve(s) via multiple fracture channels.</li>	<li><i>Temperature profiles</i>: Examine temporal evolution of temperature along multiple fracture channels.</li>	<li><i>Reactive tracer selection</i>: Identify reactive tracers suitable to reservoir conditions and measurement criteria.</li>	<li><i>Reactive tracer</i>: Examine reactive tracer breakthrough curve(s) via multiple fracture channels.</li>    <b><i>Analysis Tasks</i></b>	<li><i>Conservative tracer</i>: Estimate uncertain parameters by fitting conservative tracer breakthrough curve(s) to measured values.</li>	<li><i>Reactive tracer</i>: Predict temperature evolution using fracture aperture(s) estimated via fitting reactive tracer breakthrough curve(s) to measured values.</li></div></body>';
            app.Image = uiimage(app.WorkflowOverviewPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'appdata', 'TATIcon.png');
            % Show the figure after all components are created
            app.TATMainpanelWithCommonVariablesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TAT08CommonVars

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TATMainpanelWithCommonVariablesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TATMainpanelWithCommonVariablesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TATMainpanelWithCommonVariablesUIFigure)
        end
    end
end