classdef TAT08DesignConservativeTrTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        designConTask             matlab.ui.Figure
        FileActionsMenu           matlab.ui.container.Menu
        ReadParametersMenu        matlab.ui.container.Menu
        SaveParametersMenu        matlab.ui.container.Menu
        SavePlotDataMenu          matlab.ui.container.Menu
        TaskControlMenu           matlab.ui.container.Menu
        DesignMenu                matlab.ui.container.Menu
        ConservativeTracerMenu    matlab.ui.container.Menu
        ThermalProfileMenu        matlab.ui.container.Menu
        TracerSelectionMenu       matlab.ui.container.Menu
        ReactiveTracerMenu        matlab.ui.container.Menu
        AnalysisMenu              matlab.ui.container.Menu
        ConservativeTracerMenu_2  matlab.ui.container.Menu
        ReactiveTracerMenu_2      matlab.ui.container.Menu
        CommonVariablesMenu       matlab.ui.container.Menu
        ViewMenu                  matlab.ui.container.Menu
        SendtoMenu                matlab.ui.container.Menu
        PullfromMenu              matlab.ui.container.Menu
        Image                     matlab.ui.control.Image
        EqHelpButton              matlab.ui.control.Button
        PlotPanel                 matlab.ui.container.Panel
        UpdatePlotButton          matlab.ui.control.Button
        ConPlotAxes               matlab.ui.control.UIAxes
        PlottingOptionsPanel      matlab.ui.container.Panel
        Max_Label                 matlab.ui.control.Label
        Min_Label                 matlab.ui.control.Label
        c_max_EditField           matlab.ui.control.NumericEditField
        time_max_EditField        matlab.ui.control.NumericEditField
        c_min_EditField           matlab.ui.control.NumericEditField
        time_min_EditField        matlab.ui.control.NumericEditField
        c_Label                   matlab.ui.control.Label
        Time_Label                matlab.ui.control.Label
        PlotOptionsTable          matlab.ui.control.Table
        ParametersPanel           matlab.ui.container.Panel
        Time_EditField            matlab.ui.control.NumericEditField
        Time2_Label               matlab.ui.control.Label
        Q_Label                   matlab.ui.control.Label
        M_con_Label               matlab.ui.control.Label
        NoChannelsEditField       matlab.ui.control.NumericEditField
        NoChannelsEditFieldLabel  matlab.ui.control.Label
        DeleteChannelsButton      matlab.ui.control.Button
        AddChannelButton          matlab.ui.control.Button
        delta_p_EditField         matlab.ui.control.NumericEditField
        pDeltappkPaLabel          matlab.ui.control.Label
        paramTable                matlab.ui.control.Table
        alpha_m_EditField         matlab.ui.control.NumericEditField
        mEditFieldLabel           matlab.ui.control.Label
        alpha_c_EditField         matlab.ui.control.NumericEditField
        cEditFieldLabel           matlab.ui.control.Label
        alpha_ParamLabel          matlab.ui.control.Label
        Q_EditField               matlab.ui.control.NumericEditField
        M_con_EditField           matlab.ui.control.NumericEditField
        T_res_EditField           matlab.ui.control.NumericEditField
        T_approxoCLabel           matlab.ui.control.Label
        Average_DropDown          matlab.ui.control.DropDown
        AverageDropDownLabel      matlab.ui.control.Label
        xAxis_DropDown            matlab.ui.control.DropDown
        xAxisDropDownLabel        matlab.ui.control.Label
        ConservativeTracerDesignTaskLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Startup *******************************************************************************************
        TATPath                         % Path to Tracer Analysis Toolbox
        TATName                         % Name of Tracer Analysis Toolbox file
        TAText                          % Extension for Tracer Analysis Toolbox file
        MLDefaultColors                 % Vector of RGB codes for line default colors us in Matlab
        hScale                          % Horizonal scaling factor for monitor resolution differences
        vScale                          % Vertical scaling factor for monitor resolution differences
        npts

        % App handles
        CommonVars_Hndl

        % Parameters Table ******************************************************************
        paramTableDat                   % Parameter Table data backup
        % paramTableCheckBox            % Checkboxes used in the Parameter Table
        nChn                            % Number of channels
        alpha_c                         % c parameter for dispersivity in scaling equation
        alpha_m                         % m parameter for dispersivity in scaling equation
        T_res                           % Approximate reservoir temperature
        del_p                           % Change in pressure between injection well and production well (kPa)
        v                               % Velocity (m/s)
        M_con                           % Mass of conservative tracer (kg)
        Q                               % Pumping rate in m^3/s
        L                               % Path length (m)
        alpha                           % dispersivity (m)
        b                               % apertures (m)
        mu_f                            % viscosity of liquid water
        f                               % Mass fraction of flows in channels
        Include                         % Vector of logical variable (one for each channel) to denote if channel is Included in calculations
        Channel                         % Channel identification numbers
        t_Tr                            % Time since injection of tracer (~transport time)

        % Plot Options Panel
        PlotOpt_LineStyleList           % List of potential line styles for plot
        PlotOption_PointStyleList       % List of Point styles for average at well
        PlotOptTableData                % Backup data for plot options table
        PointStyleList                  % Categorical list of options for point style for mean T when x-axis is distance
        t_min                           % Minimum time on x-axis
        t_max                           % Maximum time on x-axis
        c_min                           % Minimum concentration on y-axis
        c_max                           % Maxiumum concentration y-axis

        % Plot Panel
        C_Con                           % Array of Concentrations of conservative tracer
        C_Con_Mean                      % Vector of mean concentrations over Included channels
        ConPlots                        % Object containing plots of concentration versus time/relative distance
    end
    
    methods (Access = private)
        
        function TATSetup(app)
            % SETUP: establishes basic information about the app
            % including location, screen size, default plot colors
            % Get location of app
            [app.TATPath, app.TATName, app.TAText] = fileparts(mfilename('fullpath'));

            % Add path to functions
            addPathNames = strcat(app.TATPath,"\TAT_Fcts");
            addpath(genpath(addPathNames))

            % Get Screen size and define scaling factors %MAP
            screenSize = get(groot,'ScreenSize');
            if screenSize(3) < app.designConTask.Position(3)
               app.hScale = screenSize(3)/2560;
            else 
                app.hScale = 1;
            end 
            if screenSize(4) < app.designConTask.Position(4)
                app.vScale = screenSize(4)/1440;
            else 
                app.vScale = 1;
            end 

            % Set Matlab's default colors for lines
            app.MLDefaultColors = ["0.000, 0.447, 0.741" "0.850, 0.325, 0.098" "0.929, 0.694, 0.125" "0.494, 0.184, 0.556" "0.466, 0.674, 0.188" ...
                "0.301, 0.745, 0.933" "0.635, 0.078, 0.184","0.000 0.000 1.000","0.000 0.500 0.000","1.000 0.000 0.000" "0.000 0.000 0.000"]; 

            % Inialize Parameter Table and Plot Options Table
            ParamTableInitial(app)
            PlotOptInitial(app)

            % Set the number of data points to be plotted
            app.npts = 101;
        end
            
        function ParamTableInitial(app)
            % Header names
            app.paramTable.ColumnName = ["Channel","v","M_con","Q","L","alpha","b","Include"];

            % Initialize Variable
            % app.paramTableCheckBox = {true; true; true; true; true; true; true; true; true; true};
            app.Channel = 1;
            app.v = 1e-3;
            app.M_con = 1;
            app.Q = 1;
            app.L = 1000;
            app.t_Tr = 100;

            % Calculate alpha and b
            app.mu_f = muH2O_LCalc(app.T_res_EditField.Value);
            app.alpha_c = app.alpha_c_EditField.Value;
            app.alpha_m = app.alpha_m_EditField.Value;
            app.alpha = app.alpha_c*app.L^app.alpha_m;
            app.b = sqrt(app.v.*app.L.*12*app.mu_f./(app.delta_p_EditField.Value)); 

            % Update Tables (full: (1)Channel,(2)v,(3)M_con,(4)Q,(5)L,(6)t_Tr,(7)alpha,(8)b,(9)f,(10)Include)
            app.paramTableDat = table(app.Channel, app.v, app.M_con, app.Q, app.L, app.t_Tr, app.alpha, app.b, 1, true, ...
                                        'VariableNames',{'Channel','v','M_con','Q','L','t_Tr','alpha','b','f','Include'});     
            app.paramTable.Data = app.paramTableDat(1,[1:5,7,8,10]); % Default table doesn't Include transport time

            app.nChn = 1;
            app.NoChannelsEditField.Value = app.nChn;
            mystyle1 = uistyle('BackgroundColor', [0.9 0.9 0.9]);
            for i = 1:app.nChn
                addStyle(app.paramTable,mystyle1,'cell',[i,6]);
                addStyle(app.paramTable,mystyle1,'cell',[i,7]);
            end

            % Define vector values from table
            app.v = table2array(app.paramTableDat(:,2));
            app.M_con = table2array(app.paramTableDat(:,3));
            app.Q = table2array(app.paramTableDat(:,4));
            app.L = table2array(app.paramTableDat(:,5));
            app.t_Tr = table2array(app.paramTableDat(:,6));
            app.Include = table2array(app.paramTableDat(:,end));
            app.T_res = app.T_res_EditField.Value;
            app.del_p = app.delta_p_EditField.Value;

            % Define min/max values for axes
            app.t_min = app.time_min_EditField.Value;
            app.t_max = app.time_max_EditField.Value;
            app.c_min = app.c_min_EditField.Value;
            app.c_max = app.c_max_EditField.Value;
        end
        
        function [b,f] = updateFraction(app,v,L,Include,T_res,del_p,averageTxtVal)
            % Get data
            app.mu_f = muH2O_LCalc(T_res);                 % Calculate viscosity of liquid water in kPa.s

            % Calculate apertures
            b = sqrt(v.*L.*12*app.mu_f./del_p);   
            
            % Calculate fractional flows in channels
            if(lower(averageTxtVal) == "yes") 
                f = QFracCalc(v,b,Include);
            else
                f(1:length(b),1) = 1;
            end
        end        
                            
        function PlotOptInitial(app)
            % Initializes the Plot Options Table
            app.PlotOpt_LineStyleList = categorical({'Solid', 'Dashed', 'Dotted', 'Dash-Dotted','none'});
            app.PointStyleList = categorical({'Open', 'Filled'});
            col1 = {app.paramTable.Data{1,1}};
            col2 = {app.MLDefaultColors(1)};
            col3 = {2};
            lstyle = app.PlotOpt_LineStyleList(1);
            col4 = {lstyle};
            app.PlotOptTableData = table(col1, col2, col3, col4);
            app.PlotOptionsTable.Data = app.PlotOptTableData;

            % Set Column widths and background color
            app.PlotOptionsTable.ColumnWidth = {65 130 81 125};
            myColorStyle = uistyle("BackgroundColor",app.PlotOptionsTable.Data{1,2}{1},"FontColor",[0.94 0.94 0.94]);
            addStyle(app.PlotOptionsTable,myColorStyle,'cell',[1,2])
        end
        
        function updatePlotOptTable(app)
            % Update Plot Options Table
    
            % Define columns
            col1 = {"0";"0";"0";"0";"0";"0";"0";"0";"0";"0";"0"};
            for i = 1:app.nChn 
                col1{i} = num2str(app.paramTable.Data{i,1});
            end
    
            col2 = {app.MLDefaultColors(1);app.MLDefaultColors(2);app.MLDefaultColors(3);app.MLDefaultColors(4);app.MLDefaultColors(5);app.MLDefaultColors(6);...
                    app.MLDefaultColors(7);app.MLDefaultColors(8);app.MLDefaultColors(9);app.MLDefaultColors(10);app.MLDefaultColors(11)};
            if(app.Average_DropDown.Value == "Yes")
                col2{app.nChn+1,1} = app.MLDefaultColors(11);
            end
    
            col3 = {2;2;2;2;2;2;2;2;2;2;2};
            lStyle = app.PlotOpt_LineStyleList(1);
            if(app.xAxis_DropDown.Value == "Distance" && app.Average_DropDown.Value == "Yes")
                col3{app.nChn+1,1} = 8;
            end
    
            col4 = {lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle};
            app.PlotOptTableData = table(col1, col2, col3, col4);
            app.PlotOptionsTable.Data = app.PlotOptTableData(1:app.nChn,:);
    
            % Update last line in Plot Options Table
            if (app.Average_DropDown.Value == "Yes")
                if(app.xAxis_DropDown.Value == "Time")
                    % Define newline for information for the Sum curve
                    col1 = {'Average'};
                    col2 = app.MLDefaultColors(11);
                    col3 = {2};
                    col4 = {app.PlotOpt_LineStyleList(1)};
                    newline = table(col1, col2, col3, col4);
                elseif (app.xAxis_DropDown.Value == "Distance")
                    % Define newline for information for the Sum curve
                    col1 = {'Average'};
                    col2 = app.MLDefaultColors(11);
                    col3 = {8};
                    col4 = {app.PointStyleList(1)};
                    newline = table(col1, col2, col3, col4);
                end
                % Append new line to Plot Option table and display
                app.PlotOptionsTable.Data  = [app.PlotOptionsTable.Data; newline];
            end
    
            % Change background color of column 2
            endLoop = app.nChn;
            if(app.Average_DropDown.Value == "Yes")
                endLoop = app.nChn+1;
            end
            for i = 1:endLoop
                myColorStyle = uistyle("BackgroundColor",app.PlotOptionsTable.Data{i,2}{1},"FontColor",[0.94 0.94 0.94]);
                addStyle(app.PlotOptionsTable,myColorStyle,'cell',[i,2]);
            end
    
            % Update label and values for min and max on x-axis
            if (app.xAxis_DropDown.Value == "Time")
                app.Time_Label.Text = "Time";
                app.time_min_EditField.Value = app.t_min;
                app.time_max_EditField.Value = app.t_max;
            else
                app.Time_Label.Text= "Rel. Dist.";
                app.time_min_EditField.Value = 0;
                app.time_max_EditField.Value = 1;
            end
    
            % Update values for min and max on y-axis
            app.c_min_EditField.Value = app.c_min;
            app.c_max_EditField.Value = app.c_max;    
    
            % Update x-axis label
            if(app.xAxis_DropDown.Value == "Time")
                app.ConPlotAxes.XLabel.String = 'Time';
            else
               app.ConPlotAxes.XLabel.String = 'Relative Distance';
            end
        end
        
        function updateParamPanel(app)
        % Updates parameter table and edit fields    

            % Update vectors based on full Parameter Table
            app.Channel = app.paramTableDat.Channel;
            app.M_con = app.paramTableDat.M_con;
            app.Q = app.paramTableDat.Q;
            app.t_Tr = app.paramTableDat.t_Tr;
        
            % Update b values and mass flow fractions
            [app.b, app.f] = updateFraction(app, app.paramTableDat.v, app.paramTableDat.L, app.paramTableDat.Include,...
                                                 app.T_res, app.del_p, app.Average_DropDown.Value);
            app.paramTableDat.b = app.b;
            app.paramTableDat.f = app.f;

            app.alpha = app.alpha_c_EditField.Value .* app.paramTableDat.L .^ app.alpha_m_EditField.Value;
            app.paramTableDat.alpha = app.alpha;
            
            % Define useful background colors to distinguish cells
            app.paramTable.BackgroundColor = 'w';
            mystyle1 = uistyle('BackgroundColor', [0.9 0.9 0.9]);
            mystyle2 = uistyle('BackgroundColor', [1 1 1]);
            
            % Display Table (full: (1)Channel,(2)v,(3)M_con,(4)Q,(5)L,(6)t_Tr,(7)alpha,(8)b,(9)f,(10)Include)
            % Modify UItable based on selections, drawing from 'full' table
            if (lower(app.Average_DropDown.Value) == "yes" && lower(app.xAxis_DropDown.Value) == "time")
                app.paramTable.Data = app.paramTableDat(:,{'Channel', 'v', 'L', 'alpha', 'b','f','Include'});
                app.paramTable.ColumnName = ["Channel", "v", "L", "alpha", "b","f","Include"];
                app.paramTable.ColumnEditable = [true true true false false false true];
                for i = 1:app.nChn
                    addStyle(app.paramTable,mystyle2,'cell',[i,7]);
                    addStyle(app.paramTable,mystyle1,'cell',[i,4]);
                    addStyle(app.paramTable,mystyle1,'cell',[i,5]);
                    addStyle(app.paramTable,mystyle1,'cell',[i,6]);
                end
            elseif (lower(app.Average_DropDown.Value) == "yes" && lower(app.xAxis_DropDown.Value) == "distance")
                app.paramTable.Data = app.paramTableDat(:,{'Channel', 'v', 'L', 'alpha', 'b','f','Include'});
                app.paramTable.ColumnName = ["Channel", "v", "L", "alpha", "b","f","Include"];
                app.paramTable.ColumnEditable = [true true true false false false true];
                for i = 1:app.nChn
                    addStyle(app.paramTable,mystyle2,'cell',[i,7]);
                    addStyle(app.paramTable,mystyle1,'cell',[i,4]);
                    addStyle(app.paramTable,mystyle1,'cell',[i,5]);
                    addStyle(app.paramTable,mystyle1,'cell',[i,6]);
                end
            elseif (lower(app.Average_DropDown.Value) == "no" && lower(app.xAxis_DropDown.Value) == "time")
                app.paramTable.Data = app.paramTableDat(:,{'Channel', 'v','M_con','Q', 'L', 'alpha', 'b','Include'});
                app.paramTable.ColumnName = ["Channel", "v","M_con","Q", "L", "alpha","b", "Include"];
                app.paramTable.ColumnEditable = [true true true true true false false true];
                for i = 1:app.nChn
                    addStyle(app.paramTable,mystyle2,'cell',[i,4]);
                    addStyle(app.paramTable,mystyle2,'cell',[i,5]);
                    addStyle(app.paramTable,mystyle1,'cell',[i,6]);
                    addStyle(app.paramTable,mystyle1,'cell',[i,7]);
                end
            elseif (lower(app.Average_DropDown.Value) == "no" && lower(app.xAxis_DropDown.Value) == "distance")
                app.paramTable.Data = app.paramTableDat(:,{'Channel', 'v','M_con','Q', 'L','t_Tr', 'alpha', 'b','Include'});
                app.paramTable.ColumnName = ["Channel", "v","M_con","Q", "L","t_Tr", "alpha","b", "Include"];
                app.paramTable.ColumnWidth = {60,'auto','auto','auto','auto','auto','auto','auto',56};
                app.paramTable.ColumnEditable = [true true true true true true false false true];
                for i = 1:app.nChn
                    addStyle(app.paramTable,mystyle2,'cell',[i,4]);
                    addStyle(app.paramTable,mystyle2,'cell',[i,5]);
                    addStyle(app.paramTable,mystyle2,'cell',[i,6]);
                    addStyle(app.paramTable,mystyle1,'cell',[i,7]);
                    addStyle(app.paramTable,mystyle1,'cell',[i,8]);
                end
            end
    
            % Assign values in Struct to appropriate edit fields
            % Assign values summation and plot type to dropdown boxes
            if (lower(app.Average_DropDown.Value) == "yes")
                app.Average_DropDown.Value = "Yes";
            elseif (lower(app.Average_DropDown.Value) == "no")
                app.Average_DropDown.Value = "No";
            else
                errordlg('Value of &ConTrDesign.Average_DropDown.Value must be Yes or No','Input Error')
                return
            end
    
            if (lower(app.xAxis_DropDown.Value) == "time")
                app.xAxis_DropDown.Value = "Time";
            elseif (lower(app.xAxis_DropDown.Value) == "distance")
                app.xAxis_DropDown.Value = "Distance";
            else
                errordlg('Value of &ConTrDesign.xAxisType must be Time or Distance','Input Error')
                return
            end
        
            % Set edit fields visibility
            if(app.Average_DropDown.Value == "Yes" && app.xAxis_DropDown.Value == "Time")
                app.M_con_EditField.Visible = 'on';     % Mass of conservative tracer
                app.M_con_Label.Visible = 'on';
                % app.M_con_EditField.Value = app.paramTableDat{1,3};
                app.Q_EditField.Visible = "on";      % Pumping Rate
                app.Q_Label.Visible = 'on';  
                % app.Q_EditField.Value = app.paramTableDat{1,4};
                app.Time_EditField.Visible = "off";     % Path Length
                app.Time2_Label.Visible = "off";
            elseif(app.Average_DropDown.Value == "No" && app.xAxis_DropDown.Value == "Time")
                app.M_con_EditField.Visible = 'off';     % Mass of conservative tracer
                app.M_con_Label.Visible = 'off';
                app.Q_EditField.Visible = "off";      % Pumping Rate
                app.Q_Label.Visible = 'off';          
                app.Time_EditField.Visible = "off";     % Times
                app.Time2_Label.Visible = "off";
            elseif (app.Average_DropDown.Value == "No" && app.xAxis_DropDown.Value == "Distance") 
                app.M_con_EditField.Visible = 'off';     % Mass of conservative tracer
                app.M_con_Label.Visible = 'off';
                app.Q_EditField.Visible = "off";      % Pumping Rate
                app.Q_Label.Visible = 'off';          
                app.Time_EditField.Visible = "off";     % Times
                app.Time2_Label.Visible = "off";
                app.Time_EditField.Value = app.paramTableDat{1,6};
            elseif (app.Average_DropDown.Value == "Yes" && app.xAxis_DropDown.Value == "Distance")
                app.M_con_EditField.Visible = 'on';     % Mass of conservative tracer
                app.M_con_Label.Visible = 'on';
                % app.M_con_EditField.Value = app.paramTableDat{1,3};
                app.Q_EditField.Visible = "on";      % Pumping Rate
                app.Q_Label.Visible = 'on';  
                % app.Q_EditField.Value = app.paramTableDat{1,4};
                app.Time_EditField.Visible = "on";     % Times
                app.Time2_Label.Visible = "on";
                % app.Time_EditField.Value = app.paramTableDat{1,6};
            end

%MAP None of this commented section seems necessary        
            % Save whether to Include curve in plot or not 
            % IncludeCurve(:) = app.paramTableDat{:,end};
            % % Reassign the default values of designConparamTableCheckBox to the read values
            % for i = 1:app.nChn
            %     app.paramTableCheckBox{i,1} = logical(IncludeCurve(1,i));
            % end
            % 
            % % Delete the last column of the table
            % app.paramTableDat(:,end) = [];
            % app.paramTable.Data(:,end) = [];
            % 
            % % Concatenate current table and paramTableCheckBox
            % app.paramTable.Data = [app.paramTable.Data app.paramTableCheckBox(1:app.nChn,1)];
            % app.paramTableDat = [app.paramTableDat app.paramTableCheckBox(1:app.nChn,1)];
            % %app.paramTable.Data = app.paramTableDat;
    
            % Update Plot Options Table
            % updatePlotOptTable(app)
    
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %Center main figure on screen
            movegui(app.designConTask, 'center');

            % Get screen size and define hScale, vScale, and plot default colors
            TATSetup(app)
    
        end

        % Button pushed function: AddChannelButton
        function AddChannelButtonPushed(app, event)
         % Check if designConnChn <= 10
            if(app.nChn < 10)
                if(app.nChn == 0)
                    maxChn = 0;
                else
                    % Find the largest channel number
                    maxChn = app.paramTableDat {1,1};
                    for i = 2:app.nChn
                        if(app.paramTableDat {i,1} > maxChn)
                            maxChn = app.paramTableDat {i,1};
                        end
                    end
                end
                maxChn = maxChn + 1;

                % Increment the number of channels and update editfield
                app.nChn = app.nChn + 1;
                app.NoChannelsEditField.Value = app.nChn;                
                newChn = app.paramTableDat(end,:) ; % New channel is copy of last row
                newChn.Channel = maxChn ; % Update channel number
                newChn.v = newChn.v * 0.8 ; % Alter velocity
                app.paramTableDat  = [app.paramTableDat; newChn]; % Add to table
            else
               errordlg('Number of channels must be <= 10','Input Error')
               return
            end

            % Update Parameters Panel
            updateParamPanel(app)

            % Update Plot Options Panel
            updatePlotOptTable(app)            
        end

        % Button pushed function: DeleteChannelsButton
        function DeleteChannelsButtonPushed(app, event)
            % Open dialogue box to input channels to be deleted
            prompt = {'Enter space-separated channel numbers:'};
            deleteDlgTitle = 'Delete Channels';
            dims = [1,40];
            deleteResponse = inputdlg(prompt,deleteDlgTitle,dims);
            deleteChn = str2num(deleteResponse{1});
            nDelete = size(deleteChn,2);

            % Display error msg if none or all channels are specified
            if(nDelete == 0)
                return
            elseif (nDelete == app.nChn)
                errTxt = 'Cannot delete all channels';
                errordlg(errTxt,'Input Error');
                return
            end
       
            % Determine the row for each channel to be deleted and add it
            % to the deleteRows vector
            for i = 1:nDelete
                 % Set error code for finding channel to 1
                chnFindError = 1;
                for j = 1: app.nChn
                    if (deleteChn(i) == app.paramTable.Data{j,1})
                        deleteRows(1,i) = j;
                        chnFindError = 0;
                        break;
                    end
                end
                % Display error message if channel number not found
                if (chnFindError == 1)
                    errTxt = ['Channel ' num2str(deleteChn(i)) ' not found. Check input.'];
                    errordlg(errTxt,'Input Error')
                    return
                end
            end
     
            % Delete rows from table if all channels found
            app.paramTable.Data(deleteRows,:) = [];
            app.PlotOptionsTable.Data(deleteRows,:) = [];
            app.paramTableDat(deleteRows,:) = [];
            app.nChn = app.nChn - nDelete;
            app.NoChannelsEditField.Value = app.nChn;

            % Update Parameter Panel
            updateParamPanel(app)
    
            % Update Plot Options Panel
            updatePlotOptTable(app)
        end

        % Menu selected function: SaveParametersMenu
        function SaveParametersMenuSelected(app, event)
        % Select file to save to
        [filename,pathname] = uiputfile('*.*');
        if isequal(filename,0)
            return;
        end
        fileNamefull = fullfile(pathname,filename);    

        % Read the preexisting structure if file exists
        if isfile(fileNamefull)
            Struct = read_namelist(fileNamefull);
        end
        
        % Update data structure from parameter dropdown boxes and edit fields
        Struct.DesignConservativeTrTest.nChn       = app.NoChannelsEditField.Value         ;
        Struct.DesignConservativeTrTest.averageTxt = {app.Average_DropDown.Value}          ;
        Struct.DesignConservativeTrTest.xaxisType  = {app.xAxis_DropDown.Value}            ;
        Struct.DesignConservativeTrTest.T_res      = app.T_res_EditField.Value             ;
        Struct.DesignConservativeTrTest.Q          = app.Q_EditField.Value                 ;
        Struct.DesignConservativeTrTest.M_con      = app.M_con_EditField.Value             ;        
        Struct.DesignConservativeTrTest.delta_p    = app.delta_p_EditField.Value           ;
        Struct.DesignConservativeTrTest.alpha_c    = app.alpha_c_EditField.Value           ;
        Struct.DesignConservativeTrTest.alpha_m    = app.alpha_m_EditField.Value           ;

        % Update data structure from parameter table 
        % (1)Channel,(2)v,(3)M_con,(4)Q,(5)L,(6)t_Tr,(7)alpha,(8)b,(9)f,(10)Include
        Struct.DesignConservativeTrTest.Table_Channel    = transpose(app.paramTableDat{:,1})   ;
        Struct.DesignConservativeTrTest.Table_v          = transpose(app.paramTableDat{:,2})     ;
        Struct.DesignConservativeTrTest.Table_M_con      = transpose(app.paramTableDat{:,3})     ;
        Struct.DesignConservativeTrTest.Table_Q          = transpose(app.paramTableDat{:,4})     ;
        Struct.DesignConservativeTrTest.Table_L          = transpose(app.paramTableDat{:,5})     ;
        Struct.DesignConservativeTrTest.Table_t_Tr       = transpose(app.paramTableDat{:,6})     ;
        Struct.DesignConservativeTrTest.Table_alpha      = transpose(app.paramTableDat{:,7})     ;
        Struct.DesignConservativeTrTest.Table_b          = transpose(app.paramTableDat{:,8})     ;    
        Struct.DesignConservativeTrTest.Table_f          = transpose(app.paramTableDat{:,9})     ;
        Struct.DesignConservativeTrTest.Table_Include    = transpose(app.paramTableDat{:,10})    ;

        % Update data structure from Plot Options Panel
        Struct.DesignConservativeTrTest.t_min    = app.time_min_EditField.Value            ;
        Struct.DesignConservativeTrTest.t_max    = app.time_max_EditField.Value            ;
        Struct.DesignConservativeTrTest.c_min    = app.c_min_EditField.Value               ;
        Struct.DesignConservativeTrTest.c_max    = app.c_max_EditField.Value               ;

        % Save the data structure in a namelist
        write_namelist(Struct,fileNamefull);
        
        end

        % Menu selected function: ReadParametersMenu
        function ReadParametersMenuSelected(app, event)

        % Select file to read
        [filename,pathname] = uigetfile('*.*');
        if isequal(filename,0)
            return;
        end
        fileNamefull = fullfile(pathname,filename);    
            
        % Import Data file into Parameter Structure
        Struct = read_namelist(fileNamefull);

        % Stop if no data
        if ~isfield(Struct,'DesignConservativeTrTest')
            msgbox('No tracer test design data found; execution halted')
            return
        end
          
        % Get the channel numbers and check for uniqueness
        app.Channel = transpose(Struct.DesignConservativeTrTest.Table_Channel);
        ChnUniqErr = ChnUnique(app,app.Channel);
        % If channels are not unique, return to task page
        if (ChnUniqErr == 1)
            return;
        end

        % Define needed variables from the input structure
        app.nChn = Struct.DesignConservativeTrTest.nChn;
        app.T_res = Struct.DesignConservativeTrTest.T_res;
        app.del_p = Struct.DesignConservativeTrTest.delta_p;
        app.alpha_c = Struct.DesignConservativeTrTest.alpha_c;
        app.alpha_m = Struct.DesignConservativeTrTest.alpha_m;    

        % Assign values to edit field that are always visible
        app.NoChannelsEditField.Value   = Struct.DesignConservativeTrTest.nChn      ;
        app.Q_EditField.Value     	    = Struct.DesignConservativeTrTest.Q         ;
        app.M_con_EditField.Value       = Struct.DesignConservativeTrTest.M_con     ;        
        app.T_res_EditField.Value       = Struct.DesignConservativeTrTest.T_res     ;
        app.alpha_c_EditField.Value     = Struct.DesignConservativeTrTest.alpha_c   ;
        app.alpha_m_EditField.Value     = Struct.DesignConservativeTrTest.alpha_m   ;
        app.delta_p_EditField.Value     = Struct.DesignConservativeTrTest.delta_p     ;

        % Assign values to dropdown boxes
        averageTxt = convertCharsToStrings(Struct.DesignConservativeTrTest.averageTxt);
        if (lower(averageTxt) == "yes")
            app.Average_DropDown.Value = 'Yes';
        elseif (lower(averageTxt) == "no")
            app.Average_DropDown.Value = 'No';
        else
            errordlg('Value of &ConTrDesign.averageTxt must be Yes or No','Input Error')
            return
        end

        xAxisType = convertCharsToStrings(Struct.DesignConservativeTrTest.xaxisType);
        if (lower(xAxisType) == "time")
            app.xAxis_DropDown.Value = 'Time';
        elseif (lower(xAxisType) == "distance")
            app.xAxis_DropDown.Value = 'Distance';
        else
            errordlg('Value of &ConTrDesign.xaxisType must be Time or Distance','Input Error')
            return
        end

        % Define the table data columns
        %Channel has already been captured
        app.v =         transpose(Struct.DesignConservativeTrTest.Table_v);
        app.M_con =     transpose(Struct.DesignConservativeTrTest.Table_M_con);
        app.Q =         transpose(Struct.DesignConservativeTrTest.Table_Q);
        app.L =         transpose(Struct.DesignConservativeTrTest.Table_L);
        app.t_Tr =      transpose(Struct.DesignConservativeTrTest.Table_t_Tr);
        app.alpha =     app.alpha_c.*app.L.^app.alpha_m;
        app.Include =   logical(transpose(Struct.DesignConservativeTrTest.Table_Include));
        [app.b, app.f] = updateFraction(app, app.v, app.L, app.Include, app.T_res, app.del_p, app.Average_DropDown.Value);

        % Create Backup Parameter Table % (1)Channel,(2)v,(3)M_con,(4)Q,(5)L,(6)t_Tr,(7)alpha,(8)b,(9)f,(10)Include
        app.paramTableDat = table(app.Channel, app.v, app.M_con, app.Q, app.L, app.t_Tr,app.alpha, app.b, app.f, app.Include,...
                                 'VariableNames',{'Channel','v','M_con','Q','L','t_Tr','alpha','b','f','Include'}); 
        % Update limits on axes
        app.time_min_EditField.Value = Struct.DesignConservativeTrTest.t_min;
        app.time_max_EditField.Value = Struct.DesignConservativeTrTest.t_max;
        app.t_min = Struct.DesignConservativeTrTest.t_min;
        app.t_max = Struct.DesignConservativeTrTest.t_max;
        app.c_min_EditField.Value = Struct.DesignConservativeTrTest.c_min;
        app.c_max_EditField.Value = Struct.DesignConservativeTrTest.c_max;
        app.c_min = Struct.DesignConservativeTrTest.c_min;
        app.c_max = Struct.DesignConservativeTrTest.c_max;

        % Update Parameter Panel
        updateParamPanel(app)

        % Update Plot Options Panel
        updatePlotOptTable(app)
        end

        % Menu selected function: SavePlotDataMenu
        function SavePlotDataMenuSelected(app, event)
        % Update plot and check for its existence            
        UpdatePlotButtonPushed(app,event)
        if isempty(app.ConPlots)
            msgbox("No plots created; halting execution","Error");
            return
        end

        % Get the channel numbers from the conservative tracer parameter table
        IncludeTr = table2array(app.paramTable.Data(:,end));
        Channels = table2array(app.paramTable.Data(IncludeTr,1));

        % Define the basic header pieces
        preheader = 'Conservative tracer design'; % An extra line to store avg C for distance case
        ChnHeaders = strcat("Chn-",num2str(Channels));
        header = [app.xAxis_DropDown.Value,  ChnHeaders'];
        
        % Define xy data
        dataMatrix = app.ConPlots(1).XData';
        for i = 1 : length(app.ConPlots)
            dataMatrix = [dataMatrix, app.ConPlots(i).YData'];
        end

        % Modify headers and  data matrix for averages, etc
        if app.Average_DropDown.Value == "Yes"
            if app.xAxis_DropDown.Value == "Time"
                header = [header, "Mean C"];
            elseif app.xAxis_DropDown.Value == "Distance"
                preheader =strcat(preheader,"Production well C = ",num2str(app.C_Con_Mean));
            end
        end
       
        % Save headers and data to file
        [savefile,savepath] = uiputfile('*.*');
        if ~savefile==0
            saveFileFull = [savepath savefile];
            writematrix(preheader,saveFileFull)                
            writematrix(header,saveFileFull,'Delimiter',',','WriteMode','append')
            writematrix(dataMatrix,saveFileFull,'Delimiter',',','WriteMode','append')  
        end
        end

        % Menu selected function: ViewMenu
        function ViewMenuSelected(app, event)
        app.CommonVars_Hndl = TAT08CommonVars;               
        end

        % Menu selected function: SendtoMenu
        function SendtoMenuSelected(app, event)
        app.CommonVars_Hndl = TAT08CommonVars;

        app.CommonVars_Hndl.M_con_EditField.Value    = app.M_con_EditField.Value;            
        app.CommonVars_Hndl.Q_EditField.Value        = app.Q_EditField.Value;
        app.CommonVars_Hndl.T_res_EditField.Value    = app.T_res_EditField.Value;
        app.CommonVars_Hndl.delta_p_EditField.Value  = app.delta_p_EditField.Value;
        app.CommonVars_Hndl.alpha_c_EditField.Value  = app.alpha_c_EditField.Value;
        app.CommonVars_Hndl.alpha_m_EditField.Value  = app.alpha_m_EditField.Value;      
        
        % Send Channel,v,L,alpha,b,f,Include to CommonVars table
        app.CommonVars_Hndl.Channel_UITable.Data = [];
        if app.Average_DropDown.Value == "Yes"
            app.CommonVars_Hndl.Channel_UITable.Data = app.paramTable.Data(:,[1,2,3,4,5,6,7]);                
        else 
            Nrows = height(app.paramTable.Data);
            temptable = ([app.paramTable.Data{:,[1,2,5,6]},...                  % Channel, v, L, alpha
                                        999e-3.*ones(Nrows,1),...               % b                   
                                        1/Nrows.*ones(Nrows,1),...              % f
                                            app.paramTable.Data{:,8}]);         % Include                
            app.CommonVars_Hndl.Channel_UITable.Data = array2table(temptable,'VariableNames',{'Channel','v','L','alpha','b','f','Include'}) ;
        end                       
        end

        % Menu selected function: PullfromMenu
        function PullfromMenuSelected(app, event)
        app.CommonVars_Hndl = TAT08CommonVars;

        app.M_con_EditField.Value      = app.CommonVars_Hndl.M_con_EditField.Value;
        app.Q_EditField.Value          = app.CommonVars_Hndl.Q_EditField.Value;
        app.T_res_EditField.Value      = app.CommonVars_Hndl.T_res_EditField.Value;
        app.delta_p_EditField.Value    = app.CommonVars_Hndl.delta_p_EditField.Value;
        app.alpha_c_EditField.Value    = app.CommonVars_Hndl.alpha_c_EditField.Value;
        app.alpha_m_EditField.Value    = app.CommonVars_Hndl.alpha_m_EditField.Value; 
       
        % Pull Channel,v,L,b,f,Include from CommonVars table 
        NrowsIncoming = height(app.CommonVars_Hndl.Channel_UITable.Data);
        NrowsExisting = height(app.paramTableDat);
        Nnew = NrowsIncoming - NrowsExisting;
        if NrowsIncoming > 0
            if height(app.paramTableDat) > NrowsIncoming
                app.paramTableDat(1 + NrowsIncoming,:) = [];
            else
                app.paramTableDat = [app.paramTableDat; repmat(app.paramTableDat(end,:),Nnew,1)];
            end
            app.paramTableDat.Channel =   app.CommonVars_Hndl.Channel_UITable.Data.Channel;                        
            app.paramTableDat.v =         app.CommonVars_Hndl.Channel_UITable.Data.v;            
            app.paramTableDat.L =         app.CommonVars_Hndl.Channel_UITable.Data.L;
            app.paramTableDat.b =         app.CommonVars_Hndl.Channel_UITable.Data.b;
            app.paramTableDat.f =         app.CommonVars_Hndl.Channel_UITable.Data.f; 
            app.paramTableDat.Include =   app.CommonVars_Hndl.Channel_UITable.Data.Include; 

            % Increment the number of channels and update nChn editfield
            app.nChn = NrowsIncoming;
            app.NoChannelsEditField.Value = app.nChn;
        end

        % Update Parameter Table, Parameter Panel, x-axis of plot
        updateParamPanel(app);

        % Update Plot Options Table
        updatePlotOptTable(app)        

        % Return control to self
        TAT08DesignConservativeTrTest ;
        end

        % Button pushed function: EqHelpButton
        function EqHelpButtonPushed(app, event)
        % Opens help file for the conservative tracer
        % Define file location
        eqHelpPath = fullfile(app.TATPath,'Docs','ConTracerHelp.pdf');
            
        % Open Help File
        winopen(eqHelpPath)
        end

        % Cell selection callback: PlotOptionsTable
        function PlotOptionsTableCellSelection(app, event)
        indices = event.Indices;
        if and(~isempty(indices),size((indices),1)==1) %if clicked in channel row
            if(indices(2) == 2) %if 2nd column cell selected
                colorOld = str2num(app.PlotOptionsTable.Data{indices(1),2}{1});
                colorNew = num2str(round(uisetcolor(colorOld),3));
                if ~strcmp(colorNew,'0') %if operation not canceled
                    app.PlotOptionsTable.Data{indices(1),2}{1} = colorNew;
                    myColorStyle = uistyle("BackgroundColor",colorNew);
                    addStyle(app.PlotOptionsTable,myColorStyle,'cell',[indices(1),indices(2)]);          
                    app.PlotOptionsTable.Selection = [indices(1),1];        
                end
            end
        end    
        end

        % Button pushed function: UpdatePlotButton
        function UpdatePlotButtonPushed(app, event)
        % Define parameters needed for calculations
        % Define a sub-Table of parameters based only on Included channels
        % Full table is (1)Channel,(2)v,(3)M_con,(4)Q,(5)L,(6)t_Tr,(7)alpha,(8)b,(9)f,(10)Include
        ParamArray =table2array(app.paramTableDat(app.paramTableDat.Include == true,[2,5,9]));  % velocity, length and fraction
        alphaParams = [app.alpha_c_EditField.Value app.alpha_m_EditField.Value];                % Collect dispersivity function parameter
        rhoH2O = rhoH2OCalc(app.T_res);                                                         % Calculate density of liquid water

        % Define the time/space values
        if (app.xAxis_DropDown.Value == "Time" && app.Average_DropDown.Value == "Yes")
            M_con_Active = app.M_con_EditField.Value;           % Mass of conservative Tracer
            Q_Active = app.Q_EditField.Value;                   % Pumping rate
            tData = linspace(app.time_min_EditField.Value, app.time_max_EditField.Value, app.npts);
            if (tData(1) == 0)
                tData(1) = tData(2)/10;
            end
        elseif (app.xAxis_DropDown.Value == "Time" && app.Average_DropDown.Value == "No")
            M_con_Active = app.M_con(app.paramTableDat.Include == true,1);        % Mass of conservative Tracer
            Q_Active = app.Q(app.paramTableDat.Include == true,1);            % Pumping rate
            tData = linspace(app.time_min_EditField.Value, app.time_max_EditField.Value, app.npts);
            if (tData(1) == 0)
                tData(1) = tData(2)/10;
            end
        elseif (app.xAxis_DropDown.Value == "Distance" && app.Average_DropDown.Value == "Yes")
            M_con_Active = app.M_con_EditField.Value;       % Mass of conservative Tracer
            Q_Active = app.Q_EditField.Value;               % Pumping rate
            x_rel = linspace(app.time_min_EditField.Value, app.time_max_EditField.Value,app.npts);
            tData = app.Time_EditField.Value;
        elseif (app.xAxis_DropDown.Value == "Distance" && app.Average_DropDown.Value == "No")
            M_con_Active = app.M_con(app.paramTableDat.Include == true,1);    % Mass of conservative Tracer
            Q_Active = app.Q(app.paramTableDat.Include == true,1);            % Pumping rate
            x_rel = linspace(app.time_min_EditField.Value, app.time_max_EditField.Value,app.npts);
            tData = table2array(app.paramTableDat(app.paramTableDat.Include == true,6));
        end

        % Calculate conservative tracer concentrations
        [app.C_Con_Mean, app.C_Con] = ConTracer(app.xAxis_DropDown.Value,tData,M_con_Active,Q_Active,alphaParams,ParamArray,rhoH2O); 

        % Plot the calculated concentrations
        if(app.Average_DropDown.Value == "No" && app.xAxis_DropDown.Value == "Time")
            app.ConPlots = plot (app.ConPlotAxes, tData(1,:), app.C_Con,'-');
        elseif(app.Average_DropDown.Value == "Yes" && app.xAxis_DropDown.Value == "Time")
            app.ConPlots = plot (app.ConPlotAxes, ...
                tData(1,:), app.C_Con,'-', ...
                tData(1,:), app.C_Con_Mean,'-');
        elseif (app.Average_DropDown.Value == "No" && app.xAxis_DropDown.Value == "Distance")
            app.ConPlots = plot (app.ConPlotAxes, x_rel(1,:), app.C_Con,'-');
        elseif (app.Average_DropDown.Value == "Yes" && app.xAxis_DropDown.Value == "Distance")
            app.C_Con_Mean = transpose(table2array(app.paramTableDat(app.paramTableDat.Include == true,9)))*app.C_Con(:,101);
            app.ConPlots = plot (app.ConPlotAxes, ...
                x_rel(1,:), app.C_Con,'-');
            hold (app.ConPlotAxes,"on")
            average_Conc = plot (app.ConPlotAxes,1,app.C_Con_Mean,'o ');
            hold (app.ConPlotAxes,"off")
        end

        % Update Line properties
        LineStyles = {'-';'--';':';'-.';''};
        
        endLoop = size(app.ConPlots,1);
        if (app.Average_DropDown.Value == "No")
            % The added line to necessary to convert PlotOptionsTable_Active to a string array, the line is then deleted in the next statement
            PlotOptionsTable_Active = [table2array(app.PlotOptionsTable.Data(app.paramTableDat.Include == true,:));["Average","0.000 0.000 0.000","2","Solid"]]; 
            PlotOptionsTable_Active = PlotOptionsTable_Active(1:endLoop,:);
        elseif(app.Average_DropDown.Value == "Yes") % && app.xAxis_DropDown.Value == "Time")
            PlotOptionsTable_Active = [table2array(app.PlotOptionsTable.Data(app.paramTableDat.Include == true,:));app.PlotOptionsTable.Data{end,:}];
        end
   
        % Format all the lines
        for i = 1: endLoop
            app.ConPlots(i).Color = str2num(PlotOptionsTable_Active(i,2));
            app.ConPlots(i).LineWidth = str2num(PlotOptionsTable_Active(i,3));
            app.ConPlots(i).LineStyle = LineStyles(ismember(app.PlotOpt_LineStyleList, PlotOptionsTable_Active(i,4)));
        end

        % Format the Mean point at x_r = 1
        if(app.Average_DropDown.Value == "Yes" && app.xAxis_DropDown.Value == "Distance" )
            average_Conc.MarkerEdgeColor = str2num(PlotOptionsTable_Active(end,2));
            average_Conc.MarkerSize = str2num(PlotOptionsTable_Active(end,3));
            average_Conc.Marker = "o";
            average_Conc.LineWidth = 2;
            if(PlotOptionsTable_Active (end,end) == "Filled")
                average_Conc.MarkerFaceColor = str2num(PlotOptionsTable_Active(end,2));
            end
        end

        % Set limits on axes
        xlim(app.ConPlotAxes,[app.time_min_EditField.Value, app.time_max_EditField.Value])
        ylim(app.ConPlotAxes,[app.c_min_EditField.Value, app.c_max_EditField.Value])
        
        % Add Legend
        legend (app.ConPlotAxes,[PlotOptionsTable_Active(:,1)])    
        end

        % Value changed function: Average_DropDown
        function Average_DropDownValueChanged(app, event)
        % Update Parameter Table, Parameter Panel, x-axis of plot
        updateParamPanel(app);

        %Update Plot Options Table
        updatePlotOptTable(app)

        %Update Plots
        UpdatePlotButtonPushed(app,event)      
        end

        % Value changed function: xAxis_DropDown
        function xAxis_DropDownValueChanged(app, event)
        % Update Parameter Table, Parameter Panel, x-axis of plot
        updateParamPanel(app);

        % Update Plot Options Table
        updatePlotOptTable(app)       

        % Update plot, because axis title has already been changed
        UpdatePlotButtonPushed(app,event)
        end

        % Value changed function: delta_p_EditField
        function delta_p_EditFieldValueChanged(app, event)
        % Update b values and mass flow fractions
        app.del_p = app.delta_p_EditField.Value;
        updateParamPanel(app);            
        end

        % Value changed function: alpha_c_EditField, alpha_m_EditField
        function alpha_params_EditFieldValueChanged(app, event)
        app.alpha_c = app.alpha_c_EditField.Value;
        app.alpha_m = app.alpha_m_EditField.Value;
        updateParamPanel(app);            
        end

        % Cell edit callback: paramTable
        function paramTableCellEdit(app, event)
        % Get the table indices for cell being edited
        indices = event.Indices;
        newData = event.NewData;

        % Save the old data
        oldData = app.paramTableDat{indices(1),1};
        
        % Update parameter backup parameter table         
        if(indices(2) == 1)  % If first column changed
        	% Check for duplicate channel numbers
            if (ismember(newData, app.paramTableDat.Channel))
                errTxt = [ 'Channel ' num2str(newData) ' already exists. Choose another channel number.'];
                errordlg(errTxt, 'Input Error')
                newData = oldData;
            end
            app.paramTableDat{indices(1),1} = newData;
            app.paramTable.Data{indices(1),1} = newData;

        elseif(indices(2) == 2)     % If second column changed
        	app.paramTableDat{indices(1),2} = newData;

        else    % If columns > 2 changed 
            if(app.Average_DropDown.Value == "Yes")     % if Averaging = "Yes"
                if(indices(2) == 3)
                    app.paramTableDat{indices(1),5} = newData;
                    app.paramTableDat{indices(1),7} = app.alpha_c*newData^app.alpha_m;
                else
                    app.paramTableDat{indices(1),end} = newData;
                end
            elseif(app.Average_DropDown.Value == "No")       % if Averaging = "No"
                if (indices(2) < 7)
	                app.paramTableDat{indices(1),indices(2)} = newData;
                    if (indices(2) == 5)
                        app.paramTableDat{indices(1),7} = app.alpha_c*newData^app.alpha_m;
                    end
                else
	                app.paramTableDat{indices(1),end} = newData;
                end
            end
        end

        % Update vectors from Parameter Table
        app.v = app.paramTable.Data{:,2};
        app.Include = table2array(app.paramTable.Data(:,end));
        if(app.Average_DropDown.Value == "Yes")
            app.L = app.paramTable.Data{:,3};
        else
            app.M_con = app.paramTable.Data{:,3};
            app.Q = app.paramTable.Data{:,4};
            app.L = app.paramTable.Data{:,5};
            app.t_Tr = app.paramTable.Data{:,6};
        end

        % Update the Parameter Table
        updateParamPanel(app)
        
        % Update Plot Options Table
        updatePlotOptTable(app)            
        end

        % Menu selected function: ThermalProfileMenu
        function ThermalProfileMenuSelected(app, event)
        app.DesignTemperatureProfile_Hndl = TAT08DesignTemperatureProfile;
        end

        % Menu selected function: TracerSelectionMenu
        function TracerSelectionMenuSelected(app, event)
        app.DesignSelectReactiveTr_Hndl = TAT08DesignSelectReactiveTr;
        end

        % Menu selected function: ReactiveTracerMenu
        function ReactiveTracerMenuSelected(app, event)
        app.DesignReactiveTrTest_Hndl = TAT08DesignReactiveTrTest;
        end

        % Menu selected function: ConservativeTracerMenu_2
        function ConservativeTracerMenu_2Selected(app, event)
        app.AnalysisConservativeTrTest_Hndl = TAT08AnalysisConservativeTrTest;
        end

        % Menu selected function: ReactiveTracerMenu_2
        function ReactiveTracerMenu_2Selected(app, event)
        app.AnalysisReactiveTrTest_Hndl = TAT08AnalysisReactiveTrTest;
        end

        % Value changed function: time_max_EditField
        function time_max_EditFieldValueChanged(app, event)
        app.t_max = app.time_max_EditField.Value;  
        end

        % Value changed function: time_min_EditField
        function time_min_EditFieldValueChanged(app, event)
        app.t_min = app.time_min_EditField.Value;     
        end

        % Value changed function: c_min_EditField
        function c_min_EditFieldValueChanged(app, event)
        app.c_min = app.c_min_EditField.Value;
        end

        % Value changed function: c_max_EditField
        function c_max_EditFieldValueChanged(app, event)
        app.c_max = app.c_max_EditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.designConTask = uifigure('Visible', 'off');
            app.designConTask.Name = 'Design Workflow -- Conservative Tracer';
            app.designConTask.Icon = fullfile(pathToMLAPP, 'appdata', 'TATIcon.png');
            app.FileActionsMenu = uimenu(app.designConTask);
            app.FileActionsMenu.Text = 'File Actions';
            app.ReadParametersMenu = uimenu(app.FileActionsMenu);
            app.ReadParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadParametersMenuSelected, true);
            app.ReadParametersMenu.Text = 'Read Parameters';
            app.SaveParametersMenu = uimenu(app.FileActionsMenu);
            app.SaveParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveParametersMenuSelected, true);
            app.SaveParametersMenu.Text = 'Save Parameters';
            app.SavePlotDataMenu = uimenu(app.FileActionsMenu);
            app.SavePlotDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SavePlotDataMenuSelected, true);
            app.SavePlotDataMenu.Text = 'Save Plot Data';
            app.TaskControlMenu = uimenu(app.designConTask);
            app.TaskControlMenu.Text = 'Task Control';
            app.DesignMenu = uimenu(app.TaskControlMenu);
            app.DesignMenu.Text = 'Design';
            app.ConservativeTracerMenu = uimenu(app.DesignMenu);
            app.ConservativeTracerMenu.Enable = 'off';
            app.ConservativeTracerMenu.Text = 'Conservative Tracer';
            app.ThermalProfileMenu = uimenu(app.DesignMenu);
            app.ThermalProfileMenu.MenuSelectedFcn = createCallbackFcn(app, @ThermalProfileMenuSelected, true);
            app.ThermalProfileMenu.Text = 'Thermal Profile';
            app.TracerSelectionMenu = uimenu(app.DesignMenu);
            app.TracerSelectionMenu.MenuSelectedFcn = createCallbackFcn(app, @TracerSelectionMenuSelected, true);
            app.TracerSelectionMenu.Text = 'Tracer Selection';
            app.ReactiveTracerMenu = uimenu(app.DesignMenu);
            app.ReactiveTracerMenu.MenuSelectedFcn = createCallbackFcn(app, @ReactiveTracerMenuSelected, true);
            app.ReactiveTracerMenu.Text = 'Reactive Tracer';
            app.AnalysisMenu = uimenu(app.TaskControlMenu);
            app.AnalysisMenu.Text = 'Analysis';
            app.ConservativeTracerMenu_2 = uimenu(app.AnalysisMenu);
            app.ConservativeTracerMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ConservativeTracerMenu_2Selected, true);
            app.ConservativeTracerMenu_2.Text = 'Conservative Tracer';
            app.ReactiveTracerMenu_2 = uimenu(app.AnalysisMenu);
            app.ReactiveTracerMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ReactiveTracerMenu_2Selected, true);
            app.ReactiveTracerMenu_2.Text = 'Reactive Tracer';
            app.CommonVariablesMenu = uimenu(app.designConTask);
            app.CommonVariablesMenu.Text = 'Common Variables';
            app.ViewMenu = uimenu(app.CommonVariablesMenu);
            app.ViewMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.ViewMenu.Text = 'View';
            app.SendtoMenu = uimenu(app.CommonVariablesMenu);
            app.SendtoMenu.MenuSelectedFcn = createCallbackFcn(app, @SendtoMenuSelected, true);
            app.SendtoMenu.Text = 'Send to';
            app.PullfromMenu = uimenu(app.CommonVariablesMenu);
            app.PullfromMenu.MenuSelectedFcn = createCallbackFcn(app, @PullfromMenuSelected, true);
            app.PullfromMenu.Text = 'Pull from';
            app.ConservativeTracerDesignTaskLabel = uilabel(app.designConTask);
            app.ConservativeTracerDesignTaskLabel.Text = 'Conservative Tracer Design Task';
            app.ParametersPanel = uipanel(app.designConTask);
            app.ParametersPanel.Title = 'Parameters';
            app.xAxisDropDownLabel = uilabel(app.ParametersPanel);
            app.xAxisDropDownLabel.Text = 'x-Axis';
            app.xAxis_DropDown = uidropdown(app.ParametersPanel);
            app.xAxis_DropDown.Items = {'Time', 'Distance'};
            app.xAxis_DropDown.Editable = 'on';
            app.xAxis_DropDown.ValueChangedFcn = createCallbackFcn(app, @xAxis_DropDownValueChanged, true);
            app.xAxis_DropDown.Value = 'Time';
            app.AverageDropDownLabel = uilabel(app.ParametersPanel);
            app.AverageDropDownLabel.Text = 'Average';
            app.Average_DropDown = uidropdown(app.ParametersPanel);
            app.Average_DropDown.Items = {'No', 'Yes'};
            app.Average_DropDown.ValueChangedFcn = createCallbackFcn(app, @Average_DropDownValueChanged, true);
            app.Average_DropDown.Value = 'No';
            app.T_approxoCLabel = uilabel(app.ParametersPanel);
            app.T_approxoCLabel.Interpreter = 'html';
            app.T_approxoCLabel.Text = 'T<sub>res</sub> (<sup>o</sup>C)';
            app.T_res_EditField = uieditfield(app.ParametersPanel, 'numeric');
            app.T_res_EditField.Value = 150;
            app.M_con_EditField = uieditfield(app.ParametersPanel, 'numeric');
            app.M_con_EditField.Visible = 'off';
            app.M_con_EditField.Value = 1;
            app.Q_EditField = uieditfield(app.ParametersPanel, 'numeric');
            app.Q_EditField.Visible = 'off';
            app.Q_EditField.Value = 1;
            app.alpha_ParamLabel = uilabel(app.ParametersPanel);
            app.alpha_ParamLabel.Interpreter = 'html';
            app.alpha_ParamLabel.Text = 'Dispersivity (&alpha; = c*L<sup>m</sup>)';
            app.cEditFieldLabel = uilabel(app.ParametersPanel);
            app.cEditFieldLabel.Text = 'c';
            app.alpha_c_EditField = uieditfield(app.ParametersPanel, 'numeric');
            app.alpha_c_EditField.ValueChangedFcn = createCallbackFcn(app, @alpha_params_EditFieldValueChanged, true);
            app.alpha_c_EditField.Value = 0.21;
            app.mEditFieldLabel = uilabel(app.ParametersPanel);
            app.mEditFieldLabel.Text = 'm';
            app.alpha_m_EditField = uieditfield(app.ParametersPanel, 'numeric');
            app.alpha_m_EditField.ValueChangedFcn = createCallbackFcn(app, @alpha_params_EditFieldValueChanged, true);
            app.alpha_m_EditField.Value = 0.51;
            app.paramTable = uitable(app.ParametersPanel);
            app.paramTable.ColumnName = {'Channel'; 'v'; 'M_con'; 'Q'; 'L'; 'alpha'; 'b'; 'Include'};
            app.paramTable.RowName = {};
            app.paramTable.ColumnEditable = [true true true true true false false];
            app.paramTable.CellEditCallback = createCallbackFcn(app, @paramTableCellEdit, true);
            app.paramTable.Tooltip = {'v: Velocity (m/s)'; 'M_con: Tracer mass (kg)'; 'Q: Flow rate (m^3/s)'; 'L: Path length (m)'; 't_Tr: Transport time (days)'; 'alpha: dispersivity (m)'; 'b: Aperture (m)'; 'f: fluid mass fraction (-)'};
            app.pDeltappkPaLabel = uilabel(app.ParametersPanel);
            app.pDeltappkPaLabel.Interpreter = 'html';
            app.pDeltappkPaLabel.Text = '&Delta;p (kPa)';
            app.delta_p_EditField = uieditfield(app.ParametersPanel, 'numeric');
            app.delta_p_EditField.ValueChangedFcn = createCallbackFcn(app, @delta_p_EditFieldValueChanged, true);
            app.delta_p_EditField.Tooltip = {'Used to estimate fracture aperture'};
            app.delta_p_EditField.Value = 9.81;
            app.AddChannelButton = uibutton(app.ParametersPanel, 'push');
            app.AddChannelButton.ButtonPushedFcn = createCallbackFcn(app, @AddChannelButtonPushed, true);
            app.AddChannelButton.Text = 'Add Channel';
            app.DeleteChannelsButton = uibutton(app.ParametersPanel, 'push');
            app.DeleteChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteChannelsButtonPushed, true);
            app.DeleteChannelsButton.Text = 'Delete Channels';
            app.NoChannelsEditFieldLabel = uilabel(app.ParametersPanel);
            app.NoChannelsEditFieldLabel.Text = 'No. Channels';
            app.NoChannelsEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.NoChannelsEditField.Editable = 'off';
            app.M_con_Label = uilabel(app.ParametersPanel);
            app.M_con_Label.Visible = 'off';
            app.M_con_Label.Interpreter = 'html';
            app.M_con_Label.Text = 'M<sub>con</sub> (kg)';
            app.Q_Label = uilabel(app.ParametersPanel);
            app.Q_Label.Visible = 'off';
            app.Q_Label.Text = 'Q (m^3/s)';
            app.Time2_Label = uilabel(app.ParametersPanel);
            app.Time2_Label.Visible = 'off';
            app.Time2_Label.Text = 'Time (days)';
            app.Time_EditField = uieditfield(app.ParametersPanel, 'numeric');
            app.Time_EditField.Visible = 'off';
            app.Time_EditField.Value = 30;
            app.PlottingOptionsPanel = uipanel(app.designConTask);
            app.PlottingOptionsPanel.Title = 'Plotting Options';
            app.PlotOptionsTable = uitable(app.PlottingOptionsPanel);
            app.PlotOptionsTable.ColumnName = {'Channel'; 'Color (RGB)'; 'Line Width'; 'Style'};
            app.PlotOptionsTable.RowName = {};
            app.PlotOptionsTable.ColumnEditable = [false true true true];
            app.PlotOptionsTable.CellSelectionCallback = createCallbackFcn(app, @PlotOptionsTableCellSelection, true);
            app.Time_Label = uilabel(app.PlottingOptionsPanel);
            app.Time_Label.Text = 'Time';
            app.c_Label = uilabel(app.PlottingOptionsPanel);
            app.c_Label.Text = 'Conc.';
            app.time_min_EditField = uieditfield(app.PlottingOptionsPanel, 'numeric');
            app.time_min_EditField.ValueChangedFcn = createCallbackFcn(app, @time_min_EditFieldValueChanged, true);
            app.c_min_EditField = uieditfield(app.PlottingOptionsPanel, 'numeric');
            app.c_min_EditField.ValueChangedFcn = createCallbackFcn(app, @c_min_EditFieldValueChanged, true);
            app.time_max_EditField = uieditfield(app.PlottingOptionsPanel, 'numeric');
            app.time_max_EditField.ValueChangedFcn = createCallbackFcn(app, @time_max_EditFieldValueChanged, true);
            app.time_max_EditField.Value = 30;
            app.c_max_EditField = uieditfield(app.PlottingOptionsPanel, 'numeric');
            app.c_max_EditField.ValueChangedFcn = createCallbackFcn(app, @c_max_EditFieldValueChanged, true);
            app.c_max_EditField.Value = Inf;
            app.Min_Label = uilabel(app.PlottingOptionsPanel);
            app.Min_Label.Text = 'Min';
            app.Max_Label = uilabel(app.PlottingOptionsPanel);
            app.Max_Label.Text = 'Max';
            app.PlotPanel = uipanel(app.designConTask);
            app.PlotPanel.Title = 'Plot';
            app.ConPlotAxes = uiaxes(app.PlotPanel);
            title(app.ConPlotAxes, 'Conservative Tracer')
            xlabel(app.ConPlotAxes, 'Time (days)')
            ylabel(app.ConPlotAxes, 'Concentration (ug/kg)')
            zlabel(app.ConPlotAxes, 'Z')
            app.UpdatePlotButton = uibutton(app.PlotPanel, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton.Text = 'Update Plot Using Current Plot Options';
            app.EqHelpButton = uibutton(app.designConTask, 'push');
            app.EqHelpButton.ButtonPushedFcn = createCallbackFcn(app, @EqHelpButtonPushed, true);
            app.EqHelpButton.Text = 'Eq. Help';
            app.Image = uiimage(app.designConTask);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'appdata', 'ConservEq.png');
            % Show the figure after all components are created
            app.designConTask.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TAT08DesignConservativeTrTest

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.designConTask)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.designConTask)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.designConTask)
        end
    end
end