classdef TAT08DesignReactiveTrTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileActionsMenu                 matlab.ui.container.Menu
        ReadparametersMenu              matlab.ui.container.Menu
        SaveparametersMenu              matlab.ui.container.Menu
        SaveplotdataMenu                matlab.ui.container.Menu
        TaskControlMenu                 matlab.ui.container.Menu
        DesignMenu                      matlab.ui.container.Menu
        desConservativetracerMenu       matlab.ui.container.Menu
        desTemperatureprofilesMenu      matlab.ui.container.Menu
        desReactivetracerselectionMenu  matlab.ui.container.Menu
        desReactivetracerMenu           matlab.ui.container.Menu
        AnalysisMenu                    matlab.ui.container.Menu
        analConservativetracerMenu      matlab.ui.container.Menu
        analReactivetracerMenu          matlab.ui.container.Menu
        CommonVariablesMenu             matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        SendtoMenu                      matlab.ui.container.Menu
        PullfromMenu                    matlab.ui.container.Menu
        OutputPanel                     matlab.ui.container.Panel
        OutputTable                     matlab.ui.control.Table
        AverageRateCoefficientsLabel    matlab.ui.control.Label
        PlotPanel                       matlab.ui.container.Panel
        Temp_Axes                       matlab.ui.control.UIAxes
        ConPlot_Axes                    matlab.ui.control.UIAxes
        Eq                              matlab.ui.control.Image
        EqHelpButton                    matlab.ui.control.Button
        PlotOptionsPanel                matlab.ui.container.Panel
        PlotOptionsTable                matlab.ui.control.Table
        UpdatePlotButton                matlab.ui.control.Button
        PlotOptPnlCurveChoiceRadioBtn   matlab.ui.container.ButtonGroup
        PlotOptPnlCurveChoiceBtnRatio   matlab.ui.control.RadioButton
        PlotOptPnlCurveChoiceBtnReaAndCon  matlab.ui.control.RadioButton
        PlotOptPnlCurveChoiceBtnRea     matlab.ui.control.RadioButton
        PlotOptions_Max_Label           matlab.ui.control.Label
        PlotOptions_Min_Label           matlab.ui.control.Label
        PlotOptions_t_Label             matlab.ui.control.Label
        PlotOptions_c_Label             matlab.ui.control.Label
        PlotOptions_T_Label             matlab.ui.control.Label
        time_min_EditField              matlab.ui.control.NumericEditField
        time_max_EditField              matlab.ui.control.NumericEditField
        c_min_EditField                 matlab.ui.control.NumericEditField
        c_max_EditField                 matlab.ui.control.NumericEditField
        T_min_EditField                 matlab.ui.control.NumericEditField
        T_max_EditField                 matlab.ui.control.NumericEditField
        ParamPanel                      matlab.ui.container.Panel
        Time_Label                      matlab.ui.control.Label
        time_EditField                  matlab.ui.control.NumericEditField
        DispersivityalphacLsupmsupLabel  matlab.ui.control.Label
        alpha_m_Label                   matlab.ui.control.Label
        alphs_c_Label                   matlab.ui.control.Label
        alpha_m_EditField               matlab.ui.control.NumericEditField
        alpha_c_EditField               matlab.ui.control.NumericEditField
        delta_p_Label                   matlab.ui.control.Label
        delta_p_EditField               matlab.ui.control.NumericEditField
        UpdatePlotButton_2              matlab.ui.control.Button
        M_rea_Label                     matlab.ui.control.Label
        M_rea_EditField                 matlab.ui.control.NumericEditField
        M_con_Label                     matlab.ui.control.Label
        M_con_EditField                 matlab.ui.control.NumericEditField
        Q_Label                         matlab.ui.control.Label
        Q_EditField                     matlab.ui.control.NumericEditField
        Arr_Ea_Label                    matlab.ui.control.Label
        Arr_Ea_EditField                matlab.ui.control.NumericEditField
        Arr_A_Label                     matlab.ui.control.Label
        Arr_A_EditField                 matlab.ui.control.NumericEditField
        paramTable                      matlab.ui.control.Table
        t_op_Label                      matlab.ui.control.Label
        t_op_EditField                  matlab.ui.control.NumericEditField
        cp_r_EditField                  matlab.ui.control.NumericEditField
        cp_r_Label                      matlab.ui.control.Label
        rho_r_EditField                 matlab.ui.control.NumericEditField
        rho_r_Label                     matlab.ui.control.Label
        T_inj_EditField                 matlab.ui.control.NumericEditField
        T_inj_Label                     matlab.ui.control.Label
        T_res_EditField                 matlab.ui.control.NumericEditField
        T_res_Label                     matlab.ui.control.Label
        nChn_EditField                  matlab.ui.control.NumericEditField
        DeleteChannel_Button            matlab.ui.control.Button
        AddChannel_Button               matlab.ui.control.Button
        Average_Dropdown                matlab.ui.control.DropDown
        AverageConcentrationsLabel      matlab.ui.control.Label
        xAxis_Dropdown                  matlab.ui.control.DropDown
        xAxis_Dropdown_Label            matlab.ui.control.Label
        Channel_No_Label                matlab.ui.control.Label
        ReaTracerLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Startup *******************************************************************************************
        TATPath                         % Path to Tracer Analysis Toolbox
        TATName                         % Name of Tracer Analysis Toolbox file
        TAText                          % Extension for Tracer Analysis Toolbox file
        MLDefaultColors                 % Vector of RGB codes for line default colors us in Matlab
        hScale                          % Horizontal scaling factor for monitor resolution differences
        vScale                          % Vertical scaling factor for monitor resolution differences

        CommonVars_Hndl                  
        DesignConservativeTrTest_Hndl
        DesignTemperatureProfile_Hndl
        DesignSelectReactiveTr_Hndl
        AnalysisConservativeTrTest_HndlParamPnlSumTDropDown
        AnalysisReactiveTrTest_Hndl        
        
        % Parameters Panel
        paramTableCheckBox              % Checkboxes used in the Table
        nChn                            % Number of channels
        paramTableDat                   % Data underlying the plot parameters table
        paramTableDatOld                % Backup of parameter table data
        Average_DropdownOld             % Backup of Sum values dropdown box value
        xAxis_DropDownOld               % Back up of Axis dropdown box value
        del_p                           % Pressure drop between injection point and extraction well
        alpha_c                         % Parameter for calculating dispersivity from alpha = c*L^m
        alpha_m                         % Parameter for calculating dispersivity from alpha = c*L^m
        alpha                           % dispersivity (m)
        mu_f                            % viscosity of liquid water
        b                               % apertures (m)
        v                               % Velocity (m/s)
        M_con                           % Mass of conservative tracer (kg)
        M_rea                           % Mass of reactive tracer (kg)
        Q                               % Pumping rate in m^3/s
        L                               % Path length (m)
        lambda_r                        % Thermal conductivity of the rock mass
        t_op                            % Operating time (days)
        A                               % Pre-exponential tern of Arrehenius equation
        Ea                              % Acivation energy
        Q_frac                          % Mass fraction of flow in each channel
        include                         % Vector of values to identify if channel is to be included in calculations
        T_res                           % Initial reservoir temperature
        T_inj                           % Temperature of injection fluid
        time                            % Time since initiation of tracer test
        y_TP                            % Temperature profile data
        C_con_mean                      % Calculated mean concentration of conservative tracer
        C_con                           % Calculated concentrations of conservative tracer
        C_ratio                         % Calculated ration of reactive to conservative tracer
        C_ratio_mean                     % Calculated ratio of means of reactive and conservative tracers

        % Plot Options Panel
        PlotOpt_LineStyleList           % Categorical list of options for line style
        InitialColor                    % Initial line color
        PlotOptTableData                % Data for plot options table
        PointStyleList                  % Categorical list of options for point style for mean T when x-axis is distance
        npts                            % Number of points to be calculated per curve
        t_min                           % Miniumum time used in plot
        t_max                           % Maxiumum time used in plot
        c_min                           % Minimum concentration used in plot
        c_max                           % Maximum concentration used in plot


        % Plot Panel
        xRTValue                        % X values used in plotting %MAP delete
        yRTCTVar                        % Conservative tracer concentration  %MAP delete
        C_rea                           % Reactive tracer concentration
        C_rea_mean                      % Average of the reactive tracer concentrations over the channels
        RTSumCurve                      % Average concentration of reactive tracer
        CTSumCurve                      % Average concentration of conservative tracer
        xRTTPValue                      % Relative distance values for temperature profile plot
        ConPlots                        % Object containing plots of conservative tracer concentration versus time/relative distance
        ReaPlots                        % Object containing plots of reactive tracer concentration versus time/relative distance
        RatioPlots                      % Object containing plots of ratio of reactive tracer to conservative tracer
        TempPlots                       % Object containing plots of the temperature versus relative distance
    end
    
    methods (Access = private)
        
        function TATSetup(app)
        % SETUP: establishes basic information about the app
        % including location, screen size, default plot colors
            % Get location of app
            [app.TATPath, app.TATName, app.TAText] = fileparts(mfilename('fullpath'));

            % Get Screen size and define scaling factors
            screenSize = get(groot,'ScreenSize');
            if screenSize(3) < app.UIFigure.Position(3)
               app.hScale = screenSize(3)/2560;
            else 
               app.hScale = 1;
            end 
            if screenSize(4) < app.UIFigure.Position(4)
               app.vScale = screenSize(4)/1440;
            else 
               app.vScale = 1;
            end   

            % Add path to functions
            addPathNames = strcat(app.TATPath,"\TAT_Fcts");
            addpath(genpath(addPathNames))

            % Set Matlab's default colors for lines
            app.MLDefaultColors = ["0.000, 0.447, 0.741" "0.850, 0.325, 0.098" "0.929, 0.694, 0.125" "0.494, 0.184, 0.556" "0.466, 0.674, 0.188" ...
                "0.301, 0.745, 0.933" "0.635, 0.078, 0.184","0.000 0.000 1.000","0.000 0.500 0.000","1.000 0.000 0.000" "0.000 0.000 0.000"]; 
             % Inialize Parameter Table and Plot Options Table
             ParamTableInitial(app)
             PlotOpt_Initial(app)

             % Initialize lower temperature in plot
             app.T_min_EditField.Value = app.T_inj_EditField.Value;
 
             % Set the number of data points to be plotted
             app.npts = 101;
        end
        
        function ParamTableInitial(app)
            % Header names
            app.paramTable.ColumnName = ["Channel", "v (m/s)", "L (m)", "lambda_r","Q","M_con","M_rea","t_op","A","Ea","alpha (m)", "b (m)","Fraction","Include"];

            % Initialize Variable
            app.paramTableCheckBox = {true; true; true; true; true; true; true; true; true; true};
            Channel_init = 1;
            v_init = 1e-3;
            M_c_init = 100;
            M_r_init = 100;
            Q_init = 1;
            L_init = 1000;
            lambda_init = 2.5;
            t_op_init = 3650;
            time_init = 30;
            A_init = app.Arr_A_EditField.Value;
            Ea_init = app.Arr_Ea_EditField.Value;
            Q_frac_init = 1;

            % Calculate alpha and b
            app.mu_f = muH2O_LCalc(app.T_res_EditField.Value);
            app.alpha_c = app.alpha_c_EditField.Value;
            app.alpha_m = app.alpha_m_EditField.Value;
            app.alpha = app.alpha_c*L_init^app.alpha_m;
            app.b = sqrt(v_init.*L_init.*12*app.mu_f./(app.delta_p_EditField.Value)); 

            % Update Tables
            app.paramTableDat = table(Channel_init, v_init, L_init, lambda_init, Q_init, M_c_init, M_r_init, t_op_init, time_init, A_init, Ea_init, app.alpha, app.b, Q_frac_init);
            app.paramTableDat = [app.paramTableDat app.paramTableCheckBox(1,1)];
            app.paramTable.Data = [app.paramTableDat(1,[1:8,10:14]) app.paramTableCheckBox(1,1)];
            app.nChn = 1;
            app.nChn_EditField.Value = app.nChn;
            mystyle1 = uistyle('BackgroundColor', [0.9 0.9 0.9]);
            for i = 1:app.nChn
                addStyle(app.paramTable,mystyle1,'cell',[i,11]);
                addStyle(app.paramTable,mystyle1,'cell',[i,12]);
                addStyle(app.paramTable,mystyle1,'cell',[i,13]);
            end
             
            % Name table variables
            TableVarNamesAll = {'Channel','v','L','lambda_r','Q','M_con','M_rea','t_op','time','A','Ea','alpha','b','Q_frac','Include'};
            app.paramTableDat.Properties.VariableNames = TableVarNamesAll ;

            % Define vector values from table
            TempArray = table2array(app.paramTableDat(:,2:15)) ;
            TempCell = num2cell(TempArray,1) ;
            [app.v,app.L,app.lambda_r,app.Q,app.M_con,app.M_rea,...
                app.t_op,app.time,app.A,app.Ea,app.alpha,app.b,app.Q_frac,app.include] =  TempCell{:};

            app.T_res = app.T_res_EditField.Value;
            app.del_p = app.delta_p_EditField.Value;

            % Define min/max values for axes
            app.t_min = app.time_min_EditField.Value;
            app.t_max = app.time_max_EditField.Value;
            app.c_min = app.c_min_EditField.Value;
            app.c_max = app.c_max_EditField.Value;
        end
        
        function PlotOptLineTableCellSelection(app,event)
        % Provides color selection when box in color column is clicked
        indices = event.Indices;
        if ~isempty(indices) %if not clicked below active cells
            if(indices(2) == 2) %if 2nd column cell selected
                colorOld = str2num(app.PlotOptionsTable.Data{indices(1),2}{1});
                colorNew = num2str(round(uisetcolor(colorOld),3));
                if ~strcmp(colorNew,'0') %if operation not canceled
                    app.PlotOptionsTable.Data{indices(1),2}{1} = colorNew;
                    myColorStyle = uistyle("BackgroundColor",colorNew);
                    addStyle(app.PlotOptionsTable,myColorStyle,'cell',[indices(1),indices(2)]);          
                end
            end
        end
        end
                
        function updatePlotOptTable(app)
        % Update Plot Options Table

        % Define columns
        col1 = {'0';'0';'0';'0';'0';'0';'0';'0';'0';'0';'0'};
        for i = 1:app.nChn 
            col1{i} = app.paramTable.Data{i,1};
        end
         
        col2 = {app.MLDefaultColors(1);app.MLDefaultColors(2);app.MLDefaultColors(3);app.MLDefaultColors(4);app.MLDefaultColors(5);app.MLDefaultColors(6);...
                app.MLDefaultColors(7);app.MLDefaultColors(8);app.MLDefaultColors(9);app.MLDefaultColors(10);app.MLDefaultColors(11)};
        if(app.Average_Dropdown.Value == "Yes")         % If averaging concentrations set color for the average
            col2{app.nChn+1,1} = app.MLDefaultColors(11);   
        end                                                 
         
        col3 = {2;2;2;2;2;2;2;2;2;2;2};
        if(app.xAxis_Dropdown.Value == "Distance" && app.Average_Dropdown.Value == "Yes")    % If plotting against distance, assign style
            col3{app.nChn+1,1} = 8;                                                                     
        end                                                                                             

        lStyle = app.PlotOpt_LineStyleList(1); 
        col4 = {lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle;lStyle};
         
        app.PlotOptTableData = table(col1, col2, col3, col4);
        app.PlotOptionsTable.Data = app.PlotOptTableData(1:app.nChn,:);

        % Update last line in Plot Options Table
        if (app.Average_Dropdown.Value == "Yes")
            if(app.xAxis_Dropdown.Value == "Time")
                % Define newline for information for the Sum curve
                col1 = {'Average'};
                col2 = app.MLDefaultColors(11);
                col3 = {2};
                col4 = {app.PlotOpt_LineStyleList(1)};
                newline = table(col1, col2, col3, col4);
            elseif (app.xAxis_Dropdown.Value == "Distance")
                % Define newline for information for the Sum curve
                col1 = {'Average'};
                col2 = app.MLDefaultColors(11);
                col3 = {8};
                col4 = {app.PointStyleList(1)};
                newline = table(col1, col2, col3, col4);
            end
            % Append new line to Plot Option table and display
            app.PlotOptionsTable.Data  = [app.PlotOptionsTable.Data; newline];
        end

        % Change background color of column 2
        endLoop = app.nChn;
        if(app.Average_Dropdown.Value == "Yes")
            endLoop = app.nChn+1;
        end
        for i = 1:endLoop
            myColorStyle = uistyle("BackgroundColor",app.PlotOptionsTable.Data{i,2}{1},"FontColor",[0.94 0.94 0.94]);
            addStyle(app.PlotOptionsTable,myColorStyle,'cell',[i,2]);
        end
                 
        end

        % Check for potential input error related to the plot        
        function TPPlotInputErrorCheck(app)
            
        end
        
        function updateRTParamPnl(app)
        % Updates parameter panel, and plot axis with changes in parameters
        % Update alpha %MAP
        app.alpha = app.alpha_c_EditField.Value .* app.paramTableDat{:,3} .^ app.alpha_m_EditField.Value ;
        app.paramTableDat{:,12} = app.alpha ;

        % Update b values and mass flow fractions
        [app.b, app.Q_frac] = updateFraction(app,app.v,app.L,app.include,app.T_res,app.del_p, app.Average_Dropdown.Value);
        app.paramTableDat{:,13} = app.b;
        app.paramTableDat{:,14} = app.Q_frac;

        % Display Table
        if (lower(app.Average_Dropdown.Value) == "no" && lower(app.xAxis_Dropdown.Value) == "time")
            app.paramTable.Data = app.paramTableDat(:,[1:8,10:15]);
            app.paramTable.ColumnName = ["Channel", "v (m/s)", "L (m)", "lambda_r","Q","M_con","M_rea","t_op","A","Ea","alpha (m)", "b (m)","Fraction","Include"];
            app.paramTable.ColumnEditable = [true true true true true true true true true true false false false true];
            app.paramTable.BackgroundColor = 'w';
            mystyle1 = uistyle('BackgroundColor', [0.9 0.9 0.9]);
            mystyle2 = uistyle('BackgroundColor', [1 1 1]);
            for i = 1:app.nChn
                addStyle(app.paramTable,mystyle2,'cell',[i,5]);
                addStyle(app.paramTable,mystyle2,'cell',[i,6]);
                addStyle(app.paramTable,mystyle2,'cell',[i,7]);
                addStyle(app.paramTable,mystyle2,'cell',[i,14]);
                addStyle(app.paramTable,mystyle1,'cell',[i,11]);
                addStyle(app.paramTable,mystyle1,'cell',[i,12]);
                addStyle(app.paramTable,mystyle1,'cell',[i,13]);
            end
        elseif (lower(app.Average_Dropdown.Value) == "yes" && lower(app.xAxis_Dropdown.Value) == "time")
            app.paramTable.Data = app.paramTableDat(:,[1:4,12:15]);
            app.paramTable.ColumnName = ["Channel", "v (m/s)", "L (m)", "lambda_r","alpha (m)", "b (m)","Fraction","Include"];
            app.paramTable.ColumnEditable = [true true true true false false false true];
            app.paramTable.BackgroundColor = 'w';
            mystyle1 = uistyle('BackgroundColor', [0.9 0.9 0.9]);
            mystyle2 = uistyle('BackgroundColor', [1 1 1]);
            for i = 1:app.nChn 
                addStyle(app.paramTable,mystyle1,'cell',[i,5]);
                addStyle(app.paramTable,mystyle1,'cell',[i,6]);
                addStyle(app.paramTable,mystyle1,'cell',[i,7]);
            end
         elseif (lower(app.Average_Dropdown.Value) == "yes" && lower(app.xAxis_Dropdown.Value) == "distance")
            app.paramTable.Data = app.paramTableDat(:,[1:4,12:15]);
            app.paramTable.ColumnName = ["Channel", "v (m/s)", "L (m)", "lambda_r","alpha (m)", "b (m)","Fraction","Include"];
            app.paramTable.ColumnEditable = [true true true true false false false true];
            app.paramTable.BackgroundColor = 'w';
            mystyle1 = uistyle('BackgroundColor', [0.9 0.9 0.9]);
            mystyle2 = uistyle('BackgroundColor', [1 1 1]);
            for i = 1:app.nChn 
                addStyle(app.paramTable,mystyle1,'cell',[i,5]);
                addStyle(app.paramTable,mystyle1,'cell',[i,6]);
                addStyle(app.paramTable,mystyle1,'cell',[i,7]);
            end
         elseif (lower(app.Average_Dropdown.Value) == "no" && lower(app.xAxis_Dropdown.Value) == "distance")
            app.paramTable.Data = app.paramTableDat(:,1:15);
            app.paramTable.ColumnName = ["Channel", "v (m/s)", "L (m)", "lambda_r","Q","M_con","M_rea","t_op","Time","A","Ea","alpha (m)", "b (m)","Fraction","Include"];
            app.paramTable.ColumnWidth = {60,'auto','auto','auto','auto','auto','auto','auto','auto','auto','auto','auto','auto','auto',56};
            app.paramTable.ColumnEditable = [true true true true true true true true true true true false false false true];
            app.paramTable.BackgroundColor = 'w';
            mystyle1 = uistyle('BackgroundColor', [0.9 0.9 0.9]);
            mystyle2 = uistyle('BackgroundColor', [1 1 1]);
            for i = 1:app.nChn
                addStyle(app.paramTable,mystyle2,'cell',[i,5]);
                addStyle(app.paramTable,mystyle2,'cell',[i,6]);
                addStyle(app.paramTable,mystyle2,'cell',[i,7]);
                addStyle(app.paramTable,mystyle2,'cell',[i,11]);
                addStyle(app.paramTable,mystyle1,'cell',[i,12]);
                addStyle(app.paramTable,mystyle1,'cell',[i,13]);
                addStyle(app.paramTable,mystyle1,'cell',[i,14]);
            end

        end

        % Set edit fields visibility 
        %MAP edited so that editfields won't change unexpectedly
        if(app.Average_Dropdown.Value == "Yes" && app.xAxis_Dropdown.Value == "Time")
            app.Q_EditField.Visible = "on";        % Pumping Rate
            app.Q_Label.Visible = 'on';  
            %MAP app.Q_EditField.Value = app.paramTableDat{1,5};

            app.M_con_EditField.Visible = 'on';    % Mass of conservative tracer
            app.M_con_Label.Visible = 'on';
            %MAP app.M_con_EditField.Value = app.paramTableDat{1,6};
            
            app.M_rea_EditField.Visible = 'on';    % Mass of conservative tracer
            app.M_rea_Label.Visible = 'on';
            %MAP app.M_rea_EditField.Value = app.paramTableDat{1,7};

            app.t_op_EditField.Visible = 'on';     % System operating time
            app.t_op_Label.Visible = 'on';
            %MAP app.t_op_EditField.Value = app.paramTableDat{1,8};

            app.time_EditField.Visible = 'off';     % Time since initiation of tracer test
            app.Time_Label.Visible = 'off';
            %MAP app.time_EditField.Value = app.paramTableDat{1,9};

            app.Arr_A_EditField.Visible = 'on';    % Pre-exponential term in Arrenius Eq
            app.Arr_A_Label.Visible = 'on';
            %MAP app.Arr_A_EditField.Value = app.paramTableDat{1,10};

            app.Arr_Ea_EditField.Visible = 'on';   % Activation energy
            app.Arr_Ea_Label.Visible = 'on';
            %MAP app.Arr_Ea_EditField.Value = app.paramTableDat{1,11};
            
        elseif(app.Average_Dropdown.Value == "No" && app.xAxis_Dropdown.Value == "Time")
            app.Q_EditField.Visible = "off";        % Pumping Rate
            app.Q_Label.Visible = 'off';  
            %MAP app.Q_EditField.Value = app.paramTableDat{1,5};

            app.M_con_EditField.Visible = 'off';    % Mass of conservative tracer
            app.M_con_Label.Visible = 'off';
            %MAP app.M_con_EditField.Value = app.paramTableDat{1,6};
            
            app.M_rea_EditField.Visible = 'off';    % Mass of conservative tracer
            app.M_rea_Label.Visible = 'off';
            %MAP pp.M_rea_EditField.Value = app.paramTableDat{1,7};

            app.t_op_EditField.Visible = 'off';     % System operating time
            app.t_op_Label.Visible = 'off';
            %MAP app.t_op_EditField.Value = app.paramTableDat{1,8};

            app.time_EditField.Visible = 'off';     % Time since initiation of tracer test
            app.Time_Label.Visible = 'off';
            %MAP app.time_EditField.Value = app.paramTableDat{1,9};

            app.Arr_A_EditField.Visible = 'off';    % Pre-exponential term in Arrenius Eq
            app.Arr_A_Label.Visible = 'off';
            %MAP app.Arr_A_EditField.Value = app.paramTableDat{1,10};

            app.Arr_Ea_EditField.Visible = 'off';   % Activation energy
            app.Arr_Ea_Label.Visible = 'off';
            %MAP app.Arr_Ea_EditField.Value = app.paramTableDat{1,11}

        elseif (app.Average_Dropdown.Value == "No" && app.xAxis_Dropdown.Value == "Distance") 
            app.Q_EditField.Visible = "off";        % Pumping Rate
            app.Q_Label.Visible = 'off';  
            %MAP app.Q_EditField.Value = app.paramTableDat{1,5};

            app.M_con_EditField.Visible = 'off';    % Mass of conservative tracer
            app.M_con_Label.Visible = 'off';
            %MAP app.M_con_EditField.Value = app.paramTableDat{1,6};
            
            app.M_rea_EditField.Visible = 'off';    % Mass of conservative tracer
            app.M_rea_Label.Visible = 'off';
            %MAP app.M_rea_EditField.Value = app.paramTableDat{1,7};

            app.t_op_EditField.Visible = 'off';     % System operating time
            app.t_op_Label.Visible = 'off';
            %MAP app.t_op_EditField.Value = app.paramTableDat{1,8};

            app.time_EditField.Visible = 'off';     % Time since initiation of tracer test
            app.Time_Label.Visible = 'off';
            %MAP app.time_EditField.Value = app.paramTableDat{1,9};

            app.Arr_A_EditField.Visible = 'off';    % Pre-exponential term in Arrenius Eq
            app.Arr_A_Label.Visible = 'off';
            %MAP app.Arr_A_EditField.Value = app.paramTableDat{1,10};

            app.Arr_Ea_EditField.Visible = 'off';   % Activation energy
            app.Arr_Ea_Label.Visible = 'off';
            app.Arr_Ea_EditField.Value = app.paramTableDat{1,11};
            %MAP app.Arr_Ea_EditField.Value = app.paramTableDat{1,11};

        elseif (app.Average_Dropdown.Value == "Yes" && app.xAxis_Dropdown.Value == "Distance")
            app.Q_EditField.Visible = "on";        % Pumping Rate
            app.Q_Label.Visible = 'on';  
            %MAP app.Q_EditField.Value = app.paramTableDat{1,5};

            app.M_con_EditField.Visible = 'on';    % Mass of conservative tracer
            app.M_con_Label.Visible = 'on';
            %MAP app.M_con_EditField.Value = app.paramTableDat{1,6};
            
            app.M_rea_EditField.Visible = 'on';    % Mass of conservative tracer
            app.M_rea_Label.Visible = 'on';
            %MAP app.M_rea_EditField.Value = app.paramTableDat{1,7};

            app.t_op_EditField.Visible = 'on';     % System operating time
            app.t_op_Label.Visible = 'on';
            %MAP app.t_op_EditField.Value = app.paramTableDat{1,8};

            app.time_EditField.Visible = 'on';     % Time since initiation of tracer test
            app.Time_Label.Visible = 'on';
            %MAP app.time_EditField.Value = app.paramTableDat{1,9};

            app.Arr_A_EditField.Visible = 'on';    % Pre-exponential term in Arrenius Eq
            app.Arr_A_Label.Visible = 'on';
            %MAP app.Arr_A_EditField.Value = app.paramTableDat{1,10};

            app.Arr_Ea_EditField.Visible = 'on';   % Activation energy
            app.Arr_Ea_Label.Visible = 'on';
            %MAP app.Arr_Ea_EditField.Value = app.paramTableDat{1,11};
        end
        end
        
        function ytp = TP_calc(app)
        % TTProfileCalc: Setup temp. profile calculation and call routine to do calculation
            
            % Get the temperatures, calculate fluid heat capacity and
            % density, get rock heat density and heat capacity
            app.T_res = app.T_res_EditField.Value;
            app.T_inj = app.T_inj_EditField.Value;
            rho_f = rhoH2OCalc(app.T_res);
            cp_f = cpH2OCalc(app.T_res);
            rho_r = app.rho_r_EditField.Value;
            cp_r = app.cp_r_EditField.Value;
            
            % Get Common parameters from temperature profile parameter table
            v_Active = table2array(app.paramTableDat(app.paramTableDat.Include == true,2));
            L_Active = table2array(app.paramTableDat(app.paramTableDat.Include == true,3));
            lambda_r_Active = table2array(app.paramTableDat(app.paramTableDat.Include == true,4));
            b_Active = table2array(app.paramTableDat(app.paramTableDat.Include == true,13));
            include_Active = table2array(app.paramTableDat(app.paramTableDat.Include == true,end));
            
            % Get x-axis values and Path Lengths
	        xr = linspace(0,1,app.npts);
            app.xRTTPValue = linspace(0, 1, app.npts);
            if (app.Average_Dropdown.Value=="No")
               time_Active = table2array(app.paramTableDat(app.paramTableDat.Include == true,8));
            elseif (app.Average_Dropdown.Value=="Yes")
               maxVal =  size(v_Active,1);
               time_Active(1:maxVal,1) = app.t_op_EditField.Value;
            end
            
            % Calculate the temperature profile for all curves in the parameter table
            ytp=TempProfile_CDP(lambda_r_Active,L_Active,xr,v_Active,b_Active,cp_f,cp_r,rho_f,rho_r,time_Active,app.T_res,app.T_inj);
        end
        
        function [b,Q_frac] = updateFraction(app,v,L,include,T_res,del_p,averageTxtVal)
            % Get data
            app.mu_f = muH2O_LCalc(T_res);  % Calculate viscosity of liquid water in kPa.s

            % Calculate apertures
            b = sqrt(v .* L  .* 12 .* app.mu_f ./ del_p) ;   
            
            % Calculate fractional flows in channels
            if(lower(averageTxtVal) == "yes") 
                Q_frac = QFracCalc(v,b,include);
            else
                Q_frac(1:app.nChn,1) = 1;
            end
        end        

 % %MAP The following function is never called. Appears to be obsolete       
 %        function y = Con_Calc(app)
 %        % Con_Calc: calculates concentration of conservative tracer
 %        % Inputs velocity(V) mass of tracer (M), extraction rate (Q),
 %        % dispersity (alpha), and distance between injection and extraction (L)
 % 
 %            % Get the temperature and calculate fluid density
 %            tempC = app.T_res_EditField.Value;
 %            rho_f = rhoH2OCalc(tempC);
 % 
 %            % Get conservative tracer parameter data from table
 %            app.v = table2array(app.paramTableDat(:,2));
 % 
 %            % Parameters if Sum == "No"
 %            if (app.Average_Dropdown.Value == "No")
 %                MC = table2array(app.paramTableDat(:,6));
 %                app.Q = table2array(app.paramTableDat(:,5));
 %                app.alpha = table2array(app.paramTableDat(:,12));
 %             % Parameter if Sum == "Yes"
 %            else
 %                fi = table2array(app.paramTableDat(:,end-1));
 %                MC = app.M_con_EditField.Value*fi;
 %                app.Q = app.Q_EditField.Value*transpose(linspace(1,1,app.nChn));
 %                app.alpha = table2array(app.paramTableDat(:,12));
 %            end
 % 
 %            % Get x-axis values and Path Lengths
 %            app.xRTValue = linspace(app.time_min_EditField.Value, app.time_max_EditField.Value, app.npts);
 %            if (app.xRTValue(1) == 0)
 %                app.xRTValue(1) = app.xRTValue(2)/10;
 %            end
 %            app.time = app.xRTValue;
 %            app.L = table2array(app.paramTableDat(:,3));
 % 
 %            % Calculate concentrations for all curves in conservative tracer in parameter table
 %            y = ConservativeTracer(app.v, MC, app.Q, app.alpha,app.L, app.time, rho_f);                   
 %        end
        
        function [lamdaBar,decayFactor] = DF_calc(app)
        % DF_calc: calculate the decay factor for a given temperature profile

            % Get data from the Parameter Table
            v_Active = table2array(app.paramTableDat(app.paramTableDat.Include == true,2));
            L_Active = table2array(app.paramTableDat(app.paramTableDat.Include == true,3));
            if (app.Average_Dropdown.Value == "No")
                A_Active = table2array(app.paramTableDat(app.paramTableDat.Include == true,10));
                Ea_Active = table2array(app.paramTableDat(app.paramTableDat.Include == true,11));
                M_con_Active = table2array(app.paramTableDat(app.paramTableDat.Include == true,6));
                M_rea_Active = table2array(app.paramTableDat(app.paramTableDat.Include == true,7));
            else
                A_Active = app.Arr_A_EditField.Value;
                Ea_Active = app.Arr_Ea_EditField.Value;
                M_con_Active = app.M_con_EditField.Value;
                M_rea_Active = app.M_rea_EditField.Value;
            end

            % Define Constants
            R_gas = 8.31441e-3; % kJ/(mol K)
            Tk = app.y_TP + 273.15;

            % Calculate integrand and calculate x values
            integrand = exp(-Ea_Active./(R_gas*Tk));
            x_rel = linspace(0,1,app.npts);

            % Calculate Integral            
            lamdaBar = zeros(app.nChn,1); % Preallocate vector
            endLoop = size(v_Active,1);
            lamdaBar = [];
            for i = 1:endLoop
                lamdaBar(i,1) = trapz(x_rel,integrand(i,:),2);   %MAP
            end
            decayFactor = (M_rea_Active./M_con_Active).*exp(-A_Active.*L_Active.*lamdaBar./v_Active);
        end
        
        function PlotOpt_Initial(app)
            % Initializes the Plot Options Table
				app.PlotOpt_LineStyleList = categorical({'Solid', 'Dashed', 'Dotted', 'Dash-Dotted','none'});
				app.PointStyleList = categorical({'Open', 'Filled'});
				col1 = {app.paramTable.Data{1,1}};
				col2 = {app.MLDefaultColors(1)};
				col3 = {2};
				%lstyle = {app.PlotOpt_LineStyleList(1)};
				%col4 = {lstyle};
                col4 = {app.PlotOpt_LineStyleList(1)};
				app.PlotOptTableData = table(col1, col2, col3, col4);
				app.PlotOptionsTable.Data = app.PlotOptTableData;

				% Set Column widths and background color
				app.PlotOptionsTable.ColumnWidth = {65 130 81 125};
				myColorStyle = uistyle("BackgroundColor",app.PlotOptionsTable.Data{1,2}{1},"FontColor",[0.94 0.94 0.94]);
				addStyle(app.PlotOptionsTable,myColorStyle,'cell',[1,2]);            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get screen size and define hScale, vScale, and plot default colors
            TATSetup(app)

            % Initialize Parameter Table
            ParamTableInitial(app)

            % Create Components
            %createPlotOptTable(app)                  % Table for plot options
        end

        % Button pushed function: EqHelpButton
        function EqHelpButtonPushed(app, event)
            % Define file location
            eqTPHelpPath = fullfile(app.TATPath,'Docs','RTTracerHelp.pdf');
            
            % Open Manual
            winopen(eqTPHelpPath)            
        end

        % Callback function
        function PlotConservativeTracerDataCheckBoxValueChanged(app, event)
            %value = app.PlotConservativeTracerDataCheckBox.Value;
            if (app.designPlotOptPnlRatioCheckBox.Value == true)
                app.PlotConservativeTracerDataCheckBox.Value = false;
                errordlg('Choose only one of the checkboxes','Input Error')
            end
        end

        % Value changed function: xAxis_Dropdown
        function xAxis_DropdownValueChanged(app, event)
            % Update Parameter Table, Parameter Panel, x-axis of plot
            updateRTParamPnl(app);

            % Update Plot Options Table
            updatePlotOptTable(app)


            % Update label and values for min and max on x-axis
            if (app.xAxis_Dropdown.Value == "Time")
                app.PlotOptions_t_Label.Text = "Time";
                app.time_min_EditField.Value = app.t_min;
                app.time_max_EditField.Value = app.t_max;
                app.time_min_EditField.Enable = true ;
                app.time_max_EditField.Enable = true ;                
            else
                % Save time values
                app.t_min = app.time_min_EditField.Value ;
                app.t_max = app.time_max_EditField.Value ;

                app.PlotOptions_t_Label.Text= "Rel. Dist.";
                app.time_min_EditField.Value = 0;
                app.time_max_EditField.Value = 1;
                app.time_min_EditField.Enable = false ;
                app.time_max_EditField.Enable = false ;
            end

            % Update Plot
            UpdatePlotButtonPushed(app)
        end

        % Value changed function: T_res_EditField
        function T_res_EditFieldValueChanged(app, event)
            value = app.T_res_EditField.Value;
            app.T_max_EditField.Value = value;
        end

        % Value changed function: T_inj_EditField
        function T_inj_EditFieldValueChanged(app, event)
            value = app.T_inj_EditField.Value;
            app.time_min_EditField.Value = value;
        end

        % Cell edit callback: paramTable
        function paramTableCellEdit(app, event)
            % Get the table indices for cell being edited
            indices = event.Indices;
            newData = event.NewData;

            % Determine new second index for mapping to correct cell
            if(app.Average_Dropdown.Value == "No" && app.xAxis_Dropdown.Value == "Time")
                if (indices(2) <= 8)
                    newIndex = indices(2);
                else
                    newIndex = indices(2) + 1;
                end
            elseif (app.Average_Dropdown.Value == "Yes" && app.xAxis_Dropdown.Value == "Time")
                if (indices(2) <= 4)
                    newIndex = indices(2);
                else
                    newIndex = indices(2) + 7;
                end
            elseif (app.Average_Dropdown.Value == "No" && app.xAxis_Dropdown.Value == "Distance")
                newIndex = indices(2);
            elseif (app.Average_Dropdown.Value == "Yes" && app.xAxis_Dropdown.Value == "Distance")
                if (indices(2) <= 4)
                    newIndex = indices(2);
                else
                    newIndex = indices(2) + 7;
                end
            end

            % Save the old data
            oldData = app.paramTableDat{indices(1),newIndex};

            % Check for duplicate channel number
            for i = 1 : app.nChn
                if(indices(2) == 1 && i ~= indices(1) && newData == app.paramTableDat{i,1})
                        errTxt = [ 'Channel ' num2str(newData) ' already exists. Choose another channel number.'];
                        errordlg(errTxt, 'Input Error')
                        newData = oldData;
                        break
                end
            end
            
            % Update data value
            app.paramTableDat{indices(1), newIndex} = newData;
            app.paramTable.Data{indices(1),indices(2)} = newData;
            
            % Update vectors based on new Parameter Table and editable cells
            TempArray = table2array(app.paramTableDat(:,[2:11,15])) ;
            TempCell = num2cell(TempArray,1) ;
            [app.v,app.L,app.lambda_r,app.Q,app.M_con,app.M_rea,app.t_op,app.time,app.A,app.Ea,app.include] =  TempCell{:};           

            % Update alpha values
            app.alpha = app.alpha_c.*app.L.^app.alpha_m;
            app.paramTableDat(:,end-3) = num2cell(app.alpha);
            app.paramTable.Data(:,end-3) = num2cell(app.alpha);

            % Calculate fractional flows and b
            [app.b,app.Q_frac] = updateFraction(app,app.v,app.L,app.include,app.T_res,app.del_p,app.Average_Dropdown.Value);

            % Insert updated b and fractional flow into parameter table
            app.paramTableDat(:,end-2) = num2cell(app.b);
            app.paramTable.Data(:,end-2) = num2cell(app.b);
            app.paramTableDat(:,end-1) = num2cell(app.Q_frac);
            app.paramTable.Data(:,end-1) = num2cell(app.Q_frac);

            % Update vectors based on new Parameter Table and non-edita%ble cells
            %app.alpha = table2array(app.paramTableDat(:,12));
            %app.b = table2array(app.paramTableDat(:,13));
            %app.Q_frac = table2array(app.paramTableDat(:,14));
            app.include = table2array(app.paramTableDat(:,15));

            % Update Plot Options Table
            updatePlotOptTable(app)            
            
        end

        % Selection changed function: PlotOptPnlCurveChoiceRadioBtn
        function designPlotOptPnlCurveChoiceBtn(app, event)
            if (app.PlotOptPnlCurveChoiceBtnRatio.Value == true)
                app.PlotOptions_c_Label.Text = "Ratio";
                ylabel(app.ConPlot_Axes, 'Reactive Tr/Conservative Tr');           
            else
                app.PlotOptions_c_Label.Text = "Conc.";
                ylabel(app.ConPlot_Axes, 'Concentration (ug/kg)');
            end            
        end

        % Value changed function: Average_Dropdown
        function Average_DropdownValueChanged(app, event)
            % Update Parameter Table, Parameter Panel, x-axis of plot
            updateRTParamPnl(app);

            % Update Plot Options Table
            updatePlotOptTable(app)

            % Update plots
            UpdatePlotButtonPushed(app)
        end

        % Button pushed function: AddChannel_Button
        function AddChannel_ButtonPushed(app, event)
            % Check if nChn <= 10
            if(app.nChn < 10)
                if(app.nChn == 0)
                    maxChn = 0;
                else
                    % Find the largest channel number
                    maxChn = app.paramTableDat{1,1};
                    for i = 2:app.nChn
                        if(app.paramTableDat {i,1} > maxChn)
                            maxChn = app.paramTableDat {i,1};
                        end
                    end
                end
                maxChn = maxChn + 1;

                % Increment the number of channels and update editfield displaying designConnChn
                app.nChn = app.nChn + 1;
                app.nChn_EditField.Value = app.nChn;

                % Create new row, as copy of last row of table
                newRow = app.paramTableDat(end,:);
                newRow{1,1} = maxChn;
                newRow{1,2} = newRow{1,2}*0.75;
                app.paramTableDat  = [app.paramTableDat; newRow];

            else
               errordlg('Number of channels must be <= 10','Input Error')
               return
            end  

            % Update vectors based on new Parameter Table
            % Update global vector variables with altered paramTableDat data
            TempArray = table2array(app.paramTableDat(:,2:15)) ;
            TempCell = num2cell(TempArray,1) ;
            [app.v,app.L,app.lambda_r,app.Q,app.M_con,app.M_rea,app.t_op,app.time,app.A,app.Ea,app.alpha,app.b,app.Q_frac,app.include] =  TempCell{:} ;
            
            % Update Parameters Table
            updateRTParamPnl(app)

            % Generate New Line for Plot Options Table
            updatePlotOptTable(app)

        end

        % Button pushed function: DeleteChannel_Button
        function DeleteChannel_ButtonPushed(app, event)
        % Button pushed function: ParamPnlDeleteChannelButton
        % Open dialogue box to input channels to be deleted

            % Create response dialogbox and get response
 	        prompt = {'Enter space-separated channel numbers:'};
            deleteDlgTitle = 'Delete Channels';
            dims = [1,40];
            deleteResponse = inputdlg(prompt,deleteDlgTitle,dims);
            if(isempty(deleteResponse))
                return
            end
            deleteChn = str2num(deleteResponse{1});
            nDelete = size(deleteChn,2);
            
            % Display error msg if none or all channels are specified
            if(nDelete == 0)
                return
            elseif (nDelete == app.nChn)
                errTxt = 'Cannot delete all channels';
                errordlg(errTxt,'Input Error');
                return
            end

            % Determine the row for each channel to be deleted and add it
            % to the deleteRows vector
            for i = 1:nDelete
                 % Set error code for finding channel to 1
                chnFindError = 1;
                for j = 1: app.nChn
                    if (deleteChn(i) == app.paramTable.Data{j,1})
                        deleteRows(1,i) = j;
                        chnFindError = 0;
                        break;
                    end
                end

                % Display error message if channel number not found
                if (chnFindError == 1)
                    errTxt = ['Channel ' num2str(deleteChn(i)) ' not found. Check input.'];
                    errordlg(errTxt,'Input Error')
                    return
                end
            end

            % Delete rows from table if all channels found
            app.paramTable.Data(deleteRows,:) = [];
            app.PlotOptionsTable.Data(deleteRows,:) = [];
            app.paramTableDat(deleteRows,:) = [];
            app.nChn = app.nChn - nDelete;
            app.nChn_EditField.Value = app.nChn;

            % Update table with new fractional flow values
            updateFraction(app,app.v,app.L,app.include,app.T_res,app.del_p, app.Average_Dropdown.Value);

            % Update plot options table
            updatePlotOptTable(app);
        end

        % Button pushed function: UpdatePlotButton, UpdatePlotButton_2
        function UpdatePlotButtonPushed(app, event)
        % UpdatePlotButtonPushed: plots temperature versus time or distance
        % Input variables: all from global definition

            % Define a sub-Table of parameters based only on included channels
            ParamArray = table2array(app.paramTableDat(app.paramTableDat.Include == true,[2,3,14]));   % velocity, length and fraction
            alphaParams = [app.alpha_c_EditField.Value app.alpha_m_EditField.Value];                % Collect dispersivity function parameter
            rhoH2O = rhoH2OCalc(app.T_res);                                                         % Calculate density of liquid water

            % Define the time/space values
            if (app.xAxis_Dropdown.Value == "Time" && app.Average_Dropdown.Value == "Yes")
                M_con_Active = app.M_con_EditField.Value;                           % Mass of conservative Tracer
                M_rea_Active = app.M_rea_EditField.Value;                           % Mass of reactive tracer
                Q_Active = app.Q_EditField.Value;                                   % Pumping rate
                tData = linspace(app.time_min_EditField.Value, app.time_max_EditField.Value, app.npts);
                if (tData(1) == 0)
                    tData(1) = tData(2)/10;
                end
            elseif (app.xAxis_Dropdown.Value == "Time" && app.Average_Dropdown.Value == "No")
                M_con_Active = app.M_con(app.paramTableDat.Include == true,1);        % Mass of conservative Tracer
                M_rea_Active = app.M_rea(app.paramTableDat.Include == true,1);        % Mass of reactive tracer
                Q_Active = app.Q(app.paramTableDat.Include == true,1);                % Pumping rate
                tData = linspace(app.time_min_EditField.Value, app.time_max_EditField.Value, app.npts);
                if (tData(1) == 0)
                    tData(1) = tData(2)/10;
                end
            elseif (app.xAxis_Dropdown.Value == "Distance" && app.Average_Dropdown.Value == "Yes")
                M_con_Active = app.M_con_EditField.Value;                           % Mass of conservative Tracer
                M_rea_Active = app.M_rea_EditField.Value;                           % Mass of reactive tracer
                Q_Active = app.Q_EditField.Value;                                   % Pumping rate
                x_rel = linspace(app.time_min_EditField.Value, app.time_max_EditField.Value,app.npts);
                tData = app.time_EditField.Value;
            elseif (app.xAxis_Dropdown.Value == "Distance" && app.Average_Dropdown.Value == "No")
                M_con_Active = app.M_con(app.paramTableDat.Include == true,1);        % Mass of conservative Tracer
                M_rea_Active = app.M_rea(app.paramTableDat.Include == true,1);        % Mass of reactive tracer
                Q_Active = app.Q(app.paramTableDat.Include == true,1);                % Pumping rate
                x_rel = linspace(app.time_min_EditField.Value, app.time_max_EditField.Value,app.npts);
                tData = table2array(app.paramTableDat(app.paramTableDat.Include == true,6));
            end

        % Calculate Curves
            % Calculate conservative tracer concentrations
            [app.C_con_mean, app.C_con] = ConTracer(app.xAxis_Dropdown.Value,tData,M_con_Active,Q_Active,alphaParams,ParamArray,rhoH2O);             
            % Calculate Temperature Profile and Mean Temperatue
            app.y_TP = TP_calc(app);
            if(app.Average_Dropdown.Value == "Yes")
                x_rel = linspace(app.time_min_EditField.Value, app.time_max_EditField.Value,app.npts);
                Q_frac_Active = ParamArray(:,end);
                Temp_mean = TMixCalc(app.y_TP(:,end),Q_frac_Active);     
            end

            % Calculate decay factor and then reactive tracer concentration
            [lambdaBar,decayFactor] = DF_calc(app);     % Decay factor
            app.C_rea = decayFactor.*app.C_con;         % Reactive Tracer concentrations
            app.C_rea_mean = sum(app.C_rea,1);          % Average reactive tracer concentration

        % Write lambdaBar to output table
        col1 = [];
        col2 = [];
        col1 = app.paramTable.Data{app.paramTableDat.Include == true,1};
        app.OutputTable.Data = table(col1, lambdaBar);

        % Plot the calculated reactive tracer concentrations
            if(app.Average_Dropdown.Value == "No" && app.xAxis_Dropdown.Value == "Time")
                app.ReaPlots = plot (app.ConPlot_Axes, tData(1,:), app.C_rea,'-');
            elseif(app.Average_Dropdown.Value == "Yes" && app.xAxis_Dropdown.Value == "Time")
                app.ReaPlots = plot (app.ConPlot_Axes, ...
                    tData(1,:), app.C_rea,'-', ...
                    tData(1,:), app.C_rea_mean,'-');
            elseif (app.Average_Dropdown.Value == "No" && app.xAxis_Dropdown.Value == "Distance")
                app.ReaPlots = plot (app.ConPlot_Axes, x_rel(1,:), app.C_con,'-');
            elseif (app.Average_Dropdown.Value == "Yes" && app.xAxis_Dropdown.Value == "Distance")
                conMeanVector = app.C_rea(:,app.npts);
                conMean = sum(conMeanVector,1);
                app.ReaPlots = plot (app.ConPlot_Axes, ...
                    x_rel(1,:), app.C_rea,'-');
                hold (app.ConPlot_Axes,"on")
                average_Conc = plot (app.ConPlot_Axes,1,conMean,'o ');
                hold (app.ConPlot_Axes,"off")
            end

            % Update Line properties
            LineStyles = {'-';'--';':';'-.';''};
            
            endLoop = size(app.ReaPlots,1);
            if (app.Average_Dropdown.Value == "No")
                % The added line to necessary to convert PlotOptionsTable_Active to a string array, the line is then deleted in the next statement
                PlotOptionsTable_Active = [table2array(app.PlotOptionsTable.Data(app.paramTableDat.Include == true,:));["Average","0.000 0.000 0.000","2","Solid"]]; 
                PlotOptionsTable_Active = PlotOptionsTable_Active(1:endLoop,:);
            elseif(app.Average_Dropdown.Value == "Yes") % && app.xAxis_Dropdown.Value == "Time")
                PlotOptionsTable_Active = [table2array(app.PlotOptionsTable.Data(app.paramTableDat.Include == true,:));app.PlotOptionsTable.Data{end,:}];
            end
       
            % Format all the lines
            for i = 1: endLoop
                app.ReaPlots(i).Color = str2num(PlotOptionsTable_Active(i,2));
                app.ReaPlots(i).LineWidth = str2num(PlotOptionsTable_Active(i,3));
                app.ReaPlots(i).LineStyle = LineStyles(ismember(app.PlotOpt_LineStyleList, PlotOptionsTable_Active(i,4)));
            end

            % Format the Mean point at x_r = 1
            if(app.Average_Dropdown.Value == "Yes" && app.xAxis_Dropdown.Value == "Distance" )
                average_Conc.MarkerEdgeColor = str2num(PlotOptionsTable_Active(end,2));
                average_Conc.MarkerSize = str2num(PlotOptionsTable_Active(end,3));
                average_Conc.Marker = "o";
                average_Conc.LineWidth = 2;
                if(PlotOptionsTable_Active (end,end) == "Filled")
                    average_Conc.MarkerFaceColor = str2num(PlotOptionsTable_Active(end,2));
                end
            end

            % Set limits on concentration axes
            xlim(app.ConPlot_Axes,[app.time_min_EditField.Value, app.time_max_EditField.Value])
            ylim(app.ConPlot_Axes,[app.c_min_EditField.Value, app.c_max_EditField.Value])

            % Set the axis labels
            if(app.xAxis_Dropdown.Value == "Time")
                xlabel(app.ConPlot_Axes, 'Time');
            else
                xlabel(app.ConPlot_Axes, 'Relative Distance');
            end
            
            % Initialize variable to define legend
            typeStr_Con = "Con-";
            typeStr_Rea = "Rea-";
            
            % Get the channel numbers and append prefixes
            legendList = table2array(app.paramTableDat(app.paramTableDat.Include == true,1));
            legendList = string(num2str(legendList));
            legendList_Con = append(typeStr_Con, legendList);
            legendList_Rea = append(typeStr_Rea, legendList);
            
            % If Averaging channels add average to legend
            if(app.Average_Dropdown.Value == "Yes")
	            legendList_Con = [legendList_Con; "Con-Avg"];
	            legendList_Rea = [legendList_Rea; "Rea-Avg"];
            end
            
            % Define legend components for reactive tracer and then add for conservative tracer if needed
            legendList = legendList_Rea;
            if (app.PlotOptPnlCurveChoiceBtnReaAndCon.Value == true)
	            legendList = [legendList; legendList_Con];
            end

            % Plot the calculated conservative tracer concentrations
            if(app.PlotOptPnlCurveChoiceBtnReaAndCon.Value == true)
                hold (app.ConPlot_Axes,"on")
			    if(app.Average_Dropdown.Value == "No" && app.xAxis_Dropdown.Value == "Time")
					    app.ConPlots = plot (app.ConPlot_Axes, tData(1,:), app.C_con,':');
				    elseif(app.Average_Dropdown.Value == "Yes" && app.xAxis_Dropdown.Value == "Time")
					    app.ConPlots = plot (app.ConPlot_Axes, ...
						    tData(1,:), app.C_con,':', ...
						    tData(1,:), app.C_con_mean,':');
				    elseif (app.Average_Dropdown.Value == "No" && app.xAxis_Dropdown.Value == "Distance")
					    app.ConPlots = plot (app.ConPlot_Axes, x_rel(1,:), app.C_con,':');
				    elseif (app.Average_Dropdown.Value == "Yes" && app.xAxis_Dropdown.Value == "Distance")
					    conMeanVector = app.C_con(:,app.npts);
					    conMean = sum(conMeanVector,1);                 
					    app.ConPlots = plot (app.ConPlot_Axes, ...
						    x_rel(1,:), app.C_con,':');
					    average_Conc = plot (app.ConPlot_Axes,1,conMean,'o ');
					    hold (app.ConPlot_Axes,"off")
                end
				    % Format all the lines
				    for i = 1: endLoop
					    app.ConPlots(i).Color = str2num(PlotOptionsTable_Active(i,2));
					    app.ConPlots(i).LineWidth = str2num(PlotOptionsTable_Active(i,3));
					    %app.ConPlots(i).LineStyle = LineStyles(ismember(app.PlotOpt_LineStyleList, PlotOptionsTable_Active(i,4)));
            	    end	
            	    
				    % Format the Mean point at x_r = 1
				    if(app.Average_Dropdown.Value == "Yes" && app.xAxis_Dropdown.Value == "Distance" )
					    average_Conc.MarkerEdgeColor = str2num(PlotOptionsTable_Active(end,2));
					    average_Conc.MarkerSize = str2num(PlotOptionsTable_Active(end,3));
					    average_Conc.Marker = "square";
					    average_Conc.LineWidth = 2;
					    if(PlotOptionsTable_Active (end,end) == "Filled")
						    average_Conc.MarkerFaceColor = str2num(PlotOptionsTable_Active(end,2));
					    end
				    end            	
                hold (app.ConPlot_Axes,"off")
            end

            % Add legend to plot
            legend(app.ConPlot_Axes, legendList);

            % Plot ratio of reactive to conservative tracers
            if(app.PlotOptPnlCurveChoiceBtnRatio.Value == true)
            	% Calculate ratios
            	app.C_ratio = app.C_rea ./ app.C_con;
            	app.C_ratio_mean = app.C_rea_mean ./ app.C_con_mean;
            
                hold (app.ConPlot_Axes,"off")
			    if(app.Average_Dropdown.Value == "No" && app.xAxis_Dropdown.Value == "Time")
					    app.RatioPlots = plot (app.ConPlot_Axes, tData(1,:), app.C_ratio,':');
				    elseif(app.Average_Dropdown.Value == "Yes" && app.xAxis_Dropdown.Value == "Time")
					    app.RatioPlots = plot (app.ConPlot_Axes, ...
						    tData(1,:), app.C_ratio,':', ...
						    tData(1,:), app.C_ratio_mean,':');
				    elseif (app.Average_Dropdown.Value == "No" && app.xAxis_Dropdown.Value == "Distance")
					    app.RatioPlots = plot (app.ConPlot_Axes, x_rel(1,:), app.C_ratio,':');
				    elseif (app.Average_Dropdown.Value == "Yes" && app.xAxis_Dropdown.Value == "Distance")
					    conMeanVector = app.C_ratio(:,app.npts);
					    conMean = app.C_ratio_mean;
					    app.RatioPlots = plot (app.ConPlot_Axes, ...
						    x_rel(1,:), app.C_ratio,':');
					    average_Conc = plot (app.ConPlot_Axes,1,conMean,'o ');
					    hold (app.ConPlot_Axes,"off")
				    end
				    
				    % Format all the lines
				    for i = 1: endLoop
					    app.RatioPlots(i).Color = str2num(PlotOptionsTable_Active(i,2));
					    app.RatioPlots(i).LineWidth = str2num(PlotOptionsTable_Active(i,3));
					    app.RatioPlots(i).LineStyle = LineStyles(ismember(app.PlotOpt_LineStyleList, PlotOptionsTable_Active(i,4)));
            	    end	
            	    
				    % Format the Mean point at x_r = 1
				    if(app.Average_Dropdown.Value == "Yes" && app.xAxis_Dropdown.Value == "Distance" )
					    average_Conc.MarkerEdgeColor = str2num(PlotOptionsTable_Active(end,2));
					    average_Conc.MarkerSize = str2num(PlotOptionsTable_Active(end,3));
					    average_Conc.Marker = "square";
					    average_Conc.LineWidth = 2;
					    if(PlotOptionsTable_Active (end,end) == "Filled")
						    average_Conc.MarkerFaceColor = str2num(PlotOptionsTable_Active(end,2));
					    end
				    end            	
                hold (app.ConPlot_Axes,"off")
                % Initialize variable to define legend
			    typeStr_Temp = "Ratio-";
    
			    % Get the channel numbers and append prefixes
			    legendList = table2array(app.paramTableDat(app.paramTableDat.Include == true,1));
			    legendList = string(num2str(legendList));
			    legendList = append(typeStr_Temp, legendList);
    
			    % If Averaging channels add average to legend
			    if(app.Average_Dropdown.Value == "Yes")
				    legendList = [legendList; "Ratio-Avg"];
                end
    
                % Add legend to plot
                legend(app.ConPlot_Axes, legendList);
            end

            % Plot the temperature profile
            hold(app.Temp_Axes,"off");
            x_rel = linspace(0, 1,app.npts);
            app.TempPlots = plot(app.Temp_Axes, x_rel(1,:), app.y_TP,'-');
            if(app.Average_Dropdown.Value == "Yes")
                hold(app.Temp_Axes,"on");
                Temp_mean_Plot=plot(app.Temp_Axes, 1, Temp_mean,'o');
            end

            % Format all lines in temperature profile
            endLoop = size(app.TempPlots,1);
            %endLoop = app.nChn;
            for i = 1: endLoop
                app.TempPlots(i).Color = str2num(PlotOptionsTable_Active(i,2));
                app.TempPlots(i).LineWidth = str2num(PlotOptionsTable_Active(i,3));
                app.TempPlots(i).LineStyle = LineStyles(ismember(app.PlotOpt_LineStyleList, PlotOptionsTable_Active(i,4)));
            end

            % Format the Mean point at x_r = 1
            if(app.Average_Dropdown.Value == "Yes" )
                Temp_mean_Plot.Color = str2num(PlotOptionsTable_Active(end,2));
                Temp_mean_Plot.Marker = "^";
                if(app.xAxis_Dropdown == "Distance")
                    Temp_mean_Plot.MarkerEdgeColor = str2num(PlotOptionsTable_Active(end,2));
                    Temp_mean_Plot.MarkerSize = str2num(PlotOptionsTable_Active(end,3));
                else
                    Temp_mean_Plot.LineWidth = 2;
                    Temp_mean_Plot.MarkerEdgeColor = [0 0 0];
                    Temp_mean_Plot.MarkerSize = 8;
                end
                if(PlotOptionsTable_Active (end,end) == "Filled")
                    Temp_mean_Plot.MarkerFaceColor = str2num(PlotOptionsTable_Active(end,2));
                end
            end

            % Set limits on temperature axes
            xlim(app.Temp_Axes,[0, 1])
            ylim(app.Temp_Axes,[app.T_min_EditField.Value, app.T_max_EditField.Value])

            % Initialize variable to define legend
            typeStr_Temp = "Temp-";
            
            % Get the channel numbers and append prefixes
            legendList = table2array(app.paramTableDat(app.paramTableDat.Include == true,1));
            legendList = string(num2str(legendList));
            legendList = append(typeStr_Temp, legendList);
            
            % If Averaging channels add average to legend
			if(app.Average_Dropdown.Value == "Yes")
				legendList = [legendList; "Temp-Avg"];
            end
            
            % Add legend to plot
            legend(app.Temp_Axes, legendList);


        end

        % Menu selected function: SaveparametersMenu
        function SaveparametersMenuSelected(app, event)
        % Select file to save to
        [filename,pathname] = uiputfile('*.*');
        if isequal(filename,0)
            return;
        end
        fileNamefull = fullfile(pathname,filename);    

        % Read the preexisting structure if file exists
        if isfile(fileNamefull)
            Struct = read_namelist(fileNamefull);
        end
      
        % Editfields
        Struct.DesignReactiveTrTest.nChn        = app.nChn_EditField.Value;
        Struct.DesignReactiveTrTest.T_res       = app.T_res_EditField.Value;
        Struct.DesignReactiveTrTest.T_inj       = app.T_inj_EditField.Value;
        Struct.DesignReactiveTrTest.Q           = app.Q_EditField.Value;      
        Struct.DesignReactiveTrTest.delta_p     = app.delta_p_EditField.Value;  
        Struct.DesignReactiveTrTest.M_con       = app.M_con_EditField.Value;
        Struct.DesignReactiveTrTest.M_rea       = app.M_rea_EditField.Value;
        Struct.DesignReactiveTrTest.alpha_c     = app.alpha_c_EditField.Value;
        Struct.DesignReactiveTrTest.alpha_m     = app.alpha_m_EditField.Value;
        Struct.DesignReactiveTrTest.axisType    = {app.xAxis_Dropdown.Value} ;
        Struct.DesignReactiveTrTest.SumConc     = {app.Average_Dropdown.Value};
        Struct.DesignReactiveTrTest.t_op        = app.t_op_EditField.Value;
        Struct.DesignReactiveTrTest.rho_r       = app.rho_r_EditField.Value;
        Struct.DesignReactiveTrTest.cp_r        = app.cp_r_EditField.Value;
        Struct.DesignReactiveTrTest.Arr_A       = app.Arr_A_EditField.Value;
        Struct.DesignReactiveTrTest.Arr_Ea      = app.Arr_Ea_EditField.Value;

        % Channel data table 
        %paramTableDat: Channel,v,L,lambda_r,Q,M_con,M_rea,t_op,time,A,Ea,alpha,b,Q_frac,Include
        Struct.DesignReactiveTrTest.Table_Channel     	= app.paramTableDat{:,1}		; 
        Struct.DesignReactiveTrTest.Table_v         	= app.paramTableDat{:,2}		; 
        Struct.DesignReactiveTrTest.Table_L         	= app.paramTableDat{:,3}		; 
        Struct.DesignReactiveTrTest.Table_lambda_r  	= app.paramTableDat{:,4}		; 
        Struct.DesignReactiveTrTest.Table_Q		        = app.paramTableDat{:,5}		; 
        Struct.DesignReactiveTrTest.Table_M_con     	= app.paramTableDat{:,6}		; 
        Struct.DesignReactiveTrTest.Table_M_rea     	= app.paramTableDat{:,7}	    ; 
        Struct.DesignReactiveTrTest.Table_t_op   	    = app.paramTableDat{:,8}		; 
        Struct.DesignReactiveTrTest.Table_time   	    = app.paramTableDat{:,9}		;        
        Struct.DesignReactiveTrTest.Table_Arr_A     	= app.paramTableDat{:,10}	    ; 
        Struct.DesignReactiveTrTest.Table_Arr_Ea     	= app.paramTableDat{:,11}	    ; 
        Struct.DesignReactiveTrTest.Table_alpha         = app.paramTableDat{:,12}		; 
        Struct.DesignReactiveTrTest.Table_b         	= app.paramTableDat{:,13}		; 
        Struct.DesignReactiveTrTest.Table_f     	    = app.paramTableDat{:,14}	    ; 
        Struct.DesignReactiveTrTest.Table_Include     	= app.paramTableDat{:,15}       ; 
              
        % Plot Options Editfields
        Struct.DesignReactiveTrTest.t_min       = app.time_min_EditField.Value;
        Struct.DesignReactiveTrTest.t_max       = app.time_max_EditField.Value;
        Struct.DesignReactiveTrTest.c_min       = app.c_min_EditField.Value;
        Struct.DesignReactiveTrTest.c_max       = app.c_max_EditField.Value;
        Struct.DesignReactiveTrTest.T_min       = app.time_min_EditField.Value;
        Struct.DesignReactiveTrTest.T_max       = app.T_max_EditField.Value;

        % Save structure to file
        write_namelist(Struct,fileNamefull);
        end

        % Menu selected function: ReadparametersMenu
        function ReadparametersMenuSelected(app, event)
        % Select file to read
        [filename,pathname] = uigetfile('*.*');
        if isequal(filename,0)
            return;
        end
        fileNamefull = fullfile(pathname,filename);    

        % Read the structure 
        Struct = read_namelist(fileNamefull);
        RelevantFieldFoundFlag = false;

        if isfield(Struct, 'DesignReactiveTrTest')
            app.t_op_EditField.Value	= Struct.DesignReactiveTrTest.t_op      ;
            app.T_res_EditField.Value	= Struct.DesignReactiveTrTest.T_res     ;            
            app.T_inj_EditField.Value	= Struct.DesignReactiveTrTest.T_inj     ;
            app.rho_r_EditField.Value	= Struct.DesignReactiveTrTest.rho_r     ;    
            app.cp_r_EditField.Value	= Struct.DesignReactiveTrTest.cp_r      ;
            app.delta_p_EditField.Value = Struct.DesignReactiveTrTest.delta_p   ;    
            app.alpha_c_EditField.Value = Struct.DesignReactiveTrTest.alpha_c   ;
            app.alpha_m_EditField.Value = Struct.DesignReactiveTrTest.alpha_m   ;            
            app.M_rea_EditField.Value   = Struct.DesignReactiveTrTest.M_rea     ;
            app.Arr_A_EditField.Value   = Struct.DesignReactiveTrTest.Arr_A     ;
            app.Arr_Ea_EditField.Value  = Struct.DesignReactiveTrTest.Arr_Ea    ;
            app.Average_Dropdown.Value  = Struct.DesignReactiveTrTest.SumConc   ;
            app.xAxis_Dropdown.Value    = Struct.DesignReactiveTrTest.axisType  ;                
            
            % Channel data
            %paramTableDat: Channel,v,L,lambda_r,Q,M_con,M_rea,t_op,time,A,Ea,alpha,b,Q_frac,include
            app.paramTableDat = [];            
            nChan = length(Struct.DesignReactiveTrTest.Table_Channel) ;
            app.nChn_EditField.Value = nChan ;            
            app.paramTableDat(1:nChan,1)		= Struct.DesignReactiveTrTest.Table_Channel'  	 	; 
            app.paramTableDat(1:nChan,2)		= Struct.DesignReactiveTrTest.Table_v'  	 	    ; 
            app.paramTableDat(1:nChan,3)		= Struct.DesignReactiveTrTest.Table_L'  	 	    ; 
            app.paramTableDat(1:nChan,4)		= Struct.DesignReactiveTrTest.Table_lambda_r'  	 	; 
            app.paramTableDat(1:nChan,5)		= Struct.DesignReactiveTrTest.Table_Q'  	 	    ; 
            app.paramTableDat(1:nChan,6)		= Struct.DesignReactiveTrTest.Table_M_con'         	; 
            app.paramTableDat(1:nChan,7)	    = Struct.DesignReactiveTrTest.Table_M_rea'     	 	; 
            app.paramTableDat(1:nChan,8)		= Struct.DesignReactiveTrTest.Table_t_op'  	 	    ; 
            app.paramTableDat(1:nChan,9)		= Struct.DesignReactiveTrTest.Table_time'           ;
            app.paramTableDat(1:nChan,10)	    = Struct.DesignReactiveTrTest.Table_Arr_A'     	 	; 
            app.paramTableDat(1:nChan,11)	    = Struct.DesignReactiveTrTest.Table_Arr_Ea'     	;             
            app.paramTableDat(1:nChan,12)		= Struct.DesignReactiveTrTest.Table_alpha'  	 	; 
            app.paramTableDat(1:nChan,13)		= Struct.DesignReactiveTrTest.Table_b'  	 	    ; 
            app.paramTableDat(1:nChan,14)		= Struct.DesignReactiveTrTest.Table_f'  	 	    ; 
            app.paramTableDat(1:nChan,15)		= Struct.DesignReactiveTrTest.Table_Include'  	 	;             

            % Plot options Editfields
            app.time_min_EditField.Value	    = Struct.DesignReactiveTrTest.t_min    ;
            app.time_max_EditField.Value	    = Struct.DesignReactiveTrTest.t_max    ;
            app.c_min_EditField.Value	        = Struct.DesignReactiveTrTest.c_min    ;
            app.c_max_EditField.Value	        = Struct.DesignReactiveTrTest.c_max    ;
            app.time_min_EditField.Value	    = Struct.DesignReactiveTrTest.T_min    ;
            app.T_max_EditField.Value	        = Struct.DesignReactiveTrTest.T_max    ;  

            RelevantFieldFoundFlag = true;            
        % if DesignReactive not found, use DesignCons and TProfile data (latter supersedes former)
        else 
            TemporaryRow = app.paramTableDat(1,:); % Need data for columns that won't exist

            if isfield(Struct, 'DesignConservativeTrTest')
                app.T_res_EditField.Value	            = Struct.DesignConservativeTrTest.T_res   ;
                app.Q_EditField.Value     	            = Struct.DesignConservativeTrTest.Q       ;
                app.alpha_c_EditField.Value             = Struct.DesignConservativeTrTest.alpha_c ;
                app.alpha_m_EditField.Value             = Struct.DesignConservativeTrTest.alpha_m ;                            
                app.delta_p_EditField.Value             = Struct.DesignConservativeTrTest.delta_p ;
                app.M_con_EditField.Value               = Struct.DesignConservativeTrTest.M_con   ;
            
                % Channel data table
                %paramTableDat: Channel,v,L,lambda_r,Q,M_con,M_rea,t_op,time,A,Ea,alpha,b,Q_frac,Include                
                nChan = length(Struct.DesignConservativeTrTest.Table_Channel);
                app.nChn_EditField.Value	= nChan    ;
                app.nChn = nChan ;
                app.paramTableDat = [];
                app.paramTableDat(1:nChan,1)		    = Struct.DesignConservativeTrTest.Table_Channel'     	 	; 
                app.paramTableDat(1:nChan,2)		    = Struct.DesignConservativeTrTest.Table_v'         	 	    ; 
                app.paramTableDat(1:nChan,3)		    = Struct.DesignConservativeTrTest.Table_L'         	 	    ; 
                app.paramTableDat(1:nChan,5)		    = Struct.DesignConservativeTrTest.Table_Q'		 	        ; 
                app.paramTableDat(1:nChan,6)		    = Struct.DesignConservativeTrTest.Table_M_con'     	 	    ; 
                app.paramTableDat(1:nChan,12)		    = Struct.DesignConservativeTrTest.Table_alpha'        	    ; 
                app.paramTableDat(1:nChan,13)		    = Struct.DesignConservativeTrTest.Table_b'        	        ;                 
                app.paramTableDat(1:nChan,14)	        = Struct.DesignConservativeTrTest.Table_f'     	 	        ;
                app.paramTableDat(1:nChan,15)	        = logical(Struct.DesignConservativeTrTest.Table_Include)'	; 

                app.paramTableDat(1:nChan,[4 6 7 8 9 10 11]) = repmat(TemporaryRow(1,[4 6 7 8 9 10 11]),nChan,1);                
                RelevantFieldFoundFlag = true;
            end            
            if isfield(Struct, 'DesignTemperatureProfile')
                app.T_res_EditField.Value	            = Struct.DesignTemperatureProfile.T_res   ;            
                app.Q_EditField.Value     	            = Struct.DesignTemperatureProfile.Q       ;
                app.t_op_EditField.Value	            = Struct.DesignTemperatureProfile.t_op    ;   
                app.T_inj_EditField.Value	            = Struct.DesignTemperatureProfile.T_inj   ;                        
                app.rho_r_EditField.Value	            = Struct.DesignTemperatureProfile.rho_r   ;    
                app.cp_r_EditField.Value	            = Struct.DesignTemperatureProfile.cp_r    ;            
                
                % Channel data table
                if or(~RelevantFieldFoundFlag, length(Struct.DesignTemperatureProfile.Table_Channel') ~= nChan)
                    if length(Struct.DesignTemperatureProfile.Table_Channel') .NE. nChan
                        msgbox('Row number mismatch between sections; using Temperature Profile data') ;
                    end
                    nChan = length(Struct.DesignTemperatureProfile.Table_Channel);
                    app.nChn_EditField.Value = nChan    ;
                    app.paramTableDat = [];
                    app.paramTableDat(1:nChan,[5 6 7 9 10 11 12]) = repmat(TemporaryRow([5 6 7 9 10 11 12]),nChan,1) ;                                        
                end
                                
                app.nChn_EditField.Value = nChan ;
                app.paramTableDat(1:nChan,1)		    = Struct.DesignTemperatureProfile.Table_Channel'     	 	; 
                app.paramTableDat(1:nChan,2)		    = Struct.DesignTemperatureProfile.Table_v'         	 	    ; 
                app.paramTableDat(1:nChan,3)		    = Struct.DesignTemperatureProfile.Table_L'         	 	    ; 
                app.paramTableDat(1:nChan,4)		    = Struct.DesignTemperatureProfile.Table_lambda_r'		 	; 
                app.paramTableDat(1:nChan,8)		    = Struct.DesignTemperatureProfile.Table_t_op'		 	    ;             
                app.paramTableDat(1:nChan,13)		    = Struct.DesignTemperatureProfile.Table_b'		 	        ; 
                app.paramTableDat(1:nChan,14)	        = Struct.DesignTemperatureProfile.Table_f'     	 	        ;
                app.paramTableDat(1:nChan,15)	        = logical(Struct.DesignTemperatureProfile.Table_Include)'	;                    
                
                RelevantFieldFoundFlag = true;            
            end                
        end

        if ~RelevantFieldFoundFlag
            msgbox('No relevant data found; execution halted')
            return            
        end      

        % Convert the new data to a table
        app.paramTableDat = array2table(app.paramTableDat, ...
            'VariableNames',{'Channel','v','L','lambda_r','Q','M_con','M_rea','t_op','time','A','Ea','alpha','b','Q_frac','Include'}) ;

        %------------------------------------------------------------ 
        % Update Parameter Table, Parameter Panel, x-axis of plot
        %MAP These global variables are called at the start of the update function, unfortunately, so have to be updated here. Should be updated in the update func    
        app.T_res =     app.T_res_EditField.Value ;
        app.del_p =     app.delta_p_EditField.Value ;
        app.nChn = nChan ;        
        
        % Update global vector variables with altered paramTableDat data
        TempArray = table2array(app.paramTableDat(:,2:15)) ;
        TempCell = num2cell(TempArray,1) ;
        [app.v,app.L,app.lambda_r,app.Q,app.M_con,app.M_rea,app.t_op,app.time,app.A,app.Ea,app.alpha,app.b,app.Q_frac,app.include] = TempCell{:} ;
           
        % Update Parameters Table
        updateRTParamPnl(app);

        % Check for the uniqueness of the channel numbers
        ChnUniqueErr = ChnUniqueness(app,app.paramTableDat);
        if (ChnUniqueErr == 1)
            app.paramTable.Data = app.paramTableDatOld;
            app.Average_Dropdown.Value = app.Average_DropdownOld;
            app.xAxis_Dropdown.Value = app.xAxis_DropDownOld;                
            return;
        end

        %Update Plot Options Table
        updatePlotOptTable(app)  
        end

        % Menu selected function: SaveplotdataMenu
        function SavePlotDataMenuSelected(app, event)
        % Update plots 
        UpdatePlotButtonPushed(app,event)

         % Initialize header vector and data matrix with column of x data
        pre_Hdr = '# Reactive tracer design'; % An extra line that can store avg C for distance case 
        HdrVector = app.xAxis_Dropdown.Value ;
        dataMatrix = app.ReaPlots(1).XData';

        % Add avg conc.s (rea & con) at production well if appropriate
        % (not implemented because means are not available)
        % if and(app.Average_Dropdown.Value == "Yes", app.xAxis_Dropdown.Value == "Distance")
        %     pre_Hdr =strcat(pre_Hdr, "; C_rea_mean = ", num2str(app.C_rea_mean), "; C_con_mean = ", num2str(app.C_con_mean));
        % end        
        
        % Concentration plots output depends on radio button selection
        if app.PlotOptPnlCurveChoiceBtnReaAndCon.Value == true           
            for i = 1 : app.nChn
                HdrVector = strcat(HdrVector, ', ', app.ReaPlots(i).DisplayName) ;
                dataMatrix = [dataMatrix, app.ReaPlots(i).YData'] ;
            end
            for i = 1 : app.nChn
                HdrVector = strcat(HdrVector, ', ', app.ConPlots(i).DisplayName) ;                
                dataMatrix = [dataMatrix, app.ConPlots(i).YData'];
            end
        elseif app.PlotOptPnlCurveChoiceBtnRea.Value == true                     
            for i = 1 : app.nChn
                HdrVector = strcat(HdrVector, ', ', app.ReaPlots(i).DisplayName) ;                
                dataMatrix = [dataMatrix, app.ReaPlots(i).YData'];
            end            
        else %PlotOptPnlCurveChoiceBtnRatio.Value == true            
            for i = 1 : app.nChn
                HdrVector = strcat(HdrVector, ', ', app.RatioPlots(i).DisplayName) ;                            
                dataMatrix = [dataMatrix, app.RatioPlots(i).YData'];
            end
        end            

        % Add temperature profiles
        HdrVector = strcat(HdrVector, ', ', 'Distance') ;
        dataMatrix = [dataMatrix, app.TempPlots(1).XData'];            
        for i = 1 : app.nChn
            HdrVector = strcat(HdrVector, ', ', app.TempPlots(i).DisplayName);                
            dataMatrix = [dataMatrix, app.TempPlots(i).YData'];
        end

        % Save headers and data to file 
        [savefile,savepath] = uiputfile('*.*');
        if ~savefile==0
            saveFileFull = [savepath savefile];
            writematrix(pre_Hdr,saveFileFull)  ;              
            writecell(split(HdrVector,',')',saveFileFull,'Delimiter',',','WriteMode','append') ;
            writematrix(dataMatrix,saveFileFull,'Delimiter',',','WriteMode','append') ;
        end      
        end

        % Menu selected function: ViewMenu
        function ViewMenuSelected(app, event)
            app.CommonVars_Hndl = TAT08CommonVars;
        end

        % Menu selected function: SendtoMenu
        function SendtoMenuSelected(app, event)
            app.CommonVars_Hndl = TAT08CommonVars;

            app.CommonVars_Hndl.T_res_EditField.Value    = app.T_res_EditField.Value;
            app.CommonVars_Hndl.T_inj_EditField.Value    = app.T_inj_EditField.Value;
            app.CommonVars_Hndl.cp_r_EditField.Value     = app.cp_r_EditField.Value;
            app.CommonVars_Hndl.rho_r_EditField.Value    = app.rho_r_EditField.Value;

            app.CommonVars_Hndl.Q_EditField.Value        = app.Q_EditField.Value;
            app.CommonVars_Hndl.delta_p_EditField.Value  = app.delta_p_EditField.Value;
            app.CommonVars_Hndl.t_op_EditField.Value     = app.t_op_EditField.Value;
            app.CommonVars_Hndl.M_con_EditField.Value    = app.M_con_EditField.Value;
            app.CommonVars_Hndl.M_rea_EditField.Value    = app.M_rea_EditField.Value;
            app.CommonVars_Hndl.alpha_c_EditField.Value  = app.alpha_c_EditField.Value;
            app.CommonVars_Hndl.alpha_m_EditField.Value  = app.alpha_m_EditField.Value;
            app.CommonVars_Hndl.Arr_A_EditField.Value    = app.Arr_A_EditField.Value;
            app.CommonVars_Hndl.Arr_Ea_EditField.Value   = app.Arr_Ea_EditField.Value;         

            % This app's table: {'Channel','v','L','lambda_r','Q','M_con','M_rea','t_op','time','A','Ea','alpha','b','Q_frac','Include'}
            % CommonVars table: {'Channel','v','L','alpha','b','f','Include'}   
            app.CommonVars_Hndl.Channel_UITable.Data  = [] ;     
            app.CommonVars_Hndl.Channel_UITable.Data = app.paramTableDat(:,{'Channel','v','L','alpha','b','Q_frac','Include'}) ;  
            app.CommonVars_Hndl.Channel_UITable.Data.Properties.VariableNames = {'Channel','v','L','alpha','b','f','Include'} ;            
        end

        % Menu selected function: PullfromMenu
        function PullfromMenuSelected(app, event)
            app.CommonVars_Hndl = TAT08CommonVars;

            app.T_res_EditField.Value      = app.CommonVars_Hndl.T_res_EditField.Value;
            app.T_inj_EditField.Value      = app.CommonVars_Hndl.T_inj_EditField.Value;
            app.cp_r_EditField.Value       = app.CommonVars_Hndl.cp_r_EditField.Value;
            app.rho_r_EditField.Value      = app.CommonVars_Hndl.rho_r_EditField.Value;

            app.Q_EditField.Value          = app.CommonVars_Hndl.Q_EditField.Value;
            app.delta_p_EditField.Value    = app.CommonVars_Hndl.delta_p_EditField.Value;
            app.t_op_EditField.Value       = app.CommonVars_Hndl.t_op_EditField.Value;
            app.M_con_EditField.Value      = app.CommonVars_Hndl.M_con_EditField.Value;
            app.M_rea_EditField.Value      = app.CommonVars_Hndl.M_rea_EditField.Value;
            app.alpha_c_EditField.Value    = app.CommonVars_Hndl.alpha_c_EditField.Value;
            app.alpha_m_EditField.Value    = app.CommonVars_Hndl.alpha_m_EditField.Value;
            app.Arr_A_EditField.Value      = app.CommonVars_Hndl.Arr_A_EditField.Value;
            app.Arr_Ea_EditField.Value     = app.CommonVars_Hndl.Arr_Ea_EditField.Value;   

            % Collect channel data (if CommonVars table not empty)
            if height(app.CommonVars_Hndl.Channel_UITable.Data) > 0
                % Get number of active channels
                app.nChn = height(app.CommonVars_Hndl.Channel_UITable.Data);
                % Pull values from table, creating data where necessary
                % This table is        {'Channel','v','L','lambda_r','Q','M_con','M_rea','t_op','time','A','Ea','alpha','b','Q_frac','Include'}
                % CommonVars table is   Channel,v,L,alpha,b,f,Include
                app.paramTableDat = app.CommonVars_Hndl.Channel_UITable.Data(:,...
                    {'Channel','v','L','L','L','L','L','L','L','L','L','alpha','b','f','Include'}) ; 
                app.paramTableDat.Properties.VariableNames = ...
                    {'Channel','v','L','lambda_r','Q','M_con','M_rea','t_op','time','A','Ea','alpha','b','Q_frac','Include'} ;
        
                % Fill in missing columns
                app.paramTableDat.Q(1:app.nChn) =           app.Q_EditField.Value;
                app.paramTableDat.M_con(1:app.nChn) =       app.M_con_EditField.Value;
                app.paramTableDat.M_rea(1:app.nChn) =       app.M_rea_EditField.Value;
                app.paramTableDat.t_op(1:app.nChn) =        app.t_op_EditField.Value;
                app.paramTableDat.time(1:app.nChn) =        app.time_EditField.Value;
                app.paramTableDat.Arr_A(1:app.nChn) =       app.Arr_A_EditField.Value;
                app.paramTableDat.Arr_Ea(1:app.nChn) =      app.Arr_Ea_EditField.Value;
                app.paramTableDat.alpha(1:app.nChn) =       app.alpha_c_EditField.Value .* app.paramTableDat.L .^  app.alpha_m_EditField.Value;
            end
            
            % Update Parameter Table, Parameter Panel, x-axis of plot
            %MAP These global variables are called at the start of the update function, unfortunately, so have to be updated here. Should be updated in the update func    
            app.T_res = app.T_res_EditField.Value ;
            app.del_p = app.delta_p_EditField.Value ;                   
            % Update global vector variables with altered paramTableDat data
            TempArray = table2array(app.paramTableDat(:,2:15)) ;
            TempCell = num2cell(TempArray,1) ;
            [app.v,app.L,app.lambda_r,app.Q,app.M_con,app.M_rea,app.t_op,app.time,app.A,app.Ea,app.alpha,app.b,app.Q_frac,app.include] = TempCell{:} ;    
     
            % Update Parameters Table
            updateRTParamPnl(app);
    
            % Update Plot Options Table
            updatePlotOptTable(app)                         

            % Return control to self
            TAT08DesignReactiveTrTest ;     
        end

        % Menu selected function: desConservativetracerMenu
        function desConservativetracerMenuSelected(app, event)
            app.DesignConservativeTrTest_Hndl = TAT08DesignConservativeTrTest;
        end

        % Menu selected function: desTemperatureprofilesMenu
        function desTemperatureprofilesMenuSelected(app, event)
            app.DesignTemperatureProfile_Hndl = TAT08DesignTemperatureProfile;              
        end

        % Menu selected function: desReactivetracerselectionMenu
        function desReactivetracerselectionMenuSelected(app, event)
            app.DesignSelectReactiveTr_Hndl = TAT08DesignSelectReactiveTr;                                    
        end

        % Menu selected function: analConservativetracerMenu
        function analConservativetracerMenuSelected(app, event)
           app.AnalysisConservativeTrTest_Hndl = TAT08AnalysisConservativeTrTest;  
        end

        % Menu selected function: analReactivetracerMenu
        function analReactivetracerMenuSelected(app, event)
           app.AnalysisReactiveTrTest_Hndl = TAT08AnalysisReactiveTrTest;
        end

        % Value changed function: delta_p_EditField
        function delta_p_EditFieldValueChanged(app, event)
            app.del_p = app.delta_p_EditField.Value;

            % Calculate new apertures and mass flow fractions
            [app.b,app.Q_frac] = updateFraction(app,app.v,app.L,app.include,app.T_res,app.del_p, app.Average_Dropdown.Value);

            % Update Parameter Table
            updateRTParamPnl(app)            
        end

        % Cell selection callback: PlotOptionsTable
        function PlotOptionsTableCellSelection(app, event)
            indices = event.Indices;
            if and(~isempty(indices),size((indices),1)==1) %if clicked in channel row
                if(indices(2) == 2) %if 2nd column cell selected
                    colorOld = str2num(app.PlotOptionsTable.Data{indices(1),2}{1});
                    colorNew = num2str(round(uisetcolor(colorOld),3));
                    if ~strcmp(colorNew,'0') %if operation not canceled
                        app.PlotOptionsTable.Data{indices(1),2}{1} = colorNew;
                        myColorStyle = uistyle("BackgroundColor",colorNew);
                        addStyle(app.PlotOptionsTable,myColorStyle,'cell',[indices(1),indices(2)]);          
                        app.PlotOptionsTable.Selection = [indices(1),1];
                    end
                end
            end     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Design Workflow -- Reactive Tracer';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'appdata', 'TATIcon.png');
            app.FileActionsMenu = uimenu(app.UIFigure);
            app.FileActionsMenu.Text = 'File Actions';
            app.ReadparametersMenu = uimenu(app.FileActionsMenu);
            app.ReadparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadparametersMenuSelected, true);
            app.ReadparametersMenu.Text = 'Read parameters';
            app.SaveparametersMenu = uimenu(app.FileActionsMenu);
            app.SaveparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveparametersMenuSelected, true);
            app.SaveparametersMenu.Text = 'Save parameters';
            app.SaveplotdataMenu = uimenu(app.FileActionsMenu);
            app.SaveplotdataMenu.MenuSelectedFcn = createCallbackFcn(app, @SavePlotDataMenuSelected, true);
            app.SaveplotdataMenu.Text = 'Save plot data';
            app.TaskControlMenu = uimenu(app.UIFigure);
            app.TaskControlMenu.Text = 'Task Control';
            app.DesignMenu = uimenu(app.TaskControlMenu);
            app.DesignMenu.Text = 'Design';
            app.desConservativetracerMenu = uimenu(app.DesignMenu);
            app.desConservativetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @desConservativetracerMenuSelected, true);
            app.desConservativetracerMenu.Text = 'Conservative tracer';
            app.desTemperatureprofilesMenu = uimenu(app.DesignMenu);
            app.desTemperatureprofilesMenu.MenuSelectedFcn = createCallbackFcn(app, @desTemperatureprofilesMenuSelected, true);
            app.desTemperatureprofilesMenu.Text = 'Temperature profiles';
            app.desReactivetracerselectionMenu = uimenu(app.DesignMenu);
            app.desReactivetracerselectionMenu.MenuSelectedFcn = createCallbackFcn(app, @desReactivetracerselectionMenuSelected, true);
            app.desReactivetracerselectionMenu.Text = 'Reactive tracer selection';
            app.desReactivetracerMenu = uimenu(app.DesignMenu);
            app.desReactivetracerMenu.Enable = 'off';
            app.desReactivetracerMenu.Text = 'Reactive tracer';
            app.AnalysisMenu = uimenu(app.TaskControlMenu);
            app.AnalysisMenu.Text = 'Analysis';
            app.analConservativetracerMenu = uimenu(app.AnalysisMenu);
            app.analConservativetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @analConservativetracerMenuSelected, true);
            app.analConservativetracerMenu.Text = 'Conservative tracer';
            app.analReactivetracerMenu = uimenu(app.AnalysisMenu);
            app.analReactivetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @analReactivetracerMenuSelected, true);
            app.analReactivetracerMenu.Text = 'Reactive tracer';
            app.CommonVariablesMenu = uimenu(app.UIFigure);
            app.CommonVariablesMenu.Text = 'Common Variables';
            app.ViewMenu = uimenu(app.CommonVariablesMenu);
            app.ViewMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.ViewMenu.Text = 'View';
            app.SendtoMenu = uimenu(app.CommonVariablesMenu);
            app.SendtoMenu.MenuSelectedFcn = createCallbackFcn(app, @SendtoMenuSelected, true);
            app.SendtoMenu.Text = 'Send to';
            app.PullfromMenu = uimenu(app.CommonVariablesMenu);
            app.PullfromMenu.MenuSelectedFcn = createCallbackFcn(app, @PullfromMenuSelected, true);
            app.PullfromMenu.Text = 'Pull from';
            app.ReaTracerLabel = uilabel(app.UIFigure);
            app.ReaTracerLabel.Text = 'Reactive Tracer Design';
            app.ParamPanel = uipanel(app.UIFigure);
            app.ParamPanel.Title = 'Parameters';
            app.Channel_No_Label = uilabel(app.ParamPanel);
            app.Channel_No_Label.Text = 'No. Channels';
            app.xAxis_Dropdown_Label = uilabel(app.ParamPanel);
            app.xAxis_Dropdown_Label.Text = 'x-Axis for Tracer Plot';
            app.xAxis_Dropdown = uidropdown(app.ParamPanel);
            app.xAxis_Dropdown.Items = {'Time', 'Distance'};
            app.xAxis_Dropdown.ValueChangedFcn = createCallbackFcn(app, @xAxis_DropdownValueChanged, true);
            app.xAxis_Dropdown.Value = 'Time';
            app.AverageConcentrationsLabel = uilabel(app.ParamPanel);
            app.AverageConcentrationsLabel.Text = 'Average Concentrations';
            app.Average_Dropdown = uidropdown(app.ParamPanel);
            app.Average_Dropdown.Items = {'Yes', 'No'};
            app.Average_Dropdown.ValueChangedFcn = createCallbackFcn(app, @Average_DropdownValueChanged, true);
            app.Average_Dropdown.Value = 'No';
            app.AddChannel_Button = uibutton(app.ParamPanel, 'push');
            app.AddChannel_Button.ButtonPushedFcn = createCallbackFcn(app, @AddChannel_ButtonPushed, true);
            app.AddChannel_Button.Text = 'Add Channel';
            app.DeleteChannel_Button = uibutton(app.ParamPanel, 'push');
            app.DeleteChannel_Button.ButtonPushedFcn = createCallbackFcn(app, @DeleteChannel_ButtonPushed, true);
            app.DeleteChannel_Button.Text = 'Delete Channel';
            app.nChn_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.nChn_EditField.Limits = [1 10];
            app.nChn_EditField.ValueDisplayFormat = '%.0f';
            app.nChn_EditField.Editable = 'off';
            app.nChn_EditField.Value = 1;
            app.T_res_Label = uilabel(app.ParamPanel);
            app.T_res_Label.Interpreter = 'html';
            app.T_res_Label.Text = 'T<sub>res</sub>. (°C)';
            app.T_res_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.T_res_EditField.Limits = [0 Inf];
            app.T_res_EditField.ValueChangedFcn = createCallbackFcn(app, @T_res_EditFieldValueChanged, true);
            app.T_res_EditField.Value = 150;
            app.T_inj_Label = uilabel(app.ParamPanel);
            app.T_inj_Label.Interpreter = 'html';
            app.T_inj_Label.Text = 'T<sub>inj</sub>. (°C)';
            app.T_inj_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.T_inj_EditField.Limits = [0 Inf];
            app.T_inj_EditField.ValueChangedFcn = createCallbackFcn(app, @T_inj_EditFieldValueChanged, true);
            app.T_inj_EditField.Value = 100;
            app.rho_r_Label = uilabel(app.ParamPanel);
            app.rho_r_Label.Interpreter = 'html';
            app.rho_r_Label.Text = '&rho; <sub>r</sub>(kg/m<sup>3</sup>)';
            app.rho_r_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.rho_r_EditField.Value = 2650;
            app.cp_r_Label = uilabel(app.ParamPanel);
            app.cp_r_Label.Interpreter = 'html';
            app.cp_r_Label.Text = 'cp<sub>r</sub> (J/kg/°C)';
            app.cp_r_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.cp_r_EditField.Value = 790;
            app.t_op_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.t_op_EditField.Limits = [0 Inf];
            app.t_op_EditField.Visible = 'off';
            app.t_op_EditField.Value = 3650;
            app.t_op_Label = uilabel(app.ParamPanel);
            app.t_op_Label.Visible = 'off';
            app.t_op_Label.Interpreter = 'html';
            app.t_op_Label.Text = 't<sub>op</sub> (days)';
            app.paramTable = uitable(app.ParamPanel);
            app.paramTable.ColumnName = {'Channel'; 'v'; 'L'; 'lambda-r'; 'Q'; 'M_con'; 'M_rea'; 't_op'; 'A'; 'Ea'; 'alpha'; 'b'; 'f'; 'Include'};
            app.paramTable.RowName = {};
            app.paramTable.ColumnEditable = [true true true true true true true true true true false false false true];
            app.paramTable.CellEditCallback = createCallbackFcn(app, @paramTableCellEdit, true);
            app.paramTable.Tooltip = {'v: velocity (m/s)'; 'L: path length (m)'; 'alpha: calculated dispersivity (m)'; 'lambda: thermal conductivity (J/m/K)'; 'b: aperture'; 'Q: pumping rate (m^3/s)'; 't_op: operating time'; 'Mc: Mass conservative tracer (kg)'; 'Mr: Mass reactive tracer (kg)'; 'A: Arrhenius parameter (1/s)'; 'Ea: Arrhenius parameter (kJ/Mol)'};
            app.Arr_A_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.Arr_A_EditField.Visible = 'off';
            app.Arr_A_EditField.Value = 4.54;
            app.Arr_A_Label = uilabel(app.ParamPanel);
            app.Arr_A_Label.Visible = 'off';
            app.Arr_A_Label.Text = 'A (1/s)';
            app.Arr_Ea_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.Arr_Ea_EditField.Visible = 'off';
            app.Arr_Ea_EditField.Value = 60;
            app.Arr_Ea_Label = uilabel(app.ParamPanel);
            app.Arr_Ea_Label.Visible = 'off';
            app.Arr_Ea_Label.Text = 'Ea (kJ/Mol)';
            app.Q_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.Q_EditField.Visible = 'off';
            app.Q_EditField.Value = 1;
            app.Q_Label = uilabel(app.ParamPanel);
            app.Q_Label.Visible = 'off';
            app.Q_Label.Interpreter = 'html';
            app.Q_Label.Text = 'Q (m<sup>3</sup>/s)';
            app.M_con_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.M_con_EditField.Visible = 'off';
            app.M_con_EditField.Value = 1;
            app.M_con_Label = uilabel(app.ParamPanel);
            app.M_con_Label.Visible = 'off';
            app.M_con_Label.Interpreter = 'html';
            app.M_con_Label.Text = 'M<sub>con</sub> (kg)';
            app.M_rea_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.M_rea_EditField.Visible = 'off';
            app.M_rea_EditField.Value = 1;
            app.M_rea_Label = uilabel(app.ParamPanel);
            app.M_rea_Label.Visible = 'off';
            app.M_rea_Label.Interpreter = 'html';
            app.M_rea_Label.Text = 'M<sub>rea</sub> (kg)';
            app.UpdatePlotButton_2 = uibutton(app.ParamPanel, 'push');
            app.UpdatePlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton_2.Text = 'Calculate & Plot';
            app.delta_p_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.delta_p_EditField.Limits = [0 Inf];
            app.delta_p_EditField.ValueDisplayFormat = '%5.2e';
            app.delta_p_EditField.ValueChangedFcn = createCallbackFcn(app, @delta_p_EditFieldValueChanged, true);
            app.delta_p_EditField.Value = 9.81;
            app.delta_p_Label = uilabel(app.ParamPanel);
            app.delta_p_Label.Interpreter = 'html';
            app.delta_p_Label.Text = '&Delta;p (kpa)';
            app.alpha_c_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.alpha_c_EditField.Limits = [0 1];
            app.alpha_c_EditField.Value = 0.085;
            app.alpha_m_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.alpha_m_EditField.Limits = [0 3];
            app.alpha_m_EditField.Value = 0.81;
            app.alphs_c_Label = uilabel(app.ParamPanel);
            app.alphs_c_Label.Text = 'c';
            app.alpha_m_Label = uilabel(app.ParamPanel);
            app.alpha_m_Label.Text = 'm';
            app.DispersivityalphacLsupmsupLabel = uilabel(app.ParamPanel);
            app.DispersivityalphacLsupmsupLabel.Interpreter = 'html';
            app.DispersivityalphacLsupmsupLabel.Text = 'Dispersivity (&alpha; = c L<sup>m</sup>)';
            app.time_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.time_EditField.Limits = [0 Inf];
            app.time_EditField.ValueDisplayFormat = '%5.2e';
            app.time_EditField.Visible = 'off';
            app.time_EditField.Value = 10;
            app.Time_Label = uilabel(app.ParamPanel);
            app.Time_Label.Visible = 'off';
            app.Time_Label.Interpreter = 'html';
            app.Time_Label.Text = 'time (days)';
            app.PlotOptionsPanel = uipanel(app.UIFigure);
            app.PlotOptionsPanel.Title = 'Plotting Options';
            app.T_max_EditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.T_max_EditField.Limits = [0 Inf];
            app.T_max_EditField.Value = Inf;
            app.T_min_EditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.T_min_EditField.Limits = [0 Inf];
            app.c_max_EditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.c_max_EditField.Limits = [0 Inf];
            app.c_max_EditField.Value = Inf;
            app.c_min_EditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.c_min_EditField.Limits = [0 Inf];
            app.time_max_EditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.time_max_EditField.Limits = [0 Inf];
            app.time_max_EditField.Value = 365;
            app.time_min_EditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.time_min_EditField.Limits = [0 Inf];
            app.PlotOptions_T_Label = uilabel(app.PlotOptionsPanel);
            app.PlotOptions_T_Label.Text = 'Temp.';
            app.PlotOptions_c_Label = uilabel(app.PlotOptionsPanel);
            app.PlotOptions_c_Label.Text = 'Conc.';
            app.PlotOptions_t_Label = uilabel(app.PlotOptionsPanel);
            app.PlotOptions_t_Label.Text = 'Time';
            app.PlotOptions_Min_Label = uilabel(app.PlotOptionsPanel);
            app.PlotOptions_Min_Label.Text = 'Min';
            app.PlotOptions_Max_Label = uilabel(app.PlotOptionsPanel);
            app.PlotOptions_Max_Label.Text = 'Max';
            app.PlotOptPnlCurveChoiceRadioBtn = uibuttongroup(app.PlotOptionsPanel);
            app.PlotOptPnlCurveChoiceRadioBtn.SelectionChangedFcn = createCallbackFcn(app, @designPlotOptPnlCurveChoiceBtn, true);
            app.PlotOptPnlCurveChoiceRadioBtn.Title = 'Tracer Plots';
            app.PlotOptPnlCurveChoiceBtnRea = uiradiobutton(app.PlotOptPnlCurveChoiceRadioBtn);
            app.PlotOptPnlCurveChoiceBtnRea.Text = 'Reactive';
            app.PlotOptPnlCurveChoiceBtnRea.Value = true;
            app.PlotOptPnlCurveChoiceBtnReaAndCon = uiradiobutton(app.PlotOptPnlCurveChoiceRadioBtn);
            app.PlotOptPnlCurveChoiceBtnReaAndCon.Text = 'Reactive and Conservative';
            app.PlotOptPnlCurveChoiceBtnRatio = uiradiobutton(app.PlotOptPnlCurveChoiceRadioBtn);
            app.PlotOptPnlCurveChoiceBtnRatio.Text = 'Ratio (R/C)';
            app.UpdatePlotButton = uibutton(app.PlotOptionsPanel, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton.Text = 'Update Plots with Current  Options';
            app.PlotOptionsTable = uitable(app.PlotOptionsPanel);
            app.PlotOptionsTable.ColumnName = {'Channel'; 'Color (RGB)'; 'Line Width'; 'Style'};
            app.PlotOptionsTable.RowName = {};
            app.PlotOptionsTable.ColumnEditable = [false true true true];
            app.PlotOptionsTable.CellSelectionCallback = createCallbackFcn(app, @PlotOptionsTableCellSelection, true);
            app.EqHelpButton = uibutton(app.UIFigure, 'push');
            app.EqHelpButton.ButtonPushedFcn = createCallbackFcn(app, @EqHelpButtonPushed, true);
            app.EqHelpButton.Text = 'Equation Help';
            app.Eq = uiimage(app.UIFigure);
            app.Eq.ImageSource = fullfile(pathToMLAPP, 'appdata', 'ReactiveTracerEq.png');
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plots';
            app.ConPlot_Axes = uiaxes(app.PlotPanel);
            title(app.ConPlot_Axes, 'Reactive Tracer BTC')
            xlabel(app.ConPlot_Axes, 'Time')
            ylabel(app.ConPlot_Axes, 'Concentration (ug/kg)')
            zlabel(app.ConPlot_Axes, 'Z')
            app.Temp_Axes = uiaxes(app.PlotPanel);
            title(app.Temp_Axes, 'Temperature Profile')
            xlabel(app.Temp_Axes, 'Relative Distance')
            ylabel(app.Temp_Axes, 'Temperature (oC)')
            zlabel(app.Temp_Axes, 'Z')
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.AverageRateCoefficientsLabel = uilabel(app.OutputPanel);
            app.AverageRateCoefficientsLabel.Text = 'Average Rate Coefficients';
            app.OutputTable = uitable(app.OutputPanel);
            app.OutputTable.ColumnName = {'Channel'; 'k_bar'};
            app.OutputTable.RowName = {};
            app.OutputTable.ColumnEditable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TAT08DesignReactiveTrTest

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end