classdef TAT08DesignSelectReactiveTr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileActionsMenu                 matlab.ui.container.Menu
        SaveparametersMenu              matlab.ui.container.Menu
        ReadparametersMenu              matlab.ui.container.Menu
        TaskControlMenu                 matlab.ui.container.Menu
        DesignMenu                      matlab.ui.container.Menu
        desConservativetracerMenu       matlab.ui.container.Menu
        desTemperatureprofilesMenu      matlab.ui.container.Menu
        desReactivetracerselectionMenu  matlab.ui.container.Menu
        desReactivetracerMenu           matlab.ui.container.Menu
        AnalysisMenu                    matlab.ui.container.Menu
        analConservativetracerMenu      matlab.ui.container.Menu
        analReactivetracerMenu          matlab.ui.container.Menu
        CommonVariablesMenu             matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        SendtoMenu                      matlab.ui.container.Menu
        PullfromMenu                    matlab.ui.container.Menu
        TableofparametersfortracersmeetingcriteriaLabel  matlab.ui.control.Label
        designTrSelTracerSelectionLabel  matlab.ui.control.Label
        InputValuesLabel                matlab.ui.control.Label
        MonitoringCriteriaLabel         matlab.ui.control.Label
        MinCC0EditField                 matlab.ui.control.NumericEditField
        MinCC0Label                     matlab.ui.control.Label
        MaxCC0ofconservativetracerLabel  matlab.ui.control.Label
        MaxCC0ofconservativetracerEditField  matlab.ui.control.NumericEditField
        DeltaCC0GoalEditField           matlab.ui.control.NumericEditField
        DeltaCC0GoalLabel               matlab.ui.control.Label
        IdentifySuitableTracersButton   matlab.ui.control.Button
        ParamPnlUITable                 matlab.ui.control.Table
        ReservoirParametersLabel        matlab.ui.control.Label
        PlotsSuitableTracersPanel       matlab.ui.container.Panel
        MaxlikelyDeltaTCEditField       matlab.ui.control.NumericEditField
        MaxlikelyDeltaTCEditFieldLabel  matlab.ui.control.Label
        ParamPnl_t_travel_EditField     matlab.ui.control.NumericEditField
        FlowpathsresidencetimedaysLabel  matlab.ui.control.Label
        ParamPnl_T_res_EditField        matlab.ui.control.NumericEditField
        FlowpathstemperatureCLabel      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Startup *******************************************************************************************
        TATPath                         % Path to Tracer Analysis Toolbox
        TATName                         % Name of Tracer Analysis Toolbox file
        TAText                          % Extension for Tracer Analysis Toolbox file
        MLDefaultColors                 % Vector of RGB codes for line default colors us in Matlab
        hScale                          % Horizontal scaling factor for monitor resolution differences
        vScale                          % Vertical scaling factor for monitor resolution differences
        
        TAT_Tracer_Data % Description        

        CommonVars_Hndl                  
        DesignConservativeTrTest_Hndl
        DesignTemperatureProfile_Hndl
        DesignReactiveTrTest_Hndl
        AnalysisConservativeTrTest_Hndl
        AnalysisReactiveTrTest_Hndl        
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get Screen size and define scaling factors                                             
            screenSize = get(groot,'ScreenSize');
            % app.hScale = screenSize(3)/2560;                
            % app.vScale = screenSize(4)/1440;

            % Get location of app
            [app.TATPath, app.TATName, app.TAText] = fileparts(mfilename('fullpath'));
            
            % Add path to functions
            addPathNames = strcat(app.TATPath,"\TAT_Fcts");
            addPathNames = strcat(app.TATPath,"\appdata");            
            addpath(genpath(addPathNames));            
        end

        % Callback function: IdentifySuitableTracersButton, 
        % ...and 1 other component
        function IdentifySuitableTracersButtonPushed(app, event)
            %--------------------------------------------------------------
            %Code to show how different tracers would behave in a specified flowpath
            %system, and focus on behavior of those meeting user-specified req's
            
            %Constants
            DB = load('appdata/TAT_Tracer_Data.mat');
            R = 8.3145;
            T_K_0C = 273.15;
            secperday = 86400;  
            
            %Parameters input by user
            T_K_eff = T_K_0C + app.ParamPnl_T_res_EditField.Value; % Effective flowpath temperature
            t_travel = secperday * app.ParamPnl_t_travel_EditField.Value; %Residence time in seconds       
            C_rel_TrCons = app.MaxCC0ofconservativetracerEditField.Value;
            delta_T = app.MaxlikelyDeltaTCEditField.Value;            
            delta_C_goal = app.DeltaCC0GoalEditField.Value; %Target change in C/C0
            Crel_min = app.MinCC0EditField.Value; %Minimum useful C/C0 of reactive tracer (neglecting cons effect)

            % Set minimum value for decay at T_res
            delta_T_useful = [-0.1,-delta_T]; %Range of temperature change considered useful            
            delta_T_max = -10; % A max delta T to use in plots
            
            %Functions for change in tracer concentration
            %Change in C/C0 at initial reservoir temperature
            loss_func = @(T, t_res, A, Ea) C_rel_TrCons * exp(-t_res .* A .* exp(-Ea./(R*T)));
            %Temperature change needed to effect target change in C/C0
            delta_T_func = @(T, t_res, A, Ea) (Ea + T .* R .* log(-log(-exp(-A .* t_res .* exp(-Ea./(R.*T))).*(delta_C_goal-1))./(A.*t_res)))./...
                                                (R .* log(-log(-exp(-A .* t_res .* exp(-Ea./(R.*T))).*(delta_C_goal-1))./(A.*t_res))) ;
            %Change in C/C0 effected by a specified effective flowpath temperature change
            delta_C_func = @(delta_T, T, t_res, A, Ea) (exp(-t_res.*A.*(exp(-Ea./(R*(T))))) - exp(-t_res.*A.*exp(-Ea./(R*(T-delta_T))))) ./ exp(-t_res.*A.*exp(-Ea./(R*T))) ;   
            
            %Reservoir temperature range for plotting
            N = 200;
            T_K_eff_min = 25 + T_K_0C;
            T_K_eff_max = 250 + T_K_0C;
            T_K_eff_vec = linspace(T_K_eff_min, T_K_eff_max, N)'; 
            
            %Create labels by combining label info
            labelset = strcat("#",(string((1:height(DB.TAT_Tracer_Data))')),": ", DB.TAT_Tracer_Data.Compound);
            
            %Function results for T vector
            delta_T_array = delta_T_func(T_K_eff_vec, t_travel,...
                                          exp(DB.TAT_Tracer_Data.PreexponentialFactorLnA1s'),...
                                          10^3.*DB.TAT_Tracer_Data.ActivationEnergykJmolEa');
            
            %% Find compounds with reasonable reaction rate (lose less than __% initially, change is greater than goal)
            loss_test = loss_func(T_K_eff, t_travel, exp(DB.TAT_Tracer_Data.PreexponentialFactorLnA1s'), 10^3.*DB.TAT_Tracer_Data.ActivationEnergykJmolEa' );
            delta_T_test = delta_T_func( T_K_eff, t_travel, exp(DB.TAT_Tracer_Data.PreexponentialFactorLnA1s'), 10^3.*DB.TAT_Tracer_Data.ActivationEnergykJmolEa' );
            delta_C_test = delta_C_func(delta_T_test, T_K_eff, t_travel,...
                                          exp(DB.TAT_Tracer_Data.PreexponentialFactorLnA1s'),...
                                          10^3.*DB.TAT_Tracer_Data.ActivationEnergykJmolEa');
            
            useful = (loss_test > Crel_min & delta_T_test < delta_T_useful(1) & delta_T_test > delta_T_useful(2));
            useful_indices = find((loss_test > Crel_min & delta_T_test < delta_T_useful(1)) & delta_T_test > delta_T_useful(2));

            %% Tabular output
            app.ParamPnlUITable.Data =table( strcat(string(useful_indices))',...
                                DB.TAT_Tracer_Data.Compound(useful_indices),...
                                DB.TAT_Tracer_Data.CASRegistryNumber(useful_indices),...
                                DB.TAT_Tracer_Data.PreexponentialFactorLnA1s(useful_indices),...
                                DB.TAT_Tracer_Data.ActivationEnergykJmolEa(useful_indices),...
                                delta_C_test(useful_indices)',...
                                delta_T_test(useful_indices)',...
                                loss_test(useful_indices)',...  
                                C_rel_TrCons*loss_test(useful_indices)' );  
            app.ParamPnlUITable.ColumnName = {'DB#','Compound','CAS#','Ea (kJ/Mol)','A (1/s)','Delta Crel','Delta Teff','Crel_reac','Crel_total'};
            app.ParamPnlUITable.Data.Properties.VariableNames = {'DBnum','Compound','CASnum','Arr_Ea','Arr_A','Delta Crel','Delta Teff','Crel_reac','Crel_total'} ;
            %% Plots
            %Curves for useful compounds
            t = tiledlayout(app.PlotsSuitableTracersPanel,1,4);
            sgtitle(t, ['Flowpath Conditions: T_e_f_f=',num2str(T_K_eff-T_K_0C),'°C; t_r_e_s=',num2str(t_travel/secperday),' days'],'FontSize',10);
            ax1 = nexttile(t);
                x = categorical(labelset(useful_indices));
                x = reordercats(x,labelset(useful_indices));
                y = [loss_test(useful_indices)', loss_test(useful_indices)'.*(delta_C_test(useful_indices))'];
                b = bar(ax1, x,y,'stacked', 'FaceColor','flat')';
                b(1).CData = lines(length(useful_indices));
                b(2).FaceColor = 'cyan';
                ylabel(ax1,'Tracer recovery (C/C_0)','FontSize',9)
                title(ax1, {'Recovery before & after ', [num2str(100*delta_C_goal), '% change in C/C_0']},'FontSize',8);
                grid(ax1,"on")
            
            ax2 = nexttile(t);
                hold(ax2,"off");
                I = 50; i = 0 : I; 
                delta_T_vec =  i./I .* delta_T_max ;
                for j = 1 : length(useful_indices)
                    delta_C_plot = loss_func(T_K_eff + delta_T_vec, t_travel,...
                                                exp(DB.TAT_Tracer_Data.PreexponentialFactorLnA1s(useful_indices(j))),...
                                                10^3.*DB.TAT_Tracer_Data.ActivationEnergykJmolEa(useful_indices(j)));
                    plot(ax2,delta_T_vec, 100 * delta_C_plot,'linewidth',2)
                    hold(ax2,"on");
                end
                grid(ax2,"on")                  
                title(ax2,{'C/C_0 vs','cooling [°C]'},'FontSize',8)
                xlabel(ax2,'Temperature change [°C]','FontSize',9)
                ylabel(ax2,'C/C_0 [%]','FontSize',9)            
            
            ax3 = nexttile(t);
                hold(ax3,'off');
                I = 50; i = 0 : I; 
                delta_T_vec =  i./I .* delta_T_max ;
                for j = 1 : length(useful_indices)
                    delta_C_plot = delta_C_func(delta_T_vec, T_K_eff, t_travel,...
                                                exp(DB.TAT_Tracer_Data.PreexponentialFactorLnA1s(useful_indices(j))),...
                                                10^3.*DB.TAT_Tracer_Data.ActivationEnergykJmolEa(useful_indices(j)));
                    plot(ax3,delta_T_vec, 100*delta_C_plot,'linewidth',2)
                    hold(ax3,"on");
                end
                title(ax3,{'Change in C/C_0','vs cooling [°C]'},'FontSize',8)
                xlabel(ax3,'Temperature change [°C]','FontSize',9)
                ylabel(ax3,'Delta C/C_0 [%]','FontSize',9)
                grid(ax3,'on')
                lgd = legend(ax2,labelset(useful_indices),'location','best');
                lgd.Layout.Tile = 4;
            
            %Data from all compounds                
                hold(app.UIAxes,"off")
                keyplot = semilogy(app.UIAxes, T_K_eff_vec-T_K_0C, -delta_T_array, 'Color','[0.5 0.5 0.5]');
                ylim(app.UIAxes,[.1,100])
                xlim(app.UIAxes,[T_K_eff_min-T_K_0C,T_K_eff_max-T_K_0C])
                xl = xline(app.UIAxes,T_K_eff-T_K_0C,'--','T_e_f_f');
                    xl.LabelVerticalAlignment = 'middle';
                    xl.LabelHorizontalAlignment = 'center';
                yl = yline(app.UIAxes,delta_T,'--','Max Delta T');
                    yl.LabelVerticalAlignment = 'top';
                    yl.LabelHorizontalAlignment = 'left';
    
                title(app.UIAxes,['T change to produce ', num2str(100*delta_C_goal), '% change in C/C_0'])
                subtitle(app.UIAxes,'Select curve to identify tracer')
                grid(app.UIAxes,"on")
                box(app.UIAxes,'on')
                %Set legend then turn off so that it stays available to data tip
                legend(app.UIAxes,labelset,'Location', 'eastoutside', 'NumColumns',1); 
                legend(app.UIAxes,'off');  

                %Define a custom data tip that identifies compound              
                for i = 1:numel(keyplot)
                    % Add a new row to DataTip showing the DisplayName of the line
                    keyplot(i).DataTipTemplate.DataTipRows(end+1) = dataTipTextRow('Compound: ',repmat({keyplot(i).DisplayName},size(keyplot(i).XData))); 
                end   

                %Make the 'useful' curves bold
                hold(app.UIAxes,"on")
                c = colororder;
                n = 0;
                for i = useful_indices
                    n = n + 1;
                    n = mod(n-1,length(c))+1;
                    keyplot(i).LineWidth = 3;
                    keyplot(i).Color = c(n,:);
                end

                %Define a custom data tip that identifies compound
                %dcm = datacursormode(app.UIFigure); %applies to figure not specific axes                
                %dcm.UpdateFcn = @customdatatip;                   
                %Function for custom data tip  
                % function output_txt = customdatatip(obj, event_obj, str)
                %     pos = get(event_obj, 'Position');
                %     output_txt = {...
                %         ['Initial T[°C]: ', num2str(pos(1),4)]...
                %         ['Delta T[-°C]: ', num2str(pos(2),4)] ...
                %         ['Compound: ', event_obj.Target.DisplayName]...
                %     };
                % end

            %Change color of button to indicate synch state
            app.IdentifySuitableTracersButton.BackgroundColor=[0.8,0.8,0.8];

        end

        % Menu selected function: SaveparametersMenu
        function SaveparametersMenuSelected(app, event)
            % Select file to save to
            [filename,pathname] = uiputfile('*.*');
            if isequal(filename,0)
                return;
            end
            fileNamefull = fullfile(pathname,filename);    

            % Read the preexisting structure if file exists
            if isfile(fileNamefull)
                Struct = read_namelist(fileNamefull);
            end
          
            % Define the relevant structure pieces
            Struct.DesignSelectReactiveTr.T_res                         = app.ParamPnl_T_res_EditField.Value            ;
            Struct.DesignSelectReactiveTr.t_travel                      = app.ParamPnl_t_travel_EditField.Value         ;
            Struct.DesignSelectReactiveTr.MaxCC0ofconservativetracer    = app.MaxCC0ofconservativetracerEditField.Value ;
            Struct.DesignSelectReactiveTr.MaxlikelyDeltaTC              = app.MaxlikelyDeltaTCEditField.Value	        ;
            Struct.DesignSelectReactiveTr.DeltaCC0Goal   	            = app.DeltaCC0GoalEditField.Value	            ;
            Struct.DesignSelectReactiveTr.MinCC0   	                    = app.MinCC0EditField.Value	                	;              

            % Data table
            Struct.DesignSelectReactiveTr.DBnum         = app.ParamPnlUITable.Data{:,1};
            Struct.DesignSelectReactiveTr.Compound      = cellstr(app.ParamPnlUITable.Data{:,2});
            Struct.DesignSelectReactiveTr.CASnum        = cellstr(app.ParamPnlUITable.Data{:,3});
            Struct.DesignSelectReactiveTr.Arr_A         = app.ParamPnlUITable.Data{:,4};
            Struct.DesignSelectReactiveTr.Arr_Ea        = app.ParamPnlUITable.Data{:,5};
            Struct.DesignSelectReactiveTr.delta_c_rel   = app.ParamPnlUITable.Data{:,6};
            Struct.DesignSelectReactiveTr.delta_T_eff   = app.ParamPnlUITable.Data{:,7};

            % Now overwrite the existing file
            write_namelist(Struct,fileNamefull);
        end

        % Menu selected function: ReadparametersMenu
        function ReadparametersMenuSelected(app, event)
            % Select file to read
            [filename,pathname] = uigetfile('*.*');
            if isequal(filename,0)
                return;
            end
            fileNamefull = fullfile(pathname,filename);    

            % Read the structure 
            Struct = read_namelist(fileNamefull);
          
            % Use the pieces to define the relevant field
            app.ParamPnl_T_res_EditField.Value	            = Struct.DesignSelectReactiveTr.T_res   	                    ;
            app.ParamPnl_t_travel_EditField.Value	        = Struct.DesignSelectReactiveTr.t_travel   	                    ;
            app.MaxCC0ofconservativetracerEditField.Value	= Struct.DesignSelectReactiveTr.MaxCC0ofconservativetracer   	;
            app.MaxlikelyDeltaTCEditField.Value	            = Struct.DesignSelectReactiveTr.MaxlikelyDeltaTC                ;
            app.DeltaCC0GoalEditField.Value	                = Struct.DesignSelectReactiveTr.DeltaCC0Goal   	                ;
            app.MinCC0EditField.Value	                    = Struct.DesignSelectReactiveTr.MinCC0   	                    ;                           

            % Table not read; easier to recalculate from saved parameters

        end

        % Menu selected function: desConservativetracerMenu
        function desConservativetracerMenuSelected(app, event)
            app.DesignConservativeTrTest_Hndl = TAT08DesignConservativeTrTest;
        end

        % Menu selected function: desTemperatureprofilesMenu
        function desTemperatureprofilesMenuSelected(app, event)
            app.DesignTemperatureProfile_Hndl = TAT08DesignTemperatureProfile;            
        end

        % Menu selected function: desReactivetracerMenu
        function desReactivetracerMenuSelected(app, event)
            app.DesignReactiveTrTest_Hndl = TAT08DesignReactiveTrTest;            
        end

        % Menu selected function: analConservativetracerMenu
        function analConservativetracerMenuSelected(app, event)
            app.AnalysisConservativeTrTest_Hndl = TAT08AnalysisConservativeTrTest;
        end

        % Menu selected function: analReactivetracerMenu
        function analReactivetracerMenuSelected(app, event)
            app.AnalysisReactiveTrTest_Hndl = TAT08AnalysisReactiveTrTest;
        end

        % Menu selected function: ViewMenu
        function ViewMenuSelected(app, event)
            app.CommonVars_Hndl = TAT08CommonVars;
        end

        % Menu selected function: SendtoMenu
        function SendtoMenuSelected(app, event)
            app.CommonVars_Hndl = TAT08CommonVars;

            app.CommonVars_Hndl.T_res_EditField.Value    = app.ParamPnl_T_res_EditField.Value;
            app.CommonVars_Hndl.t_travel_EditField.Value    = app.ParamPnl_t_travel_EditField.Value;

            % Select which tracer to use
            if height(app.ParamPnlUITable.Data) > 1
                ListString = strcat("DB#:",app.ParamPnlUITable.Data.DBnum,"  ID:", app.ParamPnlUITable.Data.Compound) ;
                [indx,~] = listdlg('PromptString',{'Select database ID number of tracer to use.',...
                    'Only one tracer can be selected.',''},...
                    'SelectionMode','single','ListString', ListString,'ListSize', [300 200]);
                if isempty(indx); return ; end
            else
                indx = 1;
            end

            app.CommonVars_Hndl.Arr_A_EditField.Value   = app.ParamPnlUITable.Data.Arr_A(indx);
            app.CommonVars_Hndl.Arr_Ea_EditField.Value  = app.ParamPnlUITable.Data.Arr_Ea(indx);
        end

        % Menu selected function: PullfromMenu
        function PullfromMenuSelected(app, event)
            app.CommonVars_Hndl = TAT08CommonVars;
            app.ParamPnl_T_res_EditField.Value =        app.CommonVars_Hndl.T_res_EditField.Value;
            app.ParamPnl_t_travel_EditField.Value =     app.CommonVars_Hndl.t_travel_EditField.Value;
        end

        % Value changed function: DeltaCC0GoalEditField, 
        % ...and 5 other components
        function EditFieldValueChanged(app, event)
            app.IdentifySuitableTracersButton.BackgroundColor = 'g';     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Design Workflow -- Reactive Tracer Selection';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'appdata', 'TATIcon.png');
            app.FileActionsMenu = uimenu(app.UIFigure);
            app.FileActionsMenu.Text = 'File Actions';
            app.SaveparametersMenu = uimenu(app.FileActionsMenu);
            app.SaveparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveparametersMenuSelected, true);
            app.SaveparametersMenu.Text = 'Save parameters';
            app.ReadparametersMenu = uimenu(app.FileActionsMenu);
            app.ReadparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadparametersMenuSelected, true);
            app.ReadparametersMenu.Text = 'Read parameters';
            app.TaskControlMenu = uimenu(app.UIFigure);
            app.TaskControlMenu.Text = 'Task Control';
            app.DesignMenu = uimenu(app.TaskControlMenu);
            app.DesignMenu.Text = 'Design';
            app.desConservativetracerMenu = uimenu(app.DesignMenu);
            app.desConservativetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @desConservativetracerMenuSelected, true);
            app.desConservativetracerMenu.Text = 'Conservative tracer';
            app.desTemperatureprofilesMenu = uimenu(app.DesignMenu);
            app.desTemperatureprofilesMenu.MenuSelectedFcn = createCallbackFcn(app, @desTemperatureprofilesMenuSelected, true);
            app.desTemperatureprofilesMenu.Text = 'Temperature profiles';
            app.desReactivetracerselectionMenu = uimenu(app.DesignMenu);
            app.desReactivetracerselectionMenu.MenuSelectedFcn = createCallbackFcn(app, @IdentifySuitableTracersButtonPushed, true);
            app.desReactivetracerselectionMenu.Text = 'Reactive tracer selection';
            app.desReactivetracerMenu = uimenu(app.DesignMenu);
            app.desReactivetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @desReactivetracerMenuSelected, true);
            app.desReactivetracerMenu.Text = 'Reactive tracer';
            app.AnalysisMenu = uimenu(app.TaskControlMenu);
            app.AnalysisMenu.Text = 'Analysis';
            app.analConservativetracerMenu = uimenu(app.AnalysisMenu);
            app.analConservativetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @analConservativetracerMenuSelected, true);
            app.analConservativetracerMenu.Text = 'Conservative tracer';
            app.analReactivetracerMenu = uimenu(app.AnalysisMenu);
            app.analReactivetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @analReactivetracerMenuSelected, true);
            app.analReactivetracerMenu.Text = 'Reactive tracer';
            app.CommonVariablesMenu = uimenu(app.UIFigure);
            app.CommonVariablesMenu.Text = 'Common Variables';
            app.ViewMenu = uimenu(app.CommonVariablesMenu);
            app.ViewMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.ViewMenu.Text = 'View';
            app.SendtoMenu = uimenu(app.CommonVariablesMenu);
            app.SendtoMenu.MenuSelectedFcn = createCallbackFcn(app, @SendtoMenuSelected, true);
            app.SendtoMenu.Text = 'Send to';
            app.PullfromMenu = uimenu(app.CommonVariablesMenu);
            app.PullfromMenu.MenuSelectedFcn = createCallbackFcn(app, @PullfromMenuSelected, true);
            app.PullfromMenu.Text = 'Pull from';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Temperature change needed to produce specified change in C/Co')
            xlabel(app.UIAxes, 'Initial reservoir temperature [°C]')
            ylabel(app.UIAxes, 'Temperature Decline [-°C]')
            zlabel(app.UIAxes, 'Z')
            app.FlowpathstemperatureCLabel = uilabel(app.UIFigure);
            app.FlowpathstemperatureCLabel.Text = 'Reservoir temperature (°C)';
            app.ParamPnl_T_res_EditField = uieditfield(app.UIFigure, 'numeric');
            app.ParamPnl_T_res_EditField.Limits = [0 1000];
            app.ParamPnl_T_res_EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.ParamPnl_T_res_EditField.Value = 200;
            app.FlowpathsresidencetimedaysLabel = uilabel(app.UIFigure);
            app.FlowpathsresidencetimedaysLabel.Text = 'Flowpath(s) residence time (days)';
            app.ParamPnl_t_travel_EditField = uieditfield(app.UIFigure, 'numeric');
            app.ParamPnl_t_travel_EditField.Limits = [1e-09 1000000];
            app.ParamPnl_t_travel_EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.ParamPnl_t_travel_EditField.Value = 30;
            app.MaxlikelyDeltaTCEditFieldLabel = uilabel(app.UIFigure);
            app.MaxlikelyDeltaTCEditFieldLabel.Text = 'Max likely DeltaT (°C)';
            app.MaxlikelyDeltaTCEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxlikelyDeltaTCEditField.Limits = [0.001 1000];
            app.MaxlikelyDeltaTCEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.MaxlikelyDeltaTCEditField.Tooltip = {'Change in effective T that might occur between reactive tracer tests'};
            app.MaxlikelyDeltaTCEditField.Value = 3;
            app.PlotsSuitableTracersPanel = uipanel(app.UIFigure);
            app.PlotsSuitableTracersPanel.Title = 'Plots - Suitable Tracers';
            app.ReservoirParametersLabel = uilabel(app.UIFigure);
            app.ReservoirParametersLabel.Text = 'Reservoir Parameters';
            app.ParamPnlUITable = uitable(app.UIFigure);
            app.ParamPnlUITable.ColumnName = {'DB#'; 'Compound'; 'CAS#'; 'A (1/s)'; 'Ea (kJ/Mol)'; 'Delta Crel'; 'Delta Teff (°C)'};
            app.ParamPnlUITable.RowName = {};
            app.ParamPnlUITable.ColumnSortable = true;
            app.ParamPnlUITable.ColumnEditable = [false false false false false false false];
            app.ParamPnlUITable.Tag = 'test';
            app.IdentifySuitableTracersButton = uibutton(app.UIFigure, 'push');
            app.IdentifySuitableTracersButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifySuitableTracersButtonPushed, true);
            app.IdentifySuitableTracersButton.Tooltip = {'Color indicates sychronization state of input & output'};
            app.IdentifySuitableTracersButton.Text = 'Identify Suitable Tracers';
            app.DeltaCC0GoalLabel = uilabel(app.UIFigure);
            app.DeltaCC0GoalLabel.Text = 'Delta C/C0 Goal (-)';
            app.DeltaCC0GoalEditField = uieditfield(app.UIFigure, 'numeric');
            app.DeltaCC0GoalEditField.Limits = [1e-09 1];
            app.DeltaCC0GoalEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.DeltaCC0GoalEditField.Tooltip = {'Desired change in reactive tracer concentration between tests'};
            app.DeltaCC0GoalEditField.Value = 0.05;
            app.MaxCC0ofconservativetracerEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxCC0ofconservativetracerEditField.Limits = [1e-09 1];
            app.MaxCC0ofconservativetracerEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.MaxCC0ofconservativetracerEditField.Value = 1;
            app.MaxCC0ofconservativetracerLabel = uilabel(app.UIFigure);
            app.MaxCC0ofconservativetracerLabel.Text = 'Max C/C0 of conservative tracer (-)';
            app.MinCC0Label = uilabel(app.UIFigure);
            app.MinCC0Label.Text = 'Min C/C0 (-)';
            app.MinCC0EditField = uieditfield(app.UIFigure, 'numeric');
            app.MinCC0EditField.Limits = [1e-09 1];
            app.MinCC0EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.MinCC0EditField.Tooltip = {'Minimum C/C0 that can be measured'};
            app.MinCC0EditField.Value = 0.01;
            app.MonitoringCriteriaLabel = uilabel(app.UIFigure);
            app.MonitoringCriteriaLabel.Text = 'Monitoring Criteria';
            app.InputValuesLabel = uilabel(app.UIFigure);
            app.InputValuesLabel.Text = 'Input Values';
            app.designTrSelTracerSelectionLabel = uilabel(app.UIFigure);
            app.designTrSelTracerSelectionLabel.Text = 'Reactive Tracer Selection';
            app.TableofparametersfortracersmeetingcriteriaLabel = uilabel(app.UIFigure);
            app.TableofparametersfortracersmeetingcriteriaLabel.Text = 'Table of parameters for tracers meeting criteria';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TAT08DesignSelectReactiveTr

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end