classdef TAT08DesignTemperatureProfile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileActionsMenu                 matlab.ui.container.Menu
        SaveparametersMenu              matlab.ui.container.Menu
        ReadparametersMenu              matlab.ui.container.Menu
        SaveplottedresultsMenu          matlab.ui.container.Menu
        TaskControlMenu                 matlab.ui.container.Menu
        DesignMenu                      matlab.ui.container.Menu
        desConservativetracerMenu       matlab.ui.container.Menu
        desTemperatureprofilesMenu      matlab.ui.container.Menu
        desReactivetracerselectionMenu  matlab.ui.container.Menu
        desReactivetracerMenu           matlab.ui.container.Menu
        AnalysisMenu                    matlab.ui.container.Menu
        analConservativetracerMenu      matlab.ui.container.Menu
        analReactivetracerMenu          matlab.ui.container.Menu
        CommonVariablesMenu             matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        SendtoMenu                      matlab.ui.container.Menu
        PullfromMenu                    matlab.ui.container.Menu
        PlotPanel                       matlab.ui.container.Panel
        PlotOptionsMaxLabel             matlab.ui.control.Label
        PlotOptionsMinLabel             matlab.ui.control.Label
        PlotOptionsConcMaxEditField     matlab.ui.control.NumericEditField
        PlotOptionsTimeMaxEditField     matlab.ui.control.NumericEditField
        PlotOptionsConcMinEditField     matlab.ui.control.NumericEditField
        PlotOptionsTimeMinEditField     matlab.ui.control.NumericEditField
        PlotOptionsConcLabel            matlab.ui.control.Label
        PlotOptionsTimeLabel            matlab.ui.control.Label
        UpdateGraphsWithCurrentPlotOptionsButton  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        FlowpathtemperatureTab          matlab.ui.container.Tab
        UIaxis_T_x                      matlab.ui.control.UIAxes
        ProducedtemperatureTab          matlab.ui.container.Tab
        UIaxis_T_t                      matlab.ui.control.UIAxes
        ProducedEnthalpyTab             matlab.ui.container.Tab
        UIaxis_H_t                      matlab.ui.control.UIAxes
        Eq                              matlab.ui.control.Image
        EqHelpButton                    matlab.ui.control.Button
        ParamPanel                      matlab.ui.container.Panel
        Common_t_op_DropDown            matlab.ui.control.DropDown
        UsecommonoperatingtimeDropDownLabel  matlab.ui.control.Label
        ParamPnl_lambda_r_Label         matlab.ui.control.Label
        ParamPnl_lambda_r_EditField     matlab.ui.control.NumericEditField
        Common_lambda_r_DropDown        matlab.ui.control.DropDown
        ParamPnl_lambda_r_DropdownLabel  matlab.ui.control.Label
        ParamPnl_Q_EditField            matlab.ui.control.NumericEditField
        PumpingrateQm3sLabel            matlab.ui.control.Label
        ExecutionControlLabel           matlab.ui.control.Label
        ThermalDataLabel                matlab.ui.control.Label
        CalculateUpdateGraphsButton     matlab.ui.control.Button
        ParamPnlUITable                 matlab.ui.control.Table
        ParamPnl_t_op_EditField         matlab.ui.control.NumericEditField
        ParamPnl_cp_r_EditField         matlab.ui.control.NumericEditField
        ParamPnl_cp_r_Label             matlab.ui.control.Label
        ParamPnl_rho_r_EditField        matlab.ui.control.NumericEditField
        ParamPnl_rho_r_Label            matlab.ui.control.Label
        ParamPnl_T_inj_EditField        matlab.ui.control.NumericEditField
        ParamPnl_T_inj_Label            matlab.ui.control.Label
        ParamPnl_T_res_EditField        matlab.ui.control.NumericEditField
        ParamPnl_T_res_Label            matlab.ui.control.Label
        ParamPnl_t_op_Label             matlab.ui.control.Label
        ParamPnlDeleteChannelButton     matlab.ui.control.Button
        ParamPnlAddChannelButton        matlab.ui.control.Button
        ParamPnlShowAvgDropDown         matlab.ui.control.DropDown
        ParamPnlShowAvgLabel            matlab.ui.control.Label
        ParamPnl_nChn_EditField         matlab.ui.control.NumericEditField
        ParamPnl_nChnLabel              matlab.ui.control.Label
        designTmpProfLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Startup *******************************************************************************************
        TATPath                         % Path to Tracer Analysis Toolbox
        TATName                         % Name of Tracer Analysis Toolbox file
        TAText                          % Extension for Tracer Analysis Toolbox file
        MLDefaultColors                 % Vector of RGB codes for line default colors us in Matlab
        hScale                          % Horizontal scaling factor for monitor resolution differences
        vScale                          % Vertical scaling factor for monitor resolution differences
        
        CommonVars_Hndl                  
        DesignConservativeTrTest_Hndl
        DesignSelectReactiveTr_Hndl
        DesignReactiveTrTest_Hndl
        AnalysisConservativeTrTest_Hndl
        AnalysisReactiveTrTest_Hndl                
        
        % Parameters Panel
        nChn                    % Number of channels for temperature profile in design workflow
        ParamPnlSumTDropDownOld % Backup of Sum values dropdown box value
        frac_i                  % fraction of flow in each channel

        % Plot Options Panel
        PlotOptLineStyleList    % Categorical list of options for line style
        ActualLineStyleList
        InitialColor            % Initial line color
        PlotOptTableData        % Data for plot options table
        PlotOptionsLineTable    % Plot options table for line properties
        PlotOptPointStyleList   % Categorical list of options for point style for mean T when x-axis is distance

        % Plot Panel
        x_rel                   % x values used in plotting
        t_days                  % t values used in plotting
        T_x                     % Temperature as func(distance)
        T_x_avg                 % Temperature of mixture of channels
        T_t                     % Temperature as func(time)
        T_t_avg                 % Production temperature over time
        H_t                     % Enthalpy as func(time)
        H_t_avg                 % Average Enthalpy as func(time)
    end
    
    methods (Access = private)
        
        function TATSetup(app)
        % SETUP: establishes basic information about the app
        % including location, screen size, default plot colors
            % Get location of app
            [app.TATPath, app.TATName, app.TAText] = fileparts(mfilename('fullpath'));

            % Add path to functions
            addPathNames = strcat(app.TATPath,"\TAT_Fcts");
            addpath(genpath(addPathNames));

            % Get Screen size and define scaling factors
            screenSize = get(groot,'ScreenSize');
            if screenSize(3) < app.UIFigure.Position(3)
               app.hScale = screenSize(3)/2560;
            else 
               app.hScale = 1;
            end 
            if screenSize(4) < app.UIFigure.Position(4)
               app.vScale = screenSize(4)/1440;
            else 
               app.vScale = 1;
            end             

            % Run relevant valuechanged function callbacks
            Common_t_op_DropDownValueChanged(app)
            Common_lambda_r_DropDownValueChanged(app)

            % Set Matlab's default colors for lines
            app.MLDefaultColors = ["0.000, 0.447, 0.741" "0.850, 0.325, 0.098" "0.929, 0.694, 0.125" "0.494, 0.184, 0.556" "0.466, 0.674, 0.188" ...
                "0.301, 0.745, 0.933" "0.635, 0.078, 0.184","0.000 0.000 1.000","0.000 0.500 0.000","1.000 0.000 0.000" "0.000 0.000 0.000"]; 
        end
        
        function ParamTableInitial(app)
            app.ParamPnlUITable.Data = table(1,0.01,1000,0.05,2.5,1,1,true,'VariableNames',{'Channel','v','L','b','lambda_r','t_op','f','Include'});
            app.nChn = 1;
            app.ParamPnl_nChn_EditField.Value = app.nChn;
        end
        
        function createPlotOptTable(app)
        % Construct the Plot Options Table in the Plot Options panel
            app.PlotOptLineStyleList = categorical({'Solid', 'Dashed', 'Dotted', 'Dash-Dotted','none'});
            app.ActualLineStyleList = {'-';'--';':';'-.';''};
            app.PlotOptPointStyleList = categorical({'Open', 'Filled'});
            app.InitialColor = [app.MLDefaultColors(1)];
            ID = {1;'Avg'};
            LColor = [app.MLDefaultColors(1);app.MLDefaultColors(10)];
            LWidth = {2;2};
            LStyle = {app.PlotOptLineStyleList(1);app.PlotOptLineStyleList(1)};
            app.PlotOptTableData = table(ID, LColor, LWidth, LStyle);
            app.PlotOptionsLineTable = uitable(app.PlotPanel);
            app.PlotOptionsLineTable.BackgroundColor = [1 1 1;0.9412 0.9412 0.9412];
            app.PlotOptionsLineTable.CellSelectionCallback = createCallbackFcn(app, @PlotOptLineTableCellSelection, true);
            app.PlotOptionsLineTable.ColumnName = {'Channel'; 'Color (RGB)'; 'Line Width'; 'Style'};
            app.PlotOptionsLineTable.RowName = {};
            app.PlotOptionsLineTable.ColumnEditable = true;
            app.PlotOptionsLineTable.RowStriping = 'off';
            app.PlotOptionsLineTable.FontWeight = 'bold';
            app.PlotOptionsLineTable.Position = [app.hScale*225 app.vScale*20 app.hScale*401 app.vScale*185];
            app.PlotOptionsLineTable.ColumnWidth = {65 130 81 125};
            app.PlotOptionsLineTable.ColumnEditable = [false, true, true, true];
                %Initialize values associated with table
                app.PlotOptionsLineTable.Data = app.PlotOptTableData;    
            for i = 1:2
                myColorStyle = uistyle("BackgroundColor",app.PlotOptionsLineTable.Data{i,2},"FontColor",[0.94 0.94 0.94]);
                addStyle(app.PlotOptionsLineTable,myColorStyle,'cell',[i,2]);
            end            
                
        end
        
        function PlotOptLineTableCellSelection(app,event)
            % Provides color selection when box in color column is clicked
            indices = event.Indices;
            if and(~isempty(indices),size((indices),1)==1) %if clicked in channel row
                if(indices(2) == 2) %if 2nd column cell selected
                    colorOld = str2num(app.PlotOptionsLineTable.Data{indices(1),2}{1});
                    colorNew = num2str(round(uisetcolor(colorOld),3));
                    if ~strcmp(colorNew,'0') %if operation not canceled
                        app.PlotOptionsLineTable.Data{indices(1),2}{1} = colorNew;
                        myColorStyle = uistyle("BackgroundColor",colorNew);
                        addStyle(app.PlotOptionsLineTable,myColorStyle,'cell',[indices(1),indices(2)]);          
                        app.PlotOptionsLineTable.Selection = [indices(1),1];
                    end
                end
            end
        end            
                
        function updatePlotOptTable(app)
        % Update Plot Options Table
   
            % Define columns
            ID = {'0';'0';'0';'0';'0';'0';'0';'0';'0';'0';'0'};
            for i = 1 : app.nChn 
                ID{i} = app.ParamPnlUITable.Data{i,1};
            end
                
            LColor = app.MLDefaultColors' ;
            LWidth = {2;2;2;2;2;2;2;2;2;2;2};
            LStyle = app.PlotOptLineStyleList(1);
            LStyle = {LStyle;LStyle;LStyle;LStyle;LStyle;LStyle;LStyle;LStyle;LStyle;LStyle;LStyle};
            if(app.ParamPnlShowAvgDropDown.Value == "Yes")
                LColor(app.nChn+1,1) = app.MLDefaultColors(11);
                LWidth{app.nChn+1,1} = 3;
            end
    
            app.PlotOptTableData = table(ID, LColor, LWidth, LStyle);
            app.PlotOptionsLineTable.Data = app.PlotOptTableData(1:app.nChn,:);
    
            % Update last line in Plot Options Table
            if (app.ParamPnlShowAvgDropDown.Value == "Yes")
                % Define newline for the sum/average curve
                ID = {'Production'};
                LColor = app.MLDefaultColors(11);
                LWidth = {3};
                LStyle = {'Solid'};
                newline = {ID, LColor, LWidth, LStyle};
                % Append new line to Plot Option table and display
                app.PlotOptionsLineTable.Data  = [app.PlotOptionsLineTable.Data; newline];
            end

            % Format the Table
            for i = 1 : height(app.PlotOptionsLineTable.Data)
                myColorStyle = uistyle("BackgroundColor",app.PlotOptionsLineTable.Data{i,2},"FontColor",[0.94 0.94 0.94]);
                addStyle(app.PlotOptionsLineTable,myColorStyle,'cell',[i,2]);
            end            
            
        end
        
        function updateFraction(app)
        % Calculates fractional flow in each of the channels
            if(app.ParamPnlShowAvgDropDown.Value == "Yes") 
                v = app.ParamPnlUITable.Data.v;
                b = app.ParamPnlUITable.Data.b;
                includeChn = app.ParamPnlUITable.Data.Include;
                app.frac_i = QFracCalc(v,b,includeChn);
            else
                app.frac_i(1:app.nChn,1) = 1;
            end
            app.ParamPnlUITable.Data.f = app.frac_i;
        end

        function [T_x, T_t, T_t_avg, H_t, H_t_avg] = TProfileCalc(app)
        % Setup temp. profile calculation and call calculation function
            % Create x-axis and t-axis values
            npts = 101;  
        	app.x_rel = linspace(0,1,npts);
        	app.t_days = linspace(app.PlotOptionsTimeMinEditField.Value, app.PlotOptionsTimeMaxEditField.Value, npts);
            
            % Get the temperatures, calculate fluid heat capacity
            T_res = app.ParamPnl_T_res_EditField.Value;
            T_inj = app.ParamPnl_T_inj_EditField.Value;
            rho_f = rhoH2OCalc(T_res);
            cp_f = cpH2OCalc(T_res);
            rho_r = app.ParamPnl_rho_r_EditField.Value;
            cp_r = app.ParamPnl_cp_r_EditField.Value;
            Q = app.ParamPnl_Q_EditField.Value;
            
            % Get channel parameters from temperature profile parameter table
            v = app.ParamPnlUITable.Data.v(app.ParamPnlUITable.Data.Include);
            L = app.ParamPnlUITable.Data.L(app.ParamPnlUITable.Data.Include);
            b = app.ParamPnlUITable.Data.b(app.ParamPnlUITable.Data.Include); 
            f = app.ParamPnlUITable.Data.f(app.ParamPnlUITable.Data.Include); 
            if (app.Common_lambda_r_DropDown.Value == "No")
            	lambda_r =  app.ParamPnlUITable.Data.lambda_r(app.ParamPnlUITable.Data.Include);
            else
                lambda_r = app.ParamPnl_lambda_r_EditField.Value;
            end            
            if (app.Common_t_op_DropDown.Value == "No")
            	t_op_days =  app.ParamPnlUITable.Data.t_op(app.ParamPnlUITable.Data.Include);
            else
                t_op_days = app.ParamPnl_t_op_EditField.Value;
            end
            
            % Calculate the temperature profile for all curves in the parameter Table            
            T_x = TempProfile(lambda_r, L, app.x_rel, v, b, cp_f, cp_r, rho_f, rho_r, 86400.*t_op_days, T_res, T_inj);

            % Calculate temperature vs time for all curves in the temperature profile parameter Table
            T_t = TempProfile(lambda_r, L, ones(length(v),1), v, b, cp_f, cp_r, rho_f, rho_r, 86400.*app.t_days, T_res, T_inj);  

            % Avg the production well values
            T_t_avg = TMixCalc(T_t,f);
 
            % Calculate enthalpy in MW
            % H_t = (T_t-T_inj) .* Q .* rho_f .* cpH2OCalc(T_t) .* f ;
            H_t =       10^-6 .* (T_t - T_inj) .* Q.* f .* rho_f .* cpH2OCalc(T_t) ;
            H_t_avg =  sum(H_t, 1) ;
        end
     end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
            % Get screen size and define hScale, vScale, and plot default colors
            TATSetup(app)

            % Initialize Parameter Table
            ParamTableInitial(app)

            % Create Components
            createPlotOptTable(app)                  % Table for plot options
        end

        % Button pushed function: CalculateUpdateGraphsButton, 
        % ...and 1 other component
        function UpdatePlotButtonPushed(app, event)
        % UpdatePlotButtonPushed: plots temperature versus time and distance
        % Input variables: all from global definition

        % Get the channel numbers from the temperature profile parameter table
        NumChan = sum(app.ParamPnlUITable.Data.Include);
        ChnNo = app.ParamPnlUITable.Data.Channel(app.ParamPnlUITable.Data.Include);
        includeChn = app.ParamPnlUITable.Data.Include;

        app.frac_i = app.ParamPnlUITable.Data.f(app.ParamPnlUITable.Data.Include);

        % Create legend list
        ChannelLabels = strcat("Channel ", num2str(ChnNo)) ;
        legendList = [] ;
        if(app.ParamPnlShowAvgDropDown.Value == "Yes")           
            legendList = [ChannelLabels; "Production"];          
            endloop = NumChan + 1;
        else
            legendList = ChannelLabels ;
            endloop = NumChan ;
        end
   
        % Calculate temperature profiles and production records
        [app.T_x, app.T_t, app.T_t_avg, app.H_t, app.H_t_avg] = TProfileCalc(app);           

        % Get vectors of line stylings
        [~,loc] = ismember(string(app.PlotOptionsLineTable.Data{1: endloop,4}), string(app.PlotOptLineStyleList'));
        linstyle = app.ActualLineStyleList(loc);    
        linWidth = (app.PlotOptionsLineTable.Data{1: endloop,3});
        linColor = (app.PlotOptionsLineTable.Data{1: endloop,2});

        % PLOT TEMPERATURE VS DISTANCE
        hold(app.UIaxis_T_x,"off");
        xlim(app.UIaxis_T_x,[0, 1]);
        for i = 1 : NumChan
                plot(app.UIaxis_T_x, app.x_rel, app.T_x(i,:), "LineWidth",linWidth{i}, "LineStyle",linstyle(i), "Color",linColor{i});
                hold(app.UIaxis_T_x,"on");
        end
        % Add the plot of the avg of the curves
        if(app.ParamPnlShowAvgDropDown.Value == "Yes")           
            app.T_x_avg = TMixCalc(app.T_x(:,end),app.frac_i); % Avg the production well values
            plot(app.UIaxis_T_x, app.x_rel(end), app.T_x_avg, 'ko')
        end
        % Add legend to plot
        legend(app.UIaxis_T_x,legendList);
        ylim(app.UIaxis_T_x,[app.PlotOptionsConcMinEditField.Value, app.PlotOptionsConcMaxEditField.Value]);

        % PLOT TEMPERATURE VS TIME
        hold(app.UIaxis_T_t,"off");
        xlim(app.UIaxis_T_t,[app.PlotOptionsTimeMinEditField.Value, app.PlotOptionsTimeMaxEditField.Value]);
        for i = 1 : NumChan
            plot(app.UIaxis_T_t, app.t_days, app.T_t(i,:), "LineWidth",linWidth{i}, "LineStyle",linstyle(i), "Color",linColor{i});
            hold(app.UIaxis_T_t,"on");
        end
        % Add the plot of the avg of the curves
        if(app.ParamPnlShowAvgDropDown.Value == "Yes")
            plot(app.UIaxis_T_t, app.t_days, app.T_t_avg,"LineWidth",linWidth{endloop},"LineStyle",linstyle(endloop),"color",linColor{endloop});           
        end
        % Add legend to plot
        legend(app.UIaxis_T_t,legendList);
        ylim(app.UIaxis_T_t,[app.PlotOptionsConcMinEditField.Value, app.PlotOptionsConcMaxEditField.Value]);
                    
        % PLOT ENTHALPY VS TIME
        hold(app.UIaxis_H_t,"off");
        xlim(app.UIaxis_H_t,[app.PlotOptionsTimeMinEditField.Value, app.PlotOptionsTimeMaxEditField.Value]);
        for i = 1 : NumChan
            plot(app.UIaxis_H_t, app.t_days, app.H_t(i,:), "LineWidth",linWidth{i}, "LineStyle",linstyle(i), "Color",linColor{i});
            hold(app.UIaxis_H_t,"on");
        end
        % Add the plot of the avg of the curves
        if(app.ParamPnlShowAvgDropDown.Value == "Yes")
            % Avg the production well values
            H_t_avg = sum(app.H_t,1);
            plot(app.UIaxis_H_t, app.t_days, H_t_avg, "LineWidth", linWidth{endloop}, "LineStyle",linstyle(endloop), "Color",linColor{endloop});            
        end
        % Add legend to plot
        legend(app.UIaxis_H_t,legendList);
        ylim(app.UIaxis_H_t,[app.PlotOptionsConcMinEditField.Value, app.PlotOptionsConcMaxEditField.Value]);
        end

        % Value changed function: ParamPnlShowAvgDropDown
        function ParamPnlShowAvgDropDownValueChanged(app, event)
            % Update Parameter Table fraction
            updateFraction(app)

            % Update Plot Options Table
            updatePlotOptTable(app)
            
            % Update the plots
            UpdatePlotButtonPushed(app)            
        end

        % Button pushed function: ParamPnlAddChannelButton
        function ParamPnlAddChannelButtonPushed(app, event)
            % Check if nChn <= 10
            if(app.nChn > 10)
               errordlg('Number of channels must be <= 10','Input Error')
               return
            else
                if(app.nChn == 0)
                    maxChn = 0;
                else
                    % Find the largest channel number
                    maxChn = max(app.ParamPnlUITable.Data.Channel); %MAP replaced the row '1' with ':'
                end
                maxChn = maxChn + 1;

                % Increment the number of channels and update editfield displaying nChn
                app.nChn = app.nChn + 1;
                app.ParamPnl_nChn_EditField.Value = app.nChn;

                % Copy last row to make new row
                newRow = app.ParamPnlUITable.Data(end,:);
                newRow.L = newRow.L * 1.2 ; % Change variable to separate plots
                newRow.Channel = maxChn;
                app.ParamPnlUITable.Data = [app.ParamPnlUITable.Data; newRow];

               % Update fractions in table
                updateFraction(app)

                % Generate New Line for Plot Options Table
                updatePlotOptTable(app)
            end

        end

        % Button pushed function: ParamPnlDeleteChannelButton
        function ParamPnlDeleteChannelButtonPushed(app, event)
        % Open dialogue box to and get list of channels to be deleted
 	        prompt = {'Enter space-separated channel numbers:'};
            deleteDlgTitle = 'Delete Channels';
            dims = [1,40];
            deleteResponse = inputdlg(prompt,deleteDlgTitle,dims);
            if(isempty(deleteResponse))
                return
            end
            deleteChn = str2num(deleteResponse{1});
            nDelete = size(deleteChn,2);

            % Display error msg if none or all channels are specified
            if(nDelete == 0)
                return
            elseif (nDelete == app.nChn)
                errTxt = 'Cannot delete all channels';
                errordlg(errTxt,'Input Error');
                return
            end
            
            % Determine the row for each channel to be deleted and add it
            % to the deleteRows vector
            for i = 1:nDelete
                 % Set error code for finding channel to 1
                chnFindError = 1;
                for j = 1: app.nChn
                    if (deleteChn(i) == app.ParamPnlUITable.Data{j,1})
                        deleteRows(1,i) = j;
                        chnFindError = 0;
                        break;
                    end
                end

                % Display error message if channel number not found
                if (chnFindError == 1)
                    errTxt = ['Channel ' num2str(deleteChn(i)) ' not found. Check input.'];
                    errordlg(errTxt,'Input Error')
                    return
                end
            end

            % Delete rows from table if all channels found
            app.ParamPnlUITable.Data(deleteRows,:) = [];
            app.PlotOptionsLineTable.Data(deleteRows,:) = [];
            app.nChn = app.nChn - nDelete;
            app.ParamPnl_nChn_EditField.Value = app.nChn;

            % Update table with new fractional flow values
            updateFraction(app);
        end

        % Cell edit callback: ParamPnlUITable
        function ParamPnlUITableCellEdit(app, event)
            % Get the table indices for cell being edited
            indices = event.Indices;
            newData = event.NewData;

            % Save the old data
            oldData = app.ParamPnlUITable.Data{indices(1),indices(2)};

            % Check for duplicate channel number
            for i = 1 : app.nChn
                if(indices(2) == 1 && i ~= indices(1) && newData == app.ParamPnlUITable.Data{i,1})
                        errTxt = [ 'Channel ' num2str(newData) ' already exists. Choose another channel number.'];
                        errordlg(errTxt, 'Input Error')
                        newData = oldData;
                        break
                end
            end
            
            % Calculate fractional flows
            updateFraction(app);

            % Insert updated fractional flow into parameter table
            app.ParamPnlUITable.Data.f = app.frac_i;

            % Update Plot Options Table
            updatePlotOptTable(app)
            
        end

        % Button pushed function: EqHelpButton
        function EqHelpButtonPushed(app, event)
        % EqHelpButtonPushed: Opens help file for the conservative tracer

            % Define file location
            eqHelpPath = fullfile(app.TATPath,'Docs','TemperatureProfileHelp.pdf');
            
            % Open Manual
            winopen(eqHelpPath)            
        end

        % Menu selected function: SaveparametersMenu
        function SaveparametersMenuSelected(app, event)
            % Select file to save to
            [filename,pathname] = uiputfile('*.*');
            if isequal(filename,0)
                return;
            end
            fileNamefull = fullfile(pathname,filename);    

            % Read the preexisting structure if file exists
            if isfile(fileNamefull)
                Struct = read_namelist(fileNamefull);
            end
          
            % Define the relevant structure pieces
            Struct.DesignTemperatureProfile.nChn       = app.ParamPnl_nChn_EditField.Value;            
            Struct.DesignTemperatureProfile.T_res      = app.ParamPnl_T_res_EditField.Value;
            Struct.DesignTemperatureProfile.T_inj      = app.ParamPnl_T_inj_EditField.Value;
            Struct.DesignTemperatureProfile.rho_r      = app.ParamPnl_rho_r_EditField.Value;
            Struct.DesignTemperatureProfile.cp_r       = app.ParamPnl_cp_r_EditField.Value;
            Struct.DesignTemperatureProfile.lambda_r   = app.ParamPnl_lambda_r_EditField.Value;
            Struct.DesignTemperatureProfile.Q          = app.ParamPnl_Q_EditField.Value;      
            Struct.DesignTemperatureProfile.t_op       = app.ParamPnl_t_op_EditField.Value;

            % Channel data table
            Struct.DesignTemperatureProfile.Table_Channel   = app.ParamPnlUITable.Data{:,1}; 
            Struct.DesignTemperatureProfile.Table_v         = app.ParamPnlUITable.Data{:,2}; 
            Struct.DesignTemperatureProfile.Table_L         = app.ParamPnlUITable.Data{:,3}; 
            Struct.DesignTemperatureProfile.Table_b         = app.ParamPnlUITable.Data{:,4}; 
            Struct.DesignTemperatureProfile.Table_lambda_r  = app.ParamPnlUITable.Data{:,5}; 
            Struct.DesignTemperatureProfile.Table_t_op      = app.ParamPnlUITable.Data{:,6}; 
            Struct.DesignTemperatureProfile.Table_f         = app.ParamPnlUITable.Data{:,7}; 
            Struct.DesignTemperatureProfile.Table_Include   = double(app.ParamPnlUITable.Data{:,8});
            
            % Now overwrite the existing file
            write_namelist(Struct,fileNamefull);   
        end

        % Menu selected function: ReadparametersMenu
        function ReadparametersMenuSelected(app, event)
        % Select file to read
        [filename,pathname] = uigetfile('*.*');
        if isequal(filename,0)
            return;
        end
        fileNamefull = fullfile(pathname,filename);    

        % Read the structure 
        Struct = read_namelist(fileNamefull);

        % Stop if no data
        if ~isfield(Struct,'DesignTemperatureProfile')
            msgbox('No Temperature Profile section found; execution halted')
            return
        end            
      
        app.ParamPnlUITable.Data = [];  
        % Use the pieces to define the relevant fields            
        app.ParamPnl_T_res_EditField.Value	    = Struct.DesignTemperatureProfile.T_res   	;
        app.ParamPnl_T_inj_EditField.Value	    = Struct.DesignTemperatureProfile.T_inj   	;
        app.ParamPnl_rho_r_EditField.Value	    = Struct.DesignTemperatureProfile.rho_r   	;
        app.ParamPnl_cp_r_EditField.Value	    = Struct.DesignTemperatureProfile.cp_r    	;
        app.ParamPnl_lambda_r_EditField.Value	= Struct.DesignTemperatureProfile.lambda_r	;
        app.ParamPnl_Q_EditField.Value     	    = Struct.DesignTemperatureProfile.Q       	;
        app.ParamPnl_t_op_EditField.Value	    = Struct.DesignTemperatureProfile.t_op    	;
       
        % Channel data table
        Channel      =   Struct.DesignTemperatureProfile.Table_Channel'            ;
        v            =   Struct.DesignTemperatureProfile.Table_v'                ;
        L            =   Struct.DesignTemperatureProfile.Table_L'                ;
        b            =   Struct.DesignTemperatureProfile.Table_b'                ;
        lambda_r     =   Struct.DesignTemperatureProfile.Table_lambda_r'         ;
        t_op         =   Struct.DesignTemperatureProfile.Table_t_op'             ;
        f            =   Struct.DesignTemperatureProfile.Table_f'                ;
        Include      =   logical(Struct.DesignTemperatureProfile.Table_Include)' ;
        app.ParamPnlUITable.Data = table(Channel,v,L,b,lambda_r,t_op,f,Include)  ;

        app.nChn = height(app.ParamPnlUITable.Data) ;
        app.ParamPnl_nChn_EditField.Value = app.nChn ;

        % Update Parameter Table fraction
        updateFraction(app)

        %Update Plot Options Table
        updatePlotOptTable(app)                               
        end

        % Menu selected function: SaveplottedresultsMenu
        function SaveplottedresultsMenuSelected(app, event)
        % Prep for saving plots to file

        % Get the channel numbers from the parameter table
        ChnNo = table2array(app.ParamPnlUITable.Data(:,1));
        Include = table2array(app.ParamPnlUITable.Data(:,end));
        
% [app.T_x, app.T_t, app.T_t_avg, app.H_t, app.H_t_avg] = TProfileCalc(app); 
      

        % Create 'preHeader' that contains title and production well values
        preheader = "Temperature profile data" ;

        % Create headers for each variable for each channel
        T_x_headers = strcat('T_x_Ch_',string(ChnNo(Include)))' ;
        T_t_headers = strcat('T_t_Ch_',string(ChnNo(Include)))' ;
        H_t_headers = strcat('H_t_Ch_',string(ChnNo(Include)))' ;

        if(app.ParamPnlShowAvgDropDown.Value == "Yes" )
            preheader = [preheader, ': Production temperature = ', num2str(app.T_x_avg)]
            header = ['Relative distance', T_x_headers, 'Time (d)', T_t_headers, 'T_t_Production' H_t_headers, 'H_t_Production'] ;
            dataMatrix = [app.x_rel', app.T_x', app.t_days' , app.T_t', app.T_t_avg', app.H_t', app.H_t_avg'] ;
        else
            header = ['Relative distance', T_x_headers, 'Time (d)', T_t_headers, H_t_headers] ;
            dataMatrix = [app.x_rel', app.T_x', app.t_days' , app.T_t', app.H_t'];
        end

        % Save headers and data to file
        [savefile,savepath] = uiputfile('*.*');
        if ~savefile==0
            saveFileFull = [savepath savefile];
            writematrix(preheader,saveFileFull)                
            writematrix(header,saveFileFull,'Delimiter',',','WriteMode','append')
            writematrix(dataMatrix,saveFileFull,'Delimiter',',','WriteMode','append')  
        end

        end

        % Menu selected function: ViewMenu
        function ViewMenuSelected(app, event)
        app.CommonVars_Hndl = TAT08CommonVars;
        end

        % Menu selected function: SendtoMenu
        function SendtoMenuSelected(app, event)
        app.CommonVars_Hndl = TAT08CommonVars;

        app.CommonVars_Hndl.T_res_EditField.Value    = app.ParamPnl_T_res_EditField.Value;
        app.CommonVars_Hndl.T_inj_EditField.Value    = app.ParamPnl_T_inj_EditField.Value;
        app.CommonVars_Hndl.rho_r_EditField.Value    = app.ParamPnl_rho_r_EditField.Value;
        app.CommonVars_Hndl.cp_r_EditField.Value     = app.ParamPnl_cp_r_EditField.Value;
        app.CommonVars_Hndl.Q_EditField.Value        = app.ParamPnl_Q_EditField.Value;
        app.CommonVars_Hndl.t_op_EditField.Value     = app.ParamPnl_t_op_EditField.Value;
        app.CommonVars_Hndl.lambda_r_EditField.Value = app.ParamPnl_lambda_r_EditField.Value;

        % This table is         Channel,v,L,b,lambda_r,t_op,f,Include
        % CommonVars table is   Channel,v,L,  alpha,b,      f,Include        
        app.CommonVars_Hndl.Channel_UITable.Data  = [] ;     
        app.CommonVars_Hndl.Channel_UITable.Data = app.ParamPnlUITable.Data(:,{'Channel','v','L','b','f','Include'}) ;   
        Nrows = height(app.ParamPnlUITable.Data) ;
        alpha = app.CommonVars_Hndl.alpha_c_EditField.Value .* app.CommonVars_Hndl.Channel_UITable.Data.L .^app.CommonVars_Hndl.alpha_m_EditField.Value ;
        app.CommonVars_Hndl.Channel_UITable.Data = addvars(app.CommonVars_Hndl.Channel_UITable.Data, alpha, 'before', 'b', 'NewVariableNames',{'alpha'}) ;

        % app.CommonVars_Hndl.Channel_UITable.Data.Properties.VariableNames = {'Channel','v','L','alpha','b','f','Include'}
        end

        % Menu selected function: PullfromMenu
        function PullfromMenuSelected(app, event)
        app.CommonVars_Hndl = TAT08CommonVars;

        app.ParamPnl_T_res_EditField.Value      = app.CommonVars_Hndl.T_res_EditField.Value;
        app.ParamPnl_T_inj_EditField.Value      = app.CommonVars_Hndl.T_inj_EditField.Value;
        app.ParamPnl_rho_r_EditField.Value      = app.CommonVars_Hndl.rho_r_EditField.Value;
        app.ParamPnl_cp_r_EditField.Value       = app.CommonVars_Hndl.cp_r_EditField.Value;
        app.ParamPnl_Q_EditField.Value          = app.CommonVars_Hndl.Q_EditField.Value;            
        app.ParamPnl_t_op_EditField.Value       = app.CommonVars_Hndl.t_op_EditField.Value;
        app.ParamPnl_lambda_r_EditField.Value   = app.CommonVars_Hndl.lambda_r_EditField.Value;

        if height(app.CommonVars_Hndl.Channel_UITable.Data) > 0
            % This table is         Channel,v,L,b,lambda_r,t_op,f,Include
            % CommonVars table is   Channel,v,L,  alpha,b,      f,Include
            app.ParamPnlUITable.Data  = [] ;     
            app.ParamPnlUITable.Data = app.CommonVars_Hndl.Channel_UITable.Data(:,{'Channel','v','L','b','f','Include'}) ;   
            app.nChn = height(app.CommonVars_Hndl.Channel_UITable.Data) ;
            lambda_r(1:app.nChn,1) = app.CommonVars_Hndl.lambda_r_EditField.Value ;
            t_op(1:app.nChn,1) = app.CommonVars_Hndl.t_op_EditField.Value ;
            app.ParamPnlUITable.Data = addvars(app.ParamPnlUITable.Data,lambda_r,t_op,'before','f') ;
            app.ParamPnlUITable.Data.Properties.VariableNames = {'Channel','v','L','b','lambda_r','t_op','f','Include'} ;
        end

        % Update Parameter Table fraction
        updateFraction(app)

        % Update Plot Options Table
        updatePlotOptTable(app)  

        % Return control to self
        TAT08DesignTemperatureProfile ;
        end

        % Menu selected function: desConservativetracerMenu
        function desConservativetracerMenuSelected(app, event)
        app.DesignConservativeTrTest_Hndl = TAT08DesignConservativeTrTest;
        end

        % Menu selected function: desReactivetracerselectionMenu
        function desReactivetracerselectionMenuSelected(app, event)
       app.DesignSelectReactiveTr_Hndl = TAT08DesignSelectReactiveTr;
        end

        % Menu selected function: desReactivetracerMenu
        function desReactivetracerMenuSelected(app, event)
        app.DesignReactiveTrTest_Hndl = TAT08DesignReactiveTrTest;            
        end

        % Menu selected function: analConservativetracerMenu
        function analConservativetracerMenuSelected(app, event)
        app.AnalysisConservativeTrTest_Hndl = TAT08AnalysisConservativeTrTest;
        end

        % Menu selected function: analReactivetracerMenu
        function analReactivetracerMenuSelected(app, event)
        app.AnalysisReactiveTrTest_Hndl = TAT08AnalysisReactiveTrTest;
        end

        % Value changed function: Common_lambda_r_DropDown
        function Common_lambda_r_DropDownValueChanged(app, event)
        if (app.Common_lambda_r_DropDown.Value == "No")
            % Define visibility of lambda_r Edit Fields
            app.ParamPnl_lambda_r_EditField.Visible = 'off';
            app.ParamPnl_lambda_r_Label.Visible = 'off';
            % Define font color of op time column
            s = uistyle("FontColor","k");
            addStyle(app.ParamPnlUITable,s,'column',5);                                               
        else
            % Define visibility of lambda_r Edit Fields
            app.ParamPnl_lambda_r_EditField.Visible = 'on';
            app.ParamPnl_lambda_r_Label.Visible = 'on';                
            % Define font color of op time column
            s = uistyle('FontColor',[0.7 0.7 0.7]);
            addStyle(app.ParamPnlUITable,s,'column',5)  
        end                            
        end

        % Value changed function: Common_t_op_DropDown
        function Common_t_op_DropDownValueChanged(app, event)
        if (app.Common_t_op_DropDown.Value == "No")
            % Define visibility of t_op Edit Fields
            app.ParamPnl_t_op_EditField.Visible = 'off';
            app.ParamPnl_t_op_Label.Visible = 'off';
            % Define font color of op time column
            s = uistyle("FontColor","k");
            addStyle(app.ParamPnlUITable,s,'column',6);                                               
        else
            % Define visibility of t_op Edit Fields
            app.ParamPnl_t_op_EditField.Visible = 'on';
            app.ParamPnl_t_op_Label.Visible = 'on';                
            % Define font color of op time column
            s = uistyle('FontColor',[0.7 0.7 0.7]);
            addStyle(app.ParamPnlUITable,s,'column',6)   
        end                                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Design Workflow -- Temperature Profile';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'appdata', 'TATIcon.png');
            app.FileActionsMenu = uimenu(app.UIFigure);
            app.FileActionsMenu.Text = 'File Actions';
            app.SaveparametersMenu = uimenu(app.FileActionsMenu);
            app.SaveparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveparametersMenuSelected, true);
            app.SaveparametersMenu.Text = 'Save parameters';
            app.ReadparametersMenu = uimenu(app.FileActionsMenu);
            app.ReadparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadparametersMenuSelected, true);
            app.ReadparametersMenu.Text = 'Read parameters';
            app.SaveplottedresultsMenu = uimenu(app.FileActionsMenu);
            app.SaveplottedresultsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveplottedresultsMenuSelected, true);
            app.SaveplottedresultsMenu.Text = 'Save plotted results';
            app.TaskControlMenu = uimenu(app.UIFigure);
            app.TaskControlMenu.Text = 'Task Control';
            app.DesignMenu = uimenu(app.TaskControlMenu);
            app.DesignMenu.Text = 'Design';
            app.desConservativetracerMenu = uimenu(app.DesignMenu);
            app.desConservativetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @desConservativetracerMenuSelected, true);
            app.desConservativetracerMenu.Text = 'Conservative tracer';
            app.desTemperatureprofilesMenu = uimenu(app.DesignMenu);
            app.desTemperatureprofilesMenu.Enable = 'off';
            app.desTemperatureprofilesMenu.Text = 'Temperature profiles';
            app.desReactivetracerselectionMenu = uimenu(app.DesignMenu);
            app.desReactivetracerselectionMenu.MenuSelectedFcn = createCallbackFcn(app, @desReactivetracerselectionMenuSelected, true);
            app.desReactivetracerselectionMenu.Text = 'Reactive tracer selection';
            app.desReactivetracerMenu = uimenu(app.DesignMenu);
            app.desReactivetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @desReactivetracerMenuSelected, true);
            app.desReactivetracerMenu.Text = 'Reactive tracer';
            app.AnalysisMenu = uimenu(app.TaskControlMenu);
            app.AnalysisMenu.Text = 'Analysis';
            app.analConservativetracerMenu = uimenu(app.AnalysisMenu);
            app.analConservativetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @analConservativetracerMenuSelected, true);
            app.analConservativetracerMenu.Text = 'Conservative tracer';
            app.analReactivetracerMenu = uimenu(app.AnalysisMenu);
            app.analReactivetracerMenu.MenuSelectedFcn = createCallbackFcn(app, @analReactivetracerMenuSelected, true);
            app.analReactivetracerMenu.Text = 'Reactive tracer';
            app.CommonVariablesMenu = uimenu(app.UIFigure);
            app.CommonVariablesMenu.Text = 'Common Variables';
            app.ViewMenu = uimenu(app.CommonVariablesMenu);
            app.ViewMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.ViewMenu.Text = 'View';
            app.SendtoMenu = uimenu(app.CommonVariablesMenu);
            app.SendtoMenu.MenuSelectedFcn = createCallbackFcn(app, @SendtoMenuSelected, true);
            app.SendtoMenu.Text = 'Send to';
            app.PullfromMenu = uimenu(app.CommonVariablesMenu);
            app.PullfromMenu.MenuSelectedFcn = createCallbackFcn(app, @PullfromMenuSelected, true);
            app.PullfromMenu.Text = 'Pull from';
            app.designTmpProfLabel = uilabel(app.UIFigure);
            app.designTmpProfLabel.Text = 'Temperature Profiles';
            app.ParamPanel = uipanel(app.UIFigure);
            app.ParamPanel.Title = 'Parameters';
            app.ParamPnl_nChnLabel = uilabel(app.ParamPanel);
            app.ParamPnl_nChnLabel.Text = 'No. Channels';
            app.ParamPnl_nChn_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_nChn_EditField.Limits = [1 10];
            app.ParamPnl_nChn_EditField.ValueDisplayFormat = '%.0f';
            app.ParamPnl_nChn_EditField.Editable = 'off';
            app.ParamPnl_nChn_EditField.Value = 1;
            app.ParamPnlShowAvgLabel = uilabel(app.ParamPanel);
            app.ParamPnlShowAvgLabel.Text = 'Show averages on plots';
            app.ParamPnlShowAvgDropDown = uidropdown(app.ParamPanel);
            app.ParamPnlShowAvgDropDown.Items = {'Yes', 'No'};
            app.ParamPnlShowAvgDropDown.ValueChangedFcn = createCallbackFcn(app, @ParamPnlShowAvgDropDownValueChanged, true);
            app.ParamPnlShowAvgDropDown.Value = 'Yes';
            app.ParamPnlAddChannelButton = uibutton(app.ParamPanel, 'push');
            app.ParamPnlAddChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ParamPnlAddChannelButtonPushed, true);
            app.ParamPnlAddChannelButton.Text = 'Add Channel';
            app.ParamPnlDeleteChannelButton = uibutton(app.ParamPanel, 'push');
            app.ParamPnlDeleteChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ParamPnlDeleteChannelButtonPushed, true);
            app.ParamPnlDeleteChannelButton.Text = 'Delete Channel';
            app.ParamPnl_t_op_Label = uilabel(app.ParamPanel);
            app.ParamPnl_t_op_Label.Text = ':                  (days)';
            app.ParamPnl_T_res_Label = uilabel(app.ParamPanel);
            app.ParamPnl_T_res_Label.Text = 'Reservoir temperature (°C)';
            app.ParamPnl_T_res_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_T_res_EditField.Limits = [0 Inf];
            app.ParamPnl_T_res_EditField.Value = 150;
            app.ParamPnl_T_inj_Label = uilabel(app.ParamPanel);
            app.ParamPnl_T_inj_Label.Text = 'Injection temperature (°C)';
            app.ParamPnl_T_inj_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_T_inj_EditField.Limits = [0 Inf];
            app.ParamPnl_T_inj_EditField.Value = 50;
            app.ParamPnl_rho_r_Label = uilabel(app.ParamPanel);
            app.ParamPnl_rho_r_Label.Interpreter = 'html';
            app.ParamPnl_rho_r_Label.Text = 'Density, (&rho;) rock (kg/m<sup>3</sup>)';
            app.ParamPnl_rho_r_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_rho_r_EditField.Value = 2650;
            app.ParamPnl_cp_r_Label = uilabel(app.ParamPanel);
            app.ParamPnl_cp_r_Label.Text = 'Specific heat (cp), rock (J/kg/°C)';
            app.ParamPnl_cp_r_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_cp_r_EditField.Value = 790;
            app.ParamPnl_t_op_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_t_op_EditField.Limits = [0 Inf];
            app.ParamPnl_t_op_EditField.Value = 1000;
            app.ParamPnlUITable = uitable(app.ParamPanel);
            app.ParamPnlUITable.ColumnName = {'Channel'; 'v'; 'L'; 'b'; 'lambda_r'; 't_op'; 'f'; 'Include'};
            app.ParamPnlUITable.RowName = {};
            app.ParamPnlUITable.ColumnEditable = [true true true true true true false true];
            app.ParamPnlUITable.CellEditCallback = createCallbackFcn(app, @ParamPnlUITableCellEdit, true);
            app.ParamPnlUITable.Tooltip = {'v: velocity (m/s)'; 'L path length (m)'; 'b: aperture (m)'; 'lambda_r: thermal conductivity (rock) (W/m/K)'; 't_op: Operating time (days)'; 'f: fluid mass fraction (-)'};
            app.CalculateUpdateGraphsButton = uibutton(app.ParamPanel, 'push');
            app.CalculateUpdateGraphsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.CalculateUpdateGraphsButton.Text = 'Calculate / Update Graphs';
            app.ThermalDataLabel = uilabel(app.ParamPanel);
            app.ThermalDataLabel.Text = 'Thermal Data';
            app.ExecutionControlLabel = uilabel(app.ParamPanel);
            app.ExecutionControlLabel.Text = 'Execution Control';
            app.PumpingrateQm3sLabel = uilabel(app.ParamPanel);
            app.PumpingrateQm3sLabel.Interpreter = 'html';
            app.PumpingrateQm3sLabel.Text = 'Pumping rate, Q (m<sup>3</sup>/s)';
            app.ParamPnl_Q_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_Q_EditField.Value = 1;
            app.ParamPnl_lambda_r_DropdownLabel = uilabel(app.ParamPanel);
            app.ParamPnl_lambda_r_DropdownLabel.Tooltip = {'Thermal conductivity (rock)'};
            app.ParamPnl_lambda_r_DropdownLabel.Interpreter = 'html';
            app.ParamPnl_lambda_r_DropdownLabel.Text = 'Use common &lambda;<sub>r</sub>';
            app.Common_lambda_r_DropDown = uidropdown(app.ParamPanel);
            app.Common_lambda_r_DropDown.Items = {'Yes', 'No'};
            app.Common_lambda_r_DropDown.ValueChangedFcn = createCallbackFcn(app, @Common_lambda_r_DropDownValueChanged, true);
            app.Common_lambda_r_DropDown.Tooltip = {'Thermal conductivity, rock'};
            app.Common_lambda_r_DropDown.Value = 'Yes';
            app.ParamPnl_lambda_r_EditField = uieditfield(app.ParamPanel, 'numeric');
            app.ParamPnl_lambda_r_EditField.Limits = [0 Inf];
            app.ParamPnl_lambda_r_EditField.Value = 2.5;
            app.ParamPnl_lambda_r_Label = uilabel(app.ParamPanel);
            app.ParamPnl_lambda_r_Label.Interpreter = 'html';
            app.ParamPnl_lambda_r_Label.Text = ':                  (W/m/K)';
            app.UsecommonoperatingtimeDropDownLabel = uilabel(app.ParamPanel);
            app.UsecommonoperatingtimeDropDownLabel.Text = 'Use common operating time';
            app.Common_t_op_DropDown = uidropdown(app.ParamPanel);
            app.Common_t_op_DropDown.Items = {'Yes', 'No'};
            app.Common_t_op_DropDown.ValueChangedFcn = createCallbackFcn(app, @Common_t_op_DropDownValueChanged, true);
            app.Common_t_op_DropDown.Value = 'Yes';
            app.EqHelpButton = uibutton(app.UIFigure, 'push');
            app.EqHelpButton.ButtonPushedFcn = createCallbackFcn(app, @EqHelpButtonPushed, true);
            app.EqHelpButton.Text = 'Equation Help';
            app.Eq = uiimage(app.UIFigure);
            app.Eq.ImageSource = fullfile(pathToMLAPP, 'appdata', 'TempProfileEq.png');
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plots';
            app.TabGroup = uitabgroup(app.PlotPanel);
            app.FlowpathtemperatureTab = uitab(app.TabGroup);
            app.FlowpathtemperatureTab.Title = 'Flowpath temperature';
            app.UIaxis_T_x = uiaxes(app.FlowpathtemperatureTab);
            title(app.UIaxis_T_x, 'Temperature Profiles')
            xlabel(app.UIaxis_T_x, 'Relative Distance (-)')
            ylabel(app.UIaxis_T_x, 'Temperature (oC)')
            zlabel(app.UIaxis_T_x, 'Z')
            app.ProducedtemperatureTab = uitab(app.TabGroup);
            app.ProducedtemperatureTab.Title = 'Produced temperature';
            app.UIaxis_T_t = uiaxes(app.ProducedtemperatureTab);
            title(app.UIaxis_T_t, 'Production Temperature ')
            xlabel(app.UIaxis_T_t, 'Operating Time (days)')
            ylabel(app.UIaxis_T_t, 'Temperature (oC)')
            zlabel(app.UIaxis_T_t, 'Z')
            app.ProducedEnthalpyTab = uitab(app.TabGroup);
            app.ProducedEnthalpyTab.Title = 'Produced Enthalpy';
            app.UIaxis_H_t = uiaxes(app.ProducedEnthalpyTab);
            title(app.UIaxis_H_t, 'Enthalpy Production Rate')
            xlabel(app.UIaxis_H_t, 'Operating Time (days)')
            ylabel(app.UIaxis_H_t, 'Enthalpy Production (MW)')
            zlabel(app.UIaxis_H_t, 'Z')
            app.UpdateGraphsWithCurrentPlotOptionsButton = uibutton(app.PlotPanel, 'push');
            app.UpdateGraphsWithCurrentPlotOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdateGraphsWithCurrentPlotOptionsButton.Text = 'Update Graphs With Current Plot Options';
            app.PlotOptionsTimeLabel = uilabel(app.PlotPanel);
            app.PlotOptionsTimeLabel.Text = 'Time (days)';
            app.PlotOptionsConcLabel = uilabel(app.PlotPanel);
            app.PlotOptionsConcLabel.Text = 'Temp (°C)';
            app.PlotOptionsTimeMinEditField = uieditfield(app.PlotPanel, 'numeric');
            app.PlotOptionsTimeMinEditField.Limits = [0 Inf];
            app.PlotOptionsConcMinEditField = uieditfield(app.PlotPanel, 'numeric');
            app.PlotOptionsConcMinEditField.Limits = [0 Inf];
            app.PlotOptionsTimeMaxEditField = uieditfield(app.PlotPanel, 'numeric');
            app.PlotOptionsTimeMaxEditField.Limits = [0 Inf];
            app.PlotOptionsTimeMaxEditField.ValueDisplayFormat = '%8.2e';
            app.PlotOptionsTimeMaxEditField.Value = 1000;
            app.PlotOptionsConcMaxEditField = uieditfield(app.PlotPanel, 'numeric');
            app.PlotOptionsConcMaxEditField.Limits = [0 Inf];
            app.PlotOptionsConcMaxEditField.Value = Inf;
            app.PlotOptionsMinLabel = uilabel(app.PlotPanel);
            app.PlotOptionsMinLabel.Text = 'Min';
            app.PlotOptionsMaxLabel = uilabel(app.PlotPanel);
            app.PlotOptionsMaxLabel.Text = 'Max';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TAT08DesignTemperatureProfile

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end